classdef visualize_light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CELL_ID                         matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        PreprocessingMenu               matlab.ui.container.Menu
        ArtifactRemovalMenu             matlab.ui.container.Menu
        ManualMenu                      matlab.ui.container.Menu
        AutomaticMenu                   matlab.ui.container.Menu
        DownsampleImageMenu             matlab.ui.container.Menu
        ThresholdImageMenu              matlab.ui.container.Menu
        HistogramMatchingMenu           matlab.ui.container.Menu
        ImageMenu                       matlab.ui.container.Menu
        NormalizeColorsMenu             matlab.ui.container.Menu
        SpectralUnmixingMenu            matlab.ui.container.Menu
        AdjustGammaMenu                 matlab.ui.container.Menu
        AllChannels                     matlab.ui.container.Menu
        IndividualChannels              matlab.ui.container.Menu
        AdjustBrightnessMenu            matlab.ui.container.Menu
        AdjustHistogramMenu             matlab.ui.container.Menu
        RotateAllMenu                   matlab.ui.container.Menu
        RotateAllHorizontalMenu         matlab.ui.container.Menu
        RotateAllVerticalMenu           matlab.ui.container.Menu
        RotateAllClockwiseMenu          matlab.ui.container.Menu
        RotateAllAntiClockwiseMenu      matlab.ui.container.Menu
        RotateImageMenu                 matlab.ui.container.Menu
        RotateImageHorizontalMenu       matlab.ui.container.Menu
        RotateImageVerticalMenu         matlab.ui.container.Menu
        RotateImageClockwiseMenu        matlab.ui.container.Menu
        RotateImageAntiClockwiseMenu    matlab.ui.container.Menu
        RotateNeuronsMenu               matlab.ui.container.Menu
        RotateNeuronsHorizontalMenu     matlab.ui.container.Menu
        RotateNeuronsVerticalMenu       matlab.ui.container.Menu
        RotateNeuronsClockwiseMenu      matlab.ui.container.Menu
        RotateNeuronsAntiClockwiseMenu  matlab.ui.container.Menu
        MirrorImageMenu                 matlab.ui.container.Menu
        MirrorNeuronsMenu               matlab.ui.container.Menu
        NeuronsMenu                     matlab.ui.container.Menu
        ImportneuronsfromMenu           matlab.ui.container.Menu
        CSVfileMenu                     matlab.ui.container.Menu
        NWBfileMenu                     matlab.ui.container.Menu
        ImportactivityfromMenu          matlab.ui.container.Menu
        NWBfileMenu_2                   matlab.ui.container.Menu
        ZephIRoutputMenu                matlab.ui.container.Menu
        FlipLRNeuronsMenu               matlab.ui.container.Menu
        AutoSegmentationMenu            matlab.ui.container.Menu
        CellposeDetectMenu             matlab.ui.container.Menu
        NeuronActivityMenu              matlab.ui.container.Menu
        TraceActivityMenu_2             matlab.ui.container.Menu
        DisplayNeuronActivityMenu       matlab.ui.container.Menu
        BatchDetectIDNeuronsMenu        matlab.ui.container.Menu
        DeleteUserIDsMenu               matlab.ui.container.Menu
        DeleteModelIDsMenu              matlab.ui.container.Menu
        ToggleAutoIDUpdatesMenu         matlab.ui.container.Menu
        ToggleNeuronDetectionMenu       matlab.ui.container.Menu
        DisplayMenu                     matlab.ui.container.Menu
        ToggleBirthTimesMenu            matlab.ui.container.Menu
        NeuronDotsMenu                  matlab.ui.container.Menu
        GFPColorMenu                    matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        SaveIDInfoMenu                  matlab.ui.container.Menu
        SaveIDImageMenu                 matlab.ui.container.Menu
        SaveNWBMenu                     matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        CheckforUpdatesMenu             matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        InstructionsMenu                matlab.ui.container.Menu
        KeyboardShortcutsMenu           matlab.ui.container.Menu
        NeuroPALWebsiteMenu             matlab.ui.container.Menu
        ReportaBugMenu                  matlab.ui.container.Menu
        DebugMenu                       matlab.ui.container.Menu
        LogtesteventMenu                matlab.ui.container.Menu
        LogfadeeventMenu                matlab.ui.container.Menu
        DebugstatesMenu                 matlab.ui.container.Menu
        ResetProcessingTabMenu          matlab.ui.container.Menu
        DebugactionsMenu                matlab.ui.container.Menu
        GUIMenu                         matlab.ui.container.Menu
        RotationGUIMenu                 matlab.ui.container.Menu
        ProcessingMenu                  matlab.ui.container.Menu
        daspect_testMenu                matlab.ui.container.Menu
        projected_axes_testMenu         matlab.ui.container.Menu
        TriggerBreakpointMenu           matlab.ui.container.Menu
        OptimizationMenu                matlab.ui.container.Menu
        ProfilingMenu                   matlab.ui.container.Menu
        EnableautoprofilingMenu         matlab.ui.container.Menu
        ProfileOnMenu                   matlab.ui.container.Menu
        ProfileOffMenu                  matlab.ui.container.Menu
        load_graphic                    matlab.ui.control.Image
        ProcToggleChannelsPanel         matlab.ui.container.Panel
        ProcToggleChannelGrid           matlab.ui.container.GridLayout
        ProcRedPanel                    matlab.ui.container.Panel
        ProcRedGrid                     matlab.ui.container.GridLayout
        ProcRDropDown                   matlab.ui.control.DropDown
        ProcRCheckBox                   matlab.ui.control.CheckBox
        ProcRLabel                      matlab.ui.control.Label
        ProcBluePanel                   matlab.ui.container.Panel
        ProcBlueGrid                    matlab.ui.container.GridLayout
        ProcBDropDown                   matlab.ui.control.DropDown
        ProcBCheckBox                   matlab.ui.control.CheckBox
        ProcBLabel                      matlab.ui.control.Label
        ProcGreenPanel                  matlab.ui.container.Panel
        ProcGreenGrid                   matlab.ui.container.GridLayout
        ProcGDropDown                   matlab.ui.control.DropDown
        ProcGCheckBox                   matlab.ui.control.CheckBox
        ProcGLabel                      matlab.ui.control.Label
        ProcWhitePanel                  matlab.ui.container.Panel
        ProcWhiteGrid                   matlab.ui.container.GridLayout
        ProcWLabel                      matlab.ui.control.Label
        ProcWDropDown                   matlab.ui.control.DropDown
        ProcWCheckBox                   matlab.ui.control.CheckBox
        ProcGFPPanel                    matlab.ui.container.Panel
        ProcGFPGrid                     matlab.ui.container.GridLayout
        ProcGFPDropDown                 matlab.ui.control.DropDown
        ProcGFPCheckBox                 matlab.ui.control.CheckBox
        ProcGFPLabel                    matlab.ui.control.Label
        ProcDICPanel                    matlab.ui.container.Panel
        ProcDICGrid                     matlab.ui.container.GridLayout
        ProcDICLabel                    matlab.ui.control.Label
        ProcDICCheckBox                 matlab.ui.control.CheckBox
        ProcDICDropDown                 matlab.ui.control.DropDown
        PlaceholderTimelinesliderLabel  matlab.ui.control.Label
        PlaceholderProcTimeline         matlab.ui.container.Panel
        GridLayout40_2                  matlab.ui.container.GridLayout
        Panel_76                        matlab.ui.container.Panel
        GridLayout42_2                  matlab.ui.container.GridLayout
        Panel_78                        matlab.ui.container.Panel
        GridLayout44_2                  matlab.ui.container.GridLayout
        proc_tGrinder                   matlab.ui.control.RangeSlider
        proc_tSlider                    matlab.ui.control.Slider
        Panel_77                        matlab.ui.container.Panel
        GridLayout43_2                  matlab.ui.container.GridLayout
        proc_tEditField                 matlab.ui.control.NumericEditField
        tEditField_2Label               matlab.ui.control.Label
        Panel_75                        matlab.ui.container.Panel
        GridLayout41_2                  matlab.ui.container.GridLayout
        proc_zEditField                 matlab.ui.control.NumericEditField
        zEditField_2Label               matlab.ui.control.Label
        proc_yEditField                 matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        proc_xEditField                 matlab.ui.control.NumericEditField
        xEditField_2Label               matlab.ui.control.Label
        PlaceholderActivitytabLabel     matlab.ui.control.Label
        PlaceholderTrackingtabsLabel    matlab.ui.control.Label
        PlaceholderButtonsCovertabsifnodataloadedLabel  matlab.ui.control.Label
        ProcessingButton                matlab.ui.control.Button
        IdButton                        matlab.ui.control.Button
        TrackingButton                  matlab.ui.control.Button
        GridSearchPanel                 matlab.ui.container.Panel
        GridLayout54                    matlab.ui.container.GridLayout
        CancelGridSearchButton          matlab.ui.control.Button
        StartGridSearchButton           matlab.ui.control.Button
        Panel_47                        matlab.ui.container.Panel
        GridLayout57                    matlab.ui.container.GridLayout
        minutesLabel                    matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        EstimatedSearchtimeLabel        matlab.ui.control.Label
        SearchrangesPanel               matlab.ui.container.Panel
        GridLayout56                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        VideoPreprocessingPanel         matlab.ui.container.Panel
        GridLayout55                    matlab.ui.container.GridLayout
        ChannelstoincludeLabel          matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        OnlyappliestogridsearchLabel    matlab.ui.control.Label
        DownsamplingFactortEditField    matlab.ui.control.NumericEditField
        DownsamplingFactortEditFieldLabel  matlab.ui.control.Label
        DownsamplingFactorzEditField    matlab.ui.control.NumericEditField
        DownsamplingFactorzEditFieldLabel  matlab.ui.control.Label
        DownsamplingFactorxyEditField   matlab.ui.control.NumericEditField
        DownsamplingFactorxyEditFieldLabel  matlab.ui.control.Label
        TrackingSelectionPanel_2        matlab.ui.container.Panel
        GridLayout49_2                  matlab.ui.container.GridLayout
        TrackspecificregionButton_2     matlab.ui.control.Button
        WorldlinestoincludeLabel        matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        StartingFrameLabel_2            matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        EndFrameLabel_2                 matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        AdvancedParameterPanel          matlab.ui.container.Panel
        NeuronMarkerGrid_2              matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        RegularizationLossCoefficientsTab  matlab.ui.container.Tab
        GridLayout35                    matlab.ui.container.GridLayout
        l_n                             matlab.ui.control.NumericEditField
        EditField4Label                 matlab.ui.control.Label
        DescriptionLabel                matlab.ui.control.Label
        ParameterLabel                  matlab.ui.control.Label
        GridSearchLabel                 matlab.ui.control.Label
        nn_max_grid                     matlab.ui.control.CheckBox
        l_n_mode_grid                   matlab.ui.control.CheckBox
        l_n_grid                        matlab.ui.control.CheckBox
        l_t_grid                        matlab.ui.control.CheckBox
        l_d_grid                        matlab.ui.control.CheckBox
        nn_max                          matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        nn_maxLabel                     matlab.ui.control.Label
        l_n_mode                        matlab.ui.control.DropDown
        MethodtouseforcalculatingNLabel  matlab.ui.control.Label
        NmodeLabel                      matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        l_t                             matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        tLabel                          matlab.ui.control.Label
        l_d                             matlab.ui.control.NumericEditField
        EditField3Label                 matlab.ui.control.Label
        dLabel                          matlab.ui.control.Label
        GradientLearningRateTab         matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        DescriptionLabel_2              matlab.ui.control.Label
        ParameterLabel_2                matlab.ui.control.Label
        GridSearchLabel_2               matlab.ui.control.Label
        z_comp_grid                     matlab.ui.control.CheckBox
        lr_ceil_grid                    matlab.ui.control.CheckBox
        lr_floor_grid                   matlab.ui.control.CheckBox
        lr_coeff_grid                   matlab.ui.control.CheckBox
        clip_grad_grid                  matlab.ui.control.CheckBox
        ZCompLabel                      matlab.ui.control.Label
        z_comp                          matlab.ui.control.NumericEditField
        Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel  matlab.ui.control.Label
        clip_grad                       matlab.ui.control.NumericEditField
        EditFieldLabel_4                matlab.ui.control.Label
        clip_gradLabel                  matlab.ui.control.Label
        lr_coeff                        matlab.ui.control.NumericEditField
        EditField6Label                 matlab.ui.control.Label
        LRCoeffLabel                    matlab.ui.control.Label
        lr_floor                        matlab.ui.control.NumericEditField
        Minimumvalueforinitiallearningratedefault002EditFieldLabel  matlab.ui.control.Label
        LRFloorLabel                    matlab.ui.control.Label
        lr_ceiling                      matlab.ui.control.NumericEditField
        EditFieldLabel_3                matlab.ui.control.Label
        lr_ceilingLabel                 matlab.ui.control.Label
        RetrievalFunctionTab            matlab.ui.container.Tab
        GridLayout50                    matlab.ui.container.GridLayout
        TextArea_6                      matlab.ui.control.TextArea
        OpenZephIRdocumentationButton   matlab.ui.control.Button
        CancelButton_2                  matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        OpenGridSearchMenuButton        matlab.ui.control.Button
        AdjustNeuronMarkerAlignmentPanel  matlab.ui.container.Panel
        NeuronMarkerGrid                matlab.ui.container.GridLayout
        Panel_44                        matlab.ui.container.Panel
        GridLayout48_2                  matlab.ui.container.GridLayout
        EnableinvolumeeditingCheckBox   matlab.ui.control.CheckBox
        EnableClickDragCheckBox         matlab.ui.control.CheckBox
        ShowallneuronsCheckBox_2        matlab.ui.control.CheckBox
        OverlayFrameMIPCheckBox_2       matlab.ui.control.CheckBox
        OverlayNeuroPALvolCheckBox_2    matlab.ui.control.CheckBox
        OverlaylastIDdframeCheckBox_2   matlab.ui.control.CheckBox
        AutojumponIDCheckBox_2          matlab.ui.control.CheckBox
        ResetMarkerPositionsButton      matlab.ui.control.Button
        Panel_43                        matlab.ui.container.Panel
        GridLayout53_2                  matlab.ui.container.GridLayout
        MarkerSizeSlider_2              matlab.ui.control.Slider
        MarkerSizeLabel_2               matlab.ui.control.Label
        SelectNeuronSubsetButton        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        ImportNeuronsfromCSVButton      matlab.ui.control.Button
        MoveNeuronMarkersPanel          matlab.ui.container.Panel
        GridLayout16_3                  matlab.ui.container.GridLayout
        Panel_86                        matlab.ui.container.Panel
        GridLayout77                    matlab.ui.container.GridLayout
        FlipinZdimensionButton          matlab.ui.control.Button
        FlipVerticallyButton            matlab.ui.control.Button
        FlipHorizontallyButton          matlab.ui.control.Button
        Panel_46                        matlab.ui.container.Panel
        GridLayout18_2                  matlab.ui.container.GridLayout
        degreesatatimeLabel             matlab.ui.control.Label
        RotateEditField                 matlab.ui.control.NumericEditField
        pixelsandLabel                  matlab.ui.control.Label
        MoveLabel                       matlab.ui.control.Label
        MoveEditField                   matlab.ui.control.NumericEditField
        Panel_45                        matlab.ui.container.Panel
        GridLayout27_2                  matlab.ui.container.GridLayout
        Left                            matlab.ui.control.Button
        Up                              matlab.ui.control.Button
        Right                           matlab.ui.control.Button
        Down                            matlab.ui.control.Button
        CCW                             matlab.ui.control.Button
        CW                              matlab.ui.control.Button
        CenterHorizontallyButton        matlab.ui.control.Button
        CenterVerticallyButton          matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        DeformNeuronMarkersPanel        matlab.ui.container.Panel
        GridLayout17_2                  matlab.ui.container.GridLayout
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        WidthSpinner                    matlab.ui.control.Spinner
        WidthSpinnerLabel               matlab.ui.control.Label
        HeightSpinner                   matlab.ui.control.Spinner
        HeightSpinnerLabel              matlab.ui.control.Label
        HoverLabel                      matlab.ui.control.Label
        GridLayout59                    matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        NeuroPALIDTab                   matlab.ui.container.Tab
        IdGridLayout                    matlab.ui.container.GridLayout
        Panel_9                         matlab.ui.container.Panel
        IdMetadataGridLayout            matlab.ui.container.GridLayout
        Panel_26                        matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        SubjectNotesTextArea            matlab.ui.control.EditField
        NotesEditFieldLabel             matlab.ui.control.Label
        StrainEditField                 matlab.ui.control.EditField
        StrainEditFieldLabel            matlab.ui.control.Label
        AgeDropDown                     matlab.ui.control.DropDown
        AgeDropDownLabel                matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexLabel                        matlab.ui.control.Label
        BodyDropDown                    matlab.ui.control.DropDown
        BodyLabel                       matlab.ui.control.Label
        SaveLabel                       matlab.ui.control.Label
        SaveIDsButton                   matlab.ui.control.Button
        FileLabel                       matlab.ui.control.Label
        OpenButton                      matlab.ui.control.Button
        Panel_30                        matlab.ui.container.Panel
        GridLayout24_2                  matlab.ui.container.GridLayout
        ColorAtlasCheckBox              matlab.ui.control.CheckBox
        ColorAtlasCheckBoxLabel         matlab.ui.control.Label
        AutoNameCheckBox                matlab.ui.control.CheckBox
        AutoNameLabel                   matlab.ui.control.Label
        MouseClickDropDown              matlab.ui.control.DropDown
        ClicksLabel                     matlab.ui.control.Label
        UserIDButton                    matlab.ui.control.Button
        IDEditField                     matlab.ui.control.EditField
        UserLabel                       matlab.ui.control.Label
        AutoIDButton                    matlab.ui.control.Button
        AutoLabel                       matlab.ui.control.Label
        AutoIDAllButton                 matlab.ui.control.Button
        AutoDetectButton                matlab.ui.control.Button
        NeuronsLabel                    matlab.ui.control.Label
        AutoIDDropDown                  matlab.ui.control.DropDown
        XYPanel                         matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        XY                              matlab.ui.control.UIAxes
        Panel_8                         matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        Panel_16                        matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        ZSlider                         matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        ZCenterEditField                matlab.ui.control.NumericEditField
        CenterZLabel                    matlab.ui.control.Label
        ZRightLabel                     matlab.ui.control.Label
        FlipZButton                     matlab.ui.control.Button
        Panel_10                        matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        ZAxisDropDown                   matlab.ui.control.DropDown
        ZLeftLabel                      matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        TabGroup4                       matlab.ui.container.TabGroup
        MaximumIntensityProjectionTab   matlab.ui.container.Tab
        GridLayout76                    matlab.ui.container.GridLayout
        MaxProjection                   matlab.ui.control.UIAxes
        NeuronActivityTab               matlab.ui.container.Tab
        GridLayout75                    matlab.ui.container.GridLayout
        LoadNeuronActivityButton        matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        GanglionNeuronIDLabel           matlab.ui.control.Label
        GanglionTree                    matlab.ui.container.Tree
        PharynxNode                     matlab.ui.container.TreeNode
        AnteriorNode                    matlab.ui.container.TreeNode
        PosteriorNode                   matlab.ui.container.TreeNode
        HeadNode                        matlab.ui.container.TreeNode
        AnteriorLNode                   matlab.ui.container.TreeNode
        AnteriorRNode                   matlab.ui.container.TreeNode
        DorsalNode                      matlab.ui.container.TreeNode
        LateralLNode                    matlab.ui.container.TreeNode
        LateralRNode                    matlab.ui.container.TreeNode
        VentralNode                     matlab.ui.container.TreeNode
        RetroVesicularNode              matlab.ui.container.TreeNode
        MidbodyNode                     matlab.ui.container.TreeNode
        AnteriorNode_2                  matlab.ui.container.TreeNode
        CentralNode                     matlab.ui.container.TreeNode
        PosteriorNode_2                 matlab.ui.container.TreeNode
        VentralCordNode                 matlab.ui.container.TreeNode
        TailNode                        matlab.ui.container.TreeNode
        PreAnalNode                     matlab.ui.container.TreeNode
        DorsoRectalNode                 matlab.ui.container.TreeNode
        LumbarLNode                     matlab.ui.container.TreeNode
        LumbarRNode                     matlab.ui.container.TreeNode
        SidePanel                       matlab.ui.container.Panel
        SideGrid                        matlab.ui.container.GridLayout
        ChannelPanel                    matlab.ui.container.Panel
        ChannelGrid                     matlab.ui.container.GridLayout
        Panel_18                        matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        RDropDown                       matlab.ui.control.DropDown
        RCheckBox                       matlab.ui.control.CheckBox
        RLabel                          matlab.ui.control.Label
        Panel_21                        matlab.ui.container.Panel
        GridLayout26_3                  matlab.ui.container.GridLayout
        WLabel                          matlab.ui.control.Label
        WDropDown                       matlab.ui.control.DropDown
        WCheckBox                       matlab.ui.control.CheckBox
        Panel_20                        matlab.ui.container.Panel
        GridLayout26_2                  matlab.ui.container.GridLayout
        BDropDown                       matlab.ui.control.DropDown
        BCheckBox                       matlab.ui.control.CheckBox
        BLabel                          matlab.ui.control.Label
        Panel_24                        matlab.ui.container.Panel
        GridLayout26_6                  matlab.ui.container.GridLayout
        GFPDropDown                     matlab.ui.control.DropDown
        GFPCheckBox                     matlab.ui.control.CheckBox
        GFPLabel                        matlab.ui.control.Label
        Panel_19                        matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        GDropDown                       matlab.ui.control.DropDown
        GCheckBox                       matlab.ui.control.CheckBox
        GLabel                          matlab.ui.control.Label
        Panel_23                        matlab.ui.container.Panel
        GridLayout26_5                  matlab.ui.container.GridLayout
        DICLabel                        matlab.ui.control.Label
        DICCheckBox                     matlab.ui.control.CheckBox
        DICDropDown                     matlab.ui.control.DropDown
        NeuronRankPanel                 matlab.ui.container.Panel
        NeuronRankGrid                  matlab.ui.container.GridLayout
        Panel_17                        matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        NextNeuronDropDown              matlab.ui.control.DropDown
        NextNeuronLabel                 matlab.ui.control.Label
        Panel_25                        matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        NeuronRankedConfidenceLabel     matlab.ui.control.Label
        UserNeuronIDsListBoxLabel       matlab.ui.control.Label
        UserNeuronIDsListBox            matlab.ui.control.ListBox
        NeuronListBoxPanel              matlab.ui.container.Panel
        NeuronListBoxGrid               matlab.ui.container.GridLayout
        UnIDdLabel                      matlab.ui.control.Label
        IDdNeuronsListBox               matlab.ui.control.ListBox
        IDdLabel                        matlab.ui.control.Label
        UnIDdNeuronsListBox             matlab.ui.control.ListBox
        VideoTrackingTab                matlab.ui.container.Tab
        VideoGridLayout                 matlab.ui.container.GridLayout
        DefaultTabGroup                 matlab.ui.container.TabGroup
        AnnotationSupportTab            matlab.ui.container.Tab
        GridLayout7_2                   matlab.ui.container.GridLayout
        ManipulateNeuronsButton         matlab.ui.control.Button
        Panel_40                        matlab.ui.container.Panel
        GridLayout53                    matlab.ui.container.GridLayout
        MarkerSizeSlider                matlab.ui.control.Slider
        MarkerSizeLabel                 matlab.ui.control.Label
        Panel_39                        matlab.ui.container.Panel
        GridLayout48                    matlab.ui.container.GridLayout
        OverlayNeuroPALvolCheckBox      matlab.ui.control.CheckBox
        ShowallneuronsCheckBox          matlab.ui.control.CheckBox
        OverlayFrameMIPCheckBox         matlab.ui.control.CheckBox
        OverlaylastIDdframeCheckBox     matlab.ui.control.CheckBox
        AutojumponIDCheckBox            matlab.ui.control.CheckBox
        AutosegmentFrameButton          matlab.ui.control.Button
        HotkeysPanel                    matlab.ui.container.Panel
        GridLayout47                    matlab.ui.container.GridLayout
        BwdFramesEditField              matlab.ui.control.NumericEditField
        FwdFramesEditField              matlab.ui.control.NumericEditField
        TextArea_5                      matlab.ui.control.TextArea
        InsertHKButton                  matlab.ui.control.StateButton
        DropDown_2                      matlab.ui.control.DropDown
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        JumpHKButton                    matlab.ui.control.StateButton
        DeleteHKButton                  matlab.ui.control.StateButton
        JmpFrameEditField_2             matlab.ui.control.NumericEditField
        Frame2HKButton                  matlab.ui.control.StateButton
        JmpFrameEditField               matlab.ui.control.NumericEditField
        Frame1HKButton                  matlab.ui.control.StateButton
        ColorControlPanel               matlab.ui.container.Panel
        GridLayout16_2                  matlab.ui.container.GridLayout
        BSlider                         matlab.ui.control.Slider
        BSliderLabel                    matlab.ui.control.Label
        GSlider                         matlab.ui.control.Slider
        GSliderLabel                    matlab.ui.control.Label
        RSlider                         matlab.ui.control.Slider
        RSliderLabel                    matlab.ui.control.Label
        BookmarkedFramesPanel           matlab.ui.container.Panel
        GridLayout8_2                   matlab.ui.container.GridLayout
        BookmarkListBox                 matlab.ui.control.ListBox
        DeleteBookmarkButton            matlab.ui.control.Button
        BookmarkNameEditField           matlab.ui.control.EditField
        SetBookmarkButton               matlab.ui.control.Button
        GotoBookmarkButton              matlab.ui.control.Button
        ParametersTab                   matlab.ui.container.Tab
        GridLayout58                    matlab.ui.container.GridLayout
        BasicParameterPanel             matlab.ui.container.Panel
        ParameterGridLayout             matlab.ui.container.GridLayout
        WormMotionPanel                 matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        MotionSlider                    matlab.ui.control.Slider
        HoveroverlabelsforfurtherdetailsorguidanceLabel  matlab.ui.control.Label
        AdvSetTab                       matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GridLayout31                    matlab.ui.container.GridLayout
        TrackingSelectionPanel          matlab.ui.container.Panel
        GridLayout49                    matlab.ui.container.GridLayout
        TrackspecificneuronsButton      matlab.ui.control.Button
        TrackspecificregionButton       matlab.ui.control.Button
        StartingFrameLabel              matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EndFrameLabel                   matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        UseGPUSwitch                    matlab.ui.control.Switch
        UseGPUSwitchLabel               matlab.ui.control.Label
        MotionPredictionSwitch          matlab.ui.control.Switch
        MotionPredictionSwitchLabel     matlab.ui.control.Label
        AllowRotationsSwitch            matlab.ui.control.Switch
        AllowRotationsSwitchLabel       matlab.ui.control.Label
        ImageMotionTab                  matlab.ui.container.Tab
        GridLayout34                    matlab.ui.container.GridLayout
        dimmer_ratio                    matlab.ui.control.NumericEditField
        CoefficientfordimmingnonfoveatedregionsEditFieldLabel  matlab.ui.control.Label
        dimmer_ratioLabel               matlab.ui.control.Label
        grid_shape                      matlab.ui.control.NumericEditField
        SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel  matlab.ui.control.Label
        grid_shapeLabel                 matlab.ui.control.Label
        fovea_sigma                     matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        foveasigmaLabel                 matlab.ui.control.Label
        ComputationTab                  matlab.ui.container.Tab
        GridLayout31_2                  matlab.ui.container.GridLayout
        sort_mode                       matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        sort_modeLabel                  matlab.ui.control.Label
        n_chunks                        matlab.ui.control.NumericEditField
        NumberofstepstodividetheforwardpassintoEditFieldLabel  matlab.ui.control.Label
        n_chunksLabel                   matlab.ui.control.Label
        n_epoch_d                       matlab.ui.control.NumericEditField
        EditField2_2Label               matlab.ui.control.Label
        n_epoch                         matlab.ui.control.NumericEditField
        n_epoch_dLabel                  matlab.ui.control.Label
        NumberofiterationsforimageregistrationLREditFieldLabel  matlab.ui.control.Label
        n_epochLabel                    matlab.ui.control.Label
        ExportparametersButton          matlab.ui.control.Button
        EditadvancedparametersButton    matlab.ui.control.Button
        ImportparametersButton          matlab.ui.control.Button
        EnableadvancedparametersCheckBox  matlab.ui.control.CheckBox
        ActivityTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_42                        matlab.ui.container.Panel
        GridLayout5_2                   matlab.ui.container.GridLayout
        ActivityAxes                    matlab.ui.control.UIAxes
        Panel_41                        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ActivityListBox                 matlab.ui.control.ListBox
        LoadStimulusFileButton          matlab.ui.control.Button
        SaveExitButton                  matlab.ui.control.Button
        ScanforIssuesButton             matlab.ui.control.Button
        CreditTab                       matlab.ui.container.Tab
        GridLayout14_3                  matlab.ui.container.GridLayout
        CitationTextArea                matlab.ui.control.TextArea
        CitationTextAreaLabel           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        GithubRepositoryButton          matlab.ui.control.Button
        ViewPreprintButton              matlab.ui.control.Button
        LaboratoryPageButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Panel_55                        matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        vert_zSlider                    matlab.ui.control.Slider
        ySlider                         matlab.ui.control.Slider
        hor_zSlider                     matlab.ui.control.Slider
        xSlider                         matlab.ui.control.Slider
        yzAxes                          matlab.ui.control.UIAxes
        xzAxes                          matlab.ui.control.UIAxes
        xyAxes                          matlab.ui.control.UIAxes
        Panel_34                        matlab.ui.container.Panel
        GridLayout40                    matlab.ui.container.GridLayout
        Panel_36                        matlab.ui.container.Panel
        GridLayout42                    matlab.ui.container.GridLayout
        Panel_38                        matlab.ui.container.Panel
        GridLayout44                    matlab.ui.container.GridLayout
        tSlider                         matlab.ui.control.Slider
        Panel_37                        matlab.ui.container.Panel
        GridLayout43                    matlab.ui.container.GridLayout
        tEditField                      matlab.ui.control.NumericEditField
        tEditFieldLabel                 matlab.ui.control.Label
        Panel_35                        matlab.ui.container.Panel
        GridLayout41                    matlab.ui.container.GridLayout
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        WorkflowPanel                   matlab.ui.container.Panel
        GridLayout13_2                  matlab.ui.container.GridLayout
        AllowflexibleaspectratioCheckBox  matlab.ui.control.CheckBox
        ImportexistingtracksButton      matlab.ui.control.Button
        InsertlastIDdFrameButton        matlab.ui.control.Button
        Panel_33                        matlab.ui.container.Panel
        GridLayout14_2                  matlab.ui.container.GridLayout
        ExtractActivityButton           matlab.ui.control.Button
        TrackNeuronsButton              matlab.ui.control.Button
        RecommendFramesButton           matlab.ui.control.Button
        SaveAnnotationsButton           matlab.ui.control.Button
        InsertNeuroPALNeuronsButton     matlab.ui.control.Button
        WorldlineInformationPanel       matlab.ui.container.Panel
        GridLayout51                    matlab.ui.container.GridLayout
        WorldlineTree                   matlab.ui.container.CheckBoxTree
        IDdNode                         matlab.ui.container.TreeNode
        UnIDdNode                       matlab.ui.container.TreeNode
        Panel_32                        matlab.ui.container.Panel
        GridLayout52                    matlab.ui.container.GridLayout
        SelectworldlinebyIDButton       matlab.ui.control.Button
        AssignrandomcolorstoblanksButton  matlab.ui.control.Button
        NeuronInformationPanel          matlab.ui.container.Panel
        GridLayout9_2                   matlab.ui.container.GridLayout
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ProvenanceEditField             matlab.ui.control.EditField
        ProvenanceEditFieldLabel        matlab.ui.control.Label
        WorldlineIDEditField            matlab.ui.control.NumericEditField
        WorldlineIDEditFieldLabel       matlab.ui.control.Label
        ColorButton                     matlab.ui.control.Button
        ColorLabel                      matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ZCoordinateEditField            matlab.ui.control.NumericEditField
        ZCoordinateEditFieldLabel       matlab.ui.control.Label
        YCoordinateEditField            matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel       matlab.ui.control.Label
        XCoordinateEditField            matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel       matlab.ui.control.Label
        ImageProcessingTab              matlab.ui.container.Tab
        ProcessingGridLayout            matlab.ui.container.GridLayout
        ProcAxPanel                     matlab.ui.container.Panel
        ProcAxGrid                      matlab.ui.container.GridLayout
        proc_xSlider                    matlab.ui.control.Slider
        proc_zSlider                    matlab.ui.control.Slider
        proc_vert_zSlider               matlab.ui.control.Slider
        proc_ySlider                    matlab.ui.control.Slider
        proc_hor_zSlider                matlab.ui.control.Slider
        proc_yzAxes                     matlab.ui.control.UIAxes
        proc_xyAxes                     matlab.ui.control.UIAxes
        proc_xzAxes                     matlab.ui.control.UIAxes
        ProcSidePanel                   matlab.ui.container.Panel
        ProcSideGrid                    matlab.ui.container.GridLayout
        ImageManipulationPanel          matlab.ui.container.Panel
        GridLayout83                    matlab.ui.container.GridLayout
        proc_crop_panel                 matlab.ui.container.Panel
        GridLayout82_2                  matlab.ui.container.GridLayout
        CancelButton_3                  matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        proc_rot_panel                  matlab.ui.container.Panel
        ProcGridOBSOLETE                matlab.ui.container.GridLayout
        proc_rot_knob                   matlab.ui.control.Knob
        proc_rot_spinner                matlab.ui.control.Spinner
        flip_lr                         matlab.ui.control.StateButton
        confirm_rotation                matlab.ui.control.Button
        flip_ud                         matlab.ui.control.StateButton
        proc_ds_panel                   matlab.ui.container.Panel
        ProcDownsamplingGrid            matlab.ui.container.GridLayout
        ProcPreviewZslowCheckBox        matlab.ui.control.CheckBox
        EndFrameEditField               matlab.ui.control.NumericEditField
        EndFrameEditFieldLabel          matlab.ui.control.Label
        StartFrameEditField             matlab.ui.control.NumericEditField
        StartFrameEditFieldLabel        matlab.ui.control.Label
        Panel_91                        matlab.ui.container.Panel
        GridLayout81                    matlab.ui.container.GridLayout
        ProcTStopEditField              matlab.ui.control.NumericEditField
        ProcTStartEditField             matlab.ui.control.NumericEditField
        TrimButton                      matlab.ui.control.Button
        ProcXYFactorUpdateButton        matlab.ui.control.Button
        ProcZSlicesEditField            matlab.ui.control.NumericEditField
        zSlicesEditFieldLabel           matlab.ui.control.Label
        ProcXYFactorEditField           matlab.ui.control.NumericEditField
        xyFactorLabel                   matlab.ui.control.Label
        DownsampleButton                matlab.ui.control.Button
        RotateButton                    matlab.ui.control.Button
        ProcCropImageButton             matlab.ui.control.Button
        VolumeChannelsPanel             matlab.ui.container.Panel
        GridLayout82                    matlab.ui.container.GridLayout
        EditChannelsButton              matlab.ui.control.StateButton
        proc_channel_panel              matlab.ui.container.Panel
        proc_channel_grid               matlab.ui.container.GridLayout
        proc_c1_editfield               matlab.ui.control.EditField
        proc_c2_editfield               matlab.ui.control.EditField
        proc_c3_editfield               matlab.ui.control.EditField
        proc_c4_editfield               matlab.ui.control.EditField
        proc_c5_editfield               matlab.ui.control.EditField
        proc_c6_editfield               matlab.ui.control.EditField
        proc_c1_checkbox                matlab.ui.control.CheckBox
        proc_c2_checkbox                matlab.ui.control.CheckBox
        proc_c3_checkbox                matlab.ui.control.CheckBox
        proc_c4_checkbox                matlab.ui.control.CheckBox
        proc_c5_checkbox                matlab.ui.control.CheckBox
        proc_c6_checkbox                matlab.ui.control.CheckBox
        proc_c1_ref                     matlab.ui.control.StateButton
        proc_c2_ref                     matlab.ui.control.StateButton
        proc_c3_ref                     matlab.ui.control.StateButton
        proc_c4_ref                     matlab.ui.control.DropDown
        proc_c5_ref                     matlab.ui.control.DropDown
        proc_c6_ref                     matlab.ui.control.DropDown
        proc_c1_dropdown                matlab.ui.control.DropDown
        proc_c2_dropdown                matlab.ui.control.DropDown
        proc_c3_dropdown                matlab.ui.control.DropDown
        proc_c4_dropdown                matlab.ui.control.DropDown
        proc_c5_dropdown                matlab.ui.control.DropDown
        proc_c6_dropdown                matlab.ui.control.DropDown
        proc_c1_up                      matlab.ui.control.Button
        proc_c2_up                      matlab.ui.control.Button
        proc_c3_up                      matlab.ui.control.Button
        proc_c4_up                      matlab.ui.control.Button
        proc_c5_up                      matlab.ui.control.Button
        proc_c6_up                      matlab.ui.control.Button
        proc_c1_down                    matlab.ui.control.Button
        proc_c2_down                    matlab.ui.control.Button
        proc_c3_down                    matlab.ui.control.Button
        proc_c4_down                    matlab.ui.control.Button
        proc_c5_down                    matlab.ui.control.Button
        proc_c6_down                    matlab.ui.control.Button
        proc_c1_delete                  matlab.ui.control.Button
        proc_c2_delete                  matlab.ui.control.Button
        proc_c3_delete                  matlab.ui.control.Button
        proc_c4_delete                  matlab.ui.control.Button
        proc_c5_delete                  matlab.ui.control.Button
        proc_c6_delete                  matlab.ui.control.Button
        SpectralUnmixingPanel           matlab.ui.container.Panel
        SpectralUnmixingGrid            matlab.ui.container.GridLayout
        background_measure              matlab.ui.control.Button
        background_b                    matlab.ui.control.NumericEditField
        background_g                    matlab.ui.control.NumericEditField
        background_r                    matlab.ui.control.NumericEditField
        BGLabel                         matlab.ui.control.Label
        Panel_89                        matlab.ui.container.Panel
        GridLayout2_3                   matlab.ui.container.GridLayout
        DropperradiusSpinner            matlab.ui.control.NumericEditField
        DropperRadiusEditFieldLabel     matlab.ui.control.Label
        SigmagaussEditField             matlab.ui.control.NumericEditField
        SigmagaussEditFieldLabel        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        blue_b                          matlab.ui.control.NumericEditField
        blue_g                          matlab.ui.control.NumericEditField
        blue_r                          matlab.ui.control.NumericEditField
        green_r                         matlab.ui.control.NumericEditField
        green_g                         matlab.ui.control.NumericEditField
        green_b                         matlab.ui.control.NumericEditField
        red_b                           matlab.ui.control.NumericEditField
        red_g                           matlab.ui.control.NumericEditField
        red_r                           matlab.ui.control.NumericEditField
        blue_measure                    matlab.ui.control.Button
        green_measure                   matlab.ui.control.Button
        red_measure                     matlab.ui.control.Button
        BLabel_2                        matlab.ui.control.Label
        GLabel_2                        matlab.ui.control.Label
        RLabel_2                        matlab.ui.control.Label
        BlueLabel                       matlab.ui.control.Label
        GreenLabel                      matlab.ui.control.Label
        RedLabel                        matlab.ui.control.Label
        ProcSavePanel                   matlab.ui.container.Panel
        ProcSaveGrid                    matlab.ui.container.GridLayout
        ProcResetButton                 matlab.ui.control.Button
        ProcSaveButton                  matlab.ui.control.Button
        ProcToggleVolumePanel           matlab.ui.container.Panel
        ProcToggleVolumeGrid            matlab.ui.container.GridLayout
        VolumeDropDown                  matlab.ui.control.DropDown
        ProcShowMIPCheckBox             matlab.ui.control.CheckBox
        ProcMainPanel                   matlab.ui.container.Panel
        ProcMainGrid                    matlab.ui.container.GridLayout
        Panel_57                        matlab.ui.container.Panel
        GridLayout64                    matlab.ui.container.GridLayout
        ProcHistogramPanel              matlab.ui.container.Panel
        ProcHistogramGrid               matlab.ui.container.GridLayout
        tl_hist_panel                   matlab.ui.container.Panel
        tl_hist_grid                    matlab.ui.container.GridLayout
        Panel_80                        matlab.ui.container.Panel
        GridLayout72_7                  matlab.ui.container.GridLayout
        tl_Label                        matlab.ui.control.Label
        tl_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel             matlab.ui.control.Label
        tl_hist_slider                  matlab.ui.control.RangeSlider
        tl_hist_ax                      matlab.ui.control.UIAxes
        tm_hist_panel                   matlab.ui.container.Panel
        tm_hist_grid                    matlab.ui.container.GridLayout
        Panel_81                        matlab.ui.container.Panel
        GridLayout72_8                  matlab.ui.container.GridLayout
        tm_Label                        matlab.ui.control.Label
        tm_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditField_2Label           matlab.ui.control.Label
        tm_hist_slider                  matlab.ui.control.RangeSlider
        tm_hist_ax                      matlab.ui.control.UIAxes
        tr_hist_panel                   matlab.ui.container.Panel
        tr_hist_grid                    matlab.ui.container.GridLayout
        Panel_82                        matlab.ui.container.Panel
        GridLayout72_9                  matlab.ui.container.GridLayout
        tr_Label                        matlab.ui.control.Label
        tr_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditField_3Label           matlab.ui.control.Label
        tr_hist_slider                  matlab.ui.control.RangeSlider
        tr_hist_ax                      matlab.ui.control.UIAxes
        bl_hist_panel                   matlab.ui.container.Panel
        bl_hist_grid                    matlab.ui.container.GridLayout
        Panel_83                        matlab.ui.container.Panel
        GridLayout72_10                 matlab.ui.container.GridLayout
        bl_Label                        matlab.ui.control.Label
        bl_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditField_4Label           matlab.ui.control.Label
        bl_hist_slider                  matlab.ui.control.RangeSlider
        bl_hist_ax                      matlab.ui.control.UIAxes
        bm_hist_panel                   matlab.ui.container.Panel
        bm_hist_grid                    matlab.ui.container.GridLayout
        Panel_84                        matlab.ui.container.Panel
        GridLayout72_11                 matlab.ui.container.GridLayout
        bm_Label                        matlab.ui.control.Label
        bm_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditField_5Label           matlab.ui.control.Label
        bm_hist_slider                  matlab.ui.control.RangeSlider
        bm_hist_ax                      matlab.ui.control.UIAxes
        br_hist_panel                   matlab.ui.container.Panel
        br_hist_grid                    matlab.ui.container.GridLayout
        Panel_85                        matlab.ui.container.Panel
        GridLayout72_12                 matlab.ui.container.GridLayout
        br_Label                        matlab.ui.control.Label
        br_GammaEditField               matlab.ui.control.NumericEditField
        GammaEditField_6Label           matlab.ui.control.Label
        br_hist_slider                  matlab.ui.control.RangeSlider
        br_hist_ax                      matlab.ui.control.UIAxes
        ProcThresholdPanel              matlab.ui.container.Panel
        ProcThresholdGrid               matlab.ui.container.GridLayout
        ProcMeasure90pthNoiseButton     matlab.ui.control.Button
        ProcMeasureROINoiseButton       matlab.ui.control.Button
        ProcThresholdKnobPanel          matlab.ui.container.Panel
        ProcThresholdKnobGrid           matlab.ui.container.GridLayout
        ProcNoiseThresholdField         matlab.ui.control.NumericEditField
        ProcNoiseThresholdKnob          matlab.ui.control.Knob
        ProcThresholdManipulationLabel  matlab.ui.control.Label
        ProcHistogramManipulationLabel  matlab.ui.control.Label
        ProcNormalizeColorsButton       matlab.ui.control.Button
        ProcHistogramMatchingButton     matlab.ui.control.Button
        Panel_87                        matlab.ui.container.Panel
        GridLayout78                    matlab.ui.container.GridLayout
        HidezerointensitypixelsCheckBox  matlab.ui.control.CheckBox
        LogTab                          matlab.ui.container.Tab
        LogGrid                         matlab.ui.container.GridLayout
        Log                             matlab.ui.control.TextArea
        UpperLogPanel                   matlab.ui.container.Panel
        UpperLogGrid                    matlab.ui.container.GridLayout
        SystemInfoGrid                  matlab.ui.container.GridLayout
        LoadedFilesPanel                matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        FileListTree                    matlab.ui.container.CheckBoxTree
        DataNode                        matlab.ui.container.TreeNode
        DataMetadataNode                matlab.ui.container.TreeNode
        VolumeNode                      matlab.ui.container.TreeNode
        NeuronsNode                     matlab.ui.container.TreeNode
        ProcessingNode                  matlab.ui.container.TreeNode
        DownsamplingNode                matlab.ui.container.TreeNode
        SegmentationNode                matlab.ui.container.TreeNode
        ModelNode                       matlab.ui.container.TreeNode
        NeuronInfoNode                  matlab.ui.container.TreeNode
        ActivityNode                    matlab.ui.container.TreeNode
        ZephIRNode                      matlab.ui.container.TreeNode
        VideoNode                       matlab.ui.container.TreeNode
        AnnotationsNode                 matlab.ui.container.TreeNode
        ZephIRMetaDataNode              matlab.ui.container.TreeNode
        ActivityCSVNode                 matlab.ui.container.TreeNode
        OutputNode                      matlab.ui.container.TreeNode
        NeuroPALArrayNode               matlab.ui.container.TreeNode
        NeuroPALNeuronIDsNode           matlab.ui.container.TreeNode
        NeuroPALEditField_2Label        matlab.ui.control.Label
        NeuroPALEditField               matlab.ui.control.EditField
        PythonExecutableEditFieldLabel  matlab.ui.control.Label
        PythonExecutableEditField       matlab.ui.control.EditField
        MATLABRuntimeEditFieldLabel     matlab.ui.control.Label
        MATLABRuntimeEditField          matlab.ui.control.EditField
        OperatingSystemEditField        matlab.ui.control.EditField
        OperatingSystemEditField_2Label  matlab.ui.control.Label
        LogControlPanel                 matlab.ui.container.GridLayout
        DebugPanel                      matlab.ui.container.Panel
        DebugGrid                       matlab.ui.container.GridLayout
        TestResultPanel                 matlab.ui.container.Panel
        TestResultGrid                  matlab.ui.container.GridLayout
        SaveRoutineCheck                matlab.ui.control.CheckBox
        SaveRoutineLabel                matlab.ui.control.Label
        SystemCallsCheck                matlab.ui.control.CheckBox
        SystemcallsLabel                matlab.ui.control.Label
        TypeProofingCheck               matlab.ui.control.CheckBox
        FlexibleImportsCheck            matlab.ui.control.CheckBox
        RobustDataHandlingLabel         matlab.ui.control.Label
        TypeproofingLabel               matlab.ui.control.Label
        FlexibleimportsLabel            matlab.ui.control.Label
        AxialProjectionsCheck           matlab.ui.control.CheckBox
        DynamicGridStructuresCheck      matlab.ui.control.CheckBox
        DynamicPanelingCheck            matlab.ui.control.CheckBox
        AxialprojectionsLabel           matlab.ui.control.Label
        DynamicgridstructuresLabel      matlab.ui.control.Label
        DynamicpanelingLabel            matlab.ui.control.Label
        GUIFunctionalityLabel           matlab.ui.control.Label
        AdvancedFunctionalityLabel      matlab.ui.control.Label
        InterdependentFunctionalityLabel  matlab.ui.control.Label
        BasicFunctionalityLabel         matlab.ui.control.Label
        EditVideoCheck                  matlab.ui.control.CheckBox
        BrowseVideoCheck                matlab.ui.control.CheckBox
        LoadVideoCheck                  matlab.ui.control.CheckBox
        EditImageCheck                  matlab.ui.control.CheckBox
        BrowseImageCheck                matlab.ui.control.CheckBox
        LoadImageCheck                  matlab.ui.control.CheckBox
        ExtractActivityLabel            matlab.ui.control.Label
        TrackNeuronsLabel               matlab.ui.control.Label
        ThumbnailAnalysisLabel          matlab.ui.control.Label
        EditvideoLabel_3                matlab.ui.control.Label
        BrowsevideoLabel_3              matlab.ui.control.Label
        HistogramMatchingLabel          matlab.ui.control.Label
        AutoidentificationLabel_2       matlab.ui.control.Label
        LoadvideoLabel_3                matlab.ui.control.Label
        SpectralUnmixingLabel           matlab.ui.control.Label
        ArtifactRemovalLabel            matlab.ui.control.Label
        EditimageLabel_2                matlab.ui.control.Label
        BrowseimageLabel                matlab.ui.control.Label
        LoadimageLabel_2                matlab.ui.control.Label
        TrackNeuronsCheck               matlab.ui.control.CheckBox
        ThumbnailAnalysisCheck          matlab.ui.control.CheckBox
        ExtractActivityCheck            matlab.ui.control.CheckBox
        AutoIdentificationCheck         matlab.ui.control.CheckBox
        HistogramMatchingCheck          matlab.ui.control.CheckBox
        SpectralUnmixingCheck           matlab.ui.control.CheckBox
        ArtifactRemovalCheck            matlab.ui.control.CheckBox
        AutosegmentationLabel_2         matlab.ui.control.Label
        AutoSegmentationCheck           matlab.ui.control.CheckBox
        PrintdebuglogButton             matlab.ui.control.Button
        RuntestroutineButton            matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        EnabledebugmenuCheckBox         matlab.ui.control.CheckBox
        EnablelognotificationsCheckBox  matlab.ui.control.CheckBox
        EnablelogCheckBox               matlab.ui.control.CheckBox
        OpenlogfileButton               matlab.ui.control.Button
        LogFilterButtonGroup            matlab.ui.container.ButtonGroup
        PythoneventsonlyButton          matlab.ui.control.RadioButton
        MATLABeventsonlyButton          matlab.ui.control.RadioButton
        SystemeventsonlyButton          matlab.ui.control.RadioButton
        ShowalleventsButton             matlab.ui.control.RadioButton
        SubmitbugreportButton           matlab.ui.control.Button
        OpenRepositoryButton            matlab.ui.control.Button
        VolTraceParent                  matlab.ui.container.Panel
        VolTraceHelperGrid              matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        DropDown                        matlab.ui.control.DropDown
        LoadStimuliButton               matlab.ui.control.Button
        AddPlotButton                   matlab.ui.control.Button
        DeletePlotDropDown              matlab.ui.control.DropDown
        DeleteNeuronDropDown            matlab.ui.control.DropDown
    end


    properties (Access = public)

        %% Program info.

        % The log of user activity for code evaluation/optimization.
        log

        % Version info.
        version = Program.ProgramInfo.version;
        mouse = struct( ...
            'state', {0}, ...
            'pos', {[0 0]}, ...
            'drag', {[]});

        % Are we using the parallelization toolbox?
        is_parallel = true;
        parallel_pool;

        data_flags = struct( ...
            'NeuroPAL_Volume', 0, ...
            'Video_Volume', 0, ...
            'Tracking_ROIs', 0, ...
            'Neurons', 0, ...
            'Neuronal_Identities', 0, ...
            'Neuronal_Activity', 0, ...
            'Stimulus_Files', 0);


        %% Image info.

        % The default parameters for the program & matching pursuit.
        params
        mp_params

        % File locations for the open image.
        image_file = [];
        id_file = [];

        % The image preferences for loading/saving.
        image_prefs = [];

        % The image info
        image_info = [];

        % Worm information.
        worm = [];
        neuron_info = Neurons.Hermaphrodite;

        % The open image of the worm.
        % A pixel matrix of (x,y,z, color channel).
        image_name
        image_data
        image_data_zscored = [];
        image_view % a subset of the image that shows only what we're viewing, a matrix of (x,y,z, subset of color channels)
        image_um_scale = [1,1,1]; % image micron scale (x,y,z)
        image_gamma = ones(6, 1); % the gamma adjustment for the image
        gamma_RGBW_DIC_GFP_index = 1:6; % the indices for gamma values

        % Reconstructed image from gaussian approximations of neuron positions, shapes, & colors.
        % A reconstructed pixel matrix of (x,y,z, color channel).
        recon
        recon_view  % a subset of the reconstructed image that shows only what we're viewing, a matrix of (x,y,z, subset of color channels)

        % The neurons in the image.
        image_neurons

        % Which neuron is selected.
        selected_neuron

        % Mouse click states (a hack to detect double clicks).
        % Note: initialization is performed by startupFcn due construction issues.
        mouse_clicked;

        % Zoom percent for the keyboard shortcut.
        zoom_percent = 0.2;

        % Toggle image panning.
        is_panning = false;

        % Mouse position correction (the cursor is offset incorrectly).
        mouse_offset = [-1,1];


        %% Neuron info.

        % Neuron marker GUI attributes.
        % Note: initialization is performed by startupFcn due construction issues.
        neuron_marker;

        % Hermaphrodite and male neuron info.
        hermaphrodite_neurons;
        male_neurons;

        % Toggle neuron labels.
        show_labels = false;
        label_color = [1,1,0];
        label_fontsize = 12;
        label_fontweight = 'bold';

        % Are we opening a file?
        is_opening_file = false;

        % Are we saving a user ID?
        is_saving_ID = false;

        neuron_activity;
        neuron_activity_by_name;
        activity_var;
        movie_var;
        framerate;
        activity_table;
        VolTrace;
        nwb_data;
        stimuli;

        color_tracker;
        style_tracker;
        activity_module;

        %% Processing Properties

        proc_image;

        % Properties related to GUI functionality
        trigger_reload = 0;
        flags = struct();
        rotation_stack = struct( ...
            'roi', {[]}, ...
            'gui', {{}}, ...
            'listeners', {{}}, ...
            'cache', {0});

        % Back up of original image_view and image_data
        repo_view;
        repo_data;

        % ROI used for cropping previews
        volume_crop_roi;
        video_crop_roi;

        % Vertical & Horizontal lines drawn on volume
        proc_xy_yline;      % (Horizontal) line on (XY) axes
        proc_yz_yline;      % Horizontal line on YZ axes
        proc_xz_yline;      % Horizontal line on XZ axes

        proc_xy_xline;      % Vertical line on XY axes
        proc_yz_xline;      % Vertical line on YZ axes
        proc_xz_xline;      % Vertical line on XZ axes

        % Vertical lines drawn on histogram plots
        proc_tl_hist_min;   % Vertical line on (t)op (l)eft (hist)ogram at (min) location
        proc_tl_hist_max;   % Vertical line on top left histogram at max location

        proc_tm_hist_min;   % Vertical line on top middle histogram at min location
        proc_tm_hist_max;   % Vertical line on top middle histogram at max location
        
        proc_tr_hist_min;   % Vertical line on top right histogram at min location
        proc_tr_hist_max;   % Vertical line on top right histogram at max location

        proc_bl_hist_min;   % Vertical line on bottom left histogram at min location
        proc_bl_hist_max;   % Vertical line on bottom left histogram at max location

        proc_bm_hist_min;   % Vertical line on bottom middle histogram at min location
        proc_bm_hist_max;   % Vertical line on bottom middle histogram at max location
        
        proc_br_hist_min;   % Vertical line on bottom right histogram at min location
        proc_br_hist_max;   % Vertical line on bottom right histogram at max location

        % Character maps
        nameMap;            % (Maps) channel dropdown values to color (names)
        shortMap;           % Maps channel dropdown values to color shorthand


        %% Tracking Properties

        % Video properties
        video_path    % Path to input video
        video_array   % 4D video array
        video_info    % Structure containing video metadata.
        video_frame_cache      % Last loaded video frame for UI redraws.
        video_frame_cache_key  % File/time key for the cached video frame.
        video_view_cache       % Bounded lazy video plane/view cache.
        video_neurons % 4D neuron array
        id_frames     % Keeps track of IDd frames
        activity      % Struct containing per-neuron activity

        % File handler properties
        nwb_file       % Read nwb file.
        nwb_mod        % NWB module corresponding to calcium data.
        nd2_file       % Read nd2 file.
        nd2_mod        % ND2 module corresponding to video data.
        tif_file       % Read tif file.
        tif_mod        % Tif module corresponding to video data.
        spectral_cache = struct( ...
            'ch_db', {[]}, ...
            'ch_px', {{}}, ...
            'ch_val', {[]}, ...
            'bg_px', {[]}, ...
            'bg_val', {[]}, ...
            'blurred_img', {[]}); % Tracks spectral unmixing parameters.

        % Neuron Manipulation properties
        repo_neurons  % Backup 4D neuron array
        sel_neurons   % Keeps track of neurons being manipulated

        % Volume properties
        parent_app    % Handle for main GUI

        % ZephIR properties
        annotations   % 4D array containing all annotations.
        worldlines    % Struct containing all worldlines.
        arguments     % Struct containing ZephIR arguments.

        % GUI properties
        ctrl_state    % Tracks whether control key is down.

        xy_xline      % Variable serving as GUI handle.
        xz_xline      % Variable serving as GUI handle.
        yz_xline      % Variable serving as GUI handle.

        xy_yline      % Variable serving as GUI handle.
        xz_yline      % Variable serving as GUI handle.
        yz_yline      % Variable serving as GUI handle.

        script_dir    % Local working directory, changes based on deployment & OS.
        script_ext    % Deployment- & OS-specific extension for scripts/executables.

        %% GUI Fillers
        proc_c7_checkbox
        proc_c7_ref
        proc_c7_dropdown
        proc_c7_editfield
        
        proc_c8_checkbox
        proc_c8_ref
        proc_c8_dropdown
        proc_c8_editfield
        
        proc_c9_checkbox
        proc_c9_ref
        proc_c9_dropdown
        proc_c9_editfield
        
        proc_c10_checkbox
        proc_c10_ref
        proc_c10_dropdown
        proc_c10_editfield
    end

    methods (Access = private)

        %% NeuroPAL ID Functions
        function InitNeuronMarker(app)
            %% Initialize the neuron marker GUI attributes.
            % Note: initialization is performed by startupFcn due construction issues.

            app.neuron_marker.shape = 'c';
            app.neuron_marker.color.edge = [0,0,0];
        end

        function DrawImageData(app)
            %% Draw the annotated image (image volume & neuron markers).

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            app.logEvent('Main','Drawing image...', 1);

            package = Program.Helpers.get_display_volume(app, 'main', app.image_data);
            app.image_view = package.display_volume;

            % Redraw the max projection.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            image(app.MaxProjection, squeeze(max(app.image_view,[],3)));

            % Redraw the Z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end


        function DrawZSlice(app, slider, view, ax)
            %% Draw the neurons in this z-slice.

            % Sanity check the Z slice value.
            z = round(app.ZSlider.Value);
            app.logEvent('Main',sprintf('Drawing slice %s...', string(z)), 0);
            app.ZSlider.Value = z;

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % Flip the Z-axis.
            z_num = z;
            if app.image_prefs.is_Z_flip
                z = size(app.image_data,3) - z + 1;
            end

            % Where are we in Z?
            background_color = [0.94,0.94,0.94];
            LD_color = [0,1,1]; % left or dorsal color
            RV_color = [1,0,1]; % right or ventral color
            z_center_thresh = 1; % center +/- 1 slice
            z_center = app.image_prefs.z_center;
            if z_num >= z_center - z_center_thresh && z_num <= z_center + z_center_thresh
                app.XYPanel.BackgroundColor = background_color;
                app.ZLeftLabel.BackgroundColor = background_color;
                app.ZRightLabel.BackgroundColor = background_color;
            elseif z_num < z_center - z_center_thresh
                app.XYPanel.BackgroundColor = LD_color;
                app.ZLeftLabel.BackgroundColor = LD_color;
                app.ZRightLabel.BackgroundColor = background_color;
            else % if z > z_center + z_center_thresh
                app.XYPanel.BackgroundColor = RV_color;
                app.ZLeftLabel.BackgroundColor = background_color;
                app.ZRightLabel.BackgroundColor = RV_color;
            end

            % Clear the contents of the axis to draw the new Z-slice.
            cla(ax);
            % Create the slice at z for displaying in the axis.
            xy = squeeze(view(:,:,z,:,:));
            % Display the current slice in the XY axis.
            gui_image = image(xy, 'Parent', ax); hold(ax, 'on');

            if strcmp(app.TabGroup.SelectedTab.Title, 'Image Processing') & strcmp(app.VolumeDropDown.Value, 'Colormap')
                image(xy, 'Parent', app.proc_xyAxes);
            end

            % Add the AddNeuron function as mouse click listener.
            gui_image.ButtonDownFcn = {@app.ImageClicked};

            % Redraw the neurons in this z slice.
            if ~isempty(app.image_neurons) && ~isempty(app.image_neurons.neurons)

                % Which neurons belong in this z-slice?
                neuron_locations = app.image_neurons.get_positions();
                neuron_marker_colors = app.image_neurons.get_marker_colors();
                neuron_marker_sizes = app.image_neurons.get_marker_sizes();
                neuron_line_size = Program.GUIPreferences.instance().neuron_dot.line;

                % Since z (which is found by MP) can be a continuous value
                % to find all the neurons in the current slice we find the
                % ones that lie in the interval [z-z_dot_view, z+z_dot_view]. Finally we
                % change the x and y dimension to fix the inconsistent
                % behavior of Matlab figures for scatter and image function.
                z_dot_view = 1.5;
                current_z_indices = neuron_locations(:,3)>z-z_dot_view & neuron_locations(:,3)<z+z_dot_view;
                positions = neuron_locations(current_z_indices, 1:2);

                % Draw the neuron markers.
                neuron_marker_plot = scatter(ax, positions(:, 2), positions(:, 1), ...
                    neuron_marker_sizes(current_z_indices), ...
                    neuron_marker_colors(current_z_indices, :), ...
                    'filled', 'MarkerEdgeColor', app.neuron_marker.color.edge, ...
                    'LineWidth', neuron_line_size);

                % Are we showing the neuron annotations?
                if app.show_labels

                    % Get the labels.
                    labels = app.image_neurons.get_annotations();
                    labels = labels(current_z_indices);

                    % Get the ON/OFF annotations.
                    is_on = app.image_neurons.get_is_annotations_on();
                    is_on = is_on(current_z_indices);

                    % Get the confidences.
                    confidences = app.image_neurons.get_annotation_confidences();
                    confidences = confidences(current_z_indices);

                    % Get the emphasized neurons.
                    is_emphasized = app.image_neurons.get_is_emphasized();
                    is_emphasized = is_emphasized(current_z_indices);

                    % Remove empty labels.
                    is_label = ~cellfun('isempty', labels);
                    labels = labels(is_label);
                    is_on = is_on(is_label);
                    confidences = confidences(is_label);
                    is_emphasized = is_emphasized(is_label);

                    % Add ON/OFF & confidence to the labels.
                    for i = 1:length(confidences)

                        % Is the neuron ON/OFF.
                        switch is_on(i)
                            case false
                                labels{i} = [labels{i} '-OFF'];
                            case true
                                labels{i} = [labels{i} '-ON'];
                        end

                        % Is the user uncertain about the ID?
                        if confidences(i) <= 0.5
                            labels{i} = [labels{i} '?'];
                        end

                        % Is the neuron emphasized?
                        if is_emphasized(i)
                            labels{i} = [labels{i} '!'];
                        end
                    end

                    % Draw the labels.
                    DrawImageLabels(app, positions(is_label,:), labels);
                end

                % Setup the mouse-click callback.
                neuron_marker_plot.ButtonDownFcn = {@app.NeuronClicked};
            end

            % Draw the color atlas.
            if app.ColorAtlasCheckBox.Value

                % Do we have the atlas info?
                if isempty(app.image_neurons.get_aligned_xyzRGBs())

                    % Uncheck the atlas.
                    app.ColorAtlasCheckBox.Value = false;

                    % Warn the user.
                    uialert(app.CELL_ID, ...
                        'Please press "Auto-ID All" to create the neuron ID atlas!', ...
                        'No Atlas', 'Icon', 'warning');

                    % Draw the neuron ID atlas.
                else
                    Methods.AutoId.instance().visualize(...
                        app.image_neurons, app.worm, 'ax', app.XY, 'z', z);
                end
            end
        end


        function DrawImageLabels(app, positions, labels, varargin)
            %% Draw text labels on the image.

            % Are the left & right labels pre-determined?
            left_i = [];
            if ~isempty(varargin)
                left_i = varargin{1};
            end

            % Determine the left & right labels.
            if isempty(left_i)
                center_y = size(app.image_view, 2) / 2;
                left_i = positions(:,2) <= center_y;
            end
            right_i = ~left_i;

            % Draw the labels on the left side.
            if sum(left_i) > 0
                left_pos = positions(left_i,1:2);
                left_pos(:,2) = left_pos(:,2) + 4;
                left_labels = cellfun(@(x) strcat('\leftarrow', x), labels(left_i), 'UniformOutput', false);
                text(app.XY, left_pos(:,2), left_pos(:,1), left_labels, ...
                    'Color', app.label_color, 'FontSize', app.label_fontsize, ...
                    'FontWeight', app.label_fontweight, 'HorizontalAlignment', 'left');
            end

            % Draw the labels on the right side.
            if sum(right_i) > 0
                right_pos = positions(right_i,1:2);
                right_pos(:,2) = right_pos(:,2) - 4;
                right_labels = cellfun(@(x) strcat(x, '\rightarrow'), labels(right_i), 'UniformOutput', false);
                text(app.XY, right_pos(:,2), right_pos(:,1), right_labels, ...
                    'Color', app.label_color, 'FontSize', app.label_fontsize, ...
                    'FontWeight', app.label_fontweight, 'HorizontalAlignment', 'right');
            end
        end


        function ImageClicked(app, image_neurons, hit)
            %% The image was clicked. Add a neuron (on middle or double-click) or delete image artifacts (if selected).

            double_click = false; % did the user double-click?

            switch hit.Button
                case 1
                    % Check for a double click.
                    if app.mouse_clicked.click
                        %disp('double click');
                        double_click = true;

                        % Single click.
                    else
                        app.mouse_clicked.click = true;
                        pause(app.mouse_clicked.double_click_delay);
                        if app.mouse_clicked.click
                            %disp('single click');
                        end
                    end
                    app.mouse_clicked.click = false;

                case 2
                    double_click = true;
            end

            % Add a neuron where the user clicked.
            if double_click
                switch app.MouseClickDropDown.Value
                    case app.MouseClickDropDown.Items{1} % Add a neuron where the user clicked.
                        app.logEvent('NeuroPAL_ID','Adding neuron...',1);
                        app.image_neurons.add_meta_data('auto_detect', false);
                        AddNeuron(app, hit);

                    case app.MouseClickDropDown.Items{2} % Auto detect a neuron where the user clicked.
                        app.logEvent('NeuroPAL_ID','Auto-detecting neuron...',1);
                        app.image_neurons.add_meta_data('auto_detect', true);
                        AddNeuron(app, hit);
                end
            end
        end


        function AddNeuron(app, hit)
            %% Add a neuron to a z-slice.

            % Where did the user click?
            mouse_pos = flip(hit.IntersectionPoint(1:2)) + app.mouse_offset;

            % Read the z slice from the ZSlider.
            z = getZSlice(app, round(app.ZSlider.Value));
            point = [mouse_pos, z];

            app.logEvent('Main',sprintf('Adding neuron @ %s...', string(point)), 0);
            % Create & add the neuron.
            app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                point, app.mp_params.hnsz, app.image_um_scale');

            % Save the updated IDs.
            SaveIDToFile(app);

            % Redraw the image.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Enable the neuron GUI.
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');
            end
        end


        function NeuronClicked(app, image_neurons, hit)
            %% Select or delete neurons where the user clicked.

            select_neuron = false; % select the neuron where the user clicked?
            delete_neuron = false; % delete the neuron where the user clicked?

            % Where did the user click?
            mouse_pos = flip(hit.IntersectionPoint(1:2));

            % Where are we in Z?
            z = getZSlice(app, round(app.ZSlider.Value));
            point = [mouse_pos, z];

            % Find the closest neuron to the mouse click.
            positions = app.image_neurons.get_positions();
            [~, neuron_i] = min(mean((positions-point).^2,2));

            switch hit.Button
                case 1 % left click

                    % Check for a double click.
                    if app.mouse_clicked.click
                        delete_neuron = true;

                        % Single click.
                    else
                        app.mouse_clicked.click = true;
                        pause(app.mouse_clicked.double_click_delay);
                        if app.mouse_clicked.click
                            select_neuron = true;
                        end
                    end
                    app.mouse_clicked.click = false;

                case 3 % right click
                    delete_neuron = true;
            end

            % Select the neuron.
            if select_neuron
                SelectNeuron(app, neuron_i);

                % Delete the neuron from the list.
            elseif delete_neuron
                DeleteNeuron(app, neuron_i);
            end
        end


        function drawStimuli(app)
            % Get the axes of the stackedplot
            ax = findobj(app.VolTrace.NodeChildren, "Type", "axes");
        
            % Loop over each stimulus
            for i = 1:size(app.stimuli, 1)
                % Get the stimulus time and name
                time = app.stimuli{i, 1};
                if size(app.stimuli, 2) == 3
                    name = app.stimuli{i, 3}{1};
                    end_time = app.stimuli{i, 2};
                else
                    name = app.stimuli{i, 2}{1};

                    if i ~= size(app.stimuli, 1)
                        end_time = app.stimuli{i+1, 1};
                    else
                        get(app.VolTrace)
                        end_time = app.VolTrace.XLimits(2);
                    end
                end
        
                % If the stimulus is 'CTX', set color to white
                if strcmp(name, 'CTX')
                    color = [1 1 1];
                else
                    % Sum the ASCII values of the name's characters
                    asciiSum = sum(double(name));
        
                    % Use mod to wrap the sum into a range [0, 1]
                    hashValue = mod(asciiSum, 256) / 255;
        
                    % Generate a color that is not too similar to white
                    color = [hashValue, abs(0.5 - hashValue), abs(1 - hashValue)];
                end
        
                text_color = color * 0.5;  % darker variant
        
                % Add the stimulus name as text
                text(ax(end), time + (end_time - time) / 2, ax(end).YLim(2), name, 'HorizontalAlignment', 'right', 'VerticalAlignment', 'middle', 'FontSize', 8, 'Color', text_color, 'Rotation', 90);
        
                % Loop over each subplot
                for j = 1:numel(ax)
                    % Draw a rectangle on the subplot using patch
                    patch(ax(j), 'XData', [time, end_time, end_time, time], 'YData', [ax(j).YLim(1), ax(j).YLim(1), ax(j).YLim(2), ax(j).YLim(2)], 'FaceColor', color, 'EdgeColor', 'none', 'FaceAlpha', 0.6);
                end
            end       

            for n=1:numel(ax)
                set(ax(n),'children',flipud(get(ax(n),'children')))
            end

            app.data_flags.('Stimulus File')
        end


        function SelectNeuron(app, neuron_i)
            %% Select a neuron from the z-slice image.

            % Update the neuron plot marker to show that it's selected.
            % Find the index of selected neuron in the list of displayed
            % neurons that are in the current slice. The index of neuron
            % in the app.image_neurons.neurons (which contains all the neurons) is
            % different from its index in the list of current z neurons.

            % Unselect the selected neuron.
            if app.selected_neuron == neuron_i
                UnselectNeuron(app);
                return;
            end

            % Unselect the previously selected neuron.
            if ~isempty(app.selected_neuron)
                app.image_neurons.neurons(app.selected_neuron).is_selected = false;
            end

            % Select the neuron.
            app.selected_neuron = neuron_i;
            app.image_neurons.neurons(app.selected_neuron).is_selected = true;

            % Flip the Z-axis.
            z = app.image_neurons.neurons(app.selected_neuron).position(3);
            z = getZSlice(app, z);

            % Redraw the Z-slice.
            app.ZSlider.Value = z;
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);

            % If the AutoID is already run and the IDs are present.
            is_IDEditField_set = false;
            if ~isempty(app.image_neurons.neurons) ...
                    && isempty(app.image_neurons.neurons(neuron_i).annotation) ...
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)

                % Initialize the IDs & probabilities.
                ids = app.image_neurons.neurons(neuron_i).probabilistic_ids;
                probs = app.image_neurons.neurons(neuron_i).probabilistic_probs();
                probs = round(probs * 100);

                % Remove zero probability choices.
                probs_i = probs > 0;
                ids = ids(probs_i);
                probs = probs(probs_i);

                % Concatenate names and probabilities to display them.
                str = strcat(ids, '=', arrayfun(@(x) num2str(x, '%d%% '), probs, 'UniformOutput', 0));

                % Update the GUI.
                app.AutoIDDropDown.Items = str;
                name = app.image_neurons.neurons(neuron_i).deterministic_id;
                name = upper(name);
                if ~app.AutoNameCheckBox.Value || ...
                        strcmp(name, 'ARTIFACT') || strcmp(name, 'NAN')
                    app.IDEditField.Value = '';
                else
                    app.IDEditField.Value = name;
                end
                is_IDEditField_set = true;

                % Select the appropriate Auto ID.
                neuron_rank = app.image_neurons.neurons(neuron_i).rank;
                if ~isempty(neuron_rank)
                    app.UserNeuronIDsListBox.Value = neuron_rank;
                    %app.UserNeuronIDsListBox.Value = app.UserNeuronIDsListBox.Items{neuron_rank};
                end
            else
                % Select nothing.
                app.AutoIDDropDown.Items = {};
                app.UserNeuronIDsListBox.Value = {};
            end

            % Show the user annotation.
            user_ID = app.image_neurons.neurons(neuron_i).annotation;
            if ~isempty(user_ID)

                % Is the neuron ON/OFF?
                switch app.image_neurons.neurons(neuron_i).is_annotation_on
                    case false
                        user_ID = [user_ID '-OFF'];
                    case true
                        user_ID = [user_ID '-ON'];
                end

                % Is the user uncertain about the ID?
                if app.image_neurons.neurons(neuron_i).annotation_confidence <= 0.5
                    user_ID = [user_ID '?'];
                end

                % Is the neuron emphasized?
                if app.image_neurons.neurons(neuron_i).is_emphasized
                    user_ID = [user_ID '!'];
                end

                % Display the user ID.
                app.IDEditField.Value = user_ID;
                is_IDEditField_set = true;
            end

            % Empty the ID field.
            if ~is_IDEditField_set
                app.IDEditField.Value = '';
            end

            % Enable the input fields.
            app.AutoIDDropDown.Enable = 'on';
            app.IDEditField.Enable = 'on';
            app.AutoIDButton.Enable = 'on';
            app.UserIDButton.Enable = 'on';

            % If the neuron is unID'd, send focus to the ID text field.
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            if isempty(user_ID)
                Program.GUIHandling.send_focus(app.IDEditField);
            end
        end


        function SelectNearestUnIDdNeuron(app, neuron_i)
            %% Select the nearest neuron in need of a user ID.

            % Select the nearest neuron in need of a user ID by z, then (x,y).
            [~, neuron_i] = app.image_neurons.nearest_unannotated(neuron_i);

            % No more neurons to ID.
            if isempty(neuron_i)
                uialert(app.CELL_ID, ...
                    'All neurons have been assigned an ID!',...
                    'All Neurons ID''d', 'Icon', 'success');
                return;
            end

            % Auto ID the nearest neuron.
            auto_ID = [];
            if ~isempty(app.image_neurons.neurons) ...
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)
                auto_ID = app.image_neurons.neurons(neuron_i).deterministic_id;
            end

            % Select the nearest neuron.
            SelectNeuron(app, neuron_i);
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {[auto_ID '???']}, 0);

            % Send focus to the ID text field.
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            %Program.GUIHandling.send_focus(app.IDEditField);
        end


        function SelectProbabilisticUnIDdNeuron(app, neuron_i)
            %% Select the most uncertain neuron in need of a user ID.

            % No more neurons to ID.
            if app.image_neurons.num_neurons() == app.image_neurons.num_user_id_neurons()
                uialert(app.CELL_ID, ...
                    'All neurons have been assigned an ID!',...
                    'All Neurons ID''d', 'Icon', 'success');
                return;
            end

            % Store the currently selcted neuron.
            old_neuron_i = neuron_i;

            % Select the nearest neuron in need of a user ID by z, then (x,y).
            [~, neuron_i] = app.image_neurons.find_rank(1);

            % Is the neuron already selected?
            if app.image_neurons.num_user_unid_neurons() > 1 && neuron_i == old_neuron_i
                [~, neuron_i] = app.image_neurons.find_rank(2);
            end

            % No more neurons to ID.
            if isempty(neuron_i)
                uialert(app.CELL_ID, ...
                    'All neurons have been assigned an ID!',...
                    'All Neurons ID''d', 'Icon', 'success');
                return;
            end

            % Auto ID the most uncertain neuron.
            auto_ID = [];
            if ~isempty(app.image_neurons.neurons) ...
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)
                auto_ID = app.image_neurons.neurons(neuron_i).deterministic_id;
            end

            % Select the most uncertain neuron.
            SelectNeuron(app, neuron_i);
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {[auto_ID '???']}, 0);

            % Send focus to the ID text field.
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            %Program.GUIHandling.send_focus(app.IDEditField);
        end


        function UnselectNeuron(app, varargin)
            %% Unselect the currently selected neuron. Optional argument, re-draw the image?
            %% Note: Matlab buffers re-draws so, if you want them to execute in an orderly fashion,
            %% you need to only make one call.

            % Unselect the selected neuron.
            if ~isempty(app.selected_neuron) && app.image_neurons.neurons(app.selected_neuron).is_selected == true

                % Unselect the neuron.
                app.image_neurons.neurons(app.selected_neuron).is_selected = false;
                app.selected_neuron = [];

                % Disable the ID fields.
                app.AutoIDDropDown.Items = {''};
                app.AutoIDDropDown.Value = '';
                app.IDEditField.Value = '';

                % Disable the input fields.
                app.AutoIDDropDown.Enable = 'off';
                app.IDEditField.Enable = 'off';
                app.AutoIDButton.Enable = 'off';
                app.UserIDButton.Enable = 'off';

                % Redraw the Z-slice.
                is_redraw = true; % re-draw the image?
                if ~isempty(varargin)
                    is_redraw = varargin{1};
                end
                if is_redraw

                    % Give the GUI time to update.
                    pause(0.2);

                    % Re-draw.
                    DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
                end
            end
        end


        function DeleteNeuron(app, neuron_i)
            %% Delete a neuron from the z-slice image.

            % Did the neuron have an ID?
            neuron = app.image_neurons.neurons(neuron_i).annotation;
            if ~isempty(neuron)
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" is ID''d!'], 'Do you want delete the neuron?'}, ...
                    'Neuron ID Deleted', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Keep the neuron.
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Unselect all neurons.
            app.UnselectNeuron(false); % wait for re-draw
            %app.UnselectNeuron();

            % NO LONGER USED!!! UPDATEAUTOIDMODEL HANDLES THIS.
            % Delete the neuron from the GUI list.
            %app.UserNeuronIDsListBox.Value = {};
            %uncertain_idx = app.image_neurons.neurons(neuron_i).rank;
            %if ~isempty(uncertain_idx)
            %    app.UserNeuronIDsListBox.Items = ...
            %        {app.UserNeuronIDsListBox.Items{1:(uncertain_idx-1)}, ...
            %        app.UserNeuronIDsListBox.Items{(uncertain_idx+1):end}};
            %end

            % Delete the neuron from the master list.
            app.image_neurons.del_neuron(neuron_i);

            % Save the updated IDs.
            app.SaveIDToFile();

            % Redraw the auto ID list.
            app.DrawAutoIDList();

            % Redraw the Z-slice.
            app.DrawZSlice(app.ZSlider, app.image_view, app.XY);

            % Update the ID'd neuron count.
            app.UpdateNeuronLists();
        end

        function DrawAutoIDList(app)
            %% Draw the auto ID neuron uncertainty list.

            % Initialize the probabilities.
            prob_IDs = app.image_neurons.get_probabilistic_ids();
            prob_vals = app.image_neurons.get_probabilistic_probs();
            ranks = app.image_neurons.get_ranks();
            if isempty(ranks)
                return;
            end

            % Construct the ranked strings.
            neurons = app.image_neurons.neurons;
            prob_vals = round(prob_vals * 100);
            prob_strs = [];
            prob_ranks = [];
            for i = 1:length(neurons)

                % Is the neuron ranked?
                neuron = neurons(i);
                if neuron.rank > 0 % && isempty(neuron.annotation)

                    % Display the most probable neuron.
                    prob_IDs = neuron.probabilistic_ids;
                    prob_vals = round(neuron.probabilistic_probs * 100);
                    prob_strs{end+1} = sprintf('%s=%d%%', prob_IDs{1}, prob_vals(1));
                    prob_ranks(end+1) = neuron.rank;

                    % Display alternative neurons.
                    alt_i = find(prob_vals(2:end) > 0) + 1;
                    if ~isempty(alt_i)
                        prob_strs{end} = [prob_strs{end}, '   or  '];
                    end
                    for j = 1:length(alt_i)
                        k = alt_i(j);
                        if j > 1
                            prob_strs{end} = [prob_strs{end}, ','];
                        end
                        prob_strs{end} = [prob_strs{end}, sprintf(' %s=%d%%', ...
                            prob_IDs{k}, prob_vals(k))];
                    end
                end
            end

            % Draw the auto ID neuron uncertainty list.
            [~, sort_i] = sort(prob_ranks);
            app.UserNeuronIDsListBox.Items = prob_strs(sort_i);
            app.UserNeuronIDsListBox.ItemsData = 1:length(sort_i);
            app.UserNeuronIDsListBox.Value = {};
        end


        function UpdateNeuronLists(app)
            %% Update the user ID'd neurons.

            % Show the selected neurons.
            selectedNodes = app.GanglionTree.SelectedNodes;
            neurons = {};
            for i = 1:length(selectedNodes)
                node = selectedNodes(i);
                if isempty(node.Children) % Child node on the tree.
                    neurons = union(neurons, GetChildNodeNeurons(app, node));
                else % Parent node on the tree.
                    neurons = union(neurons, GetParentNodeNeurons(app, node));
                end
            end
            neurons = sort(neurons);

            % None of the neurons have IDs.
            if isempty(app.image_neurons) || isempty(app.image_neurons.neurons)
                app.UserNeuronIDsListBoxLabel.Text = 'User Neuron IDs';
                app.IDdLabel.Text = '     ID''d     ';
                app.UserNeuronIDsListBox.Items = {};
                app.UserNeuronIDsListBox.ItemsData = [];
                app.UnIDdLabel.Text = sprintf('Un-ID''d = %d', length(neurons));
                app.UnIDdNeuronsListBox.Items = neurons;

                % Which neurons have IDs?
            else
                % Show the neuron counts.
                user_id_neurons = app.image_neurons.user_id_neurons();
                app.UserNeuronIDsListBoxLabel.Text = sprintf('User Neuron IDs = %d/%d', ...
                    length(user_id_neurons), app.image_neurons.num_neurons());

                % Remove emphasized neurons. They're not really ID'd.
                is_emphasized = vertcat(user_id_neurons.is_emphasized);
                user_id_neurons = user_id_neurons(~is_emphasized);

                % Separate the neurons into those with & without user IDs.
                user_id_names = vertcat({user_id_neurons.annotation});
                [id_neurons, ~, user_id_i] = intersect(neurons, user_id_names);
                unid_neurons =  setdiff(neurons, id_neurons);

                % Keep a copy of the neuron names.
                id_neuron_names = id_neurons;

                % Add ON/OFF & confidences to the neuron names.
                for i = 1:length(user_id_i)

                    % Is the neuron ON/OFF?
                    switch user_id_neurons(user_id_i(i)).is_annotation_on
                        case false
                            id_neurons{i} = [id_neurons{i} '-OFF'];
                        case true
                            id_neurons{i} = [id_neurons{i} '-ON'];
                    end

                    % Is the user uncertain about the ID?
                    if user_id_neurons(user_id_i(i)).annotation_confidence <= 0.5
                        id_neurons{i} = [id_neurons{i} ' ?'];
                    end
                end

                % Add the neuron birth times.
                if Program.GUIPreferences.instance().is_show_birth_times

                    % Initialize the neuron info.
                    neuron_data = app.hermaphrodite_neurons;
                    if contains(lower(app.worm.sex), 'xo')
                        neuron_data = app.male_neurons;
                    end

                    % Add birth times to the ID'd neurons.
                    [~, id_i, ~] = intersect(neuron_data.names, id_neuron_names);
                    if ~isempty(id_i)
                        id_stages = neuron_data.birth_stages(id_i);
                        id_neurons = cellfun(@(x,y) ...
                            sprintf('%s (%s)', x, y), ...
                            id_neurons, id_stages, ...
                            'UniformOutput', false);
                    end

                    % Add birth times to the ID'd neurons.
                    [~, unid_i, ~] = intersect(neuron_data.names, unid_neurons);
                    if ~isempty(unid_i)
                        unid_stages = neuron_data.birth_stages(unid_i);
                        unid_neurons = cellfun(@(x,y) ...
                            sprintf('%s (%s)', x, y), ...
                            unid_neurons, unid_stages, ...
                            'UniformOutput', false);
                    end
                end

                % Update the GUI.
                black = [0,0,0];
                gray = [0.95,0.95,0.95];
                app.IDdLabel.BackgroundColor = gray;
                app.IDdLabel.FontColor = black;
                app.UnIDdLabel.BackgroundColor = gray;
                app.UnIDdLabel.FontColor = black;
                app.IDdLabel.Text = sprintf('ID''d = %d', length(id_neurons));
                app.IDdNeuronsListBox.Items = id_neurons;
                app.UnIDdLabel.Text = sprintf('Un-ID''d = %d', length(unid_neurons));
                app.UnIDdNeuronsListBox.Items = unid_neurons;
            end
        end


        function UpdateSexNeuronLists(app)
            %% Update the neuron lists.
            
            % Is the worm a male?
            is_male = contains(lower(app.worm.sex), 'xo');

            % Find the male tail node.
            tree = app.GanglionTree;
            names = {tree.Children.Text};
            male_i = find(strcmp(names,'Male Tail'));

            % Add the male tail.
            if is_male && isempty(male_i)

                % Unselect all ganglia & neurons.
                app.GanglionTree.SelectedNodes = [];
                UnselectNeuron(app);

                % Remove the hermaphrodite tail.
                hermaphrodite_i = find(strcmp(names,'Tail'));
                if ~isempty(hermaphrodite_i)
                    tree.Children(hermaphrodite_i).delete();
                end

                % Add the male tail.
                app.neuron_info = Neurons.Male;
                ganglia = uitreenode(tree,'Text','Male Tail');
                uitreenode(ganglia,'Text','Pre-Anal (L)');
                uitreenode(ganglia,'Text','Pre-Anal (R)');
                uitreenode(ganglia,'Text','Rays (L)');
                uitreenode(ganglia,'Text','Rays (R)');
                uitreenode(ganglia,'Text','Dorso-Rectal');
                uitreenode(ganglia,'Text','Cloacal (L)');
                uitreenode(ganglia,'Text','Cloacal (R)');
                uitreenode(ganglia,'Text','Lumbar (L)');
                uitreenode(ganglia,'Text','Lumbar (R)');
                UpdateNeuronLists(app);

                % Remove the male tail.
            elseif ~is_male && ~isempty(male_i)

                % Unselect all ganglia & neurons.
                app.GanglionTree.SelectedNodes = [];
                UnselectNeuron(app);

                % Remove the male tail.
                tree.Children(male_i).delete();
                UpdateNeuronLists(app);

                % Add the hermaphrodite tail.
                app.neuron_info = Neurons.Hermaphrodite;
                ganglia = uitreenode(tree,'Text','Tail');
                uitreenode(ganglia,'Text','Pre-Anal');
                uitreenode(ganglia,'Text','Dorso-Rectal');
                uitreenode(ganglia,'Text','Lumbar (L)');
                uitreenode(ganglia,'Text','Lumbar (R)');
                UpdateNeuronLists(app);
            end
        end


        function neurons = GetParentNodeNeurons(app, node)
            %% Get the neurons associated with this parent node.

            neurons = [];
            switch node.Text
                case 'Pharynx'
                    neurons = app.neuron_info.getAnteriorPharynx();
                    neurons = union(neurons, app.neuron_info.getPosteriorPharynx());
                case 'Head'
                    neurons = app.neuron_info.getLeftAnteriorGanglion();
                    neurons = union(neurons, app.neuron_info.getRightAnteriorGanglion());
                    neurons = union(neurons, app.neuron_info.getDorsalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftLateralGanglion());
                    neurons = union(neurons, app.neuron_info.getRightLateralGanglion());
                    neurons = union(neurons, app.neuron_info.getVentralGanglion());
                    neurons = union(neurons, app.neuron_info.getRetroVesicularGanglion());
                case 'Midbody'
                    neurons = app.neuron_info.getAnteriorMidbody();
                    neurons = union(neurons, app.neuron_info.getCentralMidbody());
                    neurons = union(neurons, app.neuron_info.getPosteriorMidbody());
                    neurons = union(neurons, app.neuron_info.getVentralNerveCord());
                case 'Tail'
                    neurons = app.neuron_info.getPreAnalGanglion();
                    neurons = union(neurons, app.neuron_info.getDorsoRectalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftLumbarGanglion());
                    neurons = union(neurons, app.neuron_info.getRightLumbarGanglion());
                case 'Male Tail'
                    neurons = app.neuron_info.getLeftPreAnalGanglion();
                    neurons = union(neurons, app.neuron_info.getRightPreAnalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftRays());
                    neurons = union(neurons, app.neuron_info.getRightRays());
                    neurons = union(neurons, app.neuron_info.getDorsoRectalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftCloacalGanglion());
                    neurons = union(neurons, app.neuron_info.getRightCloacalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftLumbarGanglion());
                    neurons = union(neurons, app.neuron_info.getRightLumbarGanglion());
            end
        end


        function neurons = GetChildNodeNeurons(app, node)
            %% Get the neurons associated with this child node.

            neurons = [];
            switch node.Parent.Text
                case 'Pharynx'
                    switch node.Text
                        case 'Anterior'
                            neurons = app.neuron_info.getAnteriorPharynx();
                        case 'Posterior'
                            neurons = app.neuron_info.getPosteriorPharynx();
                    end
                case 'Head'
                    switch node.Text
                        case 'Anterior (L)'
                            neurons = app.neuron_info.getLeftAnteriorGanglion();
                        case 'Anterior (R)'
                            neurons = app.neuron_info.getRightAnteriorGanglion();
                        case 'Dorsal'
                            neurons = app.neuron_info.getDorsalGanglion();
                        case 'Lateral (L)'
                            neurons = app.neuron_info.getLeftLateralGanglion();
                        case 'Lateral (R)'
                            neurons = app.neuron_info.getRightLateralGanglion();
                        case 'Ventral'
                            neurons = app.neuron_info.getVentralGanglion();
                        case 'Retro-Vesicular'
                            neurons = app.neuron_info.getRetroVesicularGanglion();
                    end
                case 'Midbody'
                    switch node.Text
                        case 'Anterior'
                            neurons = app.neuron_info.getAnteriorMidbody();
                        case 'Central'
                            neurons = app.neuron_info.getCentralMidbody();
                        case 'Posterior'
                            neurons = app.neuron_info.getPosteriorMidbody();
                        case 'Ventral Cord'
                            neurons = app.neuron_info.getVentralNerveCord();
                    end
                case 'Tail'
                    switch node.Text
                        case 'Pre-Anal'
                            neurons = app.neuron_info.getPreAnalGanglion();
                        case 'Dorso-Rectal'
                            neurons = app.neuron_info.getDorsoRectalGanglion();
                        case 'Lumbar (L)'
                            neurons = app.neuron_info.getLeftLumbarGanglion();
                        case 'Lumbar (R)'
                            neurons = app.neuron_info.getRightLumbarGanglion();
                    end
                case 'Male Tail'
                    switch node.Text
                        case 'Pre-Anal (L)'
                            neurons = app.neuron_info.getLeftPreAnalGanglion();
                        case 'Pre-Anal (R)'
                            neurons = app.neuron_info.getRightPreAnalGanglion();
                        case 'Rays (L)'
                            neurons = app.neuron_info.getLeftRays();
                        case 'Rays (R)'
                            neurons = app.neuron_info.getRightRays();
                        case 'Dorso-Rectal'
                            neurons = app.neuron_info.getDorsoRectalGanglion();
                        case 'Cloacal (L)'
                            neurons = app.neuron_info.getLeftCloacalGanglion();
                        case 'Cloacal (R)'
                            neurons = app.neuron_info.getRightCloacalGanglion();
                        case 'Lumbar (L)'
                            neurons = app.neuron_info.getLeftLumbarGanglion();
                        case 'Lumbar (R)'
                            neurons = app.neuron_info.getRightLumbarGanglion();
                    end
            end
        end


        function SaveUserID(app, user_ID, neuron_i)
            %% Save the user ID for a neuron.

            % Depending on the question mark set the annotation confidence and the neuron name.
            % The marker colors will change accordingly -> see Neuron.m
            %   Examples:
            %       'AVR?' -> 50% confidence if it's AVR or not.
            %       '?' -> 0% confidence about the neuron.
            %       'AVR' -> 100% confidence that it's AVR.

            % Are we already saving an ID?
            if app.is_saving_ID
                return;
            end
            app.is_saving_ID = true;

            % Are any neurons selected?
            if ~exist("neuron_i","var")
                neuron_i = app.selected_neuron;
            end

            app.logEvent('Main',sprintf('Saving ID %s for neuron %s...', user_ID, string(neuron_i)), 1);

            if isempty(neuron_i)
                app.is_saving_ID = false;
                return;
            end

            % Get rid of the whitespace and uppercase the ID.
            user_ID = upper(strtrim(user_ID));

            % Did the user input an empty ID (e.g., "")?
            if isempty(user_ID)

                % Are we deleting the neuron's existing ID?
                neuron = app.image_neurons.neurons(neuron_i).annotation;
                if ~isempty(neuron)
                    answer = uiconfirm(app.CELL_ID, ...
                        {['"' neuron '" is ID''d!'], 'Do you want delete the ID?'}, ...
                        'Neuron ID Deleted', 'Options', {'Yes', 'Cancel'}, ...
                        'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                    % Keep the ID.
                    if strcmpi(answer, 'cancel')
                        UnselectNeuron(app);
                        app.is_saving_ID = false;
                        return;
                    end

                    % Delete the ID.
                    app.image_neurons.neurons(neuron_i).annotate('', 0, nan);
                end


                % Save the updated IDs.
                SaveIDToFile(app);

                % Update the ID'd neuron count.
                UpdateNeuronLists(app);

                % Clear the ID field.
                UnselectNeuron(app);
                app.is_saving_ID = false;

                % Select the next neuron in need of a user ID.
                if strcmp(app.NextNeuronDropDown.Value, 'Nearest')
                    app.SelectNearestUnIDdNeuron(neuron_i);
                else
                    app.SelectProbabilisticUnIDdNeuron(neuron_i);
                end

                % Done.
                return;
            end

            % Is the user emphasizing this neuron?
            % Note: as one example, neurons may be emphasized in mutants.
            neuron = user_ID;
            is_emphasized = false;
            if neuron(end) == '!'
                is_emphasized = true;
                neuron = neuron(1:(end-1));
            end

            % Is the user uncertain about the ID?
            is_uncertain = false;
            if neuron(end) == '?'
                is_uncertain = true;
                neuron = neuron(1:(end-1));
            end

            % Is the neuron ON/OFF?
            [neuron, is_on] = app.neuron_info.stripOnOff(neuron);

            % Did the user input a correct neuron name?
            % Note: emphasized neurons can exist as duplicates.
            if is_emphasized
                if ~Neurons.Hermaphrodite.isCell(neuron) && ...
                        ~Neurons.Male.isCell(neuron)
                    uiconfirm(app.CELL_ID, ...
                        {['"' neuron '" is not a cell type!'], ...
                        ['Did you forget the "L" or "R"?']}, ...
                        'Neuron Name Error', 'Options', {'Ok'}, ...
                        'DefaultOption', 1, 'CancelOption', 1, ...
                        'Icon', 'error');
                    Program.GUIHandling.send_focus(app.IDEditField);
                    app.is_saving_ID = false;
                    return;
                    %else
                    % Add the emphasis back onto the neuron.
                    % Note: this has 2 side effects
                    % 1. We can assign the neuron name more than once.
                    % 2. The neuron assignment will not affect our predictions.
                    %neuron = [neuron '!'];
                end
            elseif ~app.neuron_info.isCell(neuron)
                uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" is not a neuron in ' app.worm.sex ' animals!'], ...
                    ['Did you forget the "L" or "R", or is the sex incorrect?']}, ...
                    'Neuron Name Error', 'Options', {'Ok'}, ...
                    'DefaultOption', 1, 'CancelOption', 1, ...
                    'Icon', 'error');
                Program.GUIHandling.send_focus(app.IDEditField);
                app.is_saving_ID = false;
                return;
            end

            % Has the neuron already been assigned?
            prev_assigned_neuron = app.image_neurons.find_user_id_neuron(neuron);
            if ~isempty(prev_assigned_neuron) && ~is_emphasized && ...
                    app.image_neurons.neurons(neuron_i) ~= prev_assigned_neuron

                % Are we re-assigning the ID?
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" was assigned to a different neuron!'], ...
                    'Do you want delete the previously assigned ID?'}, ...
                    'Neuron ID Re-Assigned', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Cancel.
                if strcmpi(answer, 'cancel')
                    Program.GUIHandling.send_focus(app.IDEditField);
                    app.is_saving_ID = false;
                    return;
                end

                % Delete the previous ID.
                prev_assigned_neuron.annotate('', 0, nan);
            end

            % What did the user enter?
            app.image_neurons.neurons(neuron_i).annotation = neuron;
            app.image_neurons.neurons(neuron_i).is_annotation_on = is_on;
            app.image_neurons.neurons(neuron_i).is_emphasized = is_emphasized;
            if is_uncertain
                app.image_neurons.neurons(neuron_i).annotation_confidence = 0.5;
            else
                app.image_neurons.neurons(neuron_i).annotation_confidence = 1;
            end
            if ~isempty(app.image_neurons.neurons(neuron_i).probabilistic_probs)
                app.image_neurons.neurons(neuron_i).deterministic_id = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_ids{1} = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(1) = 1;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(2:end) = 0;
            end
            if ~isempty(app.image_neurons.neurons(neuron_i).rank)
                app.image_neurons.rank_neuron_max(neuron_i);
            end

            % Update the auto-ID model.
            UpdateAutoIDModel(app);

            % Update ID confidences
            UpdateNeuronIDConfidence(app);

            % Save the updated IDs.
            SaveIDToFile(app);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Done.
            UnselectNeuron(app);
            app.is_saving_ID = false;

            % Select the next neuron in need of a user ID.
            if strcmp(app.NextNeuronDropDown.Value, 'Nearest')
                app.SelectNearestUnIDdNeuron(neuron_i);
            else
                app.SelectProbabilisticUnIDdNeuron(neuron_i);
            end

            % Show the annotated neuron's ID.
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {user_ID}, 1);
        end


        function SaveAutoID(app, neuron)
            %% Save the auto ID for a neuron.

            % Are we already saving an ID?
            if app.is_saving_ID
                return;
            end
            app.is_saving_ID = true;

            % Are any neurons selected?
            neuron_i = app.selected_neuron;
            if isempty(neuron_i)
                app.is_saving_ID = false;
                return;
            end

            % Has the neuron already been assigned?
            prev_assigned_neuron = app.image_neurons.find_user_id_neuron(neuron);
            if ~isempty(prev_assigned_neuron) && app.image_neurons.neurons(neuron_i) ~= prev_assigned_neuron

                % Are we re-assigning the ID?
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" was assigned to a different neuron!'], ...
                    'Do you want delete the previously assigned ID?'}, ...
                    'Neuron ID Re-Assigned', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Cancel.
                if strcmpi(answer, 'cancel')
                    Program.GUIHandling.send_focus(app.IDEditField);
                    app.is_saving_ID = false;
                    return;
                end

                % Delete the previous ID.
                prev_assigned_neuron.annotate('', 0, nan);
            end

            app.logEvent('Main',sprintf('Saving automatic ID %s for neuron %s...', neuron, string(neuron_i)), 1);

            % Annotate the neuron.
            app.image_neurons.neurons(neuron_i).annotation = neuron;
            app.image_neurons.neurons(neuron_i).is_annotation_on = nan;
            app.image_neurons.neurons(neuron_i).annotation_confidence = 1;
            app.image_neurons.neurons(neuron_i).is_emphasized = false;
            if ~isempty(app.image_neurons.neurons(neuron_i).probabilistic_probs)
                app.image_neurons.neurons(neuron_i).deterministic_id = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_ids{1} = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(1) = 1;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(2:end) = 0;
            end
            if ~isempty(app.image_neurons.neurons(neuron_i).rank)
                app.image_neurons.rank_neuron_max(neuron_i);
            end

            % Update the auto-ID model.
            UpdateAutoIDModel(app);

            % Save the updated IDs.
            SaveIDToFile(app);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Done.
            UnselectNeuron(app);
            app.is_saving_ID = false;

            % Select the next neuron in need of a user ID.
            if strcmp(app.NextNeuronDropDown.Value, 'Nearest')
                app.SelectNearestUnIDdNeuron(neuron_i);
            else
                app.SelectProbabilisticUnIDdNeuron(neuron_i);
            end

            % Show the annotated neuron's ID.
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {neuron}, 1);
        end


        function UpdateAutoIDModel(app)
            %% Update the model for neuron ID.

            % Update the log_likelihood, uncertainty, and neuron ranks,
            % then update the list of neurons accordingly.
            worm = app.worm;
            if Program.GUIPreferences.instance().is_autoID_updates && ...
                    Methods.AutoId.isBodyID(worm.sex, worm.body)
                Methods.AutoId.instance().update_id(...
                    app.image_neurons, worm);
            end
            DrawAutoIDList(app);
        end


        function UpdateNeuronIDConfidence(app)
            %% Update the neuorn ID confidences.

            % Update the confidence computations that each labeled neuron
            % is correctly identified.
            worm = app.worm;
            if ~Methods.AutoId.isBodyID(worm.sex, worm.body)
                return;
            end
            Methods.AutoId.instance().update_confidences(...
                app.image_neurons, worm);
        end

        
        function SaveIDToFile(app)
            %% Save the IDs to a file.

            version = Program.ProgramInfo.version;
            mp_params = app.mp_params;
            mp_params.k = length(app.image_neurons.neurons);
            neurons = app.image_neurons;
            save(app.id_file, 'version', 'neurons', 'mp_params');
            %sp = app.image_neurons.to_superpixel();
            %save(app.id_file, 'version', 'sp', 'mp_params');
        end


        function z = getZSlice(app, z)
            %% Get the z slice (correcting for the flipped axis).

            if app.image_prefs.is_Z_flip
                z = size(app.image_data,3) - z + 1;
            end
        end


        function lMoving(~, source)
            % Function that responds to the movement of the time sliders

            % Always keep the position rounded to 1 decimal place
            source.Position(1,1) = round(source.Position(1,1),1);
            source.Position(2,1) = round(source.Position(2,1),1);
            % Add any other action here triggered by the movement of the sliders
        end


        function PickNeuron(app,caller,tag)
            if ~exist('tag','var')
                tag = 'selector';
                g_parent = app.CELL_ID;
                parent = caller.Parent;
            elseif strcmp(tag,'col') | strcmp(tag,'row')
                'tbd';
            else
                tag = 'add_graph';
                g_parent = caller.Parent;
                parent = caller.Parent.Parent;
            end

            set(caller,'Visible','Off');

            % Create DropDown
            DropDown = uidropdown(g_parent);
            DropDown.Parent = parent;
            DropDown.Position = caller.Position;
            DropDown.Items = {'Sample Neuron #1','Sample Neuron #2','Sample Neuron #3'};
            DropDown.Placeholder = 'Select Neuron';
            DropDown.Tag = tag;
            DropDown.ValueChangedFcn = createCallbackFcn(app, @DispNeuron, true);
        end


        function LoadNeuron(app,caller,event,tag)

            if ~exist('tag','var')
                % Create new 
                new_grid = uigridlayout(caller.Parent.Parent);
                delete(caller.Parent)
                new_grid.ColumnWidth = {'1x', '1x', '1x', '1x', '1x'};
                new_grid.RowHeight = {'1x', '1x', '1x', '1x', '1x', '1x'};
    
                % Create UIAxes
                new_axes = uiaxes(new_grid);
                title(new_axes, event.Value)
                xlabel(new_axes, 't')
                ylabel(new_axes, 'Ca+')
                zlabel(new_axes, 'Z')
                new_axes.Layout.Row = [1 6];
                new_axes.Layout.Column = [1 5];
    
                % Create AddNeuronButton
                new_button = uibutton(new_grid, 'push');
                new_button.Layout.Row = 2;
                new_button.Layout.Column = 5;
                new_button.Text = 'Add Neuron';
                new_button.Tag = 'in_graph';
                new_button.ButtonPushedFcn = createCallbackFcn(app, @DispNeuron, true);
            else
                targAx = caller.Parent.Children(2).Children(1);
                title(targAx, [targAx.Title.String,', ', event.Value])
            end
        end


        function CreateNeuron(app,caller,event)
            % Create TL_Panel
            new_panel = uipanel(app.VolTraceHelperGrid);

            if grid_space(1) == max(size(app.VolTraceHelperGrid.ColumnWidth))
                new_panel.Layout.Column = grid_space(2)+1;
            else
                new_panel.Layout.Column = grid_space;
            end

            if grid_space(2) == max(size(app.VolTraceHelperGrid.RowHeight))
                new_panel.Layout.Row = grid_space(1)+1;
            end

            % Create TL_Grid
            new_grid = uigridlayout(app.TL_Panel);
            new_grid.ColumnWidth = {'1x'};
            new_grid.RowHeight = {'1x'};

            % Create TL_Button
            new_button = uibutton(app.TL_Grid, 'push');
            new_button.ButtonPushedFcn = createCallbackFcn(app, @AddNeuron, true);
            new_button.FontWeight = 'bold';
            new_button.Layout.Row = 1;
            new_button.Layout.Column = 1;
            new_button.Text = 'Add Neuron';
        end
   

        function initBrowser(app, neuron)        
            if ~exist('neuron','var')
                % Create the 1D axes
                targ_neuron = {['null']};
            else
                targ_neuron = {[neuron]};
            end


            if size(properties(app.activity_module), 1) == 2
                if all(size(app.activity_module.timestamps) > 0)
                    app.VolTrace = stackedplot(app.VolTraceHelperGrid,app.activity_table,targ_neuron, 'XVariable', 'timestamps');
                else
                    app.VolTrace = stackedplot(app.VolTraceHelperGrid,app.activity_table,targ_neuron);
                end
            else
                app.VolTrace = stackedplot(app.VolTraceHelperGrid,app.activity_table,targ_neuron);
            end

            drawnow
            app.formatStack()

        end


        function formatStack(app)
            sample_neuron = keys(app.neuron_activity_by_name);
            length = max(size(app.neuron_activity_by_name(sample_neuron{1})));

            app.VolTrace.GridVisible = 'on';
            app.VolTrace.XLabel = 't';
            app.VolTrace.Layout.Row = [1, size(app.VolTraceHelperGrid.RowHeight,2)];
            app.VolTrace.Layout.Column = [1,2];

            if ~isempty(app.framerate)
                % Add a listener for changes in 'XLim' property
                stAxes = findobj(app.VolTrace.NodeChildren, 'Type','Axes');
                addlistener(stAxes, 'XLim', 'PostSet', @(src, event) updateXTicks(app, length));
                
                % Initial setup
                updateXTicks(app, app.framerate);
            end
        end


        function updateXTicks(app, framerate)
            % Get the x-axis data
            xData = app.VolTrace.XData;
        
            % Determine the type of x-axis data
            if all(xData >= 1e9) % Assuming Unix timestamps
                timeInSeconds = (xData - xData(1)) / 1000; % Convert to seconds from milliseconds
            elseif max(xData) <= length(xData) % Assuming frame counts
                timeInSeconds = xData / framerate; % Convert to seconds using framerate
            else % Assuming seconds
                timeInSeconds = xData;
            end
        
            % Convert to MM:SS format
            minutes = floor(timeInSeconds / 60);
            seconds = mod(timeInSeconds, 60);
            tickLabels = arrayfun(@(m, s) sprintf('%02d:%02d', m, s), minutes, seconds, 'UniformOutput', false);
        
            % Find the underlying axes and set the tick values and labels
            stAxes = findobj(app.VolTrace.NodeChildren, 'Type','Axes');
            set(stAxes, 'XTick', xData, 'XTickLabel', tickLabels);
        end
   

        function updateBrowser(app, neuron, subplot)
            try
                if ~any(contains(app.VolTrace.DisplayVariables,neuron))
                    if ~exist('subplot','var')
                        subplot = 1;
                    end
        
                    old_xlim = app.VolTrace.XLimits;
        
                    if ~isempty(app.VolTrace.DisplayVariables)
                        subplot_vars = app.VolTrace.DisplayVariables(subplot);
                        new_vars = [];
                        for n=1:size(app.VolTrace.DisplayVariables(subplot),2)
                            if ~strcmp(subplot_vars{n},'null')
                                new_vars = [string(new_vars),string(subplot_vars{n})];
                            end
                        end
                        new_vars = [new_vars,string(neuron)];
                        app.VolTrace.DisplayVariables(subplot) = {new_vars};
                    end
        
                    subplot_idx = size(app.VolTrace.DisplayVariables,2)-(subplot-1);
                    subplot_idx = max(1,size(app.VolTrace.LineProperties,1)-(subplot_idx-1));
                    target_color = rgb2hsv(app.neuron_info.getNeuronColor(neuron));
        
                    if strcmp(neuron(end),'L')
                        target_color(2) = target_color(2)*0.6;
                        target_style = '--';
                    else
                        target_color(3) = target_color(3)*0.6;
                        target_style = '-';
                    end
                    target_color = hsv2rgb(target_color);
                    
                    if isempty(app.color_tracker) || length(app.color_tracker) < subplot_idx || isempty(app.color_tracker{subplot_idx})
                        app.color_tracker{subplot_idx} = [target_color];
                        app.style_tracker{subplot_idx} = [{target_style}];
                    else
                        app.color_tracker{subplot_idx} = [app.color_tracker{subplot_idx}; target_color]; 
                        app.style_tracker{subplot_idx} = [app.style_tracker{subplot_idx}, target_style];
                    end
        
                    app.VolTrace.LineProperties(subplot_idx).LineStyle = app.style_tracker{subplot_idx};   
                    app.VolTrace.LineProperties(subplot_idx).Color = app.color_tracker{subplot_idx};   
                    app.VolTrace.XLimits = old_xlim;
                    drawnow;
        
                    app.drawStimuli();
                else
                    uiconfirm(app.CELL_ID, "Neuron already plotted.", "Error!");
                end
            catch
                uiconfirm(app.CELL_ID, sprintf("Neuron %s has no activity to load.", neuron), "Error!")
            end
        end


        %% Processing Tab Functions

        function drawProcImage(app)
            %Program.GUIHandling.histogram_handler(app, 'reset');

            Program.Handlers.loading.start('Rendering volume...');
            Program.Routines.Processing.render();
            
            if app.ProcPreviewZslowCheckBox.Value
                Program.Handlers.loading.start('Drawing cursor...');
                app.DrawProcLines(x, y, z, nz);
            end

            Program.Handlers.loading.done();
        end

        function [x, y, z, nx, ny, nz, nc, target_frame] = ProcLoadVideo(app)

            x = min(max(round(app.video_info.ny-app.ySlider.Value), 1), app.ySlider.Limits(2));
            y = min(max(round(app.proc_xSlider.Value), 1), app.proc_xSlider.Limits(2));
            z = min(max(round(app.proc_hor_zSlider.Value), 1), app.proc_hor_zSlider.Limits(2));
            t = round(app.proc_tSlider.Value);

            nx = app.video_info.nx;
            ny = app.video_info.ny;
            nz = app.video_info.nz;
            nc = app.video_info.nc;

            if t > app.video_info.nt || t < 0
                uiconfirm(app.CELL_ID,'Target frame exceeds video frame count.','Error');
                return
            elseif z > app.video_info.nz || z < 0
                uiconfirm(app.CELL_ID,'Target slice exceeds video slice count.','Error');
                return
            end

            target_frame = app.retrieve_frame(t);
            target_frame(target_frame < app.ProcNoiseThresholdKnob.Value) = 0;

            for c=1:nc
                channel_gamma = sprintf("%s_GammaEditField", Program.GUIHandling.pos_prefixes{c});
                target_frame(:, :, :, c, :) = imadjustn(target_frame(:, :, :, c, :), [], [], app.(channel_gamma).Value);
            end

            actions = fieldnames(app.flags);
            for a=1:length(actions)
                action = actions{a};
                if app.flags.(action) == 1
                    target_frame = Methods.ChunkyMethods.apply_vol(app, action, target_frame);
                end
            end

            if any(ismember(actions, {'crop', 'ds'}))
                nx = size(target_frame, 2);
                ny = size(target_frame, 1);
                nz = size(target_frame, 3);
            end

            target_frame = app.ApplyHistSliders(target_frame, nc);

            if app.ProcShowMIPCheckBox.Value
                xy = squeeze(max(target_frame,[],3));
            else
                xy = squeeze(target_frame(:,:,z,:,:));
            end

            image(xy, 'Parent', app.proc_xyAxes);

            if app.ProcPreviewZslowCheckBox.Value
                xz = squeeze(target_frame(:,y,:,:,:));
                yz = squeeze(target_frame(x,:,:,:,:));

                image(flipud(rot90(yz)), 'Parent', app.proc_xzAxes);
                image(xz, 'Parent', app.proc_yzAxes);
            end

        end

        function [x, y, z, nx, ny, nz, nc, image_data] = ProcLoadVol(app)
            vol_size = size(app.proc_image, 'data');
            x = min(max(round(vol_size(1)-app.proc_ySlider.Value)), app.proc_ySlider.Limits(2));
            y = min(max(round(app.proc_xSlider.Value)), app.proc_xSlider.Limits(2));
            z = min(max(round(app.proc_hor_zSlider.Value)), app.proc_hor_zSlider.Limits(2));

            if app.ProcShowMIPCheckBox.Value || app.ProcPreviewZslowCheckBox.Value
                image_data = app.proc_image.data(:, :, :, :);
            else
                image_data = app.proc_image.data(:, :, z, :);
            end

            nx = size(image_data, 2);
            ny = size(image_data, 1);
            nz = size(image_data, 3);
            nc = size(image_data, 4);

            image_data = app.ApplyHistSliders(image_data, nc);

            actions = fieldnames(app.flags);
            for a=1:length(actions)
                action = actions{a};
                if app.flags.(action) == 1
                    image_data = Methods.ChunkyMethods.apply_vol(app, action, image_data);
                end
            end

            [image_data, image_view] = app.DrawProcData(image_data);
            threshold_value = (app.ProcNoiseThresholdKnob.Value/double(max(image_data, [], 'all')))*double(max(image_view, [], 'all'));
            image_data(image_data < app.ProcNoiseThresholdKnob.Value) = 0;
            image_view(image_view < threshold_value) = 0;

            if any(ismember(actions, {'crop', 'ds'}))
                nx = size(image_view, 2);
                ny = size(image_view, 1);
                nz = size(image_view, 3);
            end

            if app.ProcShowMIPCheckBox.Value
                xy = squeeze(max(image_view,[],3));
            else
                if size(image_view, 3)>1
                    xy = squeeze(image_view(:,:,z,:,:));
                else
                    xy = squeeze(image_view);
                end
            end

            image(xy, 'Parent', app.proc_xyAxes);

            if app.ProcPreviewZslowCheckBox.Value
                xz = squeeze(image_view(:,y,:,:,:));
                yz = squeeze(image_view(x,:,:,:,:));

                image(flipud(rot90(yz)), 'Parent', app.proc_xzAxes);
                image(xz, 'Parent', app.proc_yzAxes);
            end

        end

        function target_frame = ApplyHistSliders(app, target_frame, nc)
            for n=1:nc
                channel_slider = sprintf("%s_hist_slider", Program.GUIHandling.pos_prefixes{n});
                target_frame = app.SetChanLimit(n, app.(channel_slider), target_frame);
            end
        end

        function newFrame = SetChanLimit(app, ch_idx, slider, oldFrame)
            if ((slider.Value(1) ~= 0) | (slider.Value(2) ~= app.ProcNoiseThresholdKnob.Limits(2)))
                newFrame = oldFrame;
                channel = oldFrame(:, :, :, ch_idx);
                channel(channel<slider.Value(1)) = slider.Value(1);
                channel(channel>slider.Value(2)) = slider.Value(2);
                newFrame(:, :, :, ch_idx) = channel;
            end
        end

        function DrawProcLines(app, x, y, z, nz)
            app.proc_xy_yline = yline(app.proc_xyAxes, x, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_yz_yline = yline(app.proc_yzAxes, x, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_xz_yline = yline(app.proc_xzAxes, app.proc_xzAxes.YLim(2)*(z/nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);

            app.proc_xy_xline = xline(app.proc_xyAxes, y, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_yz_xline = xline(app.proc_yzAxes, app.proc_yzAxes.XLim(2)*(z/nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_xz_xline = xline(app.proc_xzAxes, y, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

    end
    
    methods (Access = public)
        
        %% NeuroPAL ID Functions
        function UpdateFromChild(app, image_data, image_neurons, image_gamma)
            
            if ~exist('image_data','var')
                image_data = app.image_data;
            end

            if ~exist('image_neurons','var')
                image_neurons = app.image_neurons;
            end

            if ~exist('image_gamma','var')
                image_gamma = app.image_gamma;
            end

            if size(app.image_data,3) ~= size(image_data,3)

                num_z_slices = size(image_data, 3);
                z_slices = 1:num_z_slices;

                if num_z_slices <= 1
                    uialert(app.CELL_ID, 'The image is not a volume!', ...
                        'Image Not a Volume', 'Icon', 'error');
                    return;
                else
                    z_labels = arrayfun(@(z) num2str(z, '%.1f'), ...
                        (z_slices-1) * app.image_info.scale(3), 'UniformOutput', false);
                end

                app.ZSlider.Limits = [1, num_z_slices];
                app.ZSlider.MajorTicks = z_slices;
                app.ZSlider.MajorTickLabels = z_labels;
    
                %{
                % Change the neuron properties (mean and covariance) according
                % to user selected parameters.
                for neuron=1:length(app.image_neurons.neurons)
                    app.image_neurons.neurons(neuron).position = ...
                        app.image_neurons.neurons(neuron).position/( app.image_neurons.neurons(neuron).position/(size(app.image_data,3)/size(image_data,3)) );
                    app.image_neurons.neurons(neuron).covariance = ...
                        app.image_neurons.neurons(neuron).covariance/( app.image_neurons.neurons(neuron).position/(size(app.image_data,3)/size(image_data,3)) );
                end
                %}

            else
                app.image_neurons = image_neurons;
            end

            app.image_data = image_data;
            app.image_gamma = image_gamma;
            
            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            info.scale = app.image_um_scale;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Draw the neurons.
            app.ZSlider.Value = round(app.ZSlider.Limits(2)/2);
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
            app.DrawImageData();
        end


        function ImpNeurons(app,auto_neurons)
            for r = 1:size(auto_neurons, 1)
                app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                [auto_neurons(r,1) auto_neurons(r,2) auto_neurons(r,3)], app.mp_params.hnsz, app.image_um_scale');
            end
        end


        function eyedrop(app, Red, Green, Blue, Background, White, GFP, Lyso, Gut)
        
            color_struct = struct('red',Red,'green',Green,'blue',Blue,'background',Background,'white',White,'GFP',GFP,'Lyso',Lyso,'Gut',Gut);
            imwrite(squeeze(max(app.image_view,[],3)), 'cache.png');
            im = imread('cache.png');
            color_roi = drawpoint(app.MaxProjection);
            pos = round(color_roi.Position);
        
            for color = fieldnames(color_struct)'
                if color_struct.(color{1})
                    uiwait(msgbox(sprintf('In the maximum projection, click on the region that best represents %s.', color{1})))
                    color_values = impixel(im,pos(2), pos(1));
                    color_struct.(color{1}) = color_values;
                    delete(color_roi)
                end
            end
        
            delete('cache.png')
        
        end


        % Check if drag & drop ended on target component.
        function target = grab_land(app, figure, pos, parent_class, parent_tag, class, tag)
            try
                comp_array = findobj(figure, '-depth', inf,'-function','Position', @(x) (pos(1)>x(1)&pos(1)<(x(1)+x(3))&pos(2)>x(2)&pos(2)<(x(2)+x(4))));
                mid_idx = find(arrayfun(@(y) isa(y, parent_class)&strcmp(y.Tag, parent_tag), comp_array), 5);

                if ~isempty(mid_idx)
                    middleman = comp_array(mid_idx);
                    pos(1) = pos(1)-middleman.Position(1);
                    pos(2) = pos(2)-middleman.Position(2)-5;

                    deep_array = findobj(middleman.Children.Children, '-depth', inf,'-function','Position', @(x) (pos(1)>x(1)&pos(1)<(x(1)+x(3))&pos(2)>x(2)&pos(2)<(x(2)+x(4))));
                    target_idx = find(arrayfun(@(y) isa(y, class)&strcmp(y.Tag, tag), deep_array), 5);

                    if ~isempty(target_idx)
                        target = deep_array(target_idx);
                    else
                        target = [];
                    end
                else
                    target = [];
                end
            catch
                target = [];
            end
        end


        % Manage click & drag events.
        function DragManager (app, mode, event)
            if app.DisplayNeuronActivityMenu.Checked == 1
                pos = get(app.CELL_ID, 'CurrentPoint');
                switch mode
                    case 'down'
                        target = app.grab_land(app.NeuroPALIDTab, pos, 'matlab.ui.container.Panel', 'side-panel', 'matlab.ui.control.ListBox', 'neuron-selector');
                        if ~isempty(target)
                            app.HoverLabel.Position = [pos(1)-app.HoverLabel.Position(3)/2 pos(2)+1 app.HoverLabel.Position(3) app.HoverLabel.Position(4)];
                            app.HoverLabel.Text = char(target.Value);
                            app.HoverLabel.Position(3) = app.HoverLabel.FontSize*size(target.Value,2);
                            app.CELL_ID.WindowButtonMotionFcn = @(src, event) app.DragManager('move', event);
                            app.HoverLabel.Visible = "on";
                        end
                    case 'move'
                        app.HoverLabel.Position = [pos(1)-app.HoverLabel.Position(3)/2 pos(2)+1 app.HoverLabel.Position(3) app.HoverLabel.Position(4)];
                    case 'up'
                        if strcmp(app.HoverLabel.Visible,'on')
                            app.CELL_ID.WindowButtonMotionFcn = @(src, event) 1+1;
                            set(app.HoverLabel, 'Visible', 'off');
    
                            target = app.grab_land(app.NeuroPALIDTab, pos, 'matlab.ui.container.Tab', 'neuron-activity-tab', 'matlab.ui.container.GridLayout', 'browser_trace');
                            if ~isempty(target)
                                target.Children(1).Units = 'pixels';
                                total_x = target.Children(1).InnerPosition;
                                num_plots = size(target.Children(1).DisplayVariables,2);
    
                                selected_plot = 0;
                                y_divs = total_x(4) / num_plots;
                                for n=1:num_plots
                                    x = total_x;
                                    y = y_divs * (n-1);
                                    x(2) = x(2) + y;
                                    x(4) = y_divs;
                                    % sprintf('Cursor y: %d\nSubplot #%d y: %.2f through %.2f', pos(2), n, x(2), x(2) + x(4))
                                    if (pos(1)>x(1)&pos(1)<(x(1)+x(3))&pos(2)>x(2)&pos(2)<(x(2)+x(4)))
                                        selected_plot = num_plots - (n-1);
                                        break
                                    end
                                end

                                % Strip all non-alphanumeric characters from HoverLabel.Text
                                cleanText = regexprep(app.HoverLabel.Text, '[^a-zA-Z0-9]', '');
                                
                                % Pass the cleaned text to updateBrowser
                                app.updateBrowser(cleanText, selected_plot);
                            end
                        end
                end
            end
        end


        function fullPath = checkFileExistence(app, fileName)
            if exist(fileName, 'file') == 2
                fullPath = fileName;
            else
                fullPath = 'False';
            end
        end


        function load_neurons_from_nwb(app, nwb_data)
                d = uiprogressdlg(app.CELL_ID,'Title','Importing neurons from NWB file...','Indeterminate','off');
                app.logEvent('Main','Placing neuron dots...', 1)
                if any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'NeuroPALSegmentation'))
                    neurons = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').voxel_mask.data.load();
                    neuron_ids = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.get('ID_labels').data.load();
                    if size(neuron_ids,1) == size(neurons.x, 1)
                        full_ids = 1;
                    else

                        if any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.keys, 'ID_labels_index'))
                            id_indices = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.get('ID_labels_index').data.load();
                        elseif any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.keys, 'ROI_IDs'))
                            id_indices = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.get('ROI_IDs').data.load();
                        end
                        full_ids = max(cellfun(@(c) numel(c), neuron_ids)) > 1;
                        id_indices(find(id_indices == 0)) = 1;
                    end
                elseif any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'VolumeSegmentation'))
                    neurons = nwb_data.processing.get('NeuroPAL').dynamictable.get('VolumeSegmentation').voxel_mask.data.load();
                    full_ids = 2;
                elseif any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'NeuroPALNeurons'))
                    neurons = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').voxel_mask.data.load();
                    neuron_ids = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.get('ID_labels').data.load();
                    if any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.keys, 'ID_labels_index'))
                        id_indices = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.get('ID_labels_index').data.load();
                    elseif any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.keys, 'ROI_IDs'))
                        id_indices = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.get('ROI_IDs').data.load();
                    end
                    full_ids = 1;
                elseif any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'ImageSegmentation'))
                    neurons = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('ImageSegmentation').planesegmentation.get('PlaneSegmentation').voxel_mask.data.load();
                    neurons = struct('x', neurons(:, 2), 'y', neurons(:, 1), 'z', neurons(:, 3));
                    neuron_ids = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('SegmentationLabels').labels.load();
                    full_ids = 1;
                else
                    uiconfirm(app.CELL_ID, "No neurons found. You shouldn't be seeing this message!");
                end
    
                % Iterate over each row
                for i = 1:length(neurons.x)
                    d.Value = i / length(neurons.x);
                    app.logEvent('Main',sprintf('Loading neuron #%s...', string(i)), 0);
    
                    if full_ids == 1
                        name = neuron_ids{i};
                    elseif full_ids == 2
                        name = neurons.ID{i};
                    else
                        if i ~= 1
                            last_name = id_indices(i-1)+1;
                        else
                            last_name = 1;
                        end
    
                        if last_name == id_indices(i) && i > 1
                            name = '';
                        else
                            name = join(neuron_ids(last_name:id_indices(i)));
                            name = name{1};
                        end
                    end
    
                    name = upper(erase(name," "));
                    x = double(neurons.x(i));
                    y = double(neurons.y(i));
                    z = double(neurons.z(i));
    
                    app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                        [y x z], [13, 13, 3], app.image_um_scale');
    
                    if app.neuron_info.isCell(name)
                        app.logEvent('Main',sprintf('-Neuron #%s is %s...', string(i), name), 0);

                        app.is_saving_ID = true;
                        [neuron, is_on] = app.neuron_info.stripOnOff(name);
                        app.image_neurons.neurons(i).annotation = char(neuron);
                        app.image_neurons.neurons(i).is_annotation_on = is_on;

                        if name(end) == '!' | name(end) == '?'
                            app.image_neurons.neurons(i).is_emphasized = true;
                            app.image_neurons.neurons(i).annotation_confidence = 0.5;
                        else
                            app.image_neurons.neurons(i).annotation_confidence = 1;
                        end
    
                        %{
                        if ~isempty(app.image_neurons.neurons(i).probabilistic_probs)
                            app.image_neurons.neurons(i).deterministic_id = neuron;
                            app.image_neurons.neurons(i).probabilistic_ids{1} = neuron;
                            app.image_neurons.neurons(i).probabilistic_probs(1) = 1;
                            app.image_neurons.neurons(i).probabilistic_probs(2:end) = 0;
                        end
                        %}
    
                        if ~isempty(app.image_neurons.neurons(i).rank)
                            app.image_neurons.rank_neuron_max(i);
                        end
    
                        app.is_saving_ID = false;
                    else
                        fprintf('%s (#%.f) is not a valid neuron.\n', name, i)
                    end
                end
                        
                % Update the auto-ID model.
                UpdateAutoIDModel(app);
    
                % Update the ID'd neuron count.
                UpdateNeuronLists(app);
    
                % Save the updated IDs.
                SaveIDToFile(app);
    
                close(d)   
        end


        function checkedNodes = dependencyChecker(app, mode, checkedNodes, dependency, node)
            switch mode
                case 'python'
                    pip_check = system(['pip show',dependency]);
                    if ~pip_check
                        checkedNodes{end+1} = node;
                    end
                case 'toolbox'
                    toolboxes = ver;
                    toolboxes = toolboxes.Name;
                    if contains(toolboxes, dependency)
                        checkedNodes{end+1} = node;
                    end
                case 'folder'
                    if isfolder(dependency)
                        checkedNodes{end+1} = node;
                    end
                case 'var'
                    if ~isempty(dependency)
                        checkedNodes{end+1} = node;
                    end
                case 'file'
                    if isfile(dependency)
                        checkedNodes{end+1} = node;
                    end
            end
        end

        %% Tracking Functions
        
        function [struct, arr] = load_h5(app, path)
            arr = [];
            try
                struct = Program.Helpers.h5_video_info(path);
            catch err
                uiconfirm(app.CELL_ID, err.message, 'Error');
                return
            end

            app.video_info = struct;

            if struct.nt < 10
                uiconfirm(app.CELL_ID,'Your video contains too few frames for filters to be applied during activity extraction. ZephIR will skip these when you run the extraction, which may affect your results.','Warning!');
            end
        end


        function load_nwb(app, path)
            app.nwb_file = nwbRead(path);

            if any(ismember(app.nwb_file.acquisition.keys, 'CalciumImageSeries'))
                app.nwb_mod = app.nwb_file.acquisition.get('CalciumImageSeries').data;
            elseif any(ismember(app.nwb_file.processing.keys,'GCaMPVideo'))
                app.nwb_mod = app.nwb_file.processing.get('GCaMPVideo').nwbdatainterface.get('CalciumImageSeries').data;
            else
                uiconfirm(app.NeuronActivityTracingUIFigure, sprintf('Could not find video in %s', path), 'Error');
                delete(app);
                return
            end

            data_pipe = app.nwb_mod.internal;
            dims = data_pipe.dims;

            struct = {};
            struct.file = path;                         % Path to file
            struct.nx = dims(4);                        % Height
            struct.ny = dims(3);                        % Width
            struct.nz = dims(2);                        % Slice count
            struct.nc = dims(1);                        % Channel count
            struct.nt = dims(5);                        % Frame count
            struct.aspect_ratio = struct.ny/struct.nx;  % Aspect Ratio
            struct.cached = 1;                          % Frames cached
            struct.bitDepth = app.nwb_mod.internal.config.dataType;

            app.video_info = struct;

            if struct.nt < 10
                uiconfirm(app.CELL_ID,'Your video contains too few frames for filters to be applied during activity extraction. ZephIR will skip these when you run the extraction, which may affect your results.','Warning!');
            end
        end
        

        function load_nd2(app, path)
            app.nd2_file = BioformatsImage(path);

            struct = {}; 
            struct.file = path;                         % Path to file
            struct.ny = app.nd2_file.height;            % Height
            struct.nx = app.nd2_file.width;             % Width
            struct.nz = app.nd2_file.sizeZ;             % Slice count
            struct.nc = app.nd2_file.sizeC;             % Channel count
            struct.nt = app.nd2_file.sizeT;             % Frame count
            struct.aspect_ratio = struct.ny/struct.nx;  % Aspect Ratio
            struct.cached = 1;                          % Frames cached
            try
                struct.bitDepth = double(app.nd2_file.bitDepth);
            catch
                struct.bitDepth = [];
            end
            try
                struct.channel_names = app.nd2_file.channelNames;
            catch
                struct.channel_names = {};
            end

            app.video_info = struct;

            if struct.nt < 10
                uiconfirm(app.CELL_ID,'Your video contains too few frames for filters to be applied during activity extraction. ZephIR will skip these when you run the extraction, which may affect your results.','Warning!');
            end
        end

        function load_tif(app, path)
            app.tif_file = BioformatsImage(path);

            struct = {};
            struct.file = path;                         % Path to file
            struct.ny = app.tif_file.height;            % Height
            struct.nx = app.tif_file.width;             % Width
            struct.nz = app.tif_file.sizeZ;             % Slice count
            struct.nc = app.tif_file.sizeC;             % Channel count
            struct.nt = app.tif_file.sizeT;             % Frame count
            struct.aspect_ratio = struct.ny/struct.nx;  % Aspect Ratio
            struct.cached = 1;                          % Frames cached
            try
                struct.bitDepth = double(app.tif_file.bitDepth);
            catch
                struct.bitDepth = [];
            end
            try
                struct.channel_names = app.tif_file.channelNames;
            catch
                struct.channel_names = {};
            end

            app.video_info = struct;

            if struct.nt < 10
                uiconfirm(app.CELL_ID,'Your video contains too few frames for filters to be applied during activity extraction. ZephIR will skip these when you run the extraction, which may affect your results.','Warning!');
            end
        end


        function convert_HDF5(app, path)
            [filepath, name, format] = fileparts(path);
            format = lower(format);

            title = sprintf('Building data structure from %s...', path);
            d = uiprogressdlg(app.CELL_ID,'Title',title,'Indeterminate','off');

            switch format
                case '.h5'
                    % This should never happen, but we're thorough.
                    check = uiconfirm(app.CELL_ID,"File is already in HDF5 format. If you're seeing this, something has gone very wrong.","Error!","Options", ["OK"]);
                case '.nwb'        
                    % Read NWB file
                    whole_file = nwbRead(path);

                    if any(ismember(whole_file.acquisition.keys, 'CalciumImageSeries'))
                        gc_data = whole_file.acquisition.get('CalciumImageSeries').data;
                    elseif any(ismember(whole_file.processing.keys,'GCaMPVideo'))
                        gc_data = whole_file.processing.get('GCaMPVideo').nwbdatainterface.get('CalciumImageSeries').data;
                    else
                        uiconfirm(app.CELL_ID, sprintf('Could not find video in %s', path), 'Error');
                        delete(app);
                        return

                        %{
                        check = uiconfirm(app.CELL_ID, sprintf('Could not find video in %s', path), 'Error','Options',["Manually specify volume","Cancel"]);
                        
                        switch check
                            case 'Cancel'
                                delete(app);
                                return
                            otherwise
                                gc_data = app.searchNWB(whole_file);
                        end
                        %}
                    end

                    data_pipe = gc_data.internal;

                    % Get dimensions
                    bitDepth = str2num(data_pipe.dataType(5:end));
                    dims = data_pipe.dims;
                    nt = dims(5);
                    nc = dims(1);
                    nz = dims(2);
                    ny = dims(3);
                    nx = dims(4);

                    % Create the HDF5 dataset. Plane-sized chunks avoid
                    % materializing or modifying a full timepoint chunk for
                    % each streamed plane write.
                    data_h5 = fullfile(filepath, 'data.h5');
                    if exist(data_h5, 'file') == 2
                        delete(data_h5);
                    end
                    h5create(data_h5, '/data', [ny nx nz nc nt], ...
                        'ChunkSize', [ny nx 1 1 1], 'Datatype', 'uint8');
                    h5create(data_h5, '/times', nt, 'Datatype', 'double');
                    h5write(data_h5, '/times', 1:nt);

                    switch bitDepth
                        case {8, 16, 32, 64}
                            scale_factor = double(intmax('uint8')) / double(intmax(sprintf('uint%.f', bitDepth)));
                        case 12
                            scale_factor = double(intmax('uint8')) / 4096;
                        case 20
                            scale_factor = double(intmax('uint8')) / 1048576;
                        case 24
                            scale_factor = double(intmax('uint8')) / 16777216;
                        otherwise
                            error('Bit depth %s failed conversion to uint8.', data_pipe.dataType);
                    end
                    
                    d.Message = 'Processing frames...';
                    d.Value = 0;

                    for t = 1:nt
                        d.Value = t / nt;

                        for c = 1:nc
                            for z = 1:nz
                                plane_data = squeeze(gc_data(c, z, :, :, t));
                                plane_data = reshape(plane_data, ny, nx);
                                plane_data = uint8(double(plane_data) * scale_factor);
                                h5write(data_h5, '/data', plane_data, ...
                                    [1 1 z c t], [ny nx 1 1 1]);
                            end
                        end

                        if d.CancelRequested
                            break;
                        end
                    end
                case '.nd2'
                    bfImg = BioformatsImage(path);
                    nt = bfImg.sizeT;
                    nc = bfImg.sizeC;
                    nz = bfImg.sizeZ;
                    ny = bfImg.height;
                    nx = bfImg.width;

                    % Create the HDF5 dataset. Plane-sized chunks keep
                    % conversion memory bounded by one source plane.
                    data_h5 = fullfile(filepath, 'data.h5');
                    if exist(data_h5, 'file') == 2
                        delete(data_h5);
                    end
                    h5create(data_h5, '/data', [ny nx nz nc nt], ...
                        'ChunkSize', [ny nx 1 1 1], 'Datatype', 'uint8');
                    h5create(data_h5, '/times', nt, 'Datatype', 'double');
                    h5write(data_h5, '/times', 1:nt);
                    
                    % Collect data for all time points
                    channelNames = bfImg.channelNames;

                    switch bfImg.bitDepth
                        case {8, 16, 32, 64}
                            scale_factor = double(intmax('uint8')) / double(intmax(sprintf('uint%.f', bfImg.bitDepth)));
                        case 12
                            scale_factor = double(intmax('uint8')) / 4096;
                        case 20
                            scale_factor = double(intmax('uint8')) / 1048576;
                        case 24
                            scale_factor = double(intmax('uint8')) / 16777216;
                        otherwise
                            error('Bit depth %.f failed conversion to uint8.', bfImg.bitDepth);
                    end
                    
                    d.Message = 'Processing frames...';
                    d.Value = 0;

                    for t = 1:nt
                        d.Value = t / nt;
                        for c = 1:nc
                            channel = channelNames{c};
                            for z = 1:nz
                                % Read and scale the data
                                plane_data = getPlane(bfImg, z, channel, t);
                                h5write(data_h5, '/data', ...
                                    uint8(double(plane_data) * scale_factor), ...
                                    [1 1 z c t], [ny nx 1 1 1]);
                            end
                        end
                        
                        if d.CancelRequested
                            break;
                        end
                    end
                case {'.tif', '.tiff'}
                    bfImg = BioformatsImage(path);
                    nt = bfImg.sizeT;
                    nc = bfImg.sizeC;
                    nz = bfImg.sizeZ;
                    ny = bfImg.height;
                    nx = bfImg.width;

                    % Create the HDF5 dataset. Plane-sized chunks keep
                    % conversion memory bounded by one source plane.
                    data_h5 = fullfile(filepath, 'data.h5');
                    if exist(data_h5, 'file') == 2
                        delete(data_h5);
                    end
                    h5create(data_h5, '/data', [ny nx nz nc nt], ...
                        'ChunkSize', [ny nx 1 1 1], 'Datatype', 'uint8');
                    h5create(data_h5, '/times', nt, 'Datatype', 'double');
                    h5write(data_h5, '/times', 1:nt);

                    switch bfImg.bitDepth
                        case {8, 16, 32, 64}
                            scale_factor = double(intmax('uint8')) / double(intmax(sprintf('uint%.f', bfImg.bitDepth)));
                        case 12
                            scale_factor = double(intmax('uint8')) / 4096;
                        case 20
                            scale_factor = double(intmax('uint8')) / 1048576;
                        case 24
                            scale_factor = double(intmax('uint8')) / 16777216;
                        otherwise
                            error('Bit depth %.f failed conversion to uint8.', bfImg.bitDepth);
                    end

                    d.Message = 'Processing frames...';
                    d.Value = 0;

                    for t = 1:nt
                        d.Value = t / nt;
                        for c = 1:nc
                            for z = 1:nz
                                plane_data = getPlane(bfImg, z, c, t);
                                h5write(data_h5, '/data', ...
                                    uint8(double(plane_data) * scale_factor), ...
                                    [1 1 z c t], [ny nx 1 1 1]);
                            end
                        end

                        if d.CancelRequested
                            break;
                        end
                    end
                otherwise
                    close(d)
                    error('Unsupported video format for ZephIR H5 conversion: %s', format);
            end
            close(d)

            app.video_path = fullfile(filepath, 'data.h5');
            app.load_h5(app.video_path);
        end


        function load_annotations(app)
            d = uiprogressdlg(app.CELL_ID,'Title','Processing results...','Indeterminate','on');
            
            if isempty(app.video_neurons)
                app.video_neurons = struct('provenance', {}, 'worldline', {}, 'rois', {});
            end

            [path, ~, ~] = fileparts(app.video_path);
            results_info = h5info(fullfile(path, 'annotations.h5'));

            frame_results = h5read(fullfile(path, 'annotations.h5'), '/t_idx');
            prov_results = h5read(fullfile(path, 'annotations.h5'), '/provenance');
            wlid_results = h5read(fullfile(path, 'annotations.h5'), '/worldline_id');
            x_results = h5read(fullfile(path, 'annotations.h5'), '/x');
            y_results = h5read(fullfile(path, 'annotations.h5'), '/y');
            z_results = h5read(fullfile(path, 'annotations.h5'), '/z');
    
            wl_idx = h5read(fullfile(path, 'worldlines.h5'), '/id');
            wl_name = h5read(fullfile(path, 'worldlines.h5'), '/name');
            wl_col = h5read(fullfile(path, 'worldlines.h5'), '/color');
    
            for n=1:numel(frame_results)
                worldline = struct('name', {{}}, 'node', {{}}, 'color', {{}}, 'style', {{}}, 'id', {{}});
                rois = struct();
                idx = size(app.video_neurons, 2) + 1;
                existing_worldline = 0;
    
                target_wl = find(wl_idx == wlid_results(n), 1);
                if isempty(target_wl)
                    continue
                end
                target_frame = frame_results(n)+1;
                target_x = DataHandling.Helpers.h5.denormalize_coordinate(x_results(n), app.video_info.nx);
                target_y = DataHandling.Helpers.h5.denormalize_coordinate(y_results(n), app.video_info.ny);
                target_z = DataHandling.Helpers.h5.denormalize_coordinate(z_results(n), app.video_info.nz);
    
                if iscell(wl_name)
                    worldline.name = char(string(wl_name{target_wl}));
                else
                    worldline.name = char(string(wl_name(target_wl)));
                end
                worldline.color = DataHandling.Helpers.h5.color_value(wl_col, target_wl);
                
                rois(target_frame).x_slice = target_x;
                rois(target_frame).y_slice = target_y;
                rois(target_frame).z_slice = target_z;
                
                rois(target_frame).xy_pos = [target_x target_y];
                rois(target_frame).xz_pos = [target_x target_z];
                rois(target_frame).yz_pos = [target_z target_y];               
    
                for i=1:size(app.video_neurons, 2)
                    if strcmp(app.video_neurons(i).worldline.name, worldline.name)
                        idx = i;
                        existing_worldline = 1;
                        break
                    end
                end
    
                if existing_worldline
                    app.video_neurons(idx).rois(target_frame) = rois(target_frame);
                else
                    provenance = prov_results(n);
                    worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);
                    worldline.style = uistyle("FontColor", worldline.color);
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    app.video_neurons(end+1) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});
                end

                if ~any(app.id_frames == app.tSlider.Value)
                    app.id_frames = [app.id_frames; target_frame];
                end
            end

            app.visual_composer();
            app.data_flags.('Tracking_ROIs') = 1;
            app.video_info.annotations = fullfile(path, 'annotations.h5');
            close(d)
        end


        function create_worldlines(app)
        end


        function create_metadata(app)
            % Extract necessary information from the video_info structure
            metadata.shape_t = app.video_info.nt;
            metadata.shape_c = app.video_info.nc;
            metadata.shape_z = app.video_info.nz;
            metadata.shape_y = app.video_info.ny;
            metadata.shape_x = app.video_info.nx;
            
            % Convert the MATLAB structure to JSON text
            jsonText = jsonencode(metadata);
            
            [path, name, ext] = fileparts(app.video_path);

            % Write the JSON text to a file
            fileId = fopen(fullfile(path, 'metadata.json'), 'w');
            if fileId == -1
                error('Failed to open file for writing.');
            end
            fprintf(fileId, '%s', jsonText);
            fclose(fileId);
        end

        function analysis_path = ensure_video_analysis_h5(app)
            [~, ~, ext] = fileparts(app.video_path);
            if ~strcmpi(ext, '.h5')
                app.convert_HDF5(app.video_path);
            end
            analysis_path = app.video_path;
            app.create_metadata();
        end

        function channel = zephir_analysis_channel(app)
            channel = 0;
            if isfield(app.video_info, 'nc') && app.video_info.nc > 1
                channel = 1;
            end
        end

        function cmd = python_script_command(app, script_name, args)
            script_path = fullfile(app.script_dir, sprintf('%s%s', script_name, app.script_ext));
            if nargin < 3
                args = "";
            end

            if isdeployed
                cmd = sprintf('"%s" %s', script_path, args);
            else
                try
                    py_cfg = pyenv;
                    python_exe = char(py_cfg.Executable);
                catch
                    python_exe = '';
                end
                if isempty(python_exe)
                    python_exe = 'python3';
                end
                cmd = sprintf('"%s" "%s" %s', python_exe, script_path, args);
            end
        end

        function arg_string = compile_arguments(app)
            app.arguments = struct( ...
                'dataset', app.video_path, ...
                'load_checkpoint', 0, ...
                'load_args', 0, ...
                'allow_rotation', app.AllowRotationsSwitch.Value, ...
                'clip_grad', app.clip_grad.Value, ...
                'cuda', app.UseGPUSwitch.Value, ...
                'dimmer_ratio', app.dimmer_ratio.Value, ...
                'exclude_self', 1, ...
                'fovea_sigma', app.fovea_sigma.Value, ...
                'gamma', 2, ...
                'grid_shape', app.grid_shape.Value, ...
                'include_all', 1, ...
                'lambda_d', app.l_d.Value, ...
                'lambda_n', app.l_n.Value, ...
                'lambda_n_mode', app.l_n_mode.Value, ...
                'lambda_t', app.l_t.Value, ...
                'load_nn', 1, ...
                'lr_ceiling', app.lr_ceiling.Value, ...
                'lr_coef', app.lr_coeff.Value, ...
                'lr_floor', app.lr_floor.Value, ...
                'motion_predict', app.MotionPredictionSwitch.Value, ...
                'n_chunks', app.n_chunks.Value, ...
                'n_epoch', app.n_epoch.Value, ...
                'n_epoch_d', app.n_epoch_d.Value, ...
                'n_frame', 1, ...
                'nn_max', app.nn_max.Value, ...
                'save_mode', 'o', ...
                'sort_mode', app.sort_mode.Value, ...
                'z_compensator', app.z_comp.Value);


            % Initialize an empty string for the command line arguments
            arg_string = '';
            
            % Retrieve all field names from the struct
            fields = fieldnames(app.arguments);
            
            % Iterate through each field
            for i = 1:numel(fields)
                field = fields{i}; % Current field name
                value = app.arguments.(field); % Value of the current field
                
                % Check if the value is a numeric type
                if isnumeric(value)
                    valueStr = num2str(value);
                elseif islogical(value)
                    valueStr = num2str(value * 1);
                else
                    valueStr = value;
                end
                
                % Append the current argument to the command line string
                if ischar(value) || isstring(value)
                    arg_string = [arg_string, ' --', field, ' "', valueStr, '"'];
                else
                    arg_string = [arg_string, ' --', field, '=', valueStr];
                end
            end
            
            % Remove the leading space
            if ~isempty(arg_string)
                arg_string = strtrim(arg_string);
            end
        end


        function visual_composer(app, t, z, x, y)

            if ~exist ('t', 'var')
                t = round(app.tSlider.Value);
            end

            if app.OverlaylastIDdframeCheckBox_2.Value
                earlier_frames = app.id_frames(app.id_frames < t);
                if ~isempty(earlier_frames)
                    t = max(earlier_frames);
                end
            end

            if ~exist ('z', 'var')
                z = round(app.hor_zSlider.Value);
            end

            if ~exist('y', 'var')
                y = round(app.xSlider.Value);
            end

            if ~exist ('x', 'var')
                x = round(app.video_info.ny-app.ySlider.Value);
            end

            %fprintf("Loading Frame %.f with Slice %.f where x = %.f and y = %.f.\n", t, z, y, x)

            Program.Validation.frame_in_bounds(t);
            Program.Validation.slice_in_bounds(z);

            render = app.retrieveVideoRenderViews(t, z, x, y, app.OverlayFrameMIPCheckBox.Value);
            
            proj = fieldnames(render);
            for p=1:length(proj)
                projection = proj{p};
                arr = squeeze(render.(projection));
    
                if strcmp(projection, 'yz')
                    arr = permute(arr, [2, 1, 3]);
                end

                render.(projection) = app.scaleVideoProjection(arr);
            end
            
            xy_img = app.setVideoImage(app.xyAxes, render.xy, 'npal_video_xy');
            xz_img = app.setVideoImage(app.xzAxes, render.yz, 'npal_video_xz');
            yz_img = app.setVideoImage(app.yzAxes, render.xz, 'npal_video_yz');
        
            Program.Helpers.sl_sync();
            app.xEditField.Value = y;
            app.yEditField.Value = x;
            app.zEditField.Value = z;
            app.tEditField.Value = t;
            
            xy_img.ButtonDownFcn = {@app.ImageClicked};
            xz_img.ButtonDownFcn = {@app.ImageClicked};
            yz_img.ButtonDownFcn = {@app.ImageClicked};
            
            app.xyAxes.XLim = [1, size(render.xy, 2)];
            app.xyAxes.YLim = [1, size(render.xy, 1)];
            app.xzAxes.XLim = [1, size(render.yz, 2)];
            app.xzAxes.YLim = [1, size(render.yz, 1)];
            app.yzAxes.XLim = [1, size(render.xz, 2)];
            app.yzAxes.YLim = [1, size(render.xz, 1)];
            Program.GUI.configure_zephir_video_axes(app);
            
            Program.Helpers.draw_video_cursor(x, y, z);

            delete(findobj(app.xyAxes, 'Type', 'images.roi.Point'));
            delete(findobj(app.yzAxes, 'Type', 'images.roi.Point'));
            delete(findobj(app.xzAxes, 'Type', 'images.roi.Point'));

            if any(app.id_frames == t)
                app.roi_draw(app.xSlider.Value, app.ySlider.Value, z, t)
            end

            drawnow limitrate nocallbacks
        end

        function img = setVideoImage(app, ax, cdata, tag)
            img = findobj(ax, 'Type', 'image', 'Tag', tag);
            if numel(img) > 1
                delete(img(2:end));
                img = img(1);
            end

            needs_new_image = isempty(img);
            if ~needs_new_image
                try
                    needs_new_image = ~isvalid(img);
                catch
                    needs_new_image = true;
                end
            end

            if needs_new_image
                img = image(ax, cdata, ...
                    'Tag', tag, ...
                    'HitTest', 'on', ...
                    'PickableParts', 'visible');
            else
                img.CData = cdata;
                img.Visible = 'on';
                img.HitTest = 'on';
                img.PickableParts = 'visible';
            end

            img.ButtonDownFcn = {@app.ImageClicked};
            try
                uistack(img, 'bottom');
            catch
            end
        end

        function render = retrieveVideoRenderViews(app, t, z, x, y, use_mip)
            if nargin < 6 || isempty(use_mip)
                use_mip = false;
            end

            [t, z, x, y] = app.clampVideoViewCoordinates(t, z, x, y);
            use_mip = logical(use_mip);

            render_key = app.videoRenderCacheKey(t, z, x, y, use_mip);
            [hit, render] = app.getVideoRenderCache(render_key);
            if hit
                return
            end

            video_file = char(app.video_info.file);
            video_file_lower = lower(video_file);

            if endsWith(video_file_lower, '.h5')
                render = app.retrieveH5VideoRenderViews(t, z, x, y, use_mip);
            elseif endsWith(video_file_lower, '.nd2') || ...
                    endsWith(video_file_lower, '.tif') || endsWith(video_file_lower, '.tiff')
                render = app.retrieveBioformatsVideoRenderViews(t, z, x, y, use_mip);
            else
                frame = app.retrieve_frame(t);
                render = app.frameToRenderViews(frame, z, x, y, use_mip);
            end

            app.setVideoRenderCache(render_key, render);
            if ~use_mip
                app.prefetchVideoNeighborPlanes(t, z);
            end
        end

        function render = retrieveH5VideoRenderViews(app, t, z, x, y, use_mip)
            if use_mip
                render = app.getCachedVideoMipRender(t);
                return
            end

            orthos = app.getCachedVideoOrthogonalViews(t, x, y);
            render = struct( ...
                'xy', {app.readCachedVideoXYStack(t, z)}, ...
                'xz', {orthos.xz}, ...
                'yz', {orthos.yz});
        end

        function render = retrieveBioformatsVideoRenderViews(app, t, z, x, y, use_mip)
            if use_mip
                render = app.getCachedVideoMipRender(t);
                return
            end

            orthos = app.getCachedVideoOrthogonalViews(t, x, y);
            render = struct( ...
                'xy', {app.readCachedVideoXYStack(t, z)}, ...
                'xz', {orthos.xz}, ...
                'yz', {orthos.yz});
        end

        function [t, z, x, y] = clampVideoViewCoordinates(app, t, z, x, y)
            t = max(1, min(round(double(t)), app.video_info.nt));
            z = max(1, min(round(double(z)), app.video_info.nz));
            x = max(1, min(round(double(x)), app.video_info.ny));
            y = max(1, min(round(double(y)), app.video_info.nx));
        end

        function key = videoRenderCacheKey(app, t, z, x, y, use_mip)
            key = sprintf('render|%s|t%d|z%d|x%d|y%d|m%d|%dx%dx%dx%d', ...
                char(app.video_info.file), t, z, x, y, logical(use_mip), ...
                app.video_info.ny, app.video_info.nx, app.video_info.nz, app.video_info.nc);
        end

        function [hit, render] = getVideoRenderCache(app, key)
            cache = app.ensureVideoViewCache();
            hit = isfield(cache, 'render_key') && strcmp(cache.render_key, key) && ...
                isfield(cache, 'render') && ~isempty(cache.render);
            if hit
                render = cache.render;
            else
                render = [];
            end
        end

        function setVideoRenderCache(app, key, render)
            cache = app.ensureVideoViewCache();
            cache.render_key = key;
            cache.render = render;
            app.video_view_cache = cache;
        end

        function resetVideoViewCache(app)
            video_file = '';
            try
                video_file = char(app.video_info.file);
            catch
            end

            app.video_view_cache = struct( ...
                'file', video_file, ...
                'planes', {containers.Map('KeyType', 'char', 'ValueType', 'any')}, ...
                'plane_bytes', {containers.Map('KeyType', 'char', 'ValueType', 'double')}, ...
                'plane_order', {{}}, ...
                'plane_total_bytes', 0, ...
                'max_planes', 18, ...
                'max_plane_bytes', 96 * 1024 * 1024, ...
                'views', {containers.Map('KeyType', 'char', 'ValueType', 'any')}, ...
                'view_bytes', {containers.Map('KeyType', 'char', 'ValueType', 'double')}, ...
                'view_order', {{}}, ...
                'view_total_bytes', 0, ...
                'max_views', 8, ...
                'max_view_bytes', 128 * 1024 * 1024, ...
                'render_key', '', ...
                'render', []);
        end

        function cache = ensureVideoViewCache(app)
            video_file = '';
            try
                video_file = char(app.video_info.file);
            catch
            end

            needs_reset = isempty(app.video_view_cache) || ~isstruct(app.video_view_cache);
            if ~needs_reset
                try
                    needs_reset = ~strcmp(char(app.video_view_cache.file), video_file) || ...
                        ~isa(app.video_view_cache.planes, 'containers.Map') || ...
                        ~isa(app.video_view_cache.views, 'containers.Map');
                catch
                    needs_reset = true;
                end
            end

            if needs_reset
                app.resetVideoViewCache();
                app.video_view_cache.file = video_file;
            end

            cache = app.video_view_cache;
        end

        function [hit, value] = getVideoViewCacheValue(app, key)
            cache = app.ensureVideoViewCache();
            hit = isKey(cache.views, key);
            if hit
                value = cache.views(key);
                cache.view_order = app.touchVideoCacheKey(cache.view_order, key);
                app.video_view_cache = cache;
            else
                value = [];
            end
        end

        function setVideoViewCacheValue(app, key, value)
            cache = app.ensureVideoViewCache();
            value_bytes = app.videoCacheValueBytes(value);

            if isKey(cache.views, key)
                cache.view_total_bytes = cache.view_total_bytes - cache.view_bytes(key);
            end

            cache.views(key) = value;
            cache.view_bytes(key) = value_bytes;
            cache.view_total_bytes = cache.view_total_bytes + value_bytes;
            cache.view_order = app.touchVideoCacheKey(cache.view_order, key);

            while ~isempty(cache.view_order) && ...
                    (numel(cache.view_order) > cache.max_views || ...
                    cache.view_total_bytes > cache.max_view_bytes)
                old_key = cache.view_order{1};
                cache.view_order(1) = [];
                if isKey(cache.views, old_key)
                    cache.view_total_bytes = cache.view_total_bytes - cache.view_bytes(old_key);
                    remove(cache.views, old_key);
                    remove(cache.view_bytes, old_key);
                end
            end

            app.video_view_cache = cache;
        end

        function key = videoPlaneCacheKey(app, t, z, c)
            key = sprintf('plane|%s|t%d|z%d|c%d', char(app.video_info.file), t, z, c);
        end

        function plane = getCachedVideoPlane(app, t, z, c)
            key = app.videoPlaneCacheKey(t, z, c);
            cache = app.ensureVideoViewCache();
            if isKey(cache.planes, key)
                plane = cache.planes(key);
                cache.plane_order = app.touchVideoCacheKey(cache.plane_order, key);
                app.video_view_cache = cache;
                return
            end

            plane = app.readVideoPlane(t, z, c);
            app.setVideoPlaneCacheValue(key, plane);
        end

        function setVideoPlaneCacheValue(app, key, plane)
            cache = app.ensureVideoViewCache();
            plane_bytes = app.videoCacheValueBytes(plane);

            if isKey(cache.planes, key)
                cache.plane_total_bytes = cache.plane_total_bytes - cache.plane_bytes(key);
            end

            cache.planes(key) = plane;
            cache.plane_bytes(key) = plane_bytes;
            cache.plane_total_bytes = cache.plane_total_bytes + plane_bytes;
            cache.plane_order = app.touchVideoCacheKey(cache.plane_order, key);

            while ~isempty(cache.plane_order) && ...
                    (numel(cache.plane_order) > cache.max_planes || ...
                    cache.plane_total_bytes > cache.max_plane_bytes)
                old_key = cache.plane_order{1};
                cache.plane_order(1) = [];
                if isKey(cache.planes, old_key)
                    cache.plane_total_bytes = cache.plane_total_bytes - cache.plane_bytes(old_key);
                    remove(cache.planes, old_key);
                    remove(cache.plane_bytes, old_key);
                end
            end

            app.video_view_cache = cache;
        end

        function order = touchVideoCacheKey(app, order, key)
            order = order(~strcmp(order, key));
            order{end + 1} = key;
        end

        function bytes = videoCacheValueBytes(app, value)
            details = whos('value');
            bytes = double(details.bytes);
        end

        function plane = readVideoPlane(app, t, z, c)
            video_file = char(app.video_info.file);
            video_file_lower = lower(video_file);

            if endsWith(video_file_lower, '.h5')
                plane = Program.Helpers.read_h5_video_plane(app.video_info, t, z, c);
            elseif endsWith(video_file_lower, '.nd2') || ...
                    endsWith(video_file_lower, '.tif') || endsWith(video_file_lower, '.tiff')
                file = app.getVideoBioformatsReader();
                plane = app.readVideoBioformatsPlane(file, z, c, t);
            else
                frame = app.retrieve_frame(t);
                if ndims(frame) == 3
                    plane = frame(:, :, z);
                else
                    plane = frame(:, :, z, c);
                end
            end
        end

        function xy = readCachedVideoXYStack(app, t, z)
            xy = [];
            for c_idx = 1:app.video_info.nc
                plane = app.getCachedVideoPlane(t, z, c_idx);
                if isempty(xy)
                    xy = zeros(size(plane, 1), size(plane, 2), app.video_info.nc, 'like', plane);
                end
                xy(:, :, c_idx) = plane;
            end
        end

        function prefetchVideoNeighborPlanes(app, t, z)
            neighbor_slices = unique([z - 1, z + 1]);
            neighbor_slices = neighbor_slices(neighbor_slices >= 1 & neighbor_slices <= app.video_info.nz);

            for z_idx = neighbor_slices
                for c_idx = 1:app.video_info.nc
                    key = app.videoPlaneCacheKey(t, z_idx, c_idx);
                    cache = app.ensureVideoViewCache();
                    if ~isKey(cache.planes, key)
                        app.getCachedVideoPlane(t, z_idx, c_idx);
                    end
                end
            end
        end

        function render = getCachedVideoMipRender(app, t)
            key = sprintf('mip|%s|t%d|%dx%dx%dx%d', char(app.video_info.file), ...
                t, app.video_info.ny, app.video_info.nx, app.video_info.nz, app.video_info.nc);
            [hit, render] = app.getVideoViewCacheValue(key);
            if hit
                return
            end

            xy = [];
            xz = [];
            yz = [];
            for c_idx = 1:app.video_info.nc
                for z_idx = 1:app.video_info.nz
                    plane = app.readVideoPlane(t, z_idx, c_idx);
                    if isempty(xy)
                        xy = zeros(size(plane, 1), size(plane, 2), app.video_info.nc, 'like', plane);
                        xz = zeros(size(plane, 1), app.video_info.nz, app.video_info.nc, 'like', plane);
                        yz = zeros(size(plane, 2), app.video_info.nz, app.video_info.nc, 'like', plane);
                    end

                    xy(:, :, c_idx) = max(xy(:, :, c_idx), plane);
                    xz(:, z_idx, c_idx) = max(plane, [], 2);
                    yz(:, z_idx, c_idx) = max(plane, [], 1).';
                end
            end

            render = struct('xy', {xy}, 'xz', {xz}, 'yz', {yz});
            app.setVideoViewCacheValue(key, render);
        end

        function orthos = getCachedVideoOrthogonalViews(app, t, x, y)
            key = sprintf('orthos|%s|t%d|x%d|y%d|%dx%dx%d', char(app.video_info.file), ...
                t, x, y, app.video_info.nx, app.video_info.ny, app.video_info.nz);
            [hit, orthos] = app.getVideoViewCacheValue(key);
            if hit
                return
            end

            video_file = char(app.video_info.file);
            video_file_lower = lower(video_file);

            if endsWith(video_file_lower, '.h5') && ...
                    (~isfield(app.video_info, 'h5_layout') || strcmp(app.video_info.h5_layout, 'data'))
                xz = squeeze(h5read(video_file, '/data', ...
                    [1 y 1 1 t], [app.video_info.ny 1 app.video_info.nz app.video_info.nc 1]));
                yz = squeeze(h5read(video_file, '/data', ...
                    [x 1 1 1 t], [1 app.video_info.nx app.video_info.nz app.video_info.nc 1]));
            else
                xz = [];
                yz = [];
                for c_idx = 1:app.video_info.nc
                    for z_idx = 1:app.video_info.nz
                        plane = app.readVideoPlane(t, z_idx, c_idx);
                        if isempty(xz)
                            xz = zeros(size(plane, 1), app.video_info.nz, app.video_info.nc, 'like', plane);
                            yz = zeros(size(plane, 2), app.video_info.nz, app.video_info.nc, 'like', plane);
                        end
                        xz(:, z_idx, c_idx) = plane(:, y);
                        yz(:, z_idx, c_idx) = plane(x, :).';
                    end
                end
            end

            orthos = struct( ...
                'xz', {app.ensureVideoPlaneChannels(xz)}, ...
                'yz', {app.ensureVideoPlaneChannels(yz)});
            app.setVideoViewCacheValue(key, orthos);
        end

        function render = frameToRenderViews(app, frame, z, x, y, use_mip)
            if use_mip
                render = struct( ...
                    'xy', {max(frame, [], 3)}, ...
                    'xz', {max(frame, [], 2)}, ...
                    'yz', {max(frame, [], 1)});
            else
                render = struct( ...
                    'xy', {frame(:, :, z, :)}, ...
                    'xz', {frame(:, y, :, :)}, ...
                    'yz', {frame(x, :, :, :)});
            end
        end

        function plane = ensureVideoPlaneChannels(app, plane)
            plane = squeeze(plane);
            if ismatrix(plane)
                plane = reshape(plane, size(plane, 1), size(plane, 2), 1);
            end
        end

        function file = getVideoBioformatsReader(app)
            video_file = char(app.video_info.file);
            video_file_lower = lower(video_file);
            if endsWith(video_file_lower, '.nd2')
                needs_reader = isempty(app.nd2_file);
                if ~needs_reader
                    try
                        needs_reader = ~strcmp(char(app.nd2_file.filename), video_file);
                    catch
                        needs_reader = true;
                    end
                end
                if needs_reader
                    app.nd2_file = BioformatsImage(video_file);
                end
                file = app.nd2_file;
            else
                needs_reader = isempty(app.tif_file);
                if ~needs_reader
                    try
                        needs_reader = ~strcmp(char(app.tif_file.filename), video_file);
                    catch
                        needs_reader = true;
                    end
                end
                if needs_reader
                    app.tif_file = BioformatsImage(video_file);
                end
                file = app.tif_file;
            end
        end

        function plane = readVideoBioformatsPlane(app, file, z, c, t)
            if endsWith(lower(char(app.video_info.file)), '.nd2') && ...
                    isstruct(app.video_info) && isfield(app.video_info, 'channel_names') && ...
                    numel(app.video_info.channel_names) >= c
                plane = getPlane(file, z, app.video_info.channel_names{c}, t);
            else
                plane = getPlane(file, z, c, t);
            end
        end

        function display_projection = scaleVideoProjection(app, projection)
            projection = squeeze(projection);
            if ismatrix(projection)
                projection = reshape(projection, size(projection, 1), size(projection, 2), 1);
            end

            display_projection = zeros(size(projection, 1), size(projection, 2), 3, 'uint8');
            gains = [app.RSlider.Value, app.GSlider.Value, app.BSlider.Value];
            for ch = 1:min(3, size(projection, 3))
                channel = double(projection(:, :, ch));
                [low_limit, high_limit] = app.estimateVideoDisplayLimits(channel);
                scaled_channel = 255 .* (channel - low_limit) ./ (high_limit - low_limit);
                scaled_channel = scaled_channel .* gains(ch);
                display_projection(:, :, ch) = uint8(max(0, min(255, scaled_channel)));
            end
        end

        function [low_limit, high_limit] = estimateVideoDisplayLimits(app, channel)
            values = channel(isfinite(channel));
            if isempty(values)
                low_limit = 0;
                high_limit = 1;
                return
            end

            low_limit = 0;
            bit_depth = [];
            if isstruct(app.video_info) && isfield(app.video_info, 'bitDepth') && ...
                    isnumeric(app.video_info.bitDepth) && isscalar(app.video_info.bitDepth)
                bit_depth = double(app.video_info.bitDepth);
            end

            if ~isempty(bit_depth) && isfinite(bit_depth) && bit_depth > 0 && bit_depth <= 32
                high_limit = (2 .^ bit_depth) - 1;
                if high_limit > low_limit
                    return
                end
            end

            stride = max(1, floor(numel(values) / 20000));
            sample = sort(values(1:stride:end));
            high_limit = sample(max(1, round(0.9995 * numel(sample))));

            if high_limit <= low_limit
                high_limit = max(sample);
            end

            if high_limit <= low_limit
                high_limit = low_limit + 1;
            end
        end

        function configureVideoColorDefaults(app)
            app.RSlider.Value = 1;
            app.GSlider.Value = 1;
            app.BSlider.Value = 1;

            channel_names = {};
            if isstruct(app.video_info) && isfield(app.video_info, 'channel_names')
                channel_names = app.video_info.channel_names;
            end

            if app.video_info.nc == 2 && numel(channel_names) >= 2
                names = lower(string(channel_names));
                has_rfp_first = contains(names(1), "rfp") || contains(names(1), "red");
                has_gcamp_second = contains(names(2), "gcamp") || contains(names(2), "gfp") || contains(names(2), "green");
                if has_rfp_first && has_gcamp_second
                    app.RSlider.Value = 0.25;
                    app.GSlider.Value = 1;
                    app.BSlider.Value = 0;
                end
            end
        end

        function configureVideoTimeSlider(app)
            nt = max(1, round(app.video_info.nt));
            if nt <= 50
                ticks = 1:nt;
            else
                ticks = unique(round(linspace(1, nt, 13)));
            end

            app.tSlider.MajorTicks = ticks;
            app.tSlider.MajorTickLabels = cellstr(string(ticks));
            if nt <= 100
                app.tSlider.MinorTicks = 1:nt;
            else
                app.tSlider.MinorTicks = [];
            end
        end

        function clearVideoTimeSliderLiveState(app)
            appdata_keys = {'video_tslider_live_last_t', 'video_tslider_live_rendering'};
            for key_idx = 1:numel(appdata_keys)
                key = appdata_keys{key_idx};
                if isappdata(app.CELL_ID, key)
                    rmappdata(app.CELL_ID, key);
                end
            end
        end

        function tf = shouldRenderVideoTimeSliderLive(app, target_t)
            tf = false;
            if isempty(app.video_info)
                return
            end

            if isappdata(app.CELL_ID, 'video_tslider_live_rendering') && ...
                    logical(getappdata(app.CELL_ID, 'video_tslider_live_rendering'))
                return
            end

            last_t = NaN;
            if isappdata(app.CELL_ID, 'video_tslider_live_last_t')
                last_t = getappdata(app.CELL_ID, 'video_tslider_live_last_t');
            end

            if isequal(last_t, target_t)
                return
            end

            setappdata(app.CELL_ID, 'video_tslider_live_last_t', target_t);
            tf = true;
        end

        function line_handle = setVideoCursorLine(app, line_handle, ax, orientation, value)
            marker = '--';
            color = '#9c9c9c';
            width = 0.2;

            needs_new_line = isempty(line_handle);
            if ~needs_new_line
                try
                    needs_new_line = ~isvalid(line_handle);
                catch
                    needs_new_line = true;
                end
            end

            if needs_new_line
                if strcmp(orientation, 'x')
                    line_handle = xline(ax, value, marker, 'color', color, 'LineWidth', width);
                else
                    line_handle = yline(ax, value, marker, 'color', color, 'LineWidth', width);
                end
                return
            end

            try
                line_handle.Value = value;
                line_handle.LineStyle = marker;
                line_handle.Color = color;
                line_handle.LineWidth = width;
            catch
                delete(line_handle);
                if strcmp(orientation, 'x')
                    line_handle = xline(ax, value, marker, 'color', color, 'LineWidth', width);
                else
                    line_handle = yline(ax, value, marker, 'color', color, 'LineWidth', width);
                end
            end
        end


        function roi_draw(app, x, y, z, t)
            if app.ShowallneuronsCheckBox.Value
                threshold = 999;
            else
                threshold = 2;
            end

            for n=1:size(app.video_neurons, 2)
                if t <= size(app.video_neurons(n).rois, 2)
                    try
                        if z <= app.video_neurons(n).rois(t).z_slice + threshold && z >= app.video_neurons(n).rois(t).z_slice - threshold
                            app.video_neurons(n).rois(t).xy = drawpoint(app.xyAxes, 'Position', app.video_neurons(n).rois(t).xy_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);
                            addlistener(app.video_neurons(n).rois(t).xy, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));
                            addlistener(app.video_neurons(n).rois(t).xy, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));
                        end
        
                        if y <= app.video_neurons(n).rois(t).y_slice + threshold && y >= app.video_neurons(n).rois(t).y_slice - threshold
                            app.video_neurons(n).rois(t).xz = drawpoint(app.xzAxes, 'Position', app.video_neurons(n).rois(t).xz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);
                            addlistener(app.video_neurons(n).rois(t).xz, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));
                            addlistener(app.video_neurons(n).rois(t).xz, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));
                        end
        
                        if x <= app.video_neurons(n).rois(t).x_slice + threshold && x >= app.video_neurons(n).rois(t).x_slice - threshold
                            app.video_neurons(n).rois(t).yz = drawpoint(app.yzAxes, 'Position', app.video_neurons(n).rois(t).yz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);
                            addlistener(app.video_neurons(n).rois(t).yz, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));
                            addlistener(app.video_neurons(n).rois(t).yz, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));
                        end
                    catch
                        %fprintf("\n Problem neuron (#%s @ t=%s):", num2str(n), num2str(t))
                        %app.video_neurons(n).rois(t)
                    end
                end

            end
        end


        function add_worldline(app, neuron)
            if isempty(app.video_neurons)
                app.video_neurons = struct('provenance', {}, 'worldline', {}, 'rois', {});
            end

            provenance = 'NPAL';
            worldline = struct('name', {{}}, 'node', {{}}, 'color', {{}}, 'style', {{}}, 'id', {{}});
            rois = struct();
            idx = size(app.video_neurons, 2) + 1;
            existing_worldline = 0;


            % Generate or extract worldline name
            if ~exist('neuron','var')
                worldline.name = string(size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1)+1);
            else
                if ~isempty(neuron.annotation)
                    worldline.name = neuron.annotation;
                else
                    worldline.name = string(size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1)+1);
                end
            end

            % Check if this worldline already exists
            for n=1:size(app.video_neurons, 2)
                if strcmp(app.video_neurons(n).worldline.name, worldline.name)
                    idx = n;
                    existing_worldline = 1;
                    break
                end
            end

            % Populate worldline info
            if ~exist('neuron','var')

                rois(app.tSlider.Value).x_slice = app.xSlider.Value;
                rois(app.tSlider.Value).y_slice = app.ySlider.Value;
                rois(app.tSlider.Value).z_slice = app.hor_zSlider.Value;
                
                rois(app.tSlider.Value).xy_pos = [app.xSlider.Value app.video_info.ny-app.ySlider.Value];
                rois(app.tSlider.Value).xz_pos = [app.xSlider.Value app.hor_zSlider.Value];
                rois(app.tSlider.Value).yz_pos = [app.hor_zSlider.Value app.video_info.ny-app.ySlider.Value];
    
                rois(app.tSlider.Value).xy = drawpoint(app.xyAxes, 'Position', [app.xSlider.Value app.video_info.ny-app.ySlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).xz = drawpoint(app.xzAxes, 'Position', [app.xSlider.Value app.hor_zSlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).yz = drawpoint(app.yzAxes, 'Position', [app.hor_zSlider.Value app.video_info.ny-app.ySlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);

                if existing_worldline
                    app.video_neurons(idx).rois(app.tSlider.Value) = rois(app.tSlider.Value);
                else
                    worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);
                    worldline.color = [0.8, 0.8, 0.8];
                    worldline.style = uistyle("FontColor", worldline.color);
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    app.video_neurons(idx) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});
                end

                if app.AutojumponIDCheckBox.Value
                    app.jumpToUnIDd('frame');
                else
                    app.edit_worldline(size(app.video_neurons, 2), 'select');
                end
            else
                % Extract neuron color. If invalid, use color_readout.
                % If also invalid, use default worldline color (grey).
                if ~isnan(neuron.color(1)) && max(neuron.color) <= 1 && min(neuron.color) >= 0
                    worldline.color = neuron.color(1:3);
                elseif ~isnan(neuron.color_readout(1)) && max(neuron.color_readout) <= 1 && min(neuron.color_readout) >= 0
                    worldline.color = neuron.color_readout(1:3);
                else
                    worldline.color = [0.8, 0.8, 0.8];
                end

                rois(app.tSlider.Value).x_slice = neuron.position(2);
                rois(app.tSlider.Value).y_slice = neuron.position(1);
                rois(app.tSlider.Value).z_slice = neuron.position(3);
                
                rois(app.tSlider.Value).xy_pos = [neuron.position(2) neuron.position(1)];
                rois(app.tSlider.Value).xz_pos = [neuron.position(2) neuron.position(3)];
                rois(app.tSlider.Value).yz_pos = [neuron.position(3) neuron.position(1)];
    
                rois(app.tSlider.Value).xy = drawpoint(app.xyAxes, 'Position', rois(app.tSlider.Value).xy_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).xz = drawpoint(app.xzAxes, 'Position', rois(app.tSlider.Value).xz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).yz = drawpoint(app.yzAxes, 'Position', rois(app.tSlider.Value).yz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                
                if existing_worldline
                    app.video_neurons(idx).rois(app.tSlider.Value) = rois(app.tSlider.Value);
                else
                    if Neurons.Hermaphrodite.isCell(worldline.name)
                        worldline.node = uitreenode(app.IDdNode, "Text", worldline.name);
                    else
                        worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);
                    end

                    worldline.style = uistyle("FontColor", worldline.color);
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    app.video_neurons(idx) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});
                end
            end

            if ~any(app.id_frames == app.tSlider.Value)
                app.id_frames = [app.id_frames; app.tSlider.Value];
            end
        end


        function edit_worldline(app, idx, mode, new_val)
            app.visual_composer();

            switch mode
                case 'select'
                    app.NameEditField.Value = app.video_neurons(idx).worldline.name;
                    app.XCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos(1);
                    app.YCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos(2);
                    app.ZCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).z_slice;

                    app.xSlider.Value = app.XCoordinateEditField.Value;
                    app.ySlider.Value = app.video_info.ny - app.YCoordinateEditField.Value;
                    app.hor_zSlider.Value = app.ZCoordinateEditField.Value;

                    app.WorldlineIDEditField.Value = idx;
                    app.ColorButton.BackgroundColor = app.video_neurons(idx).worldline.color;
                    app.ProvenanceEditField.Value = app.video_neurons(idx).provenance;
                case 'save'
                    d = uiprogressdlg(app.CELL_ID,'Title','Saving worldline...','Indeterminate','on');
                    existing_idx = -1;

                    for i = 1:length(app.video_neurons)
                        if strcmp(app.video_neurons(i).worldline.name, app.NameEditField.Value)
                            existing_idx = i;
                            break;
                        end
                    end

                    if existing_idx == -1
                        app.video_neurons(idx).worldline.name = app.NameEditField.Value;
                        app.video_neurons(idx).worldline.node.Text = app.video_neurons(idx).worldline.name;
                        app.video_neurons(idx).provenance = app.ProvenanceEditField.Value;
                        app.edit_worldline(idx, 'position', [app.XCoordinateEditField.Value app.YCoordinateEditField.Value app.ZCoordinateEditField.Value])

                        if Neurons.Hermaphrodite.isCell(app.video_neurons(idx).worldline.name)
                            move(app.video_neurons(idx).worldline.node, app.WorldlineTree.Children(1).Children(end), 'after');
                        else
                            move(app.video_neurons(idx).worldline.node, app.WorldlineTree.Children(2).Children(end), 'after');
                        end
                    else
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).x_slice = app.video_neurons(idx).rois(app.tSlider.Value).x_slice;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).y_slice = app.video_neurons(idx).rois(app.tSlider.Value).y_slice;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).z_slice = app.video_neurons(idx).rois(app.tSlider.Value).z_slice;
                        
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).xy_pos = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).xz_pos = app.video_neurons(idx).rois(app.tSlider.Value).xz_pos;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).yz_pos = app.video_neurons(idx).rois(app.tSlider.Value).yz_pos;

                        delete(app.video_neurons(idx).worldline.node);
                        app.video_neurons(idx) = [];
                    end
                    close(d)
                case 'color'
                    app.video_neurons(idx).worldline.color = new_val;
                    app.video_neurons(idx).worldline.style = uistyle("FontColor", app.video_neurons(idx).worldline.color);
                    addStyle(app.WorldlineTree, app.video_neurons(idx).worldline.style, "node", app.video_neurons(idx).worldline.node);
                case 'name'
                    app.video_neurons(idx).worldline.name = new_val;
                    app.video_neurons(idx).worldline.node.Text = new_val;
                case 'position'
                    app.video_neurons(idx).rois(app.tSlider.Value).x_slice = new_val(1);
                    app.video_neurons(idx).rois(app.tSlider.Value).y_slice = new_val(2);
                    app.video_neurons(idx).rois(app.tSlider.Value).z_slice = new_val(3);
                    
                    app.video_neurons(idx).rois(app.tSlider.Value).xy_pos = [new_val(1) new_val(2)];
                    app.video_neurons(idx).rois(app.tSlider.Value).xz_pos = [new_val(1) new_val(3)];
                    app.video_neurons(idx).rois(app.tSlider.Value).yz_pos = [new_val(3) new_val(2)];
                case 'delete'
                    app.video_neurons(idx) = [];
            end
            
            app.visual_composer();
        end

        function import_annotations(app, pos_arr, labels)
            d = uiprogressdlg(app.CELL_ID,"Title","NeuroPAL ID","Message","Importing tracks...",'Indeterminate','off');
            app.video_neurons = struct('provenance', {}, 'worldline', {}, 'rois', {});

            % Check for converted coordinates.
            if max(pos_arr(:, 2)) <= app.video_info.nx/10
                %pos_arr(:, 2) = max(floor(pos_arr(:, 2)*app.video_info.nx- 1e-6), 0);
                pos_arr(:, 2) = (pos_arr(:, 2) * app.video_info.nx);
            end

            if max(pos_arr(:, 3)) <= app.video_info.ny/10
                %pos_arr(:, 3) = max(floor(pos_arr(:, 3)*app.video_info.ny- 1e-6), 0);
                pos_arr(:, 3) = (pos_arr(:, 3) * app.video_info.ny);
            end
            
            if mean(pos_arr(:, 4)) <= app.video_info.nz/5
                %pos_arr(:, 4) = max(floor(pos_arr(:, 4)*app.video_info.nz- 1e-6), 0);
                pos_arr(:, 4) = (pos_arr(:, 4) * app.video_info.nz);
            end
            
            pos_arr(:, 1) = round(pos_arr(:, 1));

            %if min(pos_arr(:, 4)) == 0
            %    pos_arr(:, 4) = pos_arr(:, 4) + 1;
            %end

            for n=1:size(labels, 2)
                d.Value = n/size(pos_arr, 1);
                idx = size(app.video_neurons, 2) + 1;
                existing_worldline = 0;

                label = deblank(labels{n});
                coords = pos_arr(n, :);
                frame = coords(1);
    
                provenance = 'TKMT';
                worldline = struct('name', {{}}, 'node', {{}}, 'color', {{}}, 'style', {{}}, 'id', {{}});
                rois = struct();
  
                worldline.name = label;
    
                % Check if this worldline already exists
                for i=1:size(app.video_neurons, 2)
                    if strcmp(app.video_neurons(i).worldline.name, worldline.name)
                        idx = i;
                        existing_worldline = 1;
                        break
                    end
                end

                rois(frame).x_slice = coords(2);
                rois(frame).y_slice = coords(3);
                rois(frame).z_slice = coords(4);
                rois(frame).xy_pos = [coords(2) coords(3)];
                rois(frame).xz_pos = [coords(2) coords(4)];
                rois(frame).yz_pos = [coords(4) coords(3)];

                if existing_worldline
                    app.video_neurons(idx).rois(frame) = rois(frame);
                else
                    if Neurons.Hermaphrodite.isCell(worldline.name)
                        worldline.node = uitreenode(app.IDdNode, "Text", worldline.name);
                    else
                        worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);
                    end

                    worldline.color = [0.8, 0.8, 0.8];
                    worldline.style = uistyle("FontColor", worldline.color);
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    app.video_neurons(idx) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});
                end
    
                if ~any(app.id_frames == frame)
                    app.id_frames = [app.id_frames; frame];
                end
            end

            app.visual_composer();
            close(d)
        end


        function roi_moved(app, src, n, evt)
            switch src
                case app.xyAxes
                    app.edit_worldline(n, 'position', [evt.CurrentPosition(1), evt.CurrentPosition(2), app.video_neurons(n).rois(app.tSlider.Value).z_slice]);
                case app.xzAxes
                    app.edit_worldline(n, 'position', [evt.CurrentPosition(1), app.video_neurons(n).rois(app.tSlider.Value).y_slice, evt.CurrentPosition(2)]);
                case app.yzAxes
                    app.edit_worldline(n, 'position', [app.video_neurons(n).rois(app.tSlider.Value).x_slice, evt.CurrentPosition(2), evt.CurrentPosition(1)]);
            end
        end


        function jumpToUnIDd(app, mode)
            if nargin < 2
                mode = 'frame';
            end

            switch mode
                case 'frame'
                    target_frame = round(app.tSlider.Value);
                    for n = 1:numel(app.video_neurons)
                        if ~Neurons.Hermaphrodite.isCell(app.video_neurons(n).worldline.name) && ...
                                numel(app.video_neurons(n).rois) >= target_frame && ...
                                isfield(app.video_neurons(n).rois(target_frame), 'x_slice') && ...
                                ~isempty(app.video_neurons(n).rois(target_frame).x_slice)
                            app.edit_worldline(n, 'select');
                            return
                        end
                    end
                case 'tree'
                    if ~isempty(app.UnIDdNode.Children)
                        app.WorldlineTree.SelectedNodes = app.UnIDdNode.Children(1);
                    end
                case 'video'
                    current_frame = round(app.tSlider.Value);
                    for target_frame = current_frame:app.video_info.nt
                        for n = 1:numel(app.video_neurons)
                            if ~Neurons.Hermaphrodite.isCell(app.video_neurons(n).worldline.name) && ...
                                    numel(app.video_neurons(n).rois) >= target_frame && ...
                                    isfield(app.video_neurons(n).rois(target_frame), 'x_slice') && ...
                                    ~isempty(app.video_neurons(n).rois(target_frame).x_slice)
                                app.tSlider.Value = target_frame;
                                app.edit_worldline(n, 'select');
                                return
                            end
                        end
                    end
            end
        end


        function export_arguments(app, filename)
            % Compile arguments.
            app.compile_arguments();

            % Convert the app.arguments struct to a JSON string
            jsonStr = jsonencode(app.arguments);
            
            % Open a file for writing.
            fileId = fopen(filename, 'wt');
            
            % Check if the file was opened successfully
            if fileId == -1
                error('Failed to open file for writing: %s', filename);
            end
            
            % Write the JSON string to the file
            fwrite(fileId, jsonStr, 'char');
            
            % Close the file
            fclose(fileId);
        end


        function import_arguments(app, json)
            % Read the entire content of the JSON file into a string
            jsonStr = fileread(json);
            
            % Check if the JSON string is empty
            if isempty(jsonStr)
                error('The JSON file is empty: %s', json);
            end
            
            % Convert the JSON string back into a MATLAB struct
            app.arguments = jsondecode(jsonStr);
        end


        function saveButtonPushed(app, src, fig, colorButton, targetNode)
            newStyle = uistyle("FontColor",colorButton.BackgroundColor);
            addStyle(app.WorldlineTree,newStyle,"node", app.WorldlineTree.SelectedNodes);
            delete(fig)
        end

        
        function cancelButtonPushed(app, src, fig)
            delete(fig)
        end


        %% Log Functions
        function initLog(app)
            %INITLOG This function initializes the log tab.
            %
            %   Inputs:
            %   - app: Running app instance.

            % Grab System Information
            [status, app.OperatingSystemEditField.Value] = ...
                system('ver'); %COMPATWARN

            % Populate the Matlab runtime edit field with the version of
            % Matlab that we're running.
            app.MATLABRuntimeEditField.Value = version;

            % Populate the Python executable edit field with the version of
            % python that is running in the current environment.
            app.PythonExecutableEditField.Value = pyenv().Version;

            % Populate the NeuroPAL_ID edit field with the version of the
            % program being run.
            app.NeuroPALEditField.Value = string( ...
                Program.ProgramInfo.version);
        end


        function logEvent(app, source, event, notify)
            %LOGEVENT This function adds an event to the program log.
            %
            %   Inputs:
            %   - app: Running app instance.
            %   - source: A string/char label describing the routine that
            %       triggered this event.
            %   - event: A string/char label describing the event.
            %   - notify: A boolean describing whether to pop up a log
            %       notification (true) or not (false). This essentially
            %       displays a small text box in the top right of the
            %       that contains the logged string, and which disappears
            %       after 3 seconds.

            % Check whether the log is enabled.
            if app.EnablelogCheckBox.Value
                if ~exist('notify','var')
                    % If no notify flag was passed, set notify to 0.
                    notify = 0;
                end
            
                % Get the current time.
                time = datetime("now");

                % Assemble the string to be added to the log. This is
                % effectively the current time, the source of the event,
                % and an event description.
                log_string = sprintf("%s (%s): %s", time, source, event);
            
                % Append the log string to the log.
                app.Log.Value = [app.Log.Value(:); log_string(:)];
            
                % If we're on windows, update the log file. For mac, we'll
                % need to add a workaround that bypasses the security
                % issues we encountered during debugging.
                if ~ismac
                    log_file = fopen([pwd,'/log.txt'], 'a+'); % COMPATWARN
                    fclose(log_file);
                end
            
                % If the notify flag is set to true and the user has opted
                % to enable log notifications...
                if notify && app.EnablelognotificationsCheckBox.Value
                    % Create a ui label.
                    hLabel = uilabel('Parent', app.CELL_ID, ...
                        'Text', log_string, ...
                        'Position', [app.CELL_ID.Position(3)-500, ...
                        app.TabGroup.Position(4)-23, 500, 23], ...
                        'FontColor', [0 0 0], ...
                        'HorizontalAlignment', 'right');
                    
                    % Start a timer and attach to it a function that
                    % will fade out the ui label over the course of 3
                    % seconds.
                    t = timer('TimerFcn', @(myTimerObj, thisEvent) app.fadeLog(myTimerObj, hLabel), ...
                              'Period', 0.1, ...
                              'ExecutionMode', 'fixedRate', ...
                              'TasksToExecute', 50);
                    
                    % Start the timer.
                    start(t);
                end
            end
        end


        function fadeLog(app, t, hLabel)
            %FADELOG This function fades out a given log notification
            % over the course of 3 seconds.
            %
            %   Inputs:
            %   - app: Running app instance.
            %   - t: The timer object.
            %   - hLabel: The log notification label.

            % Get the current color of the label.
            currentColor = hLabel.FontColor;

            % Add 0.02 to every RGB value of the label's font color.
            % If this causes it to pass the GUI's background color,
            % go with the GUI background color instead.
            newColor = min(currentColor + [0.02 0.02 0.02], [0.9 0.9 0.9]);
            hLabel.FontColor = newColor;
        
            % If the new color equal to the GUI's background color...
            if all(newColor == [0.9 0.9 0.9])
                % Delete the label.
                delete(hLabel);

                % Stop the timer.
                stop(t);

                % Delete the timer.
                delete(t);
            end
        end

        
        % Helper function that passes nwbsave.mlapp data back to CELL_ID
        % POLISHWARN
        function process_nwb_data(app, input)
            app.nwb_data = input;
        end
        
        function chan_array = channel_idx_sync(app)
            chan_array = [app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.DICDropDown.Value, app.GFPDropDown.Value];

            % Get the unique values and their indices
            [uniqueValues, ~, idx] = unique(chan_array);
            
            % Get the counts of each unique value
            counts = histc(idx, 1:length(uniqueValues));
            
            % Find values that occur more than once
            duplicateValues = uniqueValues(counts > 1);
            
            % Initialize cell array to hold indices of duplicate values
            duplicateIndices = cell(length(duplicateValues), 1);
            
            % Find indices of each duplicate value
            for i = 1:length(duplicateValues)
                duplicateIndices{i} = find(chan_array == duplicateValues(i));
            end

            if ~isempty(duplicateIndices)
                chan_array(max(duplicateIndices)) = 0;
                uiconfirm(app.CELL_ID, sprintf("Duplicate channel indices detected. Resetting channels %.f.", string(duplicateIndices)), "Warning!");
            end

            app.localSetProcessingChannelValue(app.ProcRDropDown, chan_array(1));
            app.localSetProcessingChannelValue(app.ProcGDropDown, chan_array(2));
            app.localSetProcessingChannelValue(app.ProcBDropDown, chan_array(3));
            app.localSetProcessingChannelValue(app.ProcWDropDown, chan_array(4));
            app.localSetProcessingChannelValue(app.ProcDICDropDown, chan_array(5));
            app.localSetProcessingChannelValue(app.ProcGFPDropDown, chan_array(6));
        end
        
        function create_backup(app, path)
            if strcmp(app.VolumeDropDown.Value, 'Colormap') & isempty(app.proc_image)    
                if ~isempty(app.image_file)
                    mat_file = fullfile(app.image_file);
                end
                
                if ~isfile(mat_file)
                    [app.image_data, app.image_info, app.image_prefs, ~, ~, ~, ~, ~] = DataHandling.NeuroPALImage.open(path);
                end
    
                app.proc_image = matfile(mat_file);
            elseif strcmp(app.VolumeDropDown.Value, 'Video') & isempty(app.video_info)
                app.proc_load('video', path);
            end
        end

        function retrieve_backup(app)
            switch app.VolumeDropDown.Value
                case 'Colormap'
                    app.proc_image = matfile(app.proc_image); % This will need to be adjusted once zephir optimization are merged in.
                case 'Video'
                    app.proc_swap_video(app.video_info.file);
            end
            
            app.volume_crop_roi = [];
            app.video_crop_roi = [];
        end
        
        function proc_code = proc_check(app, mode, path)
            %PROC_CHECK Check whether a given file or volume should trigger
            % the preprocessing routine.
            %
            %   Inputs:
            %   - app: Running app instance.
            %   - mode: String/char describing whether we're dealing with a
            %       color stack or a video.
            %   - path: Path to the file to be analyzed.
            %
            %   Outputs:
            %   - proc_code: Either true (indicating that the file
            %       triggered our preprocessing routine) or the file path
            %       itself.
            %
            %   Note that this function is rendered obsolete by the
            %   agnostic volume reader update.

            %if app.EnableautoprofilingMenu.Checked
            %    profile on
            %end

            % Initialize check as "No".
            check = "No";

            % If the passed path is empty, set proc_code to false and
            % return.
            if isempty(path)
                proc_code = 0;
                return
            end
            
            % Call dir() on the path to obtain the total bytes.
            cont = dir(path);

            % Calculate 90% of the maximum possible array size the
            % system's memory can accommodate.
            if ispc
                % If we're on windows, we can leverage memory().
                max_arr = memory().MaxPossibleArrayBytes * 0.90;
            else
                % On mac, memory() isn't supported, so we have to make
                % a system call and parse the output.
                [~, max_arr] = system('sysctl hw.memsize | awk ''{print $2}''');
                max_arr = str2double(max_arr) * 0.90;
            end

            % Get the file type we're working with.
            [filepath, name, ext] = fileparts(path);

            % If this is an nwb file, we need to specifically check the
            % size of the appropriate volume within the acquisition module.
            if strcmp(ext, '.nwb')
                % Check whether we're dealing with an image or a video,
                % then get the appropriate module.
                switch mode
                    case 'image'
                        proc_nwb = nwbRead(path);
                        module = proc_nwb.acquisition.get('NeuroPALImageRaw');

                    case 'video'
                        app.load_nwb(path);
                        module = app.nwb_mod;
                end

                % If this module's data array has an "internal" property 
                % (not all do), use that, then get the data array's
                % dimensions and datatype.
                if isprop(properties(module.data), 'internal')
                    data_dims = module.data.internal.dims;
                    dtype = module.data.internal.dataType;
                else
                    data_dims = module.data.dims;
                    dtype = module.data.dataType;
                end

                % Extract the bit depth from the data type.
                switch dtype
                    case 'single'
                        bit_depth = 4;
                    case 'double'
                        bit_depth = 8;
                    otherwise
                        bit_depth = str2double(dtype(5:end))/8;
                end

                % Calculate the prospective array size.
                f_size = data_dims * bit_depth;

            else
                % If this is not an nwb file, we assume that the array size
                % is approximately equal to the file size. Note that in the
                % agnostic volume reader update, this is changed to
                % leverage each helper class's get_data_size() function.
                f_size = cont.bytes;
            end

            % Check the size against our thresholds. It it exceeds either
            % one, set proc_code to true and construct a message that
            % describes the situation to the user.
            if f_size > 13e7
                % If the size exceeds our soft working limit of 100mb...
                message_to_user = sprintf( ...
                    "Your volume exceeds the soft " + ...
                    "working limit of 100 mb. We strongly " + ...
                    "recommend preprocessing your file as you " + ...
                    "may run into GUI and memory issues " + ...
                    "otherwise.\nPreprocess now?");
                proc_code = 1;
            elseif f_size > max_arr & app.TabGroup.SelectedTab ~= app.VideoTrackingTab
                % If the size exceeds 90% of the maximum array size.
                message_to_user = sprintf( ...
                    "Your volume exceeds remaining " + ...
                    "free memory. We strongly recommend " + ...
                    "preprocessing your file as you may run " + ...
                    "into GUI and memory issues otherwise.\n" + ...
                    "Preprocess now?");
                proc_code = 1;
            else
                proc_code = path;
            end

            % If either of our thresholds were met...
            if proc_code == 1
                % Prompt a dialog window informing the user of the
                % threshold that the file crosses and asking them whether
                % they would like to proceed to preprocessing.
                check = uiconfirm(app.CELL_ID, message_to_user, ...
                    "Warning!", "Options", ["Yes", "No"]);

                % If the user decided to switch to the preprocessing
                % routine, trigger proc_load() and return.
                if strcmpi(check, "Yes")
                    app.proc_load(mode, path);
                    return
                end
            end

            % Set proc_code equal to the file path.
            proc_code = path;

            %if app.EnableautoprofilingMenu.Checked
            %    profile off
            %    p = profile("info");
            %    profsave(p, sprintf("profiler/%s/%s", 'proc_check', datetime));
            %end
        end

        function proc_load(app, mode, path)
            %PROC_LOAD This function loads a given file into the
            % preprocessing tab.
            %
            %   Inputs:
            %   - app: Running app instance.
            %   - mode: String/char defining whether we're dealing with
            %       an image or a video.
            %   - path: String/char representing the path to the target
            %       file.
            %
            %   Note that this function has been rendered obsolete in the
            %   agnostic volume reader update.

            Program.Routines.Processing.load_file(mode, path);
            return

            % Add a task to the progress dialogue indicating that we are
            % now running the preprocessing routine.
            Program.GUI.dialogues.add_task( ...
                'Running pre-processing routine...');  

            % Initialize properties and caches that facilitate
            % preprocessing-related functionality.
            app.flags = struct();                                       % Keeps track of which preprocessing actions to perform in which order, should the user opt for previews.
            app.rotation_stack.cache = struct( ...                      % Cache used for cropping & rotation.
                'Colormap', {{}}, 'Video', {{}});

            % Separate the file path into the parent path, the file name,
            % and the file type.
            [filepath, name, ext] = fileparts(path);

            % Check whether we're dealing with an image or a video.
            switch mode
                case "image"
                    % If we're dealing with an image, add this option to
                    % the volume selection dropdown.
                    if ~any(ismember(app.VolumeDropDown.Items, 'Colormap'))
                        current_items = app.VolumeDropDown.Items;
                        current_items(end+1) = {'Colormap'};
                        app.VolumeDropDown.Items = current_items;
                    end

                    % Get the .mat version of this file.
                    mat_file = fullfile(filepath, [name, '.mat']);
                    
                    % If no .mat version of this file exists, create
                    % one.
                    if ~isfile(mat_file)
                        [app.image_data, app.image_info, app.image_prefs, ~, ~, ~, ~, ~] = DataHandling.NeuroPALImage.open(path);
                        Program.GUI.dialogues.add_task('Loading color stack configuration...');  
                    end
    
                    % Create a reader object for this new mat file.
                    Program.GUI.dialogues.step('Creating reader...');  
                    app.proc_image = matfile(mat_file);

                    % Get the info and prefs structs from this reader.
                    info = app.proc_image.info;
                    prefs = app.proc_image.prefs;

                    % Check whether the info struct contains the channel
                    % names.
                    if isfield(info, 'channel_names')
                        % If so, use these to initialize the channel
                        % struct we'll later use to initialize the
                        % preprocessing gui.
                        channel = struct( ...
                            'names', {info.channel_names});
                    else
                        % If not, initialize an empty channel struct.
                        channel = struct( ...
                            'names', {{}});
                    end

                    % If the RGBW channel indices are specified in the
                    % prefs struct...
                    if ~isempty(prefs.RGBW)
                        % Add these to the channel struct.
                        channel.idx = prefs.RGBW;
                    end

                    % If we have a DIC channel...
                    if prefs.DIC ~= 0
                        % Check whether we have just one DIC channel.
                        if isscalar(prefs.DIC)
                            % If so, add it to the channel struct's idx
                            % field.
                            channel.idx = [channel.idx; prefs.DIC];
                        else
                            % If we have multiple DIC channels, go with
                            % the first one encountered in the file. This
                            % is an assumption based on Eric's claim that
                            % the two DIC channels are always the same. We
                            % could come up with a better solution here,
                            % but since this is a hotfix and the agnostic
                            % volume reader update is about to render this
                            % function obsolete, this is the quickest
                            % solution.
                            channel.idx = [channel.idx; prefs.DIC(1)];
                        end
                    end

                    % Do the same thing that we did with the DIC channel
                    % with the GFP channel.
                    if prefs.GFP ~= 0
                        if isscalar(prefs.GFP)
                            channel.idx = [channel.idx; prefs.GFP];
                        else
                            channel.idx = [channel.idx; prefs.GFP(1)];
                        end
                    end

                    % Check whether the prefs struct specifies gammas...
                    if isempty(prefs.gamma)
                        % If not, set channel gammas equal to a 1xnc array
                        % of ones where nc is the number of channels.
                        channel.gammas = ones(size(channel.idx));
                    elseif isscalar(prefs.gamma)
                        % If a single gamma value is specified, set channel
                        % gammas equal to a 1xnc array whose every element
                        % is equal to this value.
                        channel.gammas = ones(size(channel.idx)) * prefs.gamma;
                    else
                        % If multiple gamma values are specified, set these
                        % as the channel gammas.
                        channel.gammas = prefs.gamma;
                    end
    
                    % Get the size of the data array.
                    vol_size = size(app.proc_image, 'data');
    
                    % Get the sizes of the x, y, z, and c dimensions.
                    nx = vol_size(2);
                    ny = vol_size(1);
                    nz = vol_size(3);
                    nc = vol_size(4);
                    app.localConfigureProcessingChannelItems(nc);
    
                    % Get the maximum pixel value in the image, which we
                    % later use to define the limit of the GUI components
                    % that facilitate thresholding. Note that calling
                    % intmax on the data array's data type would be faster
                    % as it avoids loading the entire variable, but it also
                    % distorts the histograms because most image volumes
                    % we encounter are not fully saturated. That said,
                    % calling max() on the data array is both slower and,
                    % depending on the matlab version, can trigger the
                    % entire data variable to be loaded in.
                    % max_val = double(intmax(class(app.proc_image.data(1, 1, 1, 1))));
                    Program.GUI.dialogues.step('Setting threshold...'); 
                    max_val = double(max(app.proc_image.data, [], ...
                        'all'));
    
                    % Set the volume dropdown value to indicate that the
                    % user is working with an image.
                    app.VolumeDropDown.Value = 'Colormap';

                    % Update the data flag to indicate that we've loaded
                    % an image volume that could be saved to an NWB file
                    % down the road.
                    app.data_flags.('NeuroPAL_Volume') = 1;

                    % Prompt the preprocessing gui to assume its image
                    % configuration. This is obsolete and should be
                    % commented out.
                    Program.Routines.GUI.toggle_colormap();

                    % Resolve the last task added to the progress dialogue.
                    Program.GUI.dialogues.resolve();  
    
                case "video"
                    % If we're dealing with a video, add a task to the
                    % progress dialog indicating that we're now loading
                    % the video configuration.
                    Program.GUI.dialogues.add_task('Loading video configuration...');  
                    
                    % Set the video_path property equal to the given path.
                    app.video_path = path;

                    % Aadd the video option to the volume selection dropdown.
                    if ~any(ismember(app.VolumeDropDown.Items, 'Video'))
                        current_items = app.VolumeDropDown.Items;
                        current_items(end+1) = {'Video'};
                        app.VolumeDropDown.Items = current_items;
                    end

                    % Call the appropriate video load function based on
                    % the file type that we are dealing with. Note that
                    % this does not load in the entire data array, but
                    % rather loads in metadata that is used to populate
                    % video-related properties. For details, see the
                    % video_info property.
                    Program.GUI.dialogues.step('Creating reader...'); 
                    if strcmp(ext, '.h5')
                        app.load_h5(path);
                        channel_names = {};
                    elseif strcmp(ext, '.nwb')
                        app.load_nwb(path);
                        channel_names = {};
                    elseif strcmp(ext, '.nd2')
                        app.load_nd2(path);
                        channel_names = DataHandling.Helpers.nd2.get_channel_names(path);
                    end
                    
                    % Get the size of the x, y, z, and c dimensions.
                    nx = app.video_info.nx;
                    ny = app.video_info.ny;
                    nz = app.video_info.nz;
                    nc = app.video_info.nc;

                    % If we have names for each channel...
                    if ~isempty(channel_names)
                        % Parse these channel names associate them with
                        % colors.
                        channels = Program.Handlers.channels.parse_info( ...
                            channel_names);

                        % Check for duplicate fluorophores.
                        [~, has_duplicate, duplicate_indices] = Program.Validation.check_for_duplicate_fluorophores(channels);
                        if has_duplicate && ~isempty(duplicate_indices)
                            % If we have duplicates, update the channel GUI
                            % appropriately.
                            Program.Handlers.channels.add_reference(channel_names{duplicate_indices})
                        end
                    else
                        % If we don't have names for each channel,
                        % initialize them as "Fluo #n" where n is the
                        % channel index.
                        channel_names = arrayfun(@(x) ...
                            sprintf('Fluo #%d', x), 1:nc, ...
                            'UniformOutput', false);
                    end

                    % Videos have no info struct, so we create one. Since
                    % the video tracking tab currently makes no use of
                    % channels beyond RGB, we don't need to worry about
                    % these values being correct. This is handles more
                    % mindfully in the agnostic volume reader update.
                    info = struct( ...
                        'White', {0}, ...
                        'DIC', {0}, ...
                        'GFP', {0});

                    % Same goes for the channel struct.
                    channel = struct( ...
                        'names', {channel_names}, ...
                        'idx', {1:length(channel_names)}, ...
                        'gammas', {ones(1, length(channel_names))});
    
                    % Determine the maximum pixel value without reading a full
                    % frame. Full video frames are too expensive to load during
                    % processing-tab setup.
                    Program.GUI.dialogues.step('Setting threshold...'); 
                    max_val = Program.Helpers.video_display_max(app);

                    % Initialize video-related video components. This is
                    % largely obsolete at this point and can be commented
                    % out -- see the preprocessing_gui class for details.
                    app.ProcTStartEditField.Value = 1;
                    app.ProcTStopEditField.Value = app.video_info.nt;
                    app.StartFrameEditField.Value = 1;
                    app.EndFrameEditField.Value = app.video_info.nt;
                    app.proc_tSlider.Limits = [1 app.video_info.nt];
                    app.proc_tSlider.Value = 1;

                    app.ProcAxGrid.RowHeight{end+1} = 'fit';
                    app.PlaceholderProcTimeline.Parent = app.ProcAxGrid;
                    app.PlaceholderProcTimeline.Layout.Row = max(size(app.ProcAxGrid.RowHeight));
                    app.PlaceholderProcTimeline.Layout.Column = [1 max(size(app.ProcAxGrid.ColumnWidth))];
                    
                    app.VolumeDropDown.Value = 'Video';
                    app.data_flags.('Video_Volume') = 1;

                    set(app.PlaceholderProcTimeline, 'Visible', 'on');
                    Program.Routines.GUI.toggle_video();
                    Program.GUI.dialogues.resolve();  
            end
    
            % Construct a persistent preprocessing_gui class instance.
            Program.GUI.dialogues.set_value(1/5);
            Program.GUI.dialogues.step('Loading gui config...');
            preprocessing_gui = Program.GUI.preprocessing_gui(mode);
            app.create_backup();
    
            
            % Use the maximum pixel intensity to set the limit of
            % thresholding-related GUI components.
            Program.GUI.dialogues.step('Setting bounds...');  
            Program.GUI.dialogues.set_value(2/5);  
            Program.GUIHandling.set_thresholds(app, max_val);
    
            % Use the channel struct to initialize our channel-related GUI
            % components.
            Program.GUI.dialogues.step('Mapping channels...');  
            Program.GUI.dialogues.set_value(3/5);  
            Program.Handlers.channels.populate(channel)
            if strcmp(mode, "colormap")
                Program.Handlers.channels.set_references(info);
            end
    
            % Initialize the nameMap and shortMap properties, which are
            % container maps that assign names to each channel. Note that
            % this is largely obsolete (in case the fact that these are
            % still container maps rather than dictionaries didn't give
            % that fact away) and can be removed once we have time to
            % ensure that doing so doesn't break anything.
            app.nameMap = containers.Map( ...
                {app.ProcRDropDown.Value, app.ProcGDropDown.Value, app.ProcBDropDown.Value, ...
                 app.ProcWDropDown.Value, app.ProcDICDropDown.Value, app.ProcGFPDropDown.Value}, ...
                {'Red', 'Green', 'Blue', 'White', 'DIC', 'GFP'} ...
            );
    
            app.shortMap = containers.Map( ...
                {app.ProcRDropDown.Value, app.ProcGDropDown.Value, app.ProcBDropDown.Value, ...
                 app.ProcWDropDown.Value, app.ProcDICDropDown.Value, app.ProcGFPDropDown.Value}, ...
                {'r', 'g', 'b', 'k', 'k', 'y'} ...
            );
    
            % Set the data aspect ratio of the xy Axes to [1 1 1].
            Program.GUI.dialogues.step('Initializing settings...');  
            Program.GUI.dialogues.set_value(4/5);  
            daspect(app.proc_xyAxes, [1 1 1]);

            % If we have less than 4 channels, set the grid layout
            % containing color channel histograms to only use one row, as
            % we have three columns per row.
            if nc < 4
                app.ProcHistogramGrid.RowHeight = {'1x'};
            end
           
            if nx ~= 1
                app.proc_xSlider.Limits = [1 nx];
            else
                error("Loaded volume has invalid dimensions:\n" + ...
                    "nx = %.f\n" + ...
                    "ny = %.f\n" + ...
                    "nz = %.f\n" + ...
                    "nc = %.f\n", ...
                    nx, ny, nz, nc)
            end

            if ny ~= 1
                app.proc_ySlider.Limits = [1 ny];
            else
                error("Loaded volume has invalid dimensions:\n" + ...
                    "nx = %.f\n" + ...
                    "ny = %.f\n" + ...
                    "nz = %.f\n" + ...
                    "nc = %.f\n", ...
                    nx, ny, nz, nc)
            end

            if nz ~= 1
                app.proc_zSlider.Limits = [1 nz];
                app.proc_hor_zSlider.Limits = app.proc_zSlider.Limits;
                app.proc_vert_zSlider.Limits = app.proc_zSlider.Limits;
            else
                error("Loaded volume has invalid dimensions:\n" + ...
                    "nx = %.f\n" + ...
                    "ny = %.f\n" + ...
                    "nz = %.f\n" + ...
                    "nc = %.f\n", ...
                    nx, ny, nz, nc)
            end
    
            app.proc_xSlider.Value = round(app.proc_xSlider.Limits(2)/2);
            app.proc_ySlider.Value = round(app.proc_ySlider.Limits(2)/2);
            app.proc_zSlider.Value = round(app.proc_zSlider.Limits(2)/2);
            app.proc_hor_zSlider.Value = app.proc_zSlider.Value;
            app.proc_vert_zSlider.Value = app.proc_zSlider.Value;
            app.proc_zEditField.Value = round(app.proc_zSlider.Value);
            app.proc_tEditField.Value = round(app.proc_tSlider.Value);

            app.ProcZSlicesEditField.Value = app.proc_zSlider.Value;
            app.ProcXYFactorEditField.Enable = 'on';
            app.ProcZSlicesEditField.Enable = 'on';

            set(app.proc_xEditField, 'Enable', 'off');
            set(app.proc_yEditField, 'Enable', 'off');
    
            if isempty(channel.gammas)
                app.tl_GammaEditField.Value = 1;
                app.tm_GammaEditField.Value = 1;
                app.tr_GammaEditField.Value = 1;
                app.bl_GammaEditField.Value = 1;
                app.bm_GammaEditField.Value = 1;
                app.br_GammaEditField.Value = 1;
            else
                for n=1:size(channel.gammas, 2)
                    switch n
                        case 1
                            app.tl_GammaEditField.Value = channel.gammas(1);
                        case 2
                            app.tm_GammaEditField.Value = channel.gammas(2);
                        case 3
                            app.tr_GammaEditField.Value = channel.gammas(3);
                        case 4
                            app.bl_GammaEditField.Value = channel.gammas(4);
                        case 5
                            app.bm_GammaEditField.Value = channel.gammas(5);
                        case 6
                            app.br_GammaEditField.Value = channel.gammas(6);
                    end
                end
            end
    
            % Draw the image.
            Program.GUI.dialogues.set_value(5/5);  
            Program.GUI.dialogues.add_task('Rendering slice...');  
            app.drawProcImage();
            Program.GUI.dialogues.resolve();  

            % Set the preprocessing tab to be visible.
            app.ImageProcessingTab.Tag = 'rendered';
            set(app.ProcessingButton, 'Visible', 'off');
            set(app.ProcessingGridLayout, 'Visible', 'on');

            app.TabGroup.SelectedTab = app.ImageProcessingTab;
            Program.GUI.dialogues.resolve();  

            % Tell the user that we recommend starting by cropping their
            % image and ask them if that's what they'd like to do. If so,
            % trigger the cropping routine.
            check = uiconfirm(app.CELL_ID, "We recommend " + ...
                "starting by cropping your image to ensure " + ...
                "that there is no superfluous space taking up " + ...
                "memory. Do you want to do so now?", ...
                "NeuroPAL_ID", ...
                "Options", ["Yes", "No, skip cropping."]);

            switch check
                case "Yes"
                    app.ProcCropImageButtonPushed([]);
                    Program.GUIHandling.gui_lock(app, 'unlock', 'processing_tab');
                case "No, skip cropping."
                    Program.GUIHandling.gui_lock(app, 'unlock', 'processing_tab');
            end

            % Unlock the preprocessing GUI.
            Program.GUIHandling.gui_lock(app, 'unlock', 'processing_tab');
        end
        
        function frame = retrieve_frame(app, t)
            %RETRIEVE_FRAME This function loads in a given frame from the
            % currently loaded image.
            %
            %   Inputs:
            %   - app: Running app instance.
            %   - t: Integer representing the frame to be loaded.
            %
            %   Outputs:
            %   - frame: Multi-dimensional array representing the frame
            %       as loaded from the video file.
            %
            %   Note that this function is rendered obsolete by the
            %   agnostic volume reader update.

            % If no frame was passed, retrieve the first frame.
            if ~exist('t', 'var')
                t = 1;
            end

            % Round the frame to ensure we don't try to load an invalid
            % index.
            t = round(t);

            video_file = char(app.video_info.file);
            if ~isempty(app.video_frame_cache) && ...
                    isstruct(app.video_frame_cache_key) && ...
                    isfield(app.video_frame_cache_key, 'file') && ...
                    isfield(app.video_frame_cache_key, 't')
                try
                    if strcmp(char(app.video_frame_cache_key.file), video_file) && ...
                            isequal(app.video_frame_cache_key.t, t)
                        frame = app.video_frame_cache;
                        return
                    end
                catch
                end
            end

            read_timer = tic;
            video_file_lower = lower(video_file);

            % Check which file type we're dealing with. Different file
            % types required diffferent chunk loading approaches.
            if endsWith(video_file_lower, '.h5')
                % Read one logical timepoint. Do not use the HDF5
                % ChunkSize as the read count: plane-chunked videos use a
                % small chunk to keep conversion/rendering memory bounded.
                read_size = [app.video_info.ny, ...
                    app.video_info.nx, ...
                    app.video_info.nz, ...
                    app.video_info.nc, ...
                    1];

                % Call h5read.
                frame = h5read(app.video_info.file, '/data', ...
                    [1 1 1 1 t], ...                                    % Where to start reading.
                    read_size);

            elseif endsWith(video_file_lower, '.nwb')
                % If this is an NWB file, check to make sure that we're
                % not accidentally zero-indexing.
                if t == 0
                    t = 1;
                end

                % Use the frame to index the nwb_mod property, which points
                % to the video's acquisition module.
                frame = app.nwb_mod(:, :, :, :, t);

                % Convert to uin8 and rearrange the array. This is a
                % temporary hardcoded fix for Daniel's files and is handled
                % more mindfully in the agnostic volume reader update. We
                % already have a more dynamic solution in the nwb helper
                % class, which we could also leverage here.
                frame = uint8(squeeze(permute(frame, [3 4 2 1])));

            elseif endsWith(video_file_lower, '.nd2')
                % Reuse the reader created during video load. Reopening
                % Bio-Formats on every redraw can retain large Java-side
                % caches and makes slider navigation much heavier.
                needs_reader = isempty(app.nd2_file);
                if ~needs_reader
                    try
                        needs_reader = ~strcmp(char(app.nd2_file.filename), char(app.video_info.file));
                    catch
                        needs_reader = true;
                    end
                end
                if needs_reader
                    app.nd2_file = BioformatsImage(app.video_info.file);
                end
                file = app.nd2_file;

                % Initialize a zero array of the size and class that we
                % expect our target frame to be.
                all_data = zeros( ...
                    [app.video_info.ny, ...
                    app.video_info.nx, ...
                    app.video_info.nz, ...
                    app.video_info.nc, 1], ...
                    class(getPlane(file, 1, 1, 1)));

                % Get the channel names.
                channelNames = file.channelNames;

                % Iterate over each channel.
                for c = 1:app.video_info.nc
                    % Get this channel's name.
                    channel = channelNames{c};

                    % Iterate over each slice.
                    for z = 1:app.video_info.nz
                        % Populate our previously initialized zero array
                        % with the appropriate plane from the nd2 file by
                        % passing the file, the slice, the channel name,
                        % and the frame to the bioformats toolbox's 
                        % getPlane() function. Note that bioformats'
                        % lazy loading of ND2 files is based on planes
                        % and indices, so you're effectively left with
                        % no option but to go plane by plane.
                        all_data(:, :, z, c, 1) = getPlane( ...
                            file, z, channel, t);
                    end
                end

                frame = squeeze(all_data);

            elseif endsWith(video_file_lower, '.tif') || endsWith(video_file_lower, '.tiff')
                % If this is a tif file, we essentially do the same thing
                % because tif reading is also handled by bioformats.
                needs_reader = isempty(app.tif_file);
                if ~needs_reader
                    try
                        needs_reader = ~strcmp(char(app.tif_file.filename), char(app.video_info.file));
                    catch
                        needs_reader = true;
                    end
                end
                if needs_reader
                    app.tif_file = BioformatsImage(app.video_info.file);
                end
                file = app.tif_file;
                all_data = zeros( ...
                    [app.video_info.ny, ...
                    app.video_info.nx, ...
                    app.video_info.nz, ...
                    app.video_info.nc, 1], ...
                    sprintf('uint%.f', file.bitDepth));

                for c = 1:app.video_info.nc
                    for z = 1:app.video_info.nz
                        all_data(:, :, z, c, 1) = getPlane(file, z, c, t);
                    end
                end

                frame = squeeze(all_data);
            else
                error('Unsupported loaded video file type: %s', app.video_info.file);
            end

            frame_info = whos('frame');
            if frame_info.bytes <= 64 * 1024 * 1024
                app.video_frame_cache = frame;
                app.video_frame_cache_key = struct('file', video_file, 't', t);
                cache_state = 'cached';
            else
                app.video_frame_cache = [];
                app.video_frame_cache_key = struct('file', '', 't', NaN);
                cache_state = 'not cached';
            end
            Program.Helpers.debug_event('OpenVideo', ...
                'Loaded video frame t=%d in %.3fs: size=%s class=%s bytes=%d %s', ...
                t, toc(read_timer), mat2str(size(frame)), class(frame), ...
                frame_info.bytes, cache_state);
        end

        function [image_data, image_view] = DrawProcData(app, image_data)
            %DRAWPROCDATA This function takes a given data array and
            % processed it based on user-defined gamma values, histogram
            % limits, etc. This is largely obsolete.
            %
            %   Inputs:
            %   - app: Running app instance.
            %   - image_data: The data array to be processed. For example,
            %       a slice or a frame of an image or a video.
            %
            %   Outputs:
            %   - image_data: The original image data array.
            %   - image_view: The processed data array.

            % Get the current gamma values.
            gammas = [ ...
                app.tl_GammaEditField.Value, ...
                app.tm_GammaEditField.Value, ...
                app.tr_GammaEditField.Value, ...
                app.bl_GammaEditField.Value, ...
                app.bm_GammaEditField.Value, ...
                app.br_GammaEditField.Value];

            % Determine the color channel indices.
            red = str2double(app.ProcRDropDown.Value);
            green = str2double(app.ProcGDropDown.Value);
            blue = str2double(app.ProcBDropDown.Value);
            white = str2double(app.ProcWDropDown.Value);
            dic = str2double(app.ProcDICDropDown.Value);
            gfp = str2double(app.ProcGFPDropDown.Value);

            % Determine the channel=color assignments for displaying.
            color_indices = [red, green, blue];

            try
                % Draw the 3 color channels.
                image_view = image_data(:,:,:,color_indices);
            catch
                image_view = image_data(:, :, :,1:3);
            end

            % Remove unchecked color channels.
            if ~app.ProcRCheckBox.Value % Red
                image_view(:,:,:,1) = 0;
            end
            if ~app.ProcGCheckBox.Value % Green
                image_view(:,:,:,2) = 0;
            end
            if ~app.ProcBCheckBox.Value % Blue
                image_view(:,:,:,3) = 0;
            end

            % Add in the white channel.
            if app.ProcWCheckBox.Value % White

                % Compute the white channel.
                wchannel = image_data(:,:,:,white);

                % Adjust the gamma.
                if length(gammas) >= white && gammas(white) ~= 1
                    wchannel = imadjustn(wchannel,[],[], gammas(white));
                end

                % Add the white channel.
                image_view = image_view + repmat(wchannel, [1,1,1,3]);
            end

            % Add in the DIC channel.
            if app.ProcDICCheckBox.Value % DIC

                % Compute the DIC channel.
                dic_channel = image_data(:,:,:,dic);

                % Adjust the gamma.
                if length(gammas) >= dic && gammas(dic) ~= 1
                    dic_channel = imadjustn(dic_channel,[],[], gammas(dic));
                end

                % Add the DIC channel.
                image_view = image_view + repmat(dic_channel, [1,1,1,3]);
            end

            % Add in the GFP channel.
            if app.ProcGFPCheckBox.Value % GFP

                % Compute the GFP channel.
                gfp_channel = image_data(:,:,:,gfp);

                % Adjust the gamma.
                if length(gammas) >= gfp && gammas(gfp) ~= 1
                    gfp_channel = imadjustn(gfp_channel,[],[], gammas(gfp));
                end

                % Add the GFP channel.
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                gfp_channel(:,:,:,~gfp_color) = 0;
                image_view = image_view + gfp_channel;
            end

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            image_view = uint16(double(intmax('uint16')) * ...
                double(image_view)/double(max(image_view(:))));
            for c = 1:size(image_view, 4)
                if gammas(c) ~= 1
                    image_view(:,:,:,c) = ...
                        imadjustn(squeeze(image_view(:,:,:,c)),[],[], ...
                        gammas(c));
                end
            end

            image_view = double(image_view)/double(max(image_view(:)));
        end


        function proc_apply_processing(app, specific_action)
            %PROC_APPLY_PROCESSING This function triggers the routines
            % that apply preprocessing actions to a file.
            %
            %   Inputs:
            %   - app: Running app instance.
            %   - specific_action: Optional argument specifying that we
            %       are applying a specific method rather than all
            %       preprocessing actions that have been been added to
            %       the stack by the user.
            
            % Add a task to the progress dialogue indicating that the file
            % is being updated.
            Program.GUI.dialogues.add_task("Updating file...");

            % Check whether specific actions were requested.
            if ~exist('specific_action', 'var')
                % If not, we grab the entire stack of preprocessing 
                % actions taken and then clear.
                preprocessing_actions = fieldnames(app.flags);    
                app.flags = struct();
            else
                % If specific actions were requested, we package these
                % into a cell array.
                preprocessing_actions = {specific_action};
            end

            % Check whether we're working with an image or a video.%%%%
            switch app.VolumeDropDown.Value
                case 'Colormap'
                    % If we're working with an image, call the appropriate
                    % function within the ChunkyMethods class.
                    Methods.ChunkyMethods.apply_colormap(app, ...
                        preprocessing_actions);

                    % Use the image file's path to initialize an event
                    % struct.
                    fEvent.file = app.proc_image.Properties.Source;

                    % Call the OpenFile function.
                    app.OpenFile(fEvent);  

                case 'Video'
                    % If we're working with a video, call the appropriate
                    % function within the chunky methods class. Note that
                    % because the tracking tab works off of the video_info
                    % struct property and we update this within the
                    % apply_video function, we don't need to explicitly
                    % pass the processed video back to the open_video
                    % function. This isn't a great way of doing things
                    % and is handled more mindfully in the agnostic volume
                    % reader update.
                    Methods.ChunkyMethods.apply_video(app, ...
                        preprocessing_actions);
            end
            
            % Resolve the last task added to the progress dialog.
            Program.GUI.dialogues.resolve();

            % Let the user know that the file has been successfully
            % updated.
            uiconfirm(app.CELL_ID, "Successfully updated file.", ...
                "NeuroPAL_ID", "Options", ["OK"]);
        end
        
        function proc_apply_settings(app)
            % Propagates updates to colormap preferences, which must be
            % done explicitly if changed in processing tab since it
            % operates on lazy load.

            % Grab channel indices.
            channels = [app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value];
            
            % Grab channel gammas.
            gammas = [app.tl_GammaEditField.Value, app.tm_GammaEditField.Value, app.tr_GammaEditField.Value, app.bl_GammaEditField.Value, app.bm_GammaEditField.Value, app.br_GammaEditField.Value];

            switch lower(app.VolumeDropDown.Value)
                case 'colormap'
                    % Set file writable & update settings.
                    app.proc_image.Properties.Writable = true;
                    temp_prefs = app.proc_image.prefs;
                    temp_prefs.rgbw = channels;
                    temp_prefs.DIC = app.ProcDICDropDown.Value;
                    temp_prefs.GFP = app.ProcGFPDropDown.Value;
                    temp_prefs.gamma = gammas;
                    app.proc_image.prefs = temp_prefs;
                    app.proc_image.Properties.Writable = false;
                case 'video'
                    % tbd
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Program.Routines.initialize()
        end

        % Callback function: IdButton, OpenButton, OpenMenu
        function OpenFile(app, event)
            if app.TabGroup.SelectedTab == app.VideoTrackingTab
                Program.Helpers.debug_event('OpenFile', ...
                    'Routing File > Open to video loader because Video Tracking tab is selected.');
                app.OpenVideo(event);
                return;
            end
            if app.TabGroup.SelectedTab == app.ImageProcessingTab
                Program.Helpers.debug_event('OpenFile', ...
                    'Routing File > Open to processing image loader because Image Processing tab is selected.');
                app.OpenProcessingImage(event);
                return;
            end

            % Are we already opening a file?
            if app.is_opening_file
                return;
            end
            app.is_opening_file = true;
            
            % Unselect any neurons.
            UnselectNeuron(app);
            if ~isempty(app.id_file) && exist(app.id_file, 'file')
                app.SaveIDToFile();
            end

            % Setup the file chooser's path.
            %path = '../';
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            if isfield(event, 'file')
                [path, name] = fileparts(event.file);
                filename = event.file;
    
                % Load the file.
                Program.GUI.dialogues.add_task('Reloading processed file...');
    
                if app.DisplayNeuronActivityMenu.Checked
                    app.DisplayNeuronActivityMenu.Checked = ~app.DisplayNeuronActivityMenu.Checked;
                    app.TabGroup4.SelectedTab = app.MaximumIntensityProjectionTab;
                end

                app.trigger_reload = 0;
            else
                % Ask the user which file they want.
                file_info = [path ';*.mat;*.czi;*.nd2;*.tif;*.tiff;*.h5;*.nwb'];
                app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
                [name, path, ~] = uigetfile(file_info, 'Select Worm Image');
                app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
                if name == 0
                    app.is_opening_file = false;
                    return; % user cancelled
                end
    
                % Load the file.
                Program.GUI.dialogues.add_task('Loading file...');
    
                if app.DisplayNeuronActivityMenu.Checked
                    app.DisplayNeuronActivityMenu.Checked = ~app.DisplayNeuronActivityMenu.Checked;
                    app.TabGroup4.SelectedTab = app.MaximumIntensityProjectionTab;
                end
    
                filename = [path, name];
                Program.GUI.dialogues.resolve();

                % Save the path in our preferences.
                GUI_prefs.image_dir = path;
                GUI_prefs.save();

                proc_code = app.proc_check("image", filename);
                if proc_code == 1
                    app.is_opening_file = false;
                    return
                end
            end

            app.logEvent('Main',sprintf('Loading file from %s...', filename), 1)

            % Save the path in our preferences.
            GUI_prefs.image_dir = path;
            GUI_prefs.save();
            try                
                [data, info, prefs, worm, mp, neurons, np_file, id_file] = ...
                    DataHandling.NeuroPALImage.open(filename);
            catch ME
                Program.Helpers.debug_event('OpenFile', ...
                    'Cannot read "%s": %s', filename, getReport(ME, 'extended', 'hyperlinks', 'off'));
                if any(strcmp(ME.identifier, ...
                        {'DataHandling:NeuroPALImage:ND2VideoInImageLoader', ...
                         'DataHandling:ND2:VideoNotImage'}))
                    uialert(app.CELL_ID, ...
                        {['"' filename '" is an ND2 time series, not a single NeuroPAL image volume.'], ...
                         ME.message, ...
                         'Use the Video Tracking loader instead.'}, ...
                        'Video File Selected in Image Loader', 'Icon', 'warning');
                else
                    msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                    uialert(app.CELL_ID, ...
                        {['Cannot read "' filename '"!'], ['Error:' msg]}, ...
                        'Image File Failure', 'Icon', 'error');
                end
                app.is_opening_file = false;
                return;
            end

            % Check the worm info.
            if ~any(strcmp(worm.body, app.BodyDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm body region "' worm.body '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                app.is_opening_file = false;
                return;
            end
            if ~any(strcmp(worm.age, app.AgeDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm age region "' worm.age '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                app.is_opening_file = false;
                return;
            end
            if ~any(strcmp(worm.sex, app.SexDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm sex region "' worm.sex '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                app.is_opening_file = false;
                return;
            end

            % Fix the prefs for z-axis orientation.
            if ~isfield(prefs, 'z_center')
                prefs.z_center = ceil(size(data,3) / 2);
                prefs.is_Z_LR = true;
                prefs.is_Z_flip = true;
            end

            % Setup the file.
            app.image_file = np_file;
            app.id_file = [];
            app.image_prefs = prefs;

            % Setup the image.
            app.image_name = name; %strrep(name, '_', '\_');
            app.image_data = data;

            % Z-score the image.
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Load and update the gamma.
            gamma_size = length(app.gamma_RGBW_DIC_GFP_index);
            if isscalar(prefs.gamma)
                app.image_gamma = ones(gamma_size, 1);
                app.image_gamma(1:3) = prefs.gamma;
                app.image_prefs.gamma = app.image_gamma;
            elseif length(prefs.gamma) < gamma_size
                app.image_gamma = ones(gamma_size, 1);
                app.image_gamma(1:length(prefs.gamma)) = prefs.gamma;
                app.image_prefs.gamma = app.image_gamma;
            else
                app.image_gamma = prefs.gamma;
            end

            % Load the image scale and info.
            app.image_um_scale = info.scale;
            app.image_info = info;

            % Setup the color channels.
            RGBW = prefs.RGBW;
            nc = size(app.image_data, 4);
            if isempty(RGBW)
                RGBW = nan(1, 4);
            elseif numel(RGBW) < 4
                RGBW(end+1:4) = nan;
            end
            RGBW = round(RGBW);
            RGBW_invalid = isnan(RGBW) | RGBW < 1 | RGBW > nc;
            RGBW(RGBW_invalid) = 1; % default invalid colors to channel 1
            RGBW_nan = isnan(RGBW);
            RGBW(RGBW_nan) = 1; % default unassigned colors to channel 1
            channels_str = arrayfun(@num2str, 1:size(app.image_data, 4), 'UniformOutput', false);
            % Red.
            app.RDropDown.Items = channels_str;
            app.RDropDown.Value = app.RDropDown.Items{RGBW(1)};
            app.RCheckBox.Value = true;
            % Green.
            app.GDropDown.Items = channels_str;
            app.GDropDown.Value = app.GDropDown.Items{RGBW(2)};
            app.GCheckBox.Value = true;
            % Blue.
            app.BDropDown.Items = channels_str;
            app.BDropDown.Value = app.BDropDown.Items{RGBW(3)};
            app.BCheckBox.Value = true;
            % White.
            app.WDropDown.Items = channels_str;
            if size(app.image_data, 4)>3
                app.WDropDown.Value = app.WDropDown.Items{RGBW(4)};
            end
            app.WCheckBox.Value = false;
            % DIC.
            app.DICDropDown.Items = channels_str;
            if ~isnan(prefs.DIC)
                try
                    app.DICDropDown.Value = app.DICDropDown.Items{prefs.DIC};
                catch
                    app.DICDropDown.Value = '5';
                end
            end
            app.DICCheckBox.Value = false;
            % GFP.
            app.GFPDropDown.Items = channels_str;
            if ~isnan(prefs.GFP)
                try
                    app.GFPDropDown.Value = app.GFPDropDown.Items{prefs.GFP};
                catch
                    app.GFPDropDown.Value = '6';
                end
            end
            app.GFPCheckBox.Value = false;

            % Setup the worm info.
            app.worm = worm;
            app.BodyDropDown.Value = worm.body;
            app.AgeDropDown.Value = worm.age;
            app.SexDropDown.Value = worm.sex;
            app.StrainEditField.Value = worm.strain;
            app.SubjectNotesTextArea.Value = worm.notes;

            % Which part of the worm is this?
            %if isempty(worm.body)
            %    worm.body = uiconfirm(app.CELL_ID, ...
            %        'Which part of the worm is in the image?', ...
            %        'Worm Body Part', 'Options', {'Head', 'Tail'}, ...
            %        'DefaultOption', 1, 'Icon', 'question');
            %   	%'Worm Body Part', 'Head', 'Midbody', 'Tail', 'Head');
            %    save(app.image_file, 'prefs', '-append');
            %end

            % Enable the image GUI.
            Program.GUIHandling.gui_lock(app, 'enable', 'identification_tab');
            Program.GUIHandling.gui_lock(app, 'disable', 'neuron_gui');

            % Determine the image scale.
            scale = ones(1,3);
            if ~isempty(info.scale)
                scale = info.scale;
            end

            % Did we detect neurons?
            app.id_file = id_file;
            app.mp_params = mp;
            read_nwb_neurons = 0;
            if ~isempty(neurons)
                app.image_neurons = neurons;
                Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');
            elseif contains(filename,'.nwb')
                
                nwb_data = nwbRead(filename);

                if any(ismember(nwb_data.processing.keys, 'NeuroPAL')) & (any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'NeuroPALSegmentation')) | any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'ImageSegmentation')) | any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'VolumeSegmentation')) | any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'NeuroPALNeurons')))
                    read_nwb_neurons = 1;
                end             

                app.image_neurons = Neurons.Image([], worm.body, 'scale', app.image_um_scale');
            else
                app.image_neurons = Neurons.Image([], worm.body, 'scale', app.image_um_scale');
            end

            % Restrict the slider to the z stack.
            num_z_slices = size(app.image_data, 3);
            if num_z_slices <= 1
                uialert(app.CELL_ID, 'The image is not a volume!', ...
                    'Image Not a Volume', 'Icon', 'error');
                app.is_opening_file = false;
                return;
            end
            Program.Helpers.configure_main_zslider(app, num_z_slices);

            % Setup the z-axis orientation.
            app.ZCenterEditField.Value = round((prefs.z_center - 1) * info.scale(3), 1);
            if prefs.is_Z_LR
                app.ZAxisDropDown.Value = 'L/R';
                app.ZLeftLabel.Text = 'LEFT';
                app.ZRightLabel.Text = 'RIGHT';
            else
                app.ZAxisDropDown.Value = 'D/V';
                app.ZLeftLabel.Text = 'DORSAL';
                app.ZRightLabel.Text = 'VENTRAL';
            end

            % Setup the max projection.
            daspect(app.XY,[1 1 1]);
            daspect(app.MaxProjection,[1 1 1]);
            axis(app.MaxProjection, 'off');

            % Label the image.
            app.XY.Title.Interpreter = 'none';
            app.XY.Title.String = app.image_name;
            app.XY.TitleFontSizeMultiplier = 2;
            app.XY.TitleFontWeight = 'bold';
            Program.Helpers.configure_image_axes_ticks( ...
                app.XY, size(app.image_data), info.scale(1:2), ...
                'XLim', [0, size(app.image_data, 2)], ...
                'YLim', [0, size(app.image_data, 1)]);

            % Select no neurons.
            app.selected_neuron = [];

            % Draw everything.
            app.UnselectNeuron();
            app.UserNeuronIDsListBox.Items = {};
            app.UserNeuronIDsListBox.ItemsData = [];
            app.UserNeuronIDsListBox.Value = {};

            if ismember('is_matched', fieldnames(app.image_prefs))
                if app.image_prefs.is_matched == 1
                    app.image_data(:, :, :, app.image_info.RGBW(1:3)) = Methods.run_histmatch(app.image_data, app.image_info.RGBW);
                end
            end
            
            app.DrawImageData();
            app.UpdateSexNeuronLists();
            if ~isempty(app.image_neurons)
                app.UpdateNeuronLists();
                app.DrawAutoIDList();
            end

            if read_nwb_neurons == 1
                app.load_neurons_from_nwb(nwb_data);
                Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');
            end

            % Go to the middle.
            zEvent.Value = round(num_z_slices / 2);
            app.ZSliderValueChanging(zEvent);
            Program.GUI.dialogues.resolve();

            addlistener(app.CELL_ID, 'WindowMousePress', @(src, event) app.DragManager('down', event));
            addlistener(app.CELL_ID, 'WindowMouseRelease', @(src, event) app.DragManager('up', event));
            
            % Done.
            app.is_opening_file = false;
            app.data_flags.('NeuroPAL_Volume') = 1;

            set(app.VolumeDropDown, 'Enable', 'on');
            
            if ~any(ismember(app.VolumeDropDown.Items, 'Colormap'))
                app.VolumeDropDown.Items{end+1} = 'Colormap';
            end
            app.VolumeDropDown.Value = 'Colormap';

            set(app.IdGridLayout, 'Visible', 'on');
            set(app.ProcessingGridLayout, 'Visible', 'on');
            set(app.IdButton, 'Visible', 'off');
            set(app.ProcessingButton, 'Visible', 'off');
        end

        function OpenProcessingImage(app, event)
            if app.is_opening_file
                return;
            end
            app.is_opening_file = true;

            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            if isstruct(event) && isfield(event, 'file')
                [path, name] = fileparts(event.file);
                filename = event.file;
            else
                file_info = [path ';*.mat;*.czi;*.tif;*.tiff;*.h5;*.nwb'];
                app.CELL_ID.Visible = 'off';
                [name, path, ~] = uigetfile(file_info, 'Select Worm Image');
                app.CELL_ID.Visible = 'on';
                if name == 0
                    app.is_opening_file = false;
                    return;
                end
                filename = [path, name];
            end

            GUI_prefs.image_dir = path;
            GUI_prefs.save();

            try
                app.is_opening_file = false;
                Program.Routines.open(filename);
                if isempty(app.image_data) || isempty(app.image_file)
                    app.is_opening_file = false;
                    return
                end
                app.proc_load('image', app.image_file);
                app.TabGroup.SelectedTab = app.ImageProcessingTab;
            catch ME
                Program.Helpers.debug_event('OpenFile', ...
                    'Cannot load "%s" in Image Processing: %s', ...
                    filename, getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.CELL_ID, ...
                    {['Cannot load "' filename '" in Image Processing.'], ...
                     ['Error:' getReport(ME, 'extended', 'hyperlinks', 'off')]}, ...
                    'Image Processing Load Failure', 'Icon', 'error');
            end

            app.is_opening_file = false;
        end

        % Menu selected function: DownsampleImageMenu
        function DecimateButtonPushed(app, event)
            Program.GUI.Apps.downsample_window(app, app.image_data, app.image_view, app.image_gamma, app.image_neurons);        
        end

        % Menu selected function: ThresholdImageMenu
        function AdaptThreshButtonPushed(app, event)
            % Open a propt to ask the adaptive thresholding parameters
            % from user.
            prompt = {'Enter the method (adaptive/fixed/otsu):', 'Enter the parameter for the model:'};
            title = 'Thresholding Parameters';
            dims = [1 35];
            definput = {'adaptive', '0.9'};
            answer = inputdlg(prompt,title,dims,definput);

            % Run thresholding based on user input.
            app.image_data = Methods.Preprocess.threshold_frame(app.image_data, answer{1}, str2double(answer{2}));
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            
            % Update the view using the thresholded image.
            app.DrawImageData();
        end

        % Callback function: HistogramMatchingMenu, 
        % ...and 1 other component
        function HistMatchButtonPushed(app, event)
            progress_message = 'Matching Histograms';
            if app.TabGroup.SelectedTab ~= app.NeuroPALIDTab
                progress_message = 'Normalizing channel percentiles';
            end
            d = uiprogressdlg(app.CELL_ID,"Indeterminate","on", "Message", progress_message);
            % Histogram matching for color normalization. The function
            % has no parameters.
            if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                app.image_data(:, :, :, app.image_info.RGBW(1:3)) = Methods.run_histmatch(app.image_data, app.image_info.RGBW);
                app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
                
                % Update the view using the histogram matched image.
                app.DrawImageData();
    
                % Save to file.
                app.image_prefs.('is_matched') = 1;
                prefs = app.image_prefs;
                save(app.image_file, 'prefs', '-append');
            else
                if strcmp(app.VolumeDropDown.Value, 'Colormap')
                    prefs = app.proc_image.prefs;
                    already_normalized = isfield(prefs, 'is_percentile_normalized') || isfield(prefs, 'is_matched');
                    if already_normalized
                        check = uiconfirm(app.CELL_ID, "This file has already had percentile normalization or histogram matching applied to it. Are you sure you want to normalize again?", "Warning!", "Options", ["Yes", "No, cancel"]);

                        if strcmp(check, "No, cancel")
                            return
                        end
                    end
                end

                Program.Routines.Processing.save_prompt('histmatch');
                app.drawProcImage();
            end
            
            close(d)
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)

            % Snap the slider into place.
            z = round(event.Value);
            app.ZSlider.Value = z;

            % Draw the neurons in this z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end

        % Button pushed function: AutoDetectButton
        function AutoDetectButtonPushed(app, event)

            % Determine the number of neurons.
            body = app.BodyDropDown.Value;
            num_neurons = app.neuron_info.numNeurons(body);
            GUI_prefs = Program.GUIPreferences.instance();
            detection_backend = GUI_prefs.get_detection_backend();
            if isempty(num_neurons) && strcmp(detection_backend, 'mp')
                return;
            end

            % Do we already have neurons detected?
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                answer = uiconfirm(app.CELL_ID, ...
                    'Neurons have already been marked, would you like to overwrite them?', ...
                    'Neurons Already Marked', ...
                    'Options', {'Overwrite', 'Cancel'}, 'DefaultOption', 2, 'CancelOption', 2, ...
                    'Icon', 'warning');
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            backend_label = upper(detection_backend);
            app.logEvent('Main', sprintf('Running %s auto-detect...', backend_label), 1);
            progress_dlg = [];
            if strcmp(detection_backend, 'cellpose')
                progress_dlg = uiprogressdlg(app.CELL_ID, ...
                    'Title', 'Cellpose Detect', ...
                    'Message', 'Running Cellpose detection. This can take several minutes...', ...
                    'Indeterminate', 'on');
            end

            % Detect the neurons.
            try
                switch detection_backend
                    case 'mp'
                        [sp, app.mp_params] = Methods.AutoDetect.instance().detect(app.image_file, ...
                            app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                            num_neurons, 1.0, app.image_um_scale', app.worm, 2);
	                    case 'nn'
	                        [sp, app.mp_params] = Methods.NNDetect.detect(app.image_file, ...
	                            app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));
	                    case 'cellpose'
	                        selected_channels = app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW));
	                        [sp, app.mp_params] = Methods.CellposeDetect.detect(app.image_file, ...
	                            app.image_data(:,:,:,selected_channels), ...
	                            app.image_um_scale', ...
	                            'ColorReadoutData', app.image_data_zscored(:,:,:,selected_channels));
	                    otherwise
	                        error('visualize_light:UnknownDetectionBackend', ...
	                            'Unknown detection backend: %s', detection_backend);
                end
            catch ME
                if ~isempty(progress_dlg) && isvalid(progress_dlg)
                    close(progress_dlg);
                end
                rethrow(ME);
            end

            if ~isempty(progress_dlg) && isvalid(progress_dlg)
                close(progress_dlg);
            end

            % Do we have any neuron predictions?
            if isempty(sp)

                % Warn the user.
                uiconfirm(app.CELL_ID, ...
                    'Auto detect neurons failed to find any neurons!', ...
                    'Auto Detect Neurons Failed', ...
                    'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'error');

                % Enable the neuron GUI.
                if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                    Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');
                end
	                return;
	            end

	            % Unselect neurons after a replacement detection has succeeded.
	            UnselectNeuron(app);
	            app.UserNeuronIDsListBox.Items = {};
	            app.UserNeuronIDsListBox.ItemsData = [];
	            app.UserNeuronIDsListBox.Value = {};

	            % Add the detected neurons to the image.
	            app.image_neurons = Neurons.Image(sp, app.worm.body, 'scale', app.image_um_scale');

            % Remove duplicate detections.
            % Neurons within 2 microns of each other.
            Methods.Utils.removeNearbyNeurons(app.image_neurons, 2, 2);

            % Save the IDs.
            SaveIDToFile(app);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Enable the neuron GUI.
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');
            end

            % Draw the image.
            app.DrawImageData();

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Auto detect neurons completed successfully.', 'Auto Detect Neurons Completed', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Button pushed function: AutoIDAllButton
        function AutoIDAllButtonPushed(app, event)

            % Are there any neurons?
            if isempty(app.image_neurons) || app.image_neurons.num_neurons() < 1
                uiconfirm(app.CELL_ID, ...
                    'There are no neurons to auto ID!', 'No Neurons to ID', ...
                    'Options', {'Cancel'}, 'DefaultOption', 1, 'CancelOption', 1, ...
                    'Icon', 'warning');
                return;
            end

            % Can we handle this body part?
            %body = app.image_neurons.bodypart; DEPRECATED
            body = app.worm.body;
            switch lower(body)
                case 'head'
                    % all good
                case 'tail'
                    % all good
                otherwise
                    uiconfirm(app.CELL_ID, ...
                        {['Presently, there is no atlas to auto ID the "' body '".'], ...
                        'Please manually ID this region.'}, ...
                        'No Atlas for Auto ID', 'Options', {'Cancel'}, ...
                        'DefaultOption', 1, 'CancelOption', 1, 'Icon', 'error');
                    return;
            end

            % Are the neurons already ID'd?
            if ~isempty(app.image_neurons.get_probabilistic_probs())
                answer = uiconfirm(app.CELL_ID, ...
                    'The neurons have already been auto ID''d, would you like to re-ID them?', ...
                    'Neurons Already Auto ID''d', ...
                    'Options', {'Re-ID', 'Cancel'}, 'DefaultOption', 2, 'CancelOption', 2, ...
                    'Icon', 'warning');
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Unselect neurons.
            UnselectNeuron(app);

            % Auto ID the neurons.
            try
                Methods.AutoId.instance().id(app.image_file, ...
                    app.image_neurons, app.worm);
            catch E
                uiconfirm(app.CELL_ID, ...
                    {E.message}, ...
                    'Error', 'Options', {'Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 1, 'Icon', 'error');
                return;
            end

            % Update the auto ID'd neuron list.
            DrawAutoIDList(app);

            % Draw the image.
            app.DrawImageData();

            % Save the IDs.
            SaveIDToFile(app);

            % Update ID confidences
            UpdateNeuronIDConfidence(app);

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Auto ID neurons completed successfully.', 'Auto ID Neurons Completed', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Callback function
        function AutoNameNeurons(app, event)
            % Save the GUI preferences.
            GUI_prefs = Program.GUIPreferences.instance();
            GUI_prefs.is_auto_name = app.AutoNameCheckBox.Value;
            GUI_prefs.save();
        end

        % Callback function: NormalizeColorsMenu, ProcNormalizeColorsButton
        function ZScoringMenuSelected(app, event)
            if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                app.image_data = Methods.Preprocess.normalize_frame(app.image_data);
                app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
                app.DrawImageData();
            else
                Program.Routines.Processing.save_prompt('zscore');
                app.drawProcImage();
            end
        end

        % Menu selected function: RotateAllHorizontalMenu
        function RotateAllHorizontal(app, event)
            % Rotate the image and neurons pi degrees around x-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [pi,0,0]);
            app.image_data = app.image_neurons.rotate_X_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateAllVerticalMenu
        function RotateAllVertical(app, event)
            % Rotate the image and neurons pi degrees around y-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [0,pi,0]);
            app.image_data = app.image_neurons.rotate_Y_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateAllClockwiseMenu
        function RotateAllClockwise(app, event)
            % Rotate the image and neurons pi/2 degrees around z-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [pi/2,0,0]);
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_Z_90(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateAllAntiClockwiseMenu
        function RotateAllAntiClockwise(app, event)
            % Rotate the image and neurons -pi/2 degrees around z-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [-pi/2,0,0]);
            if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                [app.image_data, app.image_info.scale] = ...
                    app.image_neurons.rotate_Z_270(app.image_data, app.image_info.scale);
                app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
    
                % Save the image & neurons.
                data = app.image_data;
                info = app.image_info;
                save(app.image_file, 'data', 'info', '-append');
                SaveIDToFile(app);
    
                % Redraw the image.
                app.XY.XLim = [0, size(app.image_data, 2)];
                app.XY.YLim = [0, size(app.image_data, 1)];
                app.DrawImageData();
            else
                check = uiconfirm(app.CELL_ID, "Do you want to save this operation to the file?", "NeuroPAL_ID", "Options", ["Yes", "No, stick with preview"]);

                switch check
                    case "Yes"
                        app.proc_apply_rot('acc');
                        ac_flag = 0;
                    case "No"
                        ac_flag = 1;
                end

                app.drawProcImage();
            end
        end

        % Menu selected function: RotateImageHorizontalMenu
        function RotateImageHorizontal(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi degrees around x-axis.
            app.image_data = app.image_neurons.rotate_image_X_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateImageVerticalMenu
        function RotateImageVertical(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi degrees around y-axis.
            app.image_data = app.image_neurons.rotate_image_Y_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateImageClockwiseMenu
        function RotateImageClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi/2 degrees around z-axis.
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_image_Z_90(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateImageAntiClockwiseMenu
        function RotateImageAntiClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image -pi/2 degrees around z-axis.
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_image_Z_270(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsHorizontalMenu
        function RotateNeuronsHorizontal(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons pi degrees around x-axis.
            app.image_neurons.rotate_neurons_X_180(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsVerticalMenu
        function RotateNeuronsVertical(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons pi degrees around x-axis.
            app.image_neurons.rotate_neurons_Y_180(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsClockwiseMenu
        function RotateNeuronsClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons pi/2 degrees around z-axis.
            app.image_neurons.rotate_neurons_Z_90(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsAntiClockwiseMenu
        function RotateNeuronsAntiClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons -pi/2 degrees around z-axis.
            app.image_neurons.rotate_neurons_Z_270(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: MirrorImageMenu
        function MirrorImage(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to mirror the image separately from the neurons?', ...
                'Mirror Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Mirror the image around the z-axis.
            app.image_data = app.image_neurons.mirror_image_Z(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: MirrorNeuronsMenu
        function MirrorNeurons(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to mirror the neurons separately from the image?', ...
                'Mirror Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Mirror the neurons around the z-axis.
            app.image_neurons.mirror_neurons_Z(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Value changed function: AutoIDDropDown
        function AutoIDDropDownValueChanged(app, event)
            % Update the user ID field with the chosen value.
            value = app.AutoIDDropDown.Value;
            names = strsplit(value,'=');
            app.IDEditField.Value = names{1};

            % Send focus to the user ID field.
            Program.GUIHandling.send_focus(app.IDEditField);
        end

        % Value changed function: UserNeuronIDsListBox
        function AutoNeuronIDListBoxValueChanged(app, event)

            % Find the neuron's rank.
            neuron_rank = app.UserNeuronIDsListBox.Value;

            % Select the neuron.
            [~, neuron_i] = app.image_neurons.find_rank(neuron_rank);
            SelectNeuron(app, neuron_i);

            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            Program.GUIHandling.send_focus(app.IDEditField);
        end

        % Key press function: CELL_ID
        function CELL_IDKeyPress(app, event)

            if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                if isempty(app.image_data)
                    return;
                else
                    axes = app.XY;
                    tab = 'id';
                    dims = size(app.image_data);
                    z_slider = app.ZSlider;
                end
            elseif app.TabGroup.SelectedTab == app.ImageProcessingTab
                axes = app.proc_xyAxes;
                tab = 'proc';
                z_slider = app.proc_zSlider;
                volume_value = string(app.VolumeDropDown.Value);
                if isempty(volume_value) || strlength(volume_value(1)) == 0
                    return
                end
                switch lower(char(volume_value(1)))
                    case 'colormap'
                        if isa(app.proc_image, 'matlab.io.MatFile')
                            dims = size(app.proc_image, 'data');
                        elseif ~isempty(app.image_data)
                            dims = size(app.image_data);
                        else
                            return
                        end
                    case 'video'
                        if isempty(app.video_info)
                            return
                        end
                        dims = [app.video_info.ny, app.video_info.nx, app.video_info.nz, app.video_info.nc];
                    otherwise
                        return
                end
            else
                return
            end

            % What did the user press?
            key = event.Key;
            switch key

                case '0' % Toggle the ID atlas.
                    if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                        app.ColorAtlasCheckBox.Value = ~app.ColorAtlasCheckBox.Value;
                        app.DrawImageData();
                    elseif app.TabGroup.SelectedTab == app.VideoTrackingTab
                        if app.ctrl_state
                            app.jumpToUnIDd('frame');
                        end
                    end

                case '1' % Toggle the red channel.
                    if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                        app.RCheckBox.Value = ~app.RCheckBox.Value;
                        app.DrawImageData();
                    elseif app.TabGroup.SelectedTab == app.VideoTrackingTab
                        if app.ctrl_state
                            app.tSlider.Value = app.JmpFrameEditField.Value;
                            app.visual_composer();
                        end
                    end

                case '2' % Toggle the green channel.
                    if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                        app.GCheckBox.Value = ~app.GCheckBox.Value;
                        app.DrawImageData();
                    elseif app.TabGroup.SelectedTab == app.VideoTrackingTab
                        if app.ctrl_state
                            app.tSlider.Value = app.JmpFrameEditField_2.Value;
                            app.visual_composer();
                        end
                    end

                case '3' % Toggle the blue channel.
                    app.BCheckBox.Value = ~app.BCheckBox.Value;
                    app.DrawImageData();

                case '4' % Toggle the white channel.
                    app.WCheckBox.Value = ~app.WCheckBox.Value;
                    app.DrawImageData();

                case '5' % Toggle the DIC channel.
                    app.DICCheckBox.Value = ~app.DICCheckBox.Value;
                    app.DrawImageData();

                case '6' % Toggle the GFP channel.
                    app.GFPCheckBox.Value = ~app.GFPCheckBox.Value;
                    app.DrawImageData();

                case 'leftarrow' % Go up in the z stack.
                    if z_slider.Value > 1
                        zEvent.Value = z_slider.Value - 1;
                        switch tab
                            case 'id'
                                z_slider.ValueChangingFcn(app, zEvent);
                            case 'proc'
                                z_slider.ValueChangedFcn(app, zEvent);
                        end
                    end
                    %disp(key);

                case 'rightarrow' % Go down in the z stack.
                    if z_slider.Value < z_slider.Limits(2)
                        zEvent.Value = z_slider.Value + 1;
                        switch tab
                            case 'id'
                                z_slider.ValueChangingFcn(app, zEvent);
                            case 'proc'
                                z_slider.ValueChangedFcn(app, zEvent);
                        end
                    end
                    %disp(key);

                case 'uparrow' % Zoom in.
                    zoom(axes, 1 + app.zoom_percent);

                case 'downarrow' % Zoom out.
                    zoom(axes, 1 - app.zoom_percent);

                case {'h','H'} % Toggle half zoom.
                    % Reset x-axis limits.
                    axes.XLim = [1, dims(2)];

                    % Upper -> lower half.
                    ylims = axes.YLim;
                    if ylims(1) < dims(1)/2
                        axes.YLim = [ceil(dims(1)/2), dims(1)];

                        % Lower -> upper half.
                    else
                        axes.YLim = [1, floor(dims(1)/2)];
                    end

                case {'j','J'} % Toggle third zoom.
                    % Reset x-axis limits.
                    axes.XLim = [1, dims(2)];

                    % Upper -> middle third.
                    ylims = axes.YLim;
                    if ylims(1) < dims(1)/3
                        axes.YLim = [ceil(dims(1)/3), floor(dims(1)*2/3)];

                        % Middle -> lower third.
                    elseif ylims(1) < dims(1)*2/3
                        axes.YLim = [ceil(dims(1)*2/3), dims(1)];

                        % Lower -> upper third.
                    else
                        axes.YLim = [1, floor(dims(1)/3)];
                    end

                case {'k','K'} % Toggle quarter zoom.
                    % Reset x-axis limits.
                    axes.XLim = [1, dims(2)];

                    % Upper -> upper-middle quarter.
                    ylims = axes.YLim;
                    if ylims(1) < dims(1)/4
                        axes.YLim = [ceil(dims(1)/4), floor(dims(1)*2/4)];

                        % Upper-middle -> lower-middle quarter.
                    elseif ylims(1) < dims(1)*2/4
                        axes.YLim = [ceil(dims(1)*2/4), floor(dims(1)*3/4)];

                        % Lower-middle -> lower quarter.
                    elseif ylims(1) < dims(1)*3/4
                        axes.YLim = [ceil(dims(1)*3/4), dims(1)];

                        % Lower -> upper quarter.
                    else
                        axes.YLim = [1, floor(dims(1)/4)];
                    end

                case {'p','P'} % Toggle image panning.

                    % Toggle image panning.
                    if app.is_panning
                        pan(axes,'off');
                        pause(0.5);
                        Program.GUIHandling.restore_pointer(app);
                        app.is_panning = false;
                    else
                        pan(axes,'on');
                        pause(0.5);
                        app.is_panning = true;
                    end

                case {'r','R'} % Reset the figure to center.
                    axes.XLim = [1, dims(2)];
                    axes.YLim = [1, dims(1)];
                    %app.XY.XLim = [1, size(app.image_data, 2)];
                    %app.XY.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XAxisLocation = 'bottom';
                    %app.XY.YAxisLocation = 'left';

                    %case {'x','X'} % Switch mouse to crosshairs.
                    %zoom(app.XY,'on');
                    %pause(0.2);
                    %zoom(app.XY,'off');

                case {'i','I'} % Toggle showing neuron IDs.
                    app.show_labels = ~app.show_labels;
                    DrawZSlice(app, app.ZSlider, app.image_view, app.XY);

                case {'a', 'A'} % Toggle autocompleting neuron names.
                    if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                        app.AutoNameCheckBox.Value = ~app.AutoNameCheckBox.Value;
                        AutoNameNeurons(app, event);
                    elseif app.TabGroup.SelectedTab == app.VideoTrackingTab
                        if app.ctrl_state
                            app.tSlider.Value = app.tSlider.Value + app.FwdFrameEditField.Value;
                            app.visual_composer();
                        end
                    end                    

                case {'b','B'} % Toggle showing neuron birth times.
                    ToggleNeuronBirthTimes(app, event);

                case {'d','D'}
                    if app.TabGroup.SelectedTab == app.NeuroPALIDTab
                        app.AutoNameCheckBox.Value = ~app.AutoNameCheckBox.Value;
                        AutoNameNeurons(app, event);
                    elseif app.TabGroup.SelectedTab == app.VideoTrackingTab
                        if app.ctrl_state
                            app.tSlider.Value = max(app.tSlider.Value - app.BwdFrameEditField.Value, 1);
                            app.visual_composer();
                        end
                    end                    

                case 'control'
                    app.ctrl_state = 1;

                otherwise
                    % Do nothing.
            end

        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            % ZSliderValueChanging(app, event);
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            app.DrawImageData();
        end

        % Close request function: CELL_ID
        function CELL_IDCloseRequest(app, event)

            % Unselect any neurons.
            app.UnselectNeuron(false);
            if ~isempty(app.id_file) && exist(app.id_file, 'file')
                app.SaveIDToFile();
            end

            % Save the log file for further 'number of user clicks' analysis.
            if ~isempty(app.log)
                log = app.log;
                [path, name, ~] = fileparts(app.image_file);
                directory = [path, filesep, 'logs', filesep];
                if ~exist(directory, 'dir')
                    mkdir(directory);
                end
                save([directory,name, '_',strrep(datestr(datetime('now')),':','_'), '.mat'], 'log');
            end

            % Done.
            delete(app);
            clear java;
        end

        % Callback function
        function SaveButtonPushed(app, event)
            % Save the final result of running MP and annotations in file.
            SaveIDToFile(app);
        end

        % Callback function
        function RotateSliderValueChanged(app, event)
            % Read the rotation angle.
            value = app.RotateSlider.Value;

            % Rotate image and all the neurons -> see Image.m
            app.image_data = app.image_neurons.rotate(app.image_data, [value,0,0]);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            % Rotated image might have different dimensions so here we are updating the ZLimits.
            app.ZSlider.Limits = [1, size(app.image_data, 3)];
            app.ZSliderS.Limits = [1, size(app.image_data, 3)];

            % Bring the rotate slider back to zero.
            app.RotateSlider.Value = 0;

            % Update the views.
            app.DrawImageData();
        end

        % Callback function
        function AutoDetectCheckBoxValueChanged(app, event)
            value = app.AutoDetectCheckBox.Value;
            app.image_neurons.add_meta_data('auto_detect', value);
        end

        % Value changed function: BDropDown, DICDropDown, GDropDown, 
        % ...and 3 other components
        function UpdateColorChannels(app, event)
            app.logEvent('Main','Updating color channels...', 1);

            % Are we opening a file?
            if app.is_opening_file
                return;
            end

            % Redraw the image.
            app.DrawImageData();

            app.localSetProcessingChannelValue(app.ProcRDropDown, app.RDropDown.Value);
            app.localSetProcessingChannelValue(app.ProcGDropDown, app.GDropDown.Value);
            app.localSetProcessingChannelValue(app.ProcBDropDown, app.BDropDown.Value);
            app.localSetProcessingChannelValue(app.ProcWDropDown, app.WDropDown.Value);
            app.localSetProcessingChannelValue(app.ProcDICDropDown, app.DICDropDown.Value);
            app.localSetProcessingChannelValue(app.ProcGFPDropDown, app.GFPDropDown.Value);

            % Initialize the updated color channels.
            red = str2double(app.RDropDown.Value);
            green = str2double(app.GDropDown.Value);
            blue = str2double(app.BDropDown.Value);
            white = str2double(app.WDropDown.Value);
            dic = str2double(app.DICDropDown.Value);
            gfp = str2double(app.GFPDropDown.Value);
            old_RGBW = app.image_prefs.RGBW;
            new_RGBW = [red, green, blue, white];
            app.image_prefs.RGBW = new_RGBW;
            app.image_prefs.DIC = dic;
            app.image_prefs.GFP = gfp;

            % Update the neurons.
            app.image_neurons.update_colors(app.image_data_zscored, old_RGBW, new_RGBW);
            if ~isequaln(old_RGBW, new_RGBW)
                app.SaveIDToFile();
            end

            % Save the updated preferences.
            prefs = app.image_prefs;
            save(app.image_file, 'prefs', '-append');
        end

        function localConfigureProcessingChannelItems(app, nc)
            items = ['-'; string(1:nc)'];
            items = cellstr(items);
            handles = { ...
                'ProcRDropDown', ...
                'ProcGDropDown', ...
                'ProcBDropDown', ...
                'ProcWDropDown', ...
                'ProcDICDropDown', ...
                'ProcGFPDropDown'};

            for i = 1:numel(handles)
                handle_name = handles{i};
                if ~isprop(app, handle_name)
                    continue;
                end

                dropdown = app.(handle_name);
                current_value = char(string(dropdown.Value));
                dropdown.Items = items;
                app.localSetProcessingChannelValue(dropdown, current_value);
            end
        end

        function localSetProcessingChannelValue(app, dropdown, value)
            if isempty(dropdown) || ~isvalid(dropdown)
                return;
            end

            target_value = char(string(value));
            if isempty(strtrim(target_value)) || strcmp(target_value, '0')
                target_value = '-';
            end

            items = cellstr(string(dropdown.Items));
            if any(strcmp(items, target_value))
                dropdown.Value = target_value;
            elseif any(strcmp(items, '-'))
                dropdown.Value = '-';
            elseif ~isempty(items)
                dropdown.Value = items{1};
            end
        end

        % Value changing function: IDEditField
        function IDEditFieldChanging(app, event)
            value = event.Value;

            % Space means done.
            if length(value) > 0 && isspace(value(end)) & ~strcmp(app.IDEditField.Tag, value)
                SaveUserID(app, value);
            end

            app.IDEditField.Tag = value;
        end

        % Button pushed function: UserIDButton
        function UserIDButtonPushed(app, event)
            SaveUserID(app, app.IDEditField.Value);
        end

        % Selection changed function: GanglionTree
        function GanglionTreeSelectionChanged(app, event)

            % Update the ID'd & UnID'd neurons list.
            app.IDdNeuronsListBox.Value = {};
            app.IDdLabel.BackgroundColor = 'none';
            app.IDdLabel.FontColor = [0,0,0];
            app.UnIDdNeuronsListBox.Value = {};
            app.UnIDdLabel.BackgroundColor = 'none';
            app.UnIDdLabel.FontColor = [0,0,0];
            UnselectNeuron(app);
            UpdateNeuronLists(app);
        end

        % Value changed function: IDdNeuronsListBox
        function IDdNeuronsListChanged(app, event)

            % Show the selected NeuroPAL neuron.
            value = app.IDdNeuronsListBox.Value;
            if ~isempty(value)

                % Get the neuron name.
                % Remove birth_time, confidence, and ON/OFF.
                space_i = find(value == ' ', 1);
                if ~isempty(space_i)
                    value = value(1:space_i-1);
                end
                if endsWith(value, ' ?')
                    value = value(1:(end-2));
                end
                value = app.neuron_info.stripOnOff(value);

                % No color available.
                color = app.neuron_info.getNeuronColor(value);
                if isempty(color)
                    app.IDdLabel.BackgroundColor = [0.95,0.95,0.95];
                    app.IDdLabel.FontColor = [0,0,0];

                    % Show the selected NeuroPAL neuron's color.
                else
                    app.IDdLabel.BackgroundColor = app.neuron_info.getNeuronColor(value);

                    % Make the font visible against the background color.
                    if mean(color) > 0.5
                        app.IDdLabel.FontColor = [0,0,0];
                    else
                        app.IDdLabel.FontColor = [1,1,1];
                    end
                end

                % Select the neuron & show its name.
                [neuron, neuron_i] = app.image_neurons.find_user_id_neuron(value);
                if ~isempty(neuron_i)

                    % Select the neuron.
                    SelectNeuron(app, neuron_i);

                    % Is the neuron ON/OFF?
                    name = neuron.annotation;

                    switch neuron.is_annotation_on
                        case false
                            name = [name '-OFF'];
                        case true
                            name = [name '-ON'];
                    end

                    % Is the user uncertain about the ID?
                    if neuron.annotation_confidence <= 0.5
                        name = [name '?'];
                    end

                    % Is the neuron emphasized?
                    if neuron.is_emphasized
                        name = [name '!'];
                    end

                    % Show the neuron's ID.
                    DrawImageLabels(app, neuron.position, {name});
                end
            end
        end

        % Double-clicked callback: IDdNeuronsListBox
        function IDdNeuronsListBoxDoubleClicked(app, event)
            name = app.IDdNeuronsListBox.Items{event.InteractionInformation.Item};

            if strcmp(app.VolTrace.Visible,'on')
                app.updateBrowser(name);
            end
            
        end

        % Value changed function: UnIDdNeuronsListBox
        function UnIDdNeuronsListChanged(app, event)

            % Show the selected NeuroPAL neuron's color.
            value = app.UnIDdNeuronsListBox.Value;
            if ~isempty(value)

                % Get the neuron name. Remove birth_time.
                space_i = find(value == ' ', 1);
                if ~isempty(space_i)
                    value = value(1:space_i-1);
                end

                % Get the neuron color.
                color = app.neuron_info.getNeuronColor(value);

                % No color available.
                if isempty(color)
                    app.UnIDdLabel.BackgroundColor = [0.95,0.95,0.95];
                    app.UnIDdLabel.FontColor = [0,0,0];

                    % Set the color.
                else
                    app.UnIDdLabel.BackgroundColor = app.neuron_info.getNeuronColor(value);

                    % Make the font visible against the background color.
                    if mean(color) > 0.5
                        app.UnIDdLabel.FontColor = [0,0,0];
                    else
                        app.UnIDdLabel.FontColor = [1,1,1];
                    end
                end
            end
        end

        % Button pushed function: AutoIDButton
        function AutoIDButtonPushed(app, event)
            % Save the chosen value.
            value = app.AutoIDDropDown.Value;
            if isempty(value)
                return;
            end
            names = upper(strsplit(value,'='));
            SaveAutoID(app, names{1});
        end

        % Menu selected function: DeleteUserIDsMenu
        function DeleteUserIDs(app, event)
            answer = uiconfirm(app.CELL_ID, ...
                {'Delete all the neuron''s user IDs???', 'You will lose all your work!'}, ...
                'Delete All Neuron User IDs', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Delete all user IDs.
            if strcmpi(answer, 'yes')

                % Delete all user IDs.
                UnselectNeuron(app);
                app.image_neurons.delete_annotations();

                % Save the updated IDs.
                SaveIDToFile(app);

                % Redraw the Z-slice.
                DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
            end
        end

        % Menu selected function: DeleteModelIDsMenu
        function DeleteModelIDs(app, event)
            answer = uiconfirm(app.CELL_ID, ...
                'Delete all the neuron''s model IDs?', ...
                'Delete All Neuron Model IDs', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Delete all user IDs.
            if strcmpi(answer, 'yes')

                % Delete all model IDs.
                UnselectNeuron(app);
                app.image_neurons.delete_model_IDs();

                % Save the updated IDs.
                SaveIDToFile(app);

                % Uncheck the atlas.
                app.ColorAtlasCheckBox.Value = false;

                % Draw the auto ID neuron uncertainty list.
                app.UserNeuronIDsListBox.Items = {};
                app.UserNeuronIDsListBox.Value = {};

                % Redraw the Z-slice.
                DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
            end
        end

        % Menu selected function: ToggleAutoIDUpdatesMenu
        function ToggleAutoIDUpdates(app, event)
            % Toggle disabling or enabling neuron auto-ID updates.
            GUI_prefs = Program.GUIPreferences.instance();
            if contains(lower(app.ToggleAutoIDUpdatesMenu.Text), 'disable')
                GUI_prefs.is_autoID_updates = false;
                app.ToggleAutoIDUpdatesMenu.Text = 'Enable Auto-ID Updates';
            else
                GUI_prefs.is_autoID_updates = true;
                app.ToggleAutoIDUpdatesMenu.Text = 'Disable Auto-ID Updates';
            end

            % Save the GUI preferences.
            GUI_prefs.save();
        end

        % Menu selected function: ToggleNeuronDetectionMenu
        function ToggleNeuronDetection(app, event)
            % Cycle matching-pursuit (MP), neural-network (NN), and Cellpose
            % neuron detection.
            GUI_prefs = Program.GUIPreferences.instance();
            switch GUI_prefs.get_detection_backend()
                case 'mp'
                    GUI_prefs.set_detection_backend('nn');
                    app.ToggleNeuronDetectionMenu.Text = 'Use Cellpose-Detect Neurons';
                case 'nn'
                    GUI_prefs.set_detection_backend('cellpose');
                    app.ToggleNeuronDetectionMenu.Text = 'Use MP-Detect Neurons';
                otherwise
                    GUI_prefs.set_detection_backend('mp');
                    app.ToggleNeuronDetectionMenu.Text = 'Use NN-Detect Neurons';
            end

            % Save the GUI preferences.
            GUI_prefs.save();
        end

        % Value changed function: ZAxisDropDown
        function ZAxisChanged(app, event)

            % Are we opening a file?
            if app.is_opening_file
                return;
            end

            % Set the Z-axis to be L/R.
            value = app.ZAxisDropDown.Value;
            if strcmp(value, 'L/R')
                app.image_prefs.is_Z_LR = true;
                app.ZLeftLabel.Text = 'LEFT';
                app.ZRightLabel.Text = 'RIGHT';

                % Set the Z-axis to be D/V.
            else
                app.image_prefs.is_Z_LR = false;
                app.ZLeftLabel.Text = 'DORSAL';
                app.ZRightLabel.Text = 'VENTRAL';
            end

            % Save the update.
            if ~app.is_opening_file
                prefs = app.image_prefs;
                save(app.image_file, 'prefs', '-append');
            end
        end

        % Button pushed function: FlipZButton
        function FlipZButtonPushed(app, event)

            % Flip the Z-axis.
            app.image_prefs.is_Z_flip = ~app.image_prefs.is_Z_flip;

            % Save the update.
            prefs = app.image_prefs;
            save(app.image_file, 'prefs', '-append');

            % Redraw the Z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end

        % Value changed function: ZCenterEditField
        function ZCenterChanged(app, event)

            % Are we opening a file?
            if app.is_opening_file
                return;
            end

            % Did the center change?
            value = app.ZCenterEditField.Value;
            z_center = round(value / app.image_um_scale(3)) + 1;
            if z_center == app.image_prefs.z_center
                return;
            end

            % Is the center in range?
            min_z = round(app.image_um_scale(3), 1);
            max_z = round((size(app.image_data,3) - 2) * app.image_um_scale(3), 1);
            if value < min_z || value > max_z
                uiconfirm(app.CELL_ID, ...
                    {[num2str(value, '%.1f') ' is out of range!'], ...
                    ['The z-axis center must be between [' num2str(min_z, '%.1f') ',' num2str(max_z, '%.1f')  '].']}, ...
                    'Z-Axis Center Error', 'Options', {'Ok'}, 'DefaultOption', 1, 'CancelOption', 1, ...
                    'Icon', 'error');

                % Restore the previous value.
                value = round((app.image_prefs.z_center - 1) * app.image_um_scale(3), 1);
                app.ZCenterEditField.Value = value;
            end

            % Change the center of the Z-axis.
            app.image_prefs.z_center = z_center;

            % Save the update.
            prefs = app.image_prefs;
            save(app.image_file, 'prefs', '-append');

            % Redraw the Z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end

        % Menu selected function: ManualMenu
        function ManualArtifactRemoval(app, event)
            % Give the user instruction on how to remove artifacts.
            answer = uiconfirm(app.CELL_ID, ...
                {'To manually remove artifacts:', ...
                '1. Use the cursor to add vertices, drawing', ...
                '   a polygon around the image artifacts.', ...
                '2. When done, click the 1st point of your polygon', ...
                '   or hit the return key on your keyboard.'}, ...
                'Manually Remove Artifacts', 'Options', {'Remove Artifacts', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'info');

            % Keep the existing file.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Unselect any neurons.
            app.UnselectNeuron(false);

            % Manually trace the artifacts.
            mask = Methods.Preprocess.manual_artifact_removal(app.image_view);
            if isempty(mask)
                return;
            end

            % Remove any neurons in the artifact polygon.
            if ~isempty(app.image_neurons) && ~isempty(app.image_neurons.neurons)

                % Remove the neurons in the polygon.
                neurons = app.image_neurons.neurons;
                positions = app.image_neurons.get_positions();
                z = app.ZSlider.Value;
                if app.image_prefs.is_Z_flip
                    z = size(app.image_data,3) - z + 1;
                end
                inside_mask = mask(sub2ind(size(mask), round(positions(:,1)), round(positions(:,2))));
                app.image_neurons.neurons = neurons(~(positions(:,3) < z+1.5 & positions(:,3) > z-1.5 & inside_mask));

                % Redraw the auto ID list.
                app.DrawAutoIDList();
            end
            mask = repmat(mask,1,1,size(app.image_data,3),length(app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));

            % Remove artifacts.
            tmp = app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW)));
            tmp(mask) = 0;
            app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))) = tmp;

            % Z-score the image.
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            app.DrawImageData();
        end

        % Menu selected function: AutomaticMenu
        function AutomaticArtifactRemoval(app, event)

            % Find artifacts.
            [~, mask_raw] = Methods.Preprocess.filter_gut_lysosomes(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));
            mask = repmat(mask_raw,1,1,1,length(app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));

            % Remove artifacts.
            tmp = app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW)));
            tmp(mask) = 0;
            app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))) = tmp;
            app.image_data(mask) = 0;

            % Z-score the image.
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            app.DrawImageData();

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Automatic artifact removal completed successfully.', ...
                'Automatically Remove Artifacts', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Menu selected function: BatchDetectIDNeuronsMenu
        function BatchIDNeurons(app, event)
            %% Purpose: batch detect+ID neurons for all image files in a directory.

            % Setup the file chooser's path.
            % Note: don't change the user's preferred path.
            %path = '../../';
            path = Program.GUIPreferences.instance().image_dir;

            % Ask the user which folder they want to use.
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            folder = uigetdir(path, 'Select Worm Image Folder');
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if folder == 0
                return; % user cancelled
            end

            % Determine the image files.
            dir_files = dir(folder);
            files = {dir_files.name};
            formats = {'.czi', '.nd2', '.lif', '.tif', '.tiff', '.h5'};
            files_i = find(endsWith(files, formats, 'IgnoreCase', true));
            dir_files = dir_files(files_i);
            num_files = length(dir_files);

            % Are there any files?
            if num_files < 1
                uiconfirm(app.CELL_ID, ...
                    {'No image file present in:', folder}, ...
                    'No Image Files Present', ...
                    'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'error');
                return;
            end

            % Did we already detect & ID neurons in these files?
            for i = num_files

                % Construct the ID file.
                name = dir_files(i).name;
                name_dot_i = strfind(name, '.');
                name_dot_i = name_dot_i(end);
                name = [name(1:(name_dot_i - 1)), '_ID.mat'];
                id_file = [dir_files(i).folder '/' name];

                % Does the ID file exist?
                if exist(id_file, 'file')
                    answer = uiconfirm(app.CELL_ID, ...
                        {'Some of the ID files already exist!', ' ', 'Do you want to overwrite them?'}, ...
                        'Overwrite ID Files', 'Options', {'Yes', 'Cancel'}, ...
                        'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                    % Keep the existing file.
                    if strcmpi(answer, 'cancel')
                        return;
                    end

                    % Done.
                    break;
                end
            end

            % What is the age of these worms?
            title = 'Worm Age';
            prompt  = 'What is the age of these worms?';
            list = app.AgeDropDown.Items;
            list = list(~cellfun(@isempty, list));
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            [age_i,~] = listdlg('PromptString', prompt, 'Name', title, ...
                'SelectionMode', 'single', 'ListString', list);
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if isempty(age_i)
                return;
            end
            age = list{age_i};

            % What sex are these worms?
            title = 'Worm Sex';
            prompt  = 'What is the sex of these worms?';
            list = app.SexDropDown.Items;
            list = list(~cellfun(@isempty, list));
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            [sex_i,~] = listdlg('PromptString', prompt, 'Name', title, ...
                'SelectionMode', 'single', 'ListString', list);
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if isempty(sex_i)
                return;
            end
            sex = list{sex_i};

            % Which section of the body are we detecting?
            title = 'Worm Body Section';
            prompt  = 'Which section of the worm is in these images?';
            list = app.BodyDropDown.Items;
            list = list(~cellfun(@isempty, list));
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            [body_i,~] = listdlg('PromptString', prompt, 'Name', title, ...
                'SelectionMode', 'single', 'ListString', list);
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if isempty(body_i)
                return;
            end
            body = list{body_i};

            % Create the worm info.
            worm_info.age = age;
            worm_info.sex = sex;
            worm_info.body = body;
            worm_info.strain = '';
            worm_info.notes = '';

            % Determine the number of neurons.
            GUI_prefs = Program.GUIPreferences.instance();
            num_neurons = app.neuron_info.numNeurons(body);
            detection_backend = GUI_prefs.get_detection_backend();
            if isempty(num_neurons) && strcmp(detection_backend, 'mp')
                uialert(app.CELL_ID, ...
                    {['The body section "' body '" is not yet supported.'], ' ', ...
                    'Please use the "Neurons" menu to select "Use NN-Detect Neurons" or "Use Cellpose-Detect Neurons".'}, ...
                    'Unsupported Body Section');
                return;
            end

            % Setup the file names.
            files = cell(length(dir_files),1);
            for i = 1:length(files)
                files{i} = [dir_files(i).folder '/' dir_files(i).name];
            end

            % Batch detect the neurons.
            is_parallel = app.is_parallel;
            if is_parallel

                % Start the parallel pool.
                if isempty(app.parallel_pool) || ...
                        ~isvalid(app.parallel_pool) || ...
                        ~app.parallel_pool.Connected
                    app.parallel_pool = parpool('local');
                end

                % Setup the progress bar.
                wait_title = 'Detecting Neurons';
                wb = waitbar(0, 'Initializing ...', 'Name', wait_title);
                wb.Children.Title.Interpreter = 'none';
            end
            for i = 1:num_files

                % Batch detect the neurons.
                if is_parallel
                    switch detection_backend
                        case 'mp'
                            funcs(i) = parfeval(... %app.parallel_pool, ...
                                @Methods.AutoDetect.BatchDetect, 0, files{i}, worm_info, num_neurons);
                        case 'nn'
                            funcs(i) = parfeval(... %app.parallel_pool, ...
                                @Methods.NNDetect.BatchDetect, 0, files{i}, worm_info, num_neurons);
                        case 'cellpose'
                            funcs(i) = parfeval(... %app.parallel_pool, ...
                                @Methods.CellposeDetect.BatchDetect, 0, files{i}, worm_info, num_neurons);
                    end
                    waitbar(0, wb, ...
                        ['Detecting neurons in ' num2str(i) ' files.'], ...
                        'Name', wait_title);
                else
                    switch detection_backend
                        case 'mp'
                            Methods.AutoDetect.BatchDetect(files{i}, worm_info, num_neurons);
                        case 'nn'
                            Methods.NNDetect.BatchDetect(files{i}, worm_info, num_neurons);
                        case 'cellpose'
                            Methods.CellposeDetect.BatchDetect(files{i}, worm_info, num_neurons);
                    end
                end
            end

            % Wait for each task to complete.
            if is_parallel
                for i = 1:num_files
                    funcs_i = fetchNext(funcs);

                    % Update the progress.
                    progress = double(i)/num_files;
                    waitbar(progress, wb, {['Completed: ' files{funcs_i}], ...
                        sprintf('%d%% completed ...', progress*100)}, ...
                        'Name', wait_title);
                end

                % Terminate the existing parallel session.
                delete(gcp('nocreate'));

                % Done.
                try
                    close(wb);
                catch
                    warning('The detection was canceled.');
                end
            end

            % Can we ID this body part?
            if ~Methods.AutoId.isBodyID(sex, body)
                uialert(app.CELL_ID, ...
                    {['The body section "' body '" is not yet supported.'], ' ', ...
                    'Neurons in this section of the body cannot yet be identified.'}, ...
                    'No ID for Body Section');
                return;
            end

            % Batch ID the neurons.
            is_parallel = false; % auto ID uses its own parallelization & cannot be called with a parallel pool.
            if is_parallel
                delete funcs;
                %pool = gcp(); % may want to have a ready-use pool
            end
            for i = 1:num_files

                % Batch detect the neurons.
                if is_parallel
                    funcs(i) = parfeval(... %app.parallel_pool, ...
                        @Methods.AutoId.BatchId, 0, files{i}, worm_info);
                else
                    Methods.AutoId.BatchId(files{i}, worm_info);
                end
            end

            % Wait for each task to complete.
            if is_parallel
                for i = 1:num_files
                    fetchNext(funcs);
                end

                % Terminate the existing parallel session.
                delete(gcp('nocreate'));
            end

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Batch auto detect + ID neurons completed successfully.', ...
                'Batch Auto Detect + ID Neurons', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Callback function: SaveIDInfoMenu, SaveIDsButton
        function SaveIDs2CSV(app, event)
            %%  Purpose: save the IDs, position, & colors to a CSV file.

            % Do we have any neurons ID'd?
            if ~app.image_neurons.is_any_auto_ID() && ~app.image_neurons.is_any_annotated()
                uialert(app.CELL_ID, ...
                    'No neurons have been assigned an ID!',...
                    'No Neurons ID''d', 'Icon', 'warning');
                return;
            end

            % Warn the user to align the neurons.
            answer = uiconfirm(app.CELL_ID, ...
                {'Aligned neuron colors + positions REQUIRE', ...
                'correct auto ID''s for analysis!', ' ', ...
                'Do you want to (re-)run auto ID prior to saving the IDs?'}, ...
                'Neuron Color + Position', 'Options', {'Yes', 'No', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 3, 'Icon', 'info');
            if strcmpi(answer, 'cancel')
                return;
            elseif strcmpi(answer, 'yes')
                app.AutoIDAllButtonPushed(event);
            end

            % Create the filename "<filename>_IDs.csv".
            % Is there already a file with this name & does the user want to overwrite it?
            % *** if the file exists & they don't want to overwrite, cancel the operation.
            filename = app.image_file;
            suffixI = strfind(filename, '.');
            suffixI = suffixI(end);
            csvfile = cat(2, filename(1:(suffixI - 1)), '.csv');
            if exist(csvfile, 'file')
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' csvfile '" already exists!'], ' ', 'Do you want to overwrite it?'}, ...
                    'Overwrite ID File', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Keep the existing file.
                if strcmpi(answer, 'cancel')
                    return;
                end
            end
            delete(csvfile);

            % Write the CSV file.
            try
                Output.ImageAnalysis.saveID2CSV(csvfile, app.image_prefs, ...
                    app.image_data, app.image_data_zscored, app.image_neurons, app.image_um_scale);
            catch ME
                % Error.
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {['Failed to write neuron IDs to "' csvfile '"!'], ['Error:' msg]}, ...
                    'Neuron ID File Failure', 'Icon', 'error');
            end

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Saving neuron IDs completed successfully.', ...
                'Save Neurons IDs', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Callback function
        function ColorAtlasCheckBoxValueChanged(app, event)
            app.DrawImageData();
        end

        % Value changed function: BodyDropDown
        function BodyDropDownValueChanged(app, event)

            % Update the neurons.
            value = app.BodyDropDown.Value;
            if isempty(app.image_neurons)
                app.image_neurons = Neurons.Image([], value, 'scale', app.image_um_scale');
                %else DEPRECATED
                %    app.image_neurons.bodypart = value;
            end

            % Save the changes.
            app.worm.body = value;
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
            app.SaveIDToFile();
        end

        % Value changed function: AgeDropDown
        function AgeDropDownValueChanged(app, event)

            % Update the worm's age.
            app.worm.age = app.AgeDropDown.Value;
            %UpdateSexNeuronLists(app);

            % Save the changes.
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Value changed function: SexDropDown
        function SexDropDownValueChanged(app, event)

            % Update the worm's sex.
            app.worm.sex = app.SexDropDown.Value;
            UpdateSexNeuronLists(app);

            % Save the changes.
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Value changed function: StrainEditField
        function StrainEditFieldValueChanged(app, event)
            value = app.StrainEditField.Value;
            app.worm.strain = value;
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Value changed function: SubjectNotesTextArea
        function SubjectNotesTextAreaValueChanged(app, event)
            value = app.SubjectNotesTextArea.Value;
            app.worm.notes = value;
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Menu selected function: CheckforUpdatesMenu
        function CheckforUpdatesMenuSelected(app, event)
            % Get the latest version info.
            prog_ver = Program.ProgramInfo.version;
            try
                latest_ver = Program.ProgramInfo.checkUpdates();
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot check online version!', '', 'Error:', msg}, ...
                    'Software Update Check', 'Icon', 'error');
                return;
            end

            % The software is out of date.
            if latest_ver > prog_ver
                answer = uiconfirm(app.CELL_ID, ...
                    {'A newer version of NeuroPAL ID is available!', 'Please download it.'}, ...
                    'Software Update Check', 'Options', {'Download', 'OK'}, ...
                    'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'info');

                % Go to the website.
                if strcmp(answer, 'Download')
                    web(Program.ProgramInfo.website_URL, '-browser');
                end

                % The software is up-to-date.
            else
                uialert(app.CELL_ID, ...
                    'You have the latest version of NeuroPAL ID.', ...
                    'Software Update Check', 'Icon', 'success');
            end
        end

        % Menu selected function: SaveIDImageMenu
        function SaveIDImageMenuSelected(app, event)
            % Is there an image loaded?
            data = app.image_view;
            if isempty(data)
                uialert(app.CELL_ID, 'No image!', 'Save ID Image', 'Icon', 'error');
                return;
            end

            % Initialize the neurons & scale.
            neurons = app.image_neurons.user_id_neurons();
            um_scale = app.image_um_scale;

            % Determine the filename.
            image_filename = app.image_file;
            file = strrep(image_filename, '.mat', '');

            % Is there already a file with this name & does the user want to overwrite it?
            % *** if the file exists & they don't want to overwrite, cancel the operation.
            pdf_file = [file '_1.pdf'];
            if exist(pdf_file, 'file')
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' pdf_file '" already exists!'], ' ', 'Do you want to overwrite it?'}, ...
                    'Overwrite ID Image File', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Keep the existing file.
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Print the neuron ID image(s).
            is_error = false;
            try
                Output.Illustration.saveIDImage(file, data, ...
                    neurons, um_scale, app.image_prefs.is_Z_flip);
            catch ME
                % Error.
                is_error = true;
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {['Failed to save ID images to "' file '"!'], ['Error:' msg]}, ...
                    'Save ID Image', 'Icon', 'error');
            end

            % Done.
            if ~is_error
                uiconfirm(app.CELL_ID, ...
                    'Neuron ID images saved.', ...
                    'Save Neuron ID Images', ...
                    'Options', {'OK'}, 'DefaultOption', 1, ...
                    'Icon', 'success');
            end
        end

        % Callback function
        function SaveIDCartoonMenuSelected(app, event)
            % Is there an image loaded?
            if isempty(app.image_data)
                uialert(app.CELL_ID, 'No image!', 'Save ID Cartoon', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            CELL_IDCloseRequest(app, event);
        end

        % Menu selected function: KeyboardShortcutsMenu
        function KeyboardShortcutsMenuSelected(app, event)
            try
                if isdeployed
                    DataHandling.PNGViewer.show('Keyboard.png', ...
                        'Keyboard Shortcuts');
                else
                    DataHandling.PNGViewer.show('./Data/Documents/Keyboard.png', ...
                        'Keyboard Shortcuts');
                end
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot open instructions!', '', 'Error:', msg}, ...
                    'Display Instructions', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            try
                if isdeployed
                    DataHandling.PNGViewer.show('About.png', ...
                        'About NeuroPAL ID');
                else
                    DataHandling.PNGViewer.show('./Data/Documents/About.png', ...
                        'About NeuroPAL ID');
                end
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot open instructions!', '', 'Error:', msg}, ...
                    'Display Instructions', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: InstructionsMenu
        function InstructionsMenuSelected(app, event)
            try
                if isdeployed
                    DataHandling.PNGViewer.show('Instructions.png', ...
                        'Software Instructions');
                else
                    DataHandling.PNGViewer.show('./Data/Documents/Instructions.png', ...
                        'Software Instructions');
                end
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot open instructions!', '', 'Error:', msg}, ...
                    'Display Instructions', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: GFPColorMenu
        function GFPColorMenuSelected(app, event)
            color = Program.GUIPreferences.inputGFPColor();
            if ~isempty(color)

                % Save the preferences.
                Program.GUIPreferences.save();

                % Redraw the image.
                if app.GFPCheckBox.Value
                    app.DrawImageData();
                end
            end
        end

        % Menu selected function: NeuronDotsMenu
        function NeuronDotsMenuSelected(app, event)
            dot = Program.GUIPreferences.inputNeuronDot();
            if ~isempty(dot)
                Program.GUIPreferences.save();

                % Redraw the Z-slice.
                DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
            end
        end

        % Menu selected function: ReportaBugMenu
        function ReportBug(app, event)
            answer = uiconfirm(app.CELL_ID, ...
                {'We welcome bug reports to improve the software.', ...
                ' ', ...
                'You MUST follow these instructions for bug reports:', ...
                '1. Ensure you have the latest software.', ...
                '   Your bug may have already been fixed.', ...
                '2. Review the software instructions.', ...
                '   Your issue may not be a bug.', ...
                '3. Ensure the bug has NOT already been reported.', ...
                '4. Report EXACTLY what happened on the bug website:', ...
                '  A. Your OS and the NeuroPAL ID software version.', ...
                '  B. What were you doing when the bug occurred?', ...
                '  C. What was the software supposed to do?', ...
                '  D. What did the software actually do?', ...
                '  E. Provide both the "*.mat" and "*_ID.mat"', ...
                '     files associated with the bug.', ...
                '  F. If you received an error message, provide it.'}, ...
                'Report a Bug', 'Options', {'Report', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'info');

            % Go to the website.
            if strcmp(answer, 'Report')
                web(Program.ProgramInfo.bug_URL, '-browser');
            end
        end

        % Menu selected function: NeuroPALWebsiteMenu
        function NeuroPALWebsiteMenuSelected(app, event)
            web(Program.ProgramInfo.website_URL, '-browser');
        end

        % Menu selected function: AllChannels
        function AllChannelsMenuSelected(app, event)
            % Ask the user for the gamma adjustment.
            prompt = {'Gamma adjustment:'};
            dlgtitle = 'Adjust Gamma';
            dims = [1,20];
            definput = {num2str(mean(app.image_gamma))};
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            % Adjust the gamma.
            if ~isempty(answer)

                % Sanitize the input.
                gamma = str2double(answer);
                gamma = max(gamma,0);
                gamma = min(gamma,1);

                % Save the new gamma.
                app.image_gamma(1:end) = gamma;
                app.image_prefs.gamma(1:end) = gamma;
                prefs = app.image_prefs;
                save(app.image_file, 'prefs', '-append');

                % Update the views.
                app.DrawImageData();
            end
        end

        % Menu selected function: IndividualChannels
        function IndividualChannelsMenuSelected(app, event)
            % Ask the user for the gamma adjustment.
            prompt = {'R:', 'G:', 'B:', 'W:', 'DIC:', 'GFP:'};
            dlgtitle = 'Adjust Gamma by Channel';
            dims = [1,20];
            gamma = app.image_gamma;
            prompt = prompt(1:length(gamma));
            definput = arrayfun(@(x) num2str(x), gamma, 'UniformOutput', false);
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            % Adjust the gamma.
            if ~isempty(answer)

                % Sanitize the input.
                gamma = str2double(answer);
                if size(gamma,1) < size(gamma,2)
                    gamma = gamma';
                end
                gamma = max(gamma,0);
                gamma = min(gamma,1);

                % Save the new gamma.
                app.image_gamma = gamma;
                app.image_prefs.gamma = gamma;
                prefs = app.image_prefs;
                save(app.image_file, 'prefs', '-append');

                % Update the views.
                app.DrawImageData();
            end
        end

        % Menu selected function: ToggleBirthTimesMenu
        function ToggleNeuronBirthTimes(app, event)
            % Toggle showing or hiding neuron birth times.
            GUI_prefs = Program.GUIPreferences.instance();
            if contains(lower(app.ToggleBirthTimesMenu.Text), 'show')
                GUI_prefs.is_show_birth_times = true;
                app.ToggleBirthTimesMenu.Text = 'Hide Birth Times';
            else
                GUI_prefs.is_show_birth_times = false;
                app.ToggleBirthTimesMenu.Text = 'Show Birth Times';
            end

            % Save the preferences.
            GUI_prefs.save();

            % Update the display.
            UpdateNeuronLists(app)
        end

        % Callback function
        function ImportfromcsvMenuSelected(app, event)
            
            % Display expected formatting.
            DataHandling.PNGViewer.show('./Data/Documents/csv-format.png', 'Expected CSV formatting');
            uiwait(msgbox('Please note that depending on the number of neurons being imported, this may take a while.','NeuroPAL'));

            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Select CSV file.');

            imported_neurons = readtable(join([path,name]),'NumHeaderLines',1);

            for r = 1:size(imported_neurons.Var4)
                adjusted_coords = flip([imported_neurons.Var5(r) imported_neurons.Var4(r)]) + app.mouse_offset;
                app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                    [adjusted_coords imported_neurons.Var3(r)], app.mp_params.hnsz, app.image_um_scale');
            end

            msgbox("Finished importing neurons.","Success");

            SaveIDToFile(app);

        end

        % Menu selected function: AdjustHistogramMenu
        function AdjustHistogramMenuSelected(app, event)
            app.logEvent('Main','Loading histogram GUI...', 1);
            Program.GUI.Apps.histogram_window(app, app.image_data, app.image_neurons, app.image_view, app.image_gamma, Program.GUIPreferences.instance(), app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.DICDropDown.Value, app.GFPDropDown.Value);
        end

        % Menu selected function: AutoSegmentationMenu
        function AutoSegmentationMenuSelected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
            app.logEvent('Main','Loading auto segmentation GUI...', 1);
            Program.GUI.Apps.autoseg_window(app, app.image_data, app.image_neurons, app.image_view, app.image_gamma, app.image_um_scale, app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.DICDropDown.Value, app.GFPDropDown.Value, path, app.gamma_RGBW_DIC_GFP_index)
        end

        % Menu selected function: CellposeDetectMenu
        function CellposeDetectMenuSelected(app, event)
            app.logEvent('Main', 'Running Cellpose detection...', 1);
            progress_dlg = uiprogressdlg(app.CELL_ID, ...
                'Title', 'Cellpose Detect', ...
                'Message', 'Running Cellpose detection. This can take several minutes...', ...
                'Indeterminate', 'on');

            % Do we already have neurons detected?
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                answer = uiconfirm(app.CELL_ID, ...
                    'Neurons have already been marked, would you like to overwrite them?', ...
                    'Neurons Already Marked', ...
                    'Options', {'Overwrite', 'Cancel'}, 'DefaultOption', 2, 'CancelOption', 2, ...
                    'Icon', 'warning');
                if strcmpi(answer, 'cancel')
                    if ~isempty(progress_dlg) && isvalid(progress_dlg)
                        close(progress_dlg);
                    end
                    return;
                end
            end

	            try
	                selected_channels = app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW));
	                [sp, app.mp_params] = Methods.CellposeDetect.detect(app.image_file, ...
	                    app.image_data(:,:,:,selected_channels), ...
	                    app.image_um_scale', ...
	                    'ColorReadoutData', app.image_data_zscored(:,:,:,selected_channels));
	            catch ME
	                if ~isempty(progress_dlg) && isvalid(progress_dlg)
	                    close(progress_dlg);
                end
                rethrow(ME);
            end

            if ~isempty(progress_dlg) && isvalid(progress_dlg)
                close(progress_dlg);
            end

            % Do we have any neuron predictions?
            if isempty(sp)
                uiconfirm(app.CELL_ID, ...
                    'Cellpose detection failed to return any neuron centers!', ...
                    'Cellpose Detect Failed', ...
                    'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'error');

                if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                    Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');
                end
	                return;
	            end

	            % Unselect neurons after a replacement detection has succeeded.
	            UnselectNeuron(app);
	            app.UserNeuronIDsListBox.Items = {};
	            app.UserNeuronIDsListBox.ItemsData = [];
	            app.UserNeuronIDsListBox.Value = {};

	            % Add the detected neurons to the image.
	            app.image_neurons = Neurons.Image(sp, app.worm.body, 'scale', app.image_um_scale);

            % Remove duplicate detections.
            % Neurons within 2 microns of each other.
            Methods.Utils.removeNearbyNeurons(app.image_neurons, 2, 2);

            % Save the IDs.
            SaveIDToFile(app);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Enable the neuron GUI.
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                Program.GUIHandling.gui_lock(app, 'enable', 'neuron_gui');
            end

            % Draw the image.
            app.DrawImageData();

            uiconfirm(app.CELL_ID, ...
                'Cellpose detection completed successfully.', 'Cellpose Detect Completed', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Menu selected function: SpectralUnmixingMenu
        function ColorRegisterMenuSelected(app, event)
            app.logEvent('Main','Loading color registration GUI...', 1);
            if app.TabGroup.SelectedTab ~= app.ImageProcessingTab
                Program.GUI.Apps.channel_selector(app, app.image_data, app.image_gamma, app.image_prefs, app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.GFPDropDown.Value, app.DICDropDown.Value)
            end
        end

        % Menu selected function: FlipLRNeuronsMenu
        function FlipLRNeuronsMenuSelected(app, event)
            if contains(lower(app.worm.sex), 'xo') % Check if male or hermaphrodite
                for n=1:size(app.image_neurons.neurons,2) % Go through all neurons
                    app.image_neurons.neurons(n).annotation = Neurons.Male.flipLR(app.image_neurons.neurons(n).annotation); % Run every neuron through L/R flip appropriate for gender.
                end
            else
                for n=1:size(app.image_neurons.neurons,2) % Go through all neurons
                    app.image_neurons.neurons(n).annotation = Neurons.Hermaphrodite.flipLR(app.image_neurons.neurons(n).annotation); % Run every neuron through L/R flip appropriate for gender.
                end
            end            
        end

        % Callback function: TraceActivityMenu_2, TrackingButton
        function OpenVideo(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
            fullpath = join([GUI_prefs.image_dir,app.image_name]);
            
            app.logEvent('Main','Loading tracking GUI...', 1)

            if isfield(event, 'file') && ~isempty(event.file)
                app.video_path = event.file;
                [path, name, ext] = fileparts(app.video_path);
                if ~isempty(path)
                    path = [path filesep];
                end
                Program.Helpers.debug_event('OpenVideo', ...
                    'Loading video from provided event path: %s', app.video_path);
            else
                file_info = [fullpath ';*.h5;*.nwb;*.nd2;*.tif;*.tiff'];
                [name, path, ~] = uigetfile(file_info, 'Select video array.');
                
                % Check for user cancellation
                if isequal(name,0) || isequal(path,0)
                    app.logEvent('Main', 'Video selection cancelled by user.', 0);
                    return;
                end
                app.video_path = fullfile(path, name); % Use fullfile for robustness
                [~, ~, ext] = fileparts(app.video_path);
            end

            GUI_prefs.image_dir = path;
            GUI_prefs.save();

            % Check if the constructed path is valid and points to a file
            if ~isfile(app.video_path)
                uialert(app.CELL_ID, sprintf('Selected path is not a valid file:\n%s', app.video_path), ...
                        'File Error', 'Icon', 'error');
                app.logEvent('Error', ['Invalid file selected: ' app.video_path], 1);
                return;
            end

            proc_code = app.proc_check('video', app.video_path);
            if proc_code == 1
                return;
            end

            d = [];
            try
                d = uiprogressdlg(app.CELL_ID,'Title','Loading video...','Indeterminate','on');
                Program.Helpers.debug_event('OpenVideo', ...
                    'Starting video open: %s', app.video_path);

                % Safely determine script directory
                if ~isdeployed
                    app.script_dir = fullfile(pwd, '+Wrapper');
                    app.script_ext = '.py';
                else
                    base_path = ctfroot; 
                    if ispc
                        app.script_dir = fullfile(base_path, 'lib', 'bin', 'windows');
                        app.script_ext = '.exe';
                    elseif ismac
                        app.script_dir = fullfile(base_path, 'lib', 'bin', 'macos');
                        app.script_ext = ''; % mac executables don't have extension
                    else
                        error('Unsupported operating system for deployed application script path.');
                    end
                end
                
                % Check if the script directory exists
                if ~isfolder(app.script_dir)
                    error('Critical Error: Script directory not found at "%s". NeuroPAL_ID installation may be incomplete or corrupted.', app.script_dir);
                end

                %% GUI Initialization
                Program.GUIHandling.init_click_states(app); 
                app.AdjustNeuronMarkerAlignmentPanel.Parent = app.DefaultTabGroup.Parent;
                app.AdjustNeuronMarkerAlignmentPanel.Layout = app.DefaultTabGroup.Layout;
                app.AdvancedParameterPanel.Parent = app.BasicParameterPanel.Parent;
                app.AdvancedParameterPanel.Layout = app.BasicParameterPanel.Layout;
                
                app.GridSearchPanel.Parent = app.AdvancedParameterPanel.Parent;
                app.GridSearchPanel.Layout = app.AdvancedParameterPanel.Layout;
                
                %% Load Video
                % Isolate file format
                file_ext_val = lower(ext); % Normalize
                if ~isempty(d) && isvalid(d)
                    d.Message = 'Loading video metadata...';
                    drawnow limitrate;
                end
                
                % Select loading function based on file format
                switch file_ext_val
                    case '.h5'
                        app.load_h5(app.video_path);
                    case '.nwb'
                        app.load_nwb(app.video_path);
                    case '.nd2'
                        app.load_nd2(app.video_path);
                    case {'.tif', '.tiff'}
                        app.load_tif(app.video_path);
                    otherwise
                        error('Unsupported video file format: %s', file_ext_val);
                end

                app.video_frame_cache = [];
                app.video_frame_cache_key = struct('file', '', 't', NaN);
                app.resetVideoViewCache();
                app.clearVideoTimeSliderLiveState();

                % Basic check that video_info was populated (assuming load_... functions throw errors on critical failure)
                if isempty(app.video_info) || ...
                   ~isfield(app.video_info, 'nx') || isempty(app.video_info.nx) || app.video_info.nx == 0 || ...
                   ~isfield(app.video_info, 'ny') || isempty(app.video_info.ny) || app.video_info.ny == 0 || ...
                   ~isfield(app.video_info, 'nt') || isempty(app.video_info.nt) || app.video_info.nt == 0 || ...
	                   ~isfield(app.video_info, 'nz') || isempty(app.video_info.nz) || app.video_info.nz == 0
                    error('Video metadata (dimensions: nx, ny, nt, nz) was not loaded correctly or contains zero values. Cannot proceed.');
                end
                Program.Helpers.debug_event('OpenVideo', ...
                    'Loaded metadata for %s: nx=%d ny=%d nz=%d nc=%d nt=%d', ...
                    app.video_path, app.video_info.nx, app.video_info.ny, ...
                    app.video_info.nz, app.video_info.nc, app.video_info.nt);

                app.xyAxes.XLim = [1, app.video_info.nx];
                app.xyAxes.YLim = [1, app.video_info.ny];
                
                % Define slider limits and values based on video
                app.tSlider.Limits = [1, app.video_info.nt];
                app.ActivityAxes.XTick = 0:app.video_info.nt;
                app.ActivityAxes.XTickLabel = 0:app.video_info.nt;
                app.configureVideoColorDefaults();
                app.configureVideoTimeSlider();
                app.tSlider.Value = 1;
                app.vert_zSlider.Limits = [1, app.video_info.nz];
                app.vert_zSlider.MajorTicks = [];
                app.vert_zSlider.MinorTicks = [];
                app.vert_zSlider.Value = round(app.video_info.nz/2); 
                app.hor_zSlider.Limits = [1, app.video_info.nz]; 
                app.hor_zSlider.MajorTicks = [];
                app.hor_zSlider.MinorTicks = [];
                app.hor_zSlider.Value = round(app.video_info.nz/2); 
                app.xSlider.Limits = [1, app.video_info.nx]; 
                app.xSlider.MajorTicks = [];
                app.xSlider.MinorTicks = [];
                app.xSlider.Value = round(app.video_info.nx/2); 
                app.ySlider.Limits = [1, app.video_info.ny]; 
                app.ySlider.MajorTicks = [];
                app.ySlider.MinorTicks = [];
                app.ySlider.Value = round(app.video_info.ny/2); 
                
                % Render Frame 1
                if ~isempty(d) && isvalid(d)
                    d.Message = 'Rendering first video frame...';
                    drawnow limitrate;
                end
                Program.Helpers.debug_event('OpenVideo', ...
                    'Rendering first frame for %s', app.video_path);
                app.visual_composer(1, round(app.video_info.nz/2), round(app.video_info.ny/2), round(app.video_info.nx/2)); 
                Program.Helpers.debug_event('OpenVideo', ...
                    'Rendered first frame for %s', app.video_path);
                app.data_flags.('Video_Volume') = 1; 
                
                set(app.TrackingButton, 'Visible', 'off'); 
                Program.Helpers.debug_event('OpenVideo', ...
                    'Updating video UI state for %s', app.video_path);
                if ~any(ismember(app.VolumeDropDown.Items, 'Video')) 
                    app.VolumeDropDown.Items{end+1} = 'Video'; 
                end
                volume_dropdown_callback = app.VolumeDropDown.ValueChangedFcn;
                app.VolumeDropDown.ValueChangedFcn = [];
                restore_volume_dropdown_callback = onCleanup(@() set(app.VolumeDropDown, ...
                    'ValueChangedFcn', volume_dropdown_callback));
                app.VolumeDropDown.Value = 'Video';
                app.VolumeDropDown.ValueChangedFcn = volume_dropdown_callback;
                clear restore_volume_dropdown_callback
                Program.Helpers.debug_event('OpenVideo', ...
                    'Set VolumeDropDown to Video without invoking processing swap.');
                set(app.VideoGridLayout, 'Visible', 'on'); 
                Program.Helpers.debug_event('OpenVideo', ...
                    'Enabling video tab controls.');
                Program.GUIHandling.gui_lock(app, 'enable', 'video_tab');
                Program.Helpers.debug_event('OpenVideo', ...
                    'Enabled video tab controls.');
                app.VideoTrackingTab.Tag = 'rendered';
                if ~isempty(d)
                    try
                        if isvalid(d)
                            close(d);
                        end
                    catch
                    end
                    d = [];
                end
                drawnow;
                Program.Helpers.debug_event('OpenVideo', ...
                    'Completed video open for %s', app.video_path);
                Program.GUI.update_zephir_video_tab(app);

            catch ME
                Program.Helpers.debug_event('OpenVideo', ...
                    'Video open failed for "%s": %s', app.video_path, getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.CELL_ID, sprintf('An error occurred during video opening and initialization:\n%s', ME.message), ...
                        'Operation Failed', 'Icon', 'error');
                app.logEvent('Error', ['OpenVideo - ' ME.message ' Stack: ' ME.getReport('extended', 'hyperlinks','off')], 1);
            end
            
            % Ensure the progress dialog is always closed
            if ~isempty(d)
                try
                    if isvalid(d)
                        close(d);
                    end
                catch
                end
            end
        end

        % Menu selected function: DisplayNeuronActivityMenu
        function DisplayNeuronActivityMenuSelected(app, event)
            if app.DisplayNeuronActivityMenu.Checked == 1
                set(app.VolTraceParent,'Visible','Off')
                set(app.LoadNeuronActivityButton,'Visible','On')
                set(app.DisplayNeuronActivityMenu,'Checked','off')
            else
                d = uiprogressdlg(app.CELL_ID,'Title','Loading data...',...
        'Indeterminate','on');
                app.initBrowser()
                set(app.VolTraceParent,'Parent',app.NeuroPALIDTab)
                set(app.LoadNeuronActivityButton,'Visible','Off')
                set(app.VolTraceParent,'Visible','On')
                app.VolTraceParent.Parent = app.LoadNeuronActivityButton.Parent;
                app.VolTraceParent.Layout = app.LoadNeuronActivityButton.Layout;
                set(app.DisplayNeuronActivityMenu,'Checked','on')
                close(d)
            end
        end

        % Menu selected function: CSVfileMenu
        function CSVfileMenuSelected(app, event)
                        
            % Display expected formatting.
            DataHandling.PNGViewer.show('./Data/Documents/csv-format.png', 'Expected CSV formatting');

            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Select CSV file.');

            app.logEvent('Main',sprintf('Loading activity from %s...', join(path, name)), 1);
            imported_neurons = readtable(join([path,name]),'NumHeaderLines',1);

            d = uiprogressdlg(app.CELL_ID,'Title','Loading neurons...','Indeterminate','on');
            for r = 1:size(imported_neurons.Var4)
                adjusted_coords = flip([imported_neurons.Var5(r) imported_neurons.Var4(r)]) + app.mouse_offset;
                app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                    [adjusted_coords imported_neurons.Var3(r)], app.mp_params.hnsz, app.image_um_scale');
            end
            
            SaveIDToFile(app);
            app.DrawImageData()
            close(d)

        end

        % Menu selected function: NWBfileMenu
        function NWBfileMenuSelected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.nwb'];
            [name, path, ~] = uigetfile(file_info, 'Select NWB file.');
            nwb = nwbRead(join([path,name]));
            app.load_neurons_from_nwb(nwb);
        end

        % Menu selected function: SaveNWBMenu
        function SaveNWBMenuSelected(app, event)
            filename = app.image_file;
            suffixI = strfind(filename, '.');
            suffixI = suffixI(end);
            csvfile = cat(2, filename(1:(suffixI - 1)), '.csv');
            Program.GUI.Apps.nwbsave(app, app.image_neurons, app.neuron_activity_by_name, app.image_file, csvfile, app.image_prefs, app.image_data_zscored, app.image_um_scale, app.image_data);
        end

        % Menu selected function: ZephIRoutputMenu
        function ZephIRoutputMenuSelected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Select CSV file.');
            fileName = [path,name];

            % Read the CSV file
            dataTable = readtable(fileName);
        
            % Initialize the map
            app.neuron_activity_by_name = containers.Map('KeyType', 'char', 'ValueType', 'any');
        
            d = uiprogressdlg(app.CELL_ID,'Title','Loading activity...', 'Indeterminate','on');
            % Iterate through the rows
            act_length = 0;
            for i = 1:size(dataTable, 1)
                % Get the neuron name
                try
                    neuronName = dataTable.Var2{i};
                catch
                    %fprintf('Error accessing Var2 at row %d\n', i);
                    continue
                end
        
                % Debugging print statement
                %fprintf('Processing neuron: %s\n', neuronName);
        
                % Initialize an array to store the neuron activity
                neuronActivity = [];
        
                % Iterate through the columns starting from column 6
                for j = 6:size(dataTable, 2)
                    % Append the activity to the array if it's not a NaN
                    if ~isnan(dataTable{i,j})
                        neuronActivity = [neuronActivity, dataTable{i, j}];
                        act_length = size(dataTable{i, j});
                    end
                end

                % If the neuron has activity, save it to the map
                if ~isempty(neuronActivity)
                    app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                    app.neuron_activity_by_name(neuronName) = neuronActivity;
                    %fprintf('Saved activity for neuron: %s\n', neuronName);
                end
            end

            app.neuron_activity_by_name('null') = zeros(act_length);

            neuron_names = keys(app.neuron_activity_by_name);
            neuron_activity = values(app.neuron_activity_by_name);
        
            % Determine the length of the activity arrays
            n = length(neuron_activity{1});
        
            % Initialize an empty matrix for activities
            activity_matrix = zeros(n, numel(neuron_activity));
        
            % Fill the activity matrix
            for i = 1:numel(neuron_activity)
                activity_matrix(:, i) = neuron_activity{i};
            end
        
            % Create a table with neuron names as variable names
            app.activity_table = array2table(activity_matrix, 'VariableNames', matlab.lang.makeValidName(neuron_names));

            Program.GUIHandling.gui_lock(app, 'enable', 'activity_gui');
            close(d)

            if app.DisplayNeuronActivityMenu.Checked == 0
                open_view = uiconfirm(app.CELL_ID,'Activity loaded!','Success!','Options',{'View in main app', 'View in activity browser', 'Skip view'},'DefaultOption','Skip view');
                switch open_view
                    case 'View in main app'
                        app.DisplayNeuronActivityMenuSelected();
                    case 'View in activity browser'
                        app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                    case 'Skip view'
                        return
                end
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'Log'
                    app.initLog();
                case 'NeuroPAL ID'
                    if Program.GUIHandling.processing_tab_rendered(app) && ~isempty(app.image_data)
                        Program.Helpers.sync_main_display_from_processing(app, true);
                    end
                    if app.trigger_reload
                        Program.GUIHandling.proc_reload_volumes(app);
                    end
                case 'Video Tracking'
                    if ~app.TrackingButton.Visible & strcmp(app.VideoTrackingTab.Tag, 'raw')
                        app.VideoTrackingTab.Tag = 'rendered';
                    end

                    if app.trigger_reload
                        Program.GUIHandling.proc_reload_volumes(app);
                    end
                case 'Image Processing'
                    if ~app.ProcessingButton.Visible & strcmp(app.ImageProcessingTab.Tag, 'raw')
                        volume_value = string(app.VolumeDropDown.Value);
                        if isempty(volume_value) || strlength(volume_value(1)) == 0
                            return
                        end
                        switch lower(char(volume_value(1)))
                            case 'colormap'
                                if isempty(app.image_file)
                                    return
                                end
                                app.proc_load('image', app.image_file);
                            case 'video'
                                if isempty(app.video_info) || ~isfield(app.video_info, 'file')
                                    return
                                end
                                app.proc_load('video', app.video_info.file);
                            otherwise
                                return
                        end

                        app.ImageProcessingTab.Tag = 'rendered';
                    end
            end
        end

        % Callback function
        function ClearSelectionButtonPushed(app, event)
            for n=1:size(app.BrowserTrace.Children)
                app.BrowserTrace.Children(n).Color = app.BrowserTrace.Children(n).UserData;
            end

            app.BrowserTrace.Title.String = app.BrowserTrace.UserData;
            set(app.ClearSelectionButton, 'Visible', 'off');
            set(app.ClearSelectionButton, 'Enable', 'off');
        end

        % Button pushed function: AddPlotButton
        function AddPlotButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Adding plot...', 'Indeterminate','on');
            app.VolTrace.DisplayVariables{end+1} = {'null'};
            drawnow;
            app.drawStimuli();
            app.color_tracker{end+1} = [];
            app.style_tracker{end+1} = [];
            if size(app.VolTrace.DisplayVariables,2)>1
                set(app.DeletePlotDropDown,'Enable','on')
                item_labels = string(1:size(app.VolTrace.DisplayVariables,2));
                idx = 1:size(app.VolTrace.DisplayVariables,2);
                app.DeletePlotDropDown.Items = string(arrayfun(@(x) strcat('Plot #', num2str(x)), item_labels(idx), 'UniformOutput', false));
                app.DeletePlotDropDown.ItemsData = num2cell(1:size(app.VolTrace.DisplayVariables,2));
            else
                set(app.DeletePlotDropDown,'Enable','off')
            end
            close(d)
        end

        % Clicked callback: IDdNeuronsListBox
        function IDdNeuronsListBoxClicked(app, event)
            %{
            
            name = app.IDdNeuronsListBox.Items{event.InteractionInformation.Item};

            % Get the current mouse position within the app
            mousePos = app.CELL_ID.CurrentPoint;

            % Update HoverLabel text
            app.HoverLabel.Text = name;
            
            % Make the label visible
            app.HoverLabel.Visible = 'on';

            %}
        end

        % Value changed function: DeletePlotDropDown
        function DeletePlotDropDownValueChanged(app, event)
            app.VolTrace.DisplayVariables(app.DeletePlotDropDown.Value) = [];
            app.color_tracker{app.DeletePlotDropDown.Value} = [];
            app.style_tracker{app.DeletePlotDropDown.Value} = [];
            if size(app.VolTrace.DisplayVariables,2)>1
                set(app.DeletePlotDropDown,'Enable','on')
                app.DeletePlotDropDown.Value = app.DeletePlotDropDown.ItemsData{app.DeletePlotDropDown.Value-1};
                item_labels = string(1:size(app.VolTrace.DisplayVariables,2));
                idx = 1:size(app.VolTrace.DisplayVariables,2);
                app.DeletePlotDropDown.Items = string(arrayfun(@(x) strcat('Plot #', num2str(x)), item_labels(idx), 'UniformOutput', false));
                app.DeletePlotDropDown.ItemsData = num2cell(1:size(app.VolTrace.DisplayVariables,2));
            else
                set(app.DeletePlotDropDown,'Enable','off')
                app.DeletePlotDropDown.Items = {};
                app.DeletePlotDropDown.ItemsData = [];
            end
            app.drawStimuli();
        end

        % Value changed function: DeleteNeuronDropDown
        function DeleteNeuronDropDownValueChanged(app, event)
            value = app.DeleteNeuronDropDown.Value;
            
        end

        % Button pushed function: LoadStimuliButton
        function LoadStimuliButtonPushed(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
        
            % Ask the user which file they want.
            file_info = [path ';*.txt;*.nwb'];
            [name, path, ~] = uigetfile(file_info, 'Select stimulus file.');
            fileName = [path,name];
            app.LoadStimuliButton.Tag = fileName;
            
            d = uiprogressdlg(app.CELL_ID,'Title','Loading stimuli...', 'Indeterminate','on');
            if endsWith(fileName, '.nwb')
                stim_file = nwbRead(fileName);
                if any(ismember(stim_file.processing.get('CalciumActivity').nwbdatainterface.keys, 'StimulusInfo'))
                    labels = stim_file.processing.get('CalciumActivity').nwbdatainterface.get('StimulusInfo').data.load();
                    timestamps = stim_file.processing.get('CalciumActivity').nwbdatainterface.get('StimulusInfo').timestamps.load();
                    app.stimuli = table(timestamps, labels);
                elseif ~isempty(stim_file.intervals)
                    if any(size(stim_file.intervals.keys) > 0)
                        stim_key = stim_file.intervals.keys{1};

                        start_times = stim_file.intervals.get(stim_key).start_time.data.load();
                        stop_times = stim_file.intervals.get(stim_key).stop_time.data.load();

                        if ~isempty(stim_file.intervals.get(stim_key).vectordata.keys)
                            labels = stim_file.intervals.get(stim_key).vectordata.get('stimulus').data.load();
                        else
                            labels = repmat(string(stim_key), size(start_times, 1), 1);
                        end

                        if all(size(app.activity_module.timestamps) > 0)
                            if start_times(1) < app.activity_table.timestamps(1)
                                start_times = start_times + app.activity_table.timestamps(1);
                                stop_times = stop_times + app.activity_table.timestamps(1);
                            end
                        end

                        if isprop(app.activity_module, 'starting_time_rate')
                            if app.activity_module.starting_time_rate ~= 1
                                start_times = start_times * app.activity_module.starting_time_rate;
                                stop_times = stop_times * app.activity_module.starting_time_rate;
                            end
                        end

                        app.stimuli = table(start_times, stop_times, labels);
                    end
                else
                    uiconfirm(app.CELL_ID, 'No stimulus info in this file.', 'Error');
                end
            else        
                app.stimuli = readtable(fileName, 'Delimiter', ',', 'Format', '%d%s');
            end

            app.drawStimuli();
            close(d);
        end

        % Button pushed function: OpenRepositoryButton
        function OpenRepositoryButtonPushed(app, event)
            web("https://github.com/Yemini-Lab/NeuroPAL_ID")
        end

        % Button pushed function: SubmitbugreportButton
        function SubmitbugreportButtonPushed(app, event)
            ver_support = ver;
            ver_support = strcat(ver_support.Name, ver_support.Version, ver_support.Release, ver_support.Date);
            ver_support = replace(ver_support,' ','%20');
            ver_support = replace(ver_support,'\n','%0D%0A');
            app.Log.Value
            log = strcat(app.Log.Value);
            %web(sprintf("https://github.com/Yemini-Lab/NeuroPAL_ID/issues/new?title=foo&body=**Description:**%0D%0AEnter%20your%20issue%20details.%0D%0A%0D%0A**System%20Info:**%0D%0A%s%0D%0A%0D%0A**MATLAB%20ver%20-support:**%0D%0A%s%0D%0A%0D%0A**Log:**%0D%0A%s",app.OperatingSystemEditField.Value,ver_support,log))
        end

        % Menu selected function: LogtesteventMenu
        function LogtesteventMenuSelected(app, event)
            app.logEvent('Segmentation','Placing neuron dots...', 0)
        end

        % Menu selected function: LogfadeeventMenu
        function LogfadeeventMenuSelected(app, event)
            app.logEvent('Segmentation','Placing neuron dots...', 1)
        end

        % Menu selected function: NWBfileMenu_2
        function NWBfileMenu_2Selected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
            
            file_info = [path, ';*.nwb'];
            [name, path, ~] = uigetfile(file_info, 'Select NWB file.');
            labels = [];
            spec_labels = 0;

            d = uiprogressdlg(app.CELL_ID,'Title','Loading activity...', 'Indeterminate','off');
            nwb_file = nwbRead(fullfile(path, name));

            % Initialize the map
            app.neuron_activity_by_name = containers.Map('KeyType', 'char', 'ValueType', 'any');
    
            if any(ismember(nwb_file.processing.keys, 'CalciumActivity'))
                if any(ismember(nwb_file.processing.get('CalciumActivity').nwbdatainterface.keys, 'SignalRawFluor'))
                    activity = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('SignalRawFluor').roiresponseseries.get('SignalCalciumImResponseSeries').data.load();
                    app.activity_module = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('SignalRawFluor').roiresponseseries.get('SignalCalciumImResponseSeries');
                elseif any(ismember(nwb_file.processing.get('CalciumActivity').dynamictable.keys, 'ActivityTraces'))
                    activity = nwb_file.processing.get('CalciumActivity').dynamictable.get('ActivityTraces').vectordata.get('activity').data.load();
                    activity = activity';
                    app.activity_module = nwb_file.processing.get('CalciumActivity').dynamictable.get('ActivityTraces');

                    labels = nwb_file.processing.get('CalciumActivity').dynamictable.get('ActivityTraces').vectordata.get('neuron').data.load();
                else
                    activity = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('ActivityDfOverF').roiresponseseries.get(nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('ActivityDfOverF').roiresponseseries.keys{1}).data.load();
                    app.activity_module = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('ActivityDfOverF').roiresponseseries.get(nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('ActivityDfOverF').roiresponseseries.keys{1});
                    activity = activity';
                end

                if isempty(labels) & any(ismember(nwb_file.processing.get('CalciumActivity').nwbdatainterface.keys, 'NeuronIDs'))
                    labels = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('NeuronIDs').labels.load();
                elseif isempty(labels)
                    labels = nwb_file.processing.get('CalciumActivity').dynamictable.get('ActivityLabels').vectordata.get('neuron').data.load();
                    spec_labels = 1;
                end
            else
                'debug'
                error('Could not find Calcium Activity in %s.', fullfile(path, name));
            end

            if spec_labels ~=1
                for n=1:size(labels, 1)
                    d.Value = n / size(labels, 1);
                    if strcmp(labels{n}, 'unknown') || strcmp(labels{n}, '')
                        continue
                    else
                        % Get the neuron name
                        try
                            neuronName = labels{n};
                        catch
                            %fprintf('Error accessing Var2 at row %d\n', i);
                            continue
                        end
    
                        d.Message = sprintf('Processing neuron: %s\n', neuronName);
                
                        % Initialize an array to store the neuron activity
                        neuronActivity = [];
                        neuronActivity = activity(n, :);
                
                        % If the neuron has activity, save it to the map
                        if ~isempty(neuronActivity)
                            app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                            app.neuron_activity_by_name(neuronName) = neuronActivity;
                        end
                    end
                end
            else
                for n=1:size(activity, 1)
                    d.Value = n / size(activity, 1);
                    if strcmp(labels{n}, 'unknown') || strcmp(labels{n}, '')
                        continue
                    else
                        % Get the neuron name
                        try
                            neuronName = labels{n};
                        catch
                            %fprintf('Error accessing Var2 at row %d\n', i);
                            continue
                        end
    
                        d.Message = sprintf('Processing neuron: %s\n', neuronName);
                
                        % Initialize an array to store the neuron activity
                        neuronActivity = [];
                        neuronActivity = activity(n, :);
                
                        % If the neuron has activity, save it to the map
                        if ~isempty(neuronActivity)
                            app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                            app.neuron_activity_by_name(neuronName) = neuronActivity;
                        end
                    end
                end
            end
            
            app.neuron_activity_by_name('null') = zeros(1, size(neuronActivity, 2));

            neuron_names = keys(app.neuron_activity_by_name);
            neuron_activity = values(app.neuron_activity_by_name);
        
            % Determine the length of the activity arrays
            n = length(neuron_activity{2});
        
            % Initialize an empty matrix for activities
            activity_matrix = zeros(n, size(neuron_activity, 2));
        
            % Fill the activity matrix
            for i = 1:numel(neuron_activity)
                activity_matrix(:, i) = neuron_activity{i}';
            end
        
            % Create a table with neuron names as variable names
            app.activity_table = array2table(activity_matrix, 'VariableNames', matlab.lang.makeValidName(neuron_names));

            if size(properties(app.activity_module), 1) == 2
                if any(ismember(app.activity_module.keys, 'timestamps'))
                    if all(size(app.activity_module.timestamps) > 0)
                        app.activity_table.timestamps = app.activity_module.timestamps.load();
                    end
                end
            end

            Program.GUIHandling.gui_lock(app, 'enable', 'activity_gui');
            close(d)

            if app.DisplayNeuronActivityMenu.Checked == 0
                open_view = uiconfirm(app.CELL_ID,'Activity loaded!','Success!','Options',{'View now', 'Skip view'},'DefaultOption','Skip view');
                switch open_view
                    case 'View now'
                        app.DisplayNeuronActivityMenuSelected();
                    case 'Skip view'
                        return
                end
            end
        end

        % Callback function
        function DiagnoseButtonPushed(app, event)
            [state, load] = app.engineCheck();
            app.wrapHandler(state, load);
        end

        % Callback function
        function SelectpythonexecutableButtonPushed(app, event)
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            if ~isdeployed
                if ispc
                    [name, path, ~] = uigetfile([app.path ';*.exe;'], 'Select python executable');
                elseif ismac
                    [name, path, ~] = uigetfile([app.path ';*.app;'], 'Select python executable');
                end
            end
            pyversion([path, name])
            app.CELL_ID.Visible = 'on'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
        end

        % Callback function
        function ShellEditFieldValueChanged(app, event)
            [status, output] = system(app.ShellEditField.Value);
            output = string(output); % Convert output to string
            
            % If app.ShellLog.Value is a string array or a character vector
            if isstring(app.ShellLog.Value) || ischar(app.ShellLog.Value)
                app.ShellLog.Value = [app.ShellLog.Value; output]; % Vertically concatenate
            elseif iscell(app.ShellLog.Value)
                % Convert output to cell array of character vectors, then concatenate
                outputCell = cellstr(output);
                app.ShellLog.Value = [app.ShellLog.Value; outputCell];
            else
                error('Unsupported type for app.ShellLog.Value');
            end
            
            scroll(app.ShellLog, 'bottom');
            app.ShellEditField.Value = '';
        end

        % Callback function
        function generateCoreMenuSelected(app, event)
            generateCore()
        end

        % Callback function
        function Neuron1MenuSelected(app, event)
            app.image_neurons.neurons(1)
            app.image_neurons.neurons(end)
        end

        % Value changed function: EnabledebugmenuCheckBox
        function EnabledebugmenuCheckBoxValueChanged(app, event)
            app.DebugMenu.Visible = 'on';
            app.TestResultPanel.Visible = 'on';
            app.RuntestroutineButton.Enable = 'on';
        end

        % Button pushed function: LoadNeuronActivityButton
        function LoadNeuronActivityButtonPushed(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
            
            file_info = [path, ';*.nwb;*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Select activity file.');

            d = uiprogressdlg(app.CELL_ID,'Title','Loading activity...', 'Indeterminate','off');

            % Initialize the map
            app.neuron_activity_by_name = containers.Map('KeyType', 'char', 'ValueType', 'any');

            if endsWith(name, '.nwb')
                spec_labels = 0;
    
                nwb_file = nwbRead(fullfile(path, name));
        
                if any(ismember(nwb_file.processing.keys, 'CalciumActivity'))
                    if any(ismember(nwb_file.processing.get('CalciumActivity').nwbdatainterface.keys, 'SignalRawFluor'))
                        activity = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('SignalRawFluor').roiresponseseries.get('SignalCalciumImResponseSeries').data.load();
                    else
                        activity = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('ActivityDfOverF').roiresponseseries.get(nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('ActivityDfOverF').roiresponseseries.keys{1}).data.load();
                        activity = activity';
                    end
    
                    if any(ismember(nwb_file.processing.get('CalciumActivity').nwbdatainterface.keys, 'NeuronIDs'))
                        labels = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('NeuronIDs').labels.load();
                    else
                        labels = nwb_file.processing.get('CalciumActivity').dynamictable.get('ActivityLabels').vectordata.get('neuron').data.load();
                            spec_labels = 1;
                        end
                else
                    'debug'
                    error('Could not find Calcium Activity in %s.', fullfile(path, name));
                end
    
                if spec_labels ~=1
                    for n=1:size(labels, 1)
                        d.Value = n / size(labels, 1);
                        if strcmp(labels{n}, 'unknown') || strcmp(labels{n}, '')
                            continue
                        else
                            % Get the neuron name
                            try
                                neuronName = labels{n};
                            catch
                                %fprintf('Error accessing Var2 at row %d\n', i);
                                continue
                            end
        
                            d.Message = sprintf('Processing neuron: %s\n', neuronName);
                    
                            % Initialize an array to store the neuron activity
                            neuronActivity = [];
                            neuronActivity = activity(n, :);
                    
                            % If the neuron has activity, save it to the map
                            if ~isempty(neuronActivity)
                                app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                                app.neuron_activity_by_name(neuronName) = neuronActivity;
                            end
                        end
                    end
                else
                    for n=1:size(activity, 1)
                        d.Value = n / size(activity, 1);
                        if strcmp(labels{n}, 'unknown') || strcmp(labels{n}, '')
                            continue
                        else
                            % Get the neuron name
                            try
                                neuronName = labels{n};
                            catch
                                %fprintf('Error accessing Var2 at row %d\n', i);
                                continue
                            end
        
                            d.Message = sprintf('Processing neuron: %s\n', neuronName);
                    
                            % Initialize an array to store the neuron activity
                            neuronActivity = [];
                            neuronActivity = activity(n, :);
                    
                            % If the neuron has activity, save it to the map
                            if ~isempty(neuronActivity)
                                app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                                app.neuron_activity_by_name(neuronName) = neuronActivity;
                            end
                        end
                    end
                end
                
                app.neuron_activity_by_name('null') = zeros(1, size(neuronActivity, 2));
    
                neuron_names = keys(app.neuron_activity_by_name);
                neuron_activity = values(app.neuron_activity_by_name);
            
                % Determine the length of the activity arrays
                n = length(neuron_activity{2});
            
                % Initialize an empty matrix for activities
                activity_matrix = zeros(n, size(neuron_activity, 2));
            
                % Fill the activity matrix
                for i = 1:numel(neuron_activity)
                    activity_matrix(:, i) = neuron_activity{i}';
                end
            
                % Create a table with neuron names as variable names
                app.activity_table = array2table(activity_matrix, 'VariableNames', matlab.lang.makeValidName(neuron_names));
            elseif endsWith(name, '.csv')
                % Read the CSV file
                dataTable = readtable(fileName);
            
                % Iterate through the rows
                act_length = 0;
                for i = 1:size(dataTable, 1)
                    % Get the neuron name
                    try
                        neuronName = dataTable.Var2{i};
                    catch
                        %fprintf('Error accessing Var2 at row %d\n', i);
                        continue
                    end
            
                    % Debugging print statement
                    %fprintf('Processing neuron: %s\n', neuronName);
            
                    % Initialize an array to store the neuron activity
                    neuronActivity = [];
            
                    % Iterate through the columns starting from column 6
                    for j = 6:size(dataTable, 2)
                        % Append the activity to the array if it's not a NaN
                        if ~isnan(dataTable{i,j})
                            neuronActivity = [neuronActivity, dataTable{i, j}];
                            act_length = size(dataTable{i, j});
                        end
                    end
            
                    % If the neuron has activity, save it to the map
                    if ~isempty(neuronActivity)
                        app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                        app.neuron_activity_by_name(neuronName) = neuronActivity;
                        %fprintf('Saved activity for neuron: %s\n', neuronName);
                    end
                end
    
                app.neuron_activity_by_name('null') = zeros(act_length);
    
                neuron_names = keys(app.neuron_activity_by_name);
                neuron_activity = values(app.neuron_activity_by_name);
            
                % Determine the length of the activity arrays
                n = length(neuron_activity{1});
            
                % Initialize an empty matrix for activities
                activity_matrix = zeros(n, numel(neuron_activity));
            
                % Fill the activity matrix
                for i = 1:numel(neuron_activity)
                    activity_matrix(:, i) = neuron_activity{i};
                end
            
                % Create a table with neuron names as variable names
                app.activity_table = array2table(activity_matrix, 'VariableNames', matlab.lang.makeValidName(neuron_names));
            end
    
            app.DisplayNeuronActivityMenuSelected();
            Program.GUIHandling.gui_lock(app, 'enable', 'activity_gui');
            close(d)
        end

        % Value changed function: BCheckBox, DICCheckBox, GCheckBox, 
        % ...and 3 other components
        function RCheckBoxValueChanged(app, event)
            app.ProcRCheckBox.Value = app.RCheckBox.Value;
            app.ProcGCheckBox.Value = app.GCheckBox.Value;
            app.ProcBCheckBox.Value = app.BCheckBox.Value;
            app.ProcWCheckBox.Value = app.WCheckBox.Value;
            app.ProcDICCheckBox.Value = app.DICCheckBox.Value;
            app.ProcGFPCheckBox.Value = app.GFPCheckBox.Value;

            app.DrawImageData();
        end

        % Value changed function: proc_xSlider
        function proc_xSliderValueChanged(app, event)
            app.proc_xSlider.Value = round(app.proc_xSlider.Value);
            app.proc_xEditField.Value = app.proc_xSlider.Value;
            app.drawProcImage();
        end

        % Value changed function: proc_hor_zSlider
        function proc_hor_zSliderValueChanged(app, event)
            Program.GUIHandling.sync_processing_zcontrols(app, round(event.Value));
            Program.Routines.Processing.render();
        end

        % Value changed function: proc_vert_zSlider
        function proc_vert_zSliderValueChanged(app, event)
            z_value = round(app.proc_vert_zSlider.Limits(2) - event.Value);
            Program.GUIHandling.sync_processing_zcontrols(app, z_value);
            Program.Routines.Processing.render();
        end

        % Value changed function: proc_ySlider
        function proc_ySliderValueChanged(app, event)
            app.proc_ySlider.Value = round(app.proc_ySlider.Value);
            app.proc_yEditField.Value = app.proc_ySlider.Value;
            app.drawProcImage();
        end

        % Value changing function: proc_xSlider
        function proc_xSliderValueChanging(app, event)
            delete(app.proc_xz_xline);
            delete(app.proc_xy_xline);
            value = round(event.Value);
            app.proc_xz_xline = xline(app.proc_xzAxes, value, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_xy_xline = xline(app.proc_xyAxes, value, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_xEditField.Value = value;
        end

        % Value changing function: proc_hor_zSlider
        function proc_hor_zSliderValueChanging(app, event)
            Program.GUIHandling.sync_processing_zcontrols(app, round(event.Value));
            Program.Routines.Processing.render_live_slice();
            drawnow limitrate nocallbacks;
        end

        % Value changing function: proc_ySlider
        function proc_ySliderValueChanging(app, event)
            delete(app.proc_yz_yline)
            delete(app.proc_xy_yline)

            app.proc_yz_yline = yline(app.proc_yzAxes, round(size(app.image_data, 1)-event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_xy_yline = yline(app.proc_xyAxes, round(size(app.image_data, 1)-event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_yEditField.Value = round(event.Value);
        end

        % Value changing function: proc_vert_zSlider
        function proc_vert_zSliderValueChanging(app, event)
            z_value = round(app.proc_vert_zSlider.Limits(2) - event.Value);
            Program.GUIHandling.sync_processing_zcontrols(app, z_value);
            Program.Routines.Processing.render_live_slice();
            drawnow limitrate nocallbacks;
        end

        % Value changing function: proc_zSlider
        function proc_zSliderValueChanging(app, event)
            Program.GUIHandling.sync_processing_zcontrols(app, round(event.Value));
            Program.Routines.Processing.render_live_slice();
            drawnow limitrate nocallbacks;
        end

        % Value changed function: ProcNoiseThresholdKnob
        function ProcNoiseThresholdKnobValueChanged(app, event)
            app.ProcNoiseThresholdField.Value = event.Value;
            app.drawProcImage();
        end

        % Button pushed function: InsertNeuroPALNeuronsButton
        function InsertNeuroPALNeuronsButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Importing neurons...','Indeterminate','on');
            
            target_neurons = app.image_neurons.neurons;
            target_positions = app.image_neurons.get_positions;
            vol_nx = size(app.image_view, 2);
            vol_ny = size(app.image_view, 1);
            
            if isempty(target_positions)
                close(d)
                uiconfirm(app.CELL_ID, 'No NeuroPAL neurons are available to insert.', 'No Neurons');
                return
            end

            has_dimension_mismatch = vol_nx ~= app.video_info.nx || vol_ny ~= app.video_info.ny || ...
                any(target_positions(:,2) > app.video_info.nx) || any(target_positions(:,1) > app.video_info.ny);
            if has_dimension_mismatch
                uiconfirm(app.CELL_ID, 'Volume dimensions do not match video dimensions. Imported array will be resized.', 'Warning!');

                x_ratio = app.video_info.nx / vol_nx;
                y_ratio = app.video_info.ny / vol_ny;

                for n=1:size(target_neurons, 2)
                    target_neurons(n).position(2) = target_neurons(n).position(2) * x_ratio;
                    target_neurons(n).position(1) = target_neurons(n).position(1) * y_ratio;
                    
                    if app.image_prefs.is_Z_flip
                        target_neurons(n).position(3) = abs(target_neurons(n).position(3)-app.video_info.nz);
                    end

                    app.add_worldline(target_neurons(n))

                    if app.image_prefs.is_Z_flip
                        target_neurons(n).position(3) = abs(target_neurons(n).position(3)-app.video_info.nz);
                    end

                    target_neurons(n).position(2) = target_neurons(n).position(2) / x_ratio;
                    target_neurons(n).position(1) = target_neurons(n).position(1) / y_ratio;
                end
            else
                for n=1:size(app.image_neurons.neurons, 2)
                    
                    if app.image_prefs.is_Z_flip
                        target_neurons(n).position(3) = abs(target_neurons(n).position(3)-app.video_info.nz);
                    end

                    app.add_worldline(target_neurons(n))
                    
                    if app.image_prefs.is_Z_flip
                        target_neurons(n).position(3) = abs(target_neurons(n).position(3)-app.video_info.nz);
                    end
                end
            end

            app.visual_composer();
            close(d)
        end

        % Button pushed function: InsertlastIDdFrameButton
        function InsertlastIDdFrameButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title',"Importing from last ID'd frame...",'Indeterminate','on');
            earlier_frames = app.id_frames(app.id_frames < app.tSlider.Value);
            if isempty(earlier_frames)
                close(d)
                uiconfirm(app.CELL_ID, "No earlier ID'd frame is available to import.", "No Source Frame");
                return
            end
            source_frame = max(earlier_frames);

            for n=1:size(app.video_neurons, 2)
                if numel(app.video_neurons(n).rois) >= source_frame
                    app.video_neurons(n).rois(app.tSlider.Value) = app.video_neurons(n).rois(source_frame);
                end
            end
            if ~any(app.id_frames == app.tSlider.Value)
                app.id_frames = [app.id_frames; app.tSlider.Value];
            end

            app.visual_composer();
            close(d)
        end

        % Button pushed function: AssignrandomcolorstoblanksButton
        function AssignrandomcolorstoblanksButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Updating colors...','Indeterminate','on');
            for n=1:size(app.video_neurons, 2)
                current_color = app.video_neurons(n).worldline.color;
                if isnumeric(current_color) && numel(current_color) >= 3 && ...
                        isequal(round(current_color(1:3), 6), [0.8, 0.8, 0.8])
                    app.video_neurons(n).worldline.color = rand(1, 3);
                    app.video_neurons(n).worldline.style = uistyle("FontColor", app.video_neurons(n).worldline.color);
                    addStyle(app.WorldlineTree, app.video_neurons(n).worldline.style, "node", app.video_neurons(n).worldline.node);
                end
            end
            app.visual_composer();
            close(d)
        end

        % Button pushed function: ManipulateNeuronsButton
        function ManipulateNeuronsButtonPushed(app, event)
            set(app.AdjustNeuronMarkerAlignmentPanel, 'Visible', 'on');
            set(app.DefaultTabGroup, 'Visible', 'off');
            
            app.repo_neurons = app.video_neurons;
            app.sel_neurons = 1:size(app.video_neurons, 2);
            app.sel_neurons = app.sel_neurons';
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            app.visual_composer();
        end

        % Value changed function: GSlider
        function GSliderValueChanged(app, event)
            app.visual_composer();
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            app.visual_composer();
        end

        % Value changed function: tSlider
        function tSliderValueChanged(app, event)
            app.tSlider.Value = min(max(round(event.Value), 1), app.tSlider.Limits(2));
            setappdata(app.CELL_ID, 'video_tslider_live_last_t', app.tSlider.Value);
            app.visual_composer();
        end

        % Value changing function: tSlider
        function tSliderValueChanging(app, event)
            target_t = min(max(round(event.Value), 1), app.tSlider.Limits(2));
            app.tEditField.Value = target_t;

            if ~app.shouldRenderVideoTimeSliderLive(target_t)
                drawnow limitrate nocallbacks
                return
            end

            setappdata(app.CELL_ID, 'video_tslider_live_rendering', true);
            cleanup = onCleanup(@() setappdata(app.CELL_ID, ...
                'video_tslider_live_rendering', false));
            app.visual_composer(target_t);
            clear cleanup
            drawnow limitrate nocallbacks
        end

        % Button pushed function: SetBookmarkButton
        function SetBookmarkButtonPushed(app, event)
            bm_frame = round(app.tSlider.Value);

            if ~isempty(app.BookmarkNameEditField.Value)
                bm_name = app.BookmarkNameEditField.Value;
            else
                bm_name = sprintf('Bookmark #%.f', size(app.BookmarkListBox.Items,2)+1);
            end

            app.BookmarkListBox.Items = [app.BookmarkListBox.Items, sprintf("%s (Frame %.f)", bm_name, bm_frame)];

            if size(app.BookmarkListBox.ItemsData)>0
                app.BookmarkListBox.ItemsData = [app.BookmarkListBox.ItemsData, bm_frame];
            else
                app.BookmarkListBox.ItemsData = [bm_frame];
            end

            app.BookmarkNameEditField.Value = '';
        end

        % Button pushed function: GotoBookmarkButton
        function GotoBookmarkButtonPushed(app, event)
            if ~isempty(app.BookmarkListBox.Value)
                [~, idx] = ismember(app.BookmarkListBox.Value, app.BookmarkListBox.Items);
                if idx < 1 || numel(app.BookmarkListBox.ItemsData) < idx
                    uiconfirm(app.CELL_ID, "Selected bookmark has no frame data.", "Invalid Bookmark");
                    return
                end
                app.tSlider.Value = app.BookmarkListBox.ItemsData(idx);
                app.visual_composer();
            else
                uiconfirm(app.CELL_ID,"Error", 'No bookmark selected.');
            end
        end

        % Button pushed function: DeleteBookmarkButton
        function DeleteBookmarkButtonPushed(app, event)
            if ~isempty(app.BookmarkListBox.Value)
                [~,idx] = ismember(app.BookmarkListBox.Value, app.BookmarkListBox.Items);
                app.BookmarkListBox.Items(idx) = [];
                if numel(app.BookmarkListBox.ItemsData) >= idx
                    app.BookmarkListBox.ItemsData(idx) = [];
                end
            else
                uiconfirm(app.CELL_ID,"Error", 'No bookmark selected.');
            end
        end

        % Value changed function: OverlayFrameMIPCheckBox
        function OverlayFrameMIPCheckBoxValueChanged(app, event)
            app.OverlayFrameMIPCheckBox_2.Value = app.OverlayFrameMIPCheckBox.Value;
            app.visual_composer();
        end

        % Value changed function: OverlaylastIDdframeCheckBox
        function OverlaylastIDdframeCheckBoxValueChanged(app, event)
            app.OverlaylastIDdframeCheckBox_2.Value = app.OverlaylastIDdframeCheckBox.Value;
            app.visual_composer();
        end

        % Button pushed function: RecommendFramesButton
        function RecommendFramesButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Analyzing frames...','Indeterminate','on');
            analysis_path = app.ensure_video_analysis_h5();
            
            args = sprintf('--channel=%.f --dataset="%s" --nx=%.f --ny=%.f --nz=%.f --nc=%.f --nt=%.f', ...
                app.zephir_analysis_channel(), analysis_path, app.video_info.nx, app.video_info.ny, app.video_info.nz, app.video_info.nc, app.video_info.nt);
            cmd = app.python_script_command('recommend_frames', args);

            [status, output] = system(sprintf('%s', cmd));
            if status ~= 0
                close(d)
                error('Failed to select reference frames: %s', output);
            end
            t_ref_match = regexp(output, '\[[0-9,\s]+\]', 'match');
            if isempty(t_ref_match)
                close(d)
                error('Frame recommendation output did not contain a frame list: %s', output);
            end
            t_ref = split(strrep(strrep(t_ref_match{end}, '[', ''), ']', ''), ',');
            close(d)

            d = uiprogressdlg(app.CELL_ID,'Title','Creating bookmarks...','Indeterminate','on');

            for n=1:size(t_ref,1)
                bmFrame = str2double(strtrim(t_ref{n}));
                bmLabel = sprintf('Reference Frame #%.f (Frame %.f)', n, bmFrame+1);
                
                app.BookmarkListBox.Items = [app.BookmarkListBox.Items, bmLabel];
                if size(app.BookmarkListBox.ItemsData)>0
                    app.BookmarkListBox.ItemsData = [app.BookmarkListBox.ItemsData, bmFrame+1];
                else
                    app.BookmarkListBox.ItemsData = [bmFrame+1];
                end
            end

            close(d)
        end

        % Button pushed function: TrackNeuronsButton
        function TrackNeuronsButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Preparing analysis data...','Indeterminate','on');
            analysis_path = app.ensure_video_analysis_h5();
            close(d)
            
            d = uiprogressdlg(app.CELL_ID,'Title','Saving annotations...','Indeterminate','on');
            [analysis_dir, ~, ~] = fileparts(analysis_path);
            DataHandling.Helpers.h5.write_tracks(app.video_info, app.video_neurons, fullfile(analysis_dir, 'annotations.h5'));
            close(d)

            d = uiprogressdlg(app.CELL_ID,'Title','Compiling arguments...','Indeterminate','on');
            args = app.compile_arguments();
            close(d)

            d = uiprogressdlg(app.CELL_ID,'Title','Tracking neurons...','Indeterminate','on');
            py_cmd = app.python_script_command('main', args);
            [status, output] = system(py_cmd, '-echo');
            close(d)

            switch status
                case 0
                    app.load_annotations();
                otherwise
                    error('Failed to run tracking routine: %s', output);
            end
        end

        % Button pushed function: ExtractActivityButton
        function ExtractActivityButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Extracting activity...','Indeterminate','on');
            analysis_path = app.ensure_video_analysis_h5();
            py_cmd = app.python_script_command('extract_traces', sprintf('--channel=%.f --dataset="%s"', app.zephir_analysis_channel(), analysis_path));
            [status, output] = system(py_cmd, '-echo');
            close(d)
            if status ~= 0
                error('Failed to extract activity: %s', output);
            end

            d = uiprogressdlg(app.CELL_ID,'Title','Loading activity...', 'Indeterminate','on');
            app.activity = struct();

            [path, ~, ~] = fileparts(app.video_path);

            % Read the CSV file into a table
            tbl = readtable(fullfile(path, 'results.csv'), ReadVariableNames=true);
            tbl
            
            % Loop through each row of the table
            for i = 1:height(tbl)
                % Ensure the worldline name is a string and valid field name
                worldlineName = matlab.lang.makeValidName(char(string(tbl.worldline(i))));

                if ~isfield(app.activity, worldlineName)
                    % If this worldline name is not yet a key in the struct, add it
                    app.activity.(worldlineName) = [];
                end

                T = tbl(i, 4:end);
                app.activity.(worldlineName) = table2array(T(1, :));
                app.ActivityListBox.Items = [app.ActivityListBox.Items, worldlineName];
    
                if size(app.ActivityListBox.ItemsData)>0
                    app.ActivityListBox.ItemsData = [app.ActivityListBox.ItemsData, worldlineName];
                else
                    app.ActivityListBox.ItemsData = [worldlineName];
                end
            end

            app.data_flags.('Neuronal_Activity') = 1;
            close(d)
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            app.ySlider.Value = min(max(round(event.Value), 1), app.ySlider.Limits(2));
            app.visual_composer();
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            app.xSlider.Value = min(max(round(event.Value), 1), app.xSlider.Limits(2));
            app.visual_composer();
        end

        % Value changed function: hor_zSlider
        function hor_zSliderValueChanged(app, event)
            app.hor_zSlider.Value = min(max(round(event.Value), 1), app.hor_zSlider.Limits(2));
            app.vert_zSlider.Value = max(round(app.vert_zSlider.Limits(2)-app.hor_zSlider.Value), 1);

            app.visual_composer();
        end

        % Value changed function: vert_zSlider
        function vert_zSliderValueChanged(app, event)
            app.vert_zSlider.Value = min(max(round(event.Value), 1), app.vert_zSlider.Limits(2));
            app.hor_zSlider.Value = max(round(app.vert_zSlider.Limits(2)-app.vert_zSlider.Value), 1);
            app.visual_composer();
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            value = round(event.Value);
            app.xz_xline = app.setVideoCursorLine(app.xz_xline, app.xzAxes, 'x', value);
            app.xy_xline = app.setVideoCursorLine(app.xy_xline, app.xyAxes, 'x', value);
            drawnow limitrate nocallbacks
        end

        % Value changing function: hor_zSlider
        function hor_zSliderValueChanging(app, event)
            value = app.yzAxes.XLim(2)*(round(event.Value)/app.video_info.nz);
            app.yz_xline = app.setVideoCursorLine(app.yz_xline, app.yzAxes, 'x', value);
            drawnow limitrate nocallbacks
        end

        % Value changing function: vert_zSlider
        function vert_zSliderValueChanging(app, event)
            value = app.xzAxes.YLim(2)-app.xzAxes.YLim(2)*(round(event.Value)/app.video_info.nz);
            app.xz_yline = app.setVideoCursorLine(app.xz_yline, app.xzAxes, 'y', value);
            drawnow limitrate nocallbacks
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            value = round(app.video_info.ny-event.Value);
            app.yz_yline = app.setVideoCursorLine(app.yz_yline, app.yzAxes, 'y', value);
            app.xy_yline = app.setVideoCursorLine(app.xy_yline, app.xyAxes, 'y', value);
            drawnow limitrate nocallbacks
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            app.ColorButton.BackgroundColor = uisetcolor(app.ColorButton.BackgroundColor, 'Select Color');
            app.edit_worldline(app.WorldlineIDEditField.Value, 'color', app.ColorButton.BackgroundColor);
        end

        % Selection changed function: WorldlineTree
        function WorldlineTreeSelectionChanged(app, event)
            app.edit_worldline(app.WorldlineTree.SelectedNodes.NodeData, 'select');
        end

        % Button pushed function: Button_2
        function Button_2Pushed2(app, event)
            app.edit_worldline(app.WorldlineIDEditField.Value, 'delete');
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            app.edit_worldline(app.WorldlineIDEditField.Value, 'save');
        end

        % Button pushed function: ViewPreprintButton
        function ViewPreprintButtonPushed(app, event)
            web('https://www.biorxiv.org/content/10.1101/2022.07.18.500485v1')
        end

        % Button pushed function: GithubRepositoryButton
        function GithubRepositoryButtonPushed(app, event)
            web('https://github.com/venkatachalamlab/ZephIR')
        end

        % Button pushed function: LaboratoryPageButton
        function LaboratoryPageButtonPushed(app, event)
            web('https://venkatachalamlab.org/')
        end

        % Value changed function: MarkerSizeSlider
        function MarkerSizeSliderValueChanged(app, event)
            app.visual_composer();
        end

        % Button pushed function: ImportparametersButton
        function ImportparametersButtonPushed(app, event)
            [path, ~, ~] = fileparts(app.video_path);
            file_info = [path ';*.json'];
            [name, path, ~] = uigetfile(file_info, 'Select json file.');
            app.import_arguments(fullfile(path, name));
        end

        % Button pushed function: ExportparametersButton
        function ExportparametersButtonPushed(app, event)
            [path, ~, ~] = fileparts(app.video_path);
            app.export_arguments(fullfile(path, 'arguments.json'));
        end

        % Value changed function: EnableadvancedparametersCheckBox
        function EnableadvancedparametersCheckBoxValueChanged(app, event)
            switch app.EnableadvancedparametersCheckBox.Value
                case 0
                    set(app.EditadvancedparametersButton, 'Enable', 'off');
                case 1
                    if isempty(app.EnableadvancedparametersCheckBox.Tag)
                        uiconfirm(app.CELL_ID, "Editing advanced parameters can significantly affect performance and multiply processing time. Make sure you know what you're doing.", 'Warning!');
                        app.EnableadvancedparametersCheckBox.Tag = 'Warning issued.';
                    end

                    set(app.EditadvancedparametersButton, 'Enable', 'on');
            end
        end

        % Button pushed function: EditadvancedparametersButton
        function EditadvancedparametersButtonPushed(app, event)
            set(app.AdvancedParameterPanel, 'Visible', 'on');
            set(app.BasicParameterPanel, 'Visible', 'off');
        end

        % Button pushed function: SelectNeuronSubsetButton
        function SelectNeuronSubsetButtonPushed(app, event)
            uiwait(msgbox('Click and drag your cursor on the image to select the neurons you want to manipulate.','Instructions'))
            roi = drawfreehand(app.xyAxes,'Color','white','StripeColor','m');

            xVertices = roi.Position(:, 1);
            yVertices = roi.Position(:, 2);
            
            app.sel_neurons = [];

            for n=1:size(app.video_neurons, 2)
                try
                    [in, on] = inpolygon(app.video_neurons(n).rois(app.tSlider.Value).xy_pos(1), app.video_neurons(n).rois(app.tSlider.Value).xy_pos(2), xVertices, yVertices);
                    if  in || on
                        app.sel_neurons = [app.sel_neurons; n];
                    end
                end
            end

            delete(roi);
        end

        % Button pushed function: Up
        function UpButtonPushed(app, event)
            for n=1:size(app.sel_neurons)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice - app.MoveEditField.Value;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).yz_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: Down
        function DownButtonPushed(app, event)
            for n=1:size(app.sel_neurons)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice + app.MoveEditField.Value;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).yz_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            for n=1:size(app.sel_neurons)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice + app.MoveEditField.Value;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xz_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            for n=1:size(app.sel_neurons)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice - app.MoveEditField.Value;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xz_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: FlipHorizontallyButton
        function FlipHorizontallyButtonPushed(app, event)
            min = Inf;
            max = -Inf;
            for n=1:size(app.sel_neurons,1)
                try
                    if app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) < min
                        min = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    end
    
                    if app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) > max
                        max = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    end
                end
            end

            mid = mean([max, min]);


            for n=1:size(app.sel_neurons,1)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice = 2 * mid - app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xz_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: FlipVerticallyButton
        function FlipVerticallyButtonPushed(app, event)
            min = Inf;
            max = -Inf;
            
            for n=1:size(app.sel_neurons,1)
                try
                    if app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) < min
                        max = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    end
    
                    if app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) > max
                        min = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    end
                end
            end

            mid = mean([max, min]);

            for n=1:size(app.sel_neurons,1)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice = 2 * mid - app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).yz_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: CenterVerticallyButton
        function CenterVerticallyButtonPushed(app, event)
            % Calculate the midpoint of the frame width
            mid = app.video_info.ny / 2;
            
            % Calculate the current mean position of selected neurons
            currentMean = 0;
            for n=1:size(app.sel_neurons,1)
                try
                    currentMean = currentMean + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2);
                end
            end
            currentMean = currentMean / size(app.sel_neurons,1);
            
            % Calculate the shift needed to center the neurons
            shiftAmount = mid - currentMean;
            
            % Apply the shift to all selected neurons
            for n=1:size(app.sel_neurons,1)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) + shiftAmount;
                        
                    % Assuming you want to adjust the x_slice and xz_pos similarly
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice + shiftAmount;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).yz_pos(2) = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).yz_pos(2) + shiftAmount;
                end
            end
            
            % Call the visual composer to update the visualization
            app.visual_composer;
        end

        % Button pushed function: CenterHorizontallyButton
        function CenterHorizontallyButtonPushed(app, event)
            % Calculate the midpoint of the frame width
            mid = app.video_info.nx / 2;
            
            % Calculate the current mean position of selected neurons
            currentMean = 0;
            for n=1:size(app.sel_neurons,1)
                try
                    currentMean = currentMean + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1);
                end
            end
            currentMean = currentMean / size(app.sel_neurons,1);
            
            % Calculate the shift needed to center the neurons
            shiftAmount = mid - currentMean;
            
            % Apply the shift to all selected neurons
            for n=1:size(app.sel_neurons,1)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) + shiftAmount;
                        
                    % Assuming you want to adjust the x_slice and xz_pos similarly
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice + shiftAmount;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xz_pos(1) = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xz_pos(1) + shiftAmount;
                end
            end
            
            % Call the visual composer to update the visualization
            app.visual_composer;
        end

        % Button pushed function: CCW
        function CCWButtonPushed(app, event)
            % Convert angle from degrees to radians
            theta = app.RotateEditField.Value * (pi / 180);
            
            % Calculate the centroid of selected neurons
            centroidX = 0;
            centroidY = 0;
            for n = 1:size(app.sel_neurons, 1)
                centroidX = centroidX + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1);
                centroidY = centroidY + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2);
            end
            centroidX = centroidX / size(app.sel_neurons, 1);
            centroidY = centroidY / size(app.sel_neurons, 1);
            
            % Rotation matrix for counterclockwise rotation
            R = [cos(theta) -sin(theta); sin(theta) cos(theta)];
            
            % Rotate each selected neuron
            for n = 1:size(app.sel_neurons, 1)
                try
                    % Get the current position
                    posX = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1);
                    posY = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2);
                    
                    % Translate point to origin (centroid based)
                    posX = posX - centroidX;
                    posY = posY - centroidY;
                    
                    % Rotate point
                    rotatedPos = R * [posX; posY];
                    
                    % Translate point back
                    rotatedPos(1) = rotatedPos(1) + centroidX;
                    rotatedPos(2) = rotatedPos(2) + centroidY;
                    
                    % Update position
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = rotatedPos(1);
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = rotatedPos(2);
                end
            end
            
            % Call the visual composer to update the visualization
            app.visual_composer;
        end

        % Button pushed function: CW
        function CWButtonPushed(app, event)
            % Calculate the centroid of the selected neurons
            centroidX = 0;
            centroidY = 0;
            for n = 1:size(app.sel_neurons,1)
                centroidX = centroidX + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1);
                centroidY = centroidY + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2);
            end
            centroidX = centroidX / size(app.sel_neurons,1);
            centroidY = centroidY / size(app.sel_neurons,1);
            
            % Convert degrees to radians for rotation
            jRad = app.RotateEditField.Value * (pi / 180);
            
            % Rotate each neuron around the centroid
            for n = 1:size(app.sel_neurons,1)
                try
                    % Translate point to origin (relative to centroid)
                    x = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) - centroidX;
                    y = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) - centroidY;
                    
                    % Apply rotation
                    xRotated = x * cos(jRad) + y * sin(jRad);
                    yRotated = -x * sin(jRad) + y * cos(jRad);
                    
                    % Translate point back
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = xRotated + centroidX;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = yRotated + centroidY;
                end
            end
            
            % Call the visual composer to update the visualization
            app.visual_composer();
        end

        % Value changed function: OverlayFrameMIPCheckBox_2
        function OverlayFrameMIPCheckBox_2ValueChanged(app, event)
            app.OverlayFrameMIPCheckBox.Value = app.OverlayFrameMIPCheckBox_2.Value;
            app.visual_composer();
        end

        % Value changed function: ShowallneuronsCheckBox_2
        function ShowallneuronsCheckBox_2ValueChanged(app, event)
            app.ShowallneuronsCheckBox.Value = app.ShowallneuronsCheckBox_2.Value;
            app.visual_composer();
        end

        % Value changed function: ShowallneuronsCheckBox
        function ShowallneuronsCheckBoxValueChanged(app, event)
            app.ShowallneuronsCheckBox_2.Value = app.ShowallneuronsCheckBox.Value;
            app.visual_composer();
        end

        % Button pushed function: ResetMarkerPositionsButton
        function ResetMarkerPositionsButtonPushed(app, event)
            app.video_neurons = app.repo_neurons;
            app.visual_composer();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed2(app, event)
            set(app.DefaultTabGroup, 'Visible', 'on');
            set(app.AdjustNeuronMarkerAlignmentPanel, 'Visible', 'off');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.video_neurons = app.repo_neurons;
            set(app.DefaultTabGroup, 'Visible', 'on');
            set(app.AdjustNeuronMarkerAlignmentPanel, 'Visible', 'off');
            app.visual_composer();
        end

        % Button pushed function: OpenGridSearchMenuButton
        function OpenGridSearchMenuButtonPushed(app, event)
            set(app.AdvancedParameterPanel, 'Visible', 'off');
            set(app.GridSearchPanel, 'Visible', 'on');
        end

        % Button pushed function: CancelButton_2
        function CancelButton_2Pushed(app, event)
            set(app.AdvancedParameterPanel, 'Visible', 'off');
            set(app.BasicParameterPanel, 'Visible', 'on');
        end

        % Button pushed function: CancelGridSearchButton
        function CancelGridSearchButtonPushed(app, event)
            set(app.AdvancedParameterPanel, 'Visible', 'on');
            set(app.GridSearchPanel, 'Visible', 'off');
        end

        % Callback function
        function ApplyButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Applying changes...','Indeterminate','on');
            
            app.image_data = imresize(app.image_data,[size(app.image_data, 1)*app.ProcXYFactorEditField.Value size(app.image_data, 2)*app.ProcXFactorEditField.Value]);

            nth_slice = round(size(app.image_data, 3) / app.ProcZSlicesEditField.Value);
            total_slices = size(app.image_data, 3);
            removed_slices = find(mod(1:total_slices, nth_slice) == 0);
            remaining_slices = setdiff(1:total_slices, removed_slices);
            
            % Scale factor for z-dimension
            z_scale_factor = length(remaining_slices) / total_slices;
            
            % Update neuron positions
            for n = 1:size(app.image_neurons.neurons, 2)
                app.image_neurons.neurons(n).position(1) = app.image_neurons.neurons(n).position(1) * app.ProcXYFactorEditField.Value;
                app.image_neurons.neurons(n).position(2) = app.image_neurons.neurons(n).position(2) * app.ProcXFactorEditField.Value;

                current_z = app.image_neurons.neurons(n).position(3);
            
                % Scale down z-position
                scaled_z = round(current_z * z_scale_factor);
            
                if ismember(current_z, removed_slices)
                    % Find the nearest remaining slice
                    [~, closest_idx] = min(abs(remaining_slices - scaled_z));
                    new_z = remaining_slices(closest_idx);
                else
                    new_z = scaled_z;
                end
            
                app.image_neurons.neurons(n).position(3) = new_z;
                sprintf("Neuron #%.f moved from slice %.f to slice %.f.", n, current_z, new_z);
            end
            
            % Remove slices from image_data
            app.image_data(:,:,removed_slices,:,:) = [];

            app.drawProcImage();

            close(d)
        end

        % Callback function
        function ResetButtonPushed(app, event)
            app.image_data = app.repo_data;
            app.image_view = app.repo_view;
            app.drawProcImage();
        end

        % Value changed function: ProcShowMIPCheckBox
        function ProcShowMIPCheckBoxValueChanged(app, event)
            app.drawProcImage();
        end

        % Button pushed function: ProcResetButton
        function ProcResetButtonPushed(app, event)
            Program.Handlers.loading.start('Resetting processing tab...');
            Program.Routines.Processing.reset();
            Program.Handlers.loading.done();
        end

        % Value changed function: tl_hist_slider
        function tl_hist_sliderValueChanged(app, event)
            min_c = app.tl_hist_slider.Value(1);
            max_c = app.tl_hist_slider.Value(2);

            delete(app.proc_tl_hist_min);
            delete(app.proc_tl_hist_max);

            app.proc_tl_hist_min = xline(app.tl_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_tl_hist_max = xline(app.tl_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2);           

            app.drawProcImage();
        end

        % Value changed function: tm_hist_slider
        function tm_hist_sliderValueChanged(app, event)
            min_c = app.tm_hist_slider.Value(1);
            max_c = app.tm_hist_slider.Value(2);

            delete(app.proc_tm_hist_min);
            delete(app.proc_tm_hist_max);

            app.proc_tm_hist_min = xline(app.tm_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_tm_hist_max = xline(app.tm_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2);            

            app.drawProcImage();
        end

        % Value changed function: tr_hist_slider
        function tr_hist_sliderValueChanged(app, event)
            min_c = app.tr_hist_slider.Value(1);
            max_c = app.tr_hist_slider.Value(2);

            delete(app.proc_tr_hist_min);
            delete(app.proc_tr_hist_max);

            app.proc_tr_hist_min = xline(app.tr_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_tr_hist_max = xline(app.tr_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2);            

            app.drawProcImage();
        end

        % Value changed function: bl_hist_slider
        function bl_hist_sliderValueChanged(app, event)
            min_c = app.bl_hist_slider.Value(1);
            max_c = app.bl_hist_slider.Value(2);

            delete(app.proc_bl_hist_min);
            delete(app.proc_bl_hist_max);

            app.proc_bl_hist_min = xline(app.bl_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_bl_hist_max = xline(app.bl_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2);            

            app.drawProcImage();
        end

        % Value changed function: bm_hist_slider
        function bm_hist_sliderValueChanged(app, event)
            min_c = app.bm_hist_slider.Value(1);
            max_c = app.bm_hist_slider.Value(2);

            delete(app.proc_bm_hist_min);
            delete(app.proc_bm_hist_max);

            app.proc_bm_hist_min = xline(app.bm_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_bm_hist_max = xline(app.bm_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2);            

            app.drawProcImage();
        end

        % Value changed function: br_hist_slider
        function br_hist_sliderValueChanged(app, event)
            min_c = app.br_hist_slider.Value(1);
            max_c = app.br_hist_slider.Value(2);

            delete(app.proc_br_hist_min);
            delete(app.proc_br_hist_max);

            app.proc_br_hist_min = xline(app.br_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_br_hist_max = xline(app.br_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2);            

            app.drawProcImage();
        end

        % Value changed function: tl_GammaEditField
        function tl_GammaEditFieldValueChanged(app, event)
            app.drawProcImage();
            app.proc_apply_settings();
        end

        % Value changed function: tm_GammaEditField
        function tm_GammaEditFieldValueChanged(app, event)
            app.drawProcImage();
            app.proc_apply_settings();
        end

        % Value changed function: tr_GammaEditField
        function tr_GammaEditFieldValueChanged(app, event)
            app.drawProcImage();
            app.proc_apply_settings();
        end

        % Value changed function: bl_GammaEditField
        function bl_GammaEditFieldValueChanged(app, event)
            app.drawProcImage();
            app.proc_apply_settings();
        end

        % Value changed function: bm_GammaEditField
        function bm_GammaEditFieldValueChanged(app, event)
            app.drawProcImage();
            app.proc_apply_settings();
        end

        % Value changed function: br_GammaEditField
        function br_GammaEditFieldValueChanged(app, event)
            app.drawProcImage();
            app.proc_apply_settings();
        end

        % Value changing function: tl_hist_slider
        function tl_hist_sliderValueChanging(app, event)
            min_c = app.tl_hist_slider.Value(1);
            max_c = app.tl_hist_slider.Value(2);

            delete(app.proc_tl_hist_min);
            delete(app.proc_tl_hist_max);

            app.proc_tl_hist_min = xline(app.tl_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_tl_hist_max = xline(app.tl_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2);    
        end

        % Value changing function: tm_hist_slider
        function tm_hist_sliderValueChanging(app, event)
            min_c = app.tm_hist_slider.Value(1);
            max_c = app.tm_hist_slider.Value(2);

            delete(app.proc_tm_hist_min);
            delete(app.proc_tm_hist_max);

            app.proc_tm_hist_min = xline(app.tm_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_tm_hist_max = xline(app.tm_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2);    
        end

        % Value changing function: tr_hist_slider
        function tr_hist_sliderValueChanging(app, event)
            min_c = app.tr_hist_slider.Value(1);
            max_c = app.tr_hist_slider.Value(2);

            delete(app.proc_tr_hist_min);
            delete(app.proc_tr_hist_max);

            app.proc_tr_hist_min = xline(app.tr_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_tr_hist_max = xline(app.tr_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2); 
        end

        % Value changing function: bl_hist_slider
        function bl_hist_sliderValueChanging(app, event)
            min_c = app.bl_hist_slider.Value(1);
            max_c = app.bl_hist_slider.Value(2);

            delete(app.proc_bl_hist_min);
            delete(app.proc_bl_hist_max);

            app.proc_bl_hist_min = xline(app.bl_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_bl_hist_max = xline(app.bl_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2);   
        end

        % Value changing function: bm_hist_slider
        function bm_hist_sliderValueChanging(app, event)
            min_c = app.bm_hist_slider.Value(1);
            max_c = app.bm_hist_slider.Value(2);

            delete(app.proc_bm_hist_min);
            delete(app.proc_bm_hist_max);

            app.proc_bm_hist_min = xline(app.bm_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_bm_hist_max = xline(app.bm_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2);    
        end

        % Value changing function: br_hist_slider
        function br_hist_sliderValueChanging(app, event)
            min_c = app.br_hist_slider.Value(1);
            max_c = app.br_hist_slider.Value(2);

            delete(app.proc_br_hist_min);
            delete(app.proc_br_hist_max);

            app.proc_br_hist_min = xline(app.br_hist_ax, min_c, '--', 'color', 'magenta', 'LineWidth', 0.2);
            app.proc_br_hist_max = xline(app.br_hist_ax, max_c, '--', 'color', 'magenta', 'LineWidth', 0.2);    
        end

        % Menu selected function: daspect_testMenu
        function daspect_testMenuSelected(app, event)
            daspect(app.proc_xyAxes, [1 1 1]);
        end

        % Menu selected function: projected_axes_testMenu
        function projected_axes_testMenuSelected(app, event)
            truePosition = tightPosition(app.proc_xyAxes);
            app.ProcAxGrid.ColumnWidth{end+1} = '1x';
            app.ProcAxGrid.RowHeight{end+1} = '1x';
            app.ProcAxGrid.ColumnWidth{1} = truePosition(3);
            app.ProcAxGrid.RowHeight{1} = truePosition(4);
        end

        % Button pushed function: SelectworldlinebyIDButton
        function SelectworldlinebyIDButtonPushed(app, event)
            wlid = inputdlg("Enter a worldline ID.", "NeuroPAL_ID");
            wlid = wlid{1};

            if ~isempty(wlid)
                wlid = str2num(wlid);
                if wlid == floor(wlid)
                    app.edit_worldline(wlid, 'select');
                else
                    msgbox("Worldline IDs must be integers.", "Error!");
                end
            end
        end

        % Button pushed function: ProcessingButton
        function ProcessingButtonPushed(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
            fullpath = join([GUI_prefs.image_dir,app.image_name]);
            file_info = [fullpath ';*.mat;*.czi;*.nd2;*.tif;*.tiff;*.h5;*.nwb;*.nd2'];
            [name, path, ~] = uigetfile(file_info, 'Select volume.');
            [~, ~, fmt] = fileparts([path, name]);
            
            d = uiprogressdlg(app.CELL_ID, 'Message', 'Loading volume...', 'Title', 'NeuroPAL_ID');

            if contains(';*.h5;*.nwb;*.nd2', fmt)
                check = uiconfirm(app.CELL_ID, "Load volume as an image or a video?", "NeuroPAL_ID","Options",["Image","Video"]);
            else
                check = "Image";
            end

            switch check
                case "Image"


                case "Video"
                    app.video_path = [path, name];

                    switch char(fmt)
                        case 'h5'
                            app.load_h5(app.video_path);
                        case 'nwb'
                            app.load_nwb(app.video_path);
                        case 'nd2'
                            app.load_nd2(app.video_path);
                    end

                    if ~any(ismember(app.VolumeDropDown.Items, 'Video'))
                        app.VolumeDropDown.Items{end+1} = 'Video';
                    end

                    app.VolumeDropDown.Value = 'Video';
            end
            
            close(d);
        end

        % Button pushed function: FlipinZdimensionButton
        function FlipinZdimensionButtonPushed(app, event)
            for n=1:size(app.sel_neurons, 2)
                app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).z_slice = abs(app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).z_slice-app.video_info.nz);
            end
        end

        % Value changed function: ProcBDropDown, ProcDICDropDown, 
        % ...and 4 other components
        function ProcRDropDownValueChanged(app, event)
            Program.GUIHandling.sync_channels(event);
            app.proc_apply_settings();
        end

        % Button pushed function: ImportexistingtracksButton
        function ImportexistingtracksButtonPushed(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
            fullpath = join([GUI_prefs.image_dir,app.image_name]);

            % file_info = [fullpath ';*.h5;*.nwb;*.xml'];
            file_info = [fullpath ';*.xml;*.h5;*.nwb'];
            [name, path, ~] = uigetfile(file_info, 'Select tracking file.');
            if isequal(name, 0)
                return
            end
            [positions, labels] = Program.Routines.Videos.tracks.load(fullfile(path, name));

            app.import_annotations(positions, labels);
            app.data_flags.('Tracking_ROIs') = 1;
            app.video_info.annotations = fullfile(path, name);
        end

        % Value changed function: AllowflexibleaspectratioCheckBox
        function AllowflexibleaspectratioCheckBoxValueChanged(app, event)
            if app.AllowflexibleaspectratioCheckBox.Value
                daspect(app.xyAxes, 'auto');
            else
                daspect(app.xyAxes, [1 1 1]);
            end
        end

        % Button pushed function: SaveAnnotationsButton
        function SaveAnnotationsButtonPushed(app, event)
            [path, ~, ~] = fileparts(app.video_info.file);
            file_info = [path ';*.h5;*.xml'];
            [name, path, ~] = uiputfile(file_info,'File Selection',sprintf('%s_annotations.xml', string(datetime('now','TimeZone','local', 'Format','yyyy_MM_dd'))));
            if isequal(name, 0)
                return
            end
            filename = fullfile(path, name);
            
            d = uiprogressdlg(app.CELL_ID,'Title','Saving annotations...','Indeterminate','off');

            if endsWith(name, '.h5')
                DataHandling.Helpers.h5.write_tracks(app.video_info, app.video_neurons, filename);
            elseif endsWith(name, '.xml')
                DataHandling.writeTrackMate(app.video_info, app.video_neurons, filename, app.CELL_ID);

                %{
                typicalXML = load('Data\NeuroPAL\xmlStruct.mat');
                xmlLog = {'File generated in NeuroPAL_ID.'};
                typicalXML.Model.AllSpots = struct();

                idx = 0;
                nn = max(size(app.video_neurons));

                for t=1:app.video_info.nt
                    d.Value = t/app.video_info.nt;

                    typicalXML.Model.AllSpots.(sprintf('SpotsInFrame%.f', idx)) = struct('frame_att', t);

                    for n=1:nn
                        d.Value = min((t+n/nn)/app.video_info.nt, 1);
                        name = app.video_neurons(n).worldline.name;

                        try
                            x = app.video_neurons(n).rois(t).x_slice;
                            y = app.video_neurons(n).rois(t).y_slice;
                            z = app.video_neurons(n).rois(t).z_slice;
                            xmlLog{end+1} = {sprintf('Saving annotation #%.f (%s) @ t=%.f x=%.f y=%.f z=%.f.', idx, name, t, x, y, z)};
                        catch
                            x = {};
                            y = {};
                            z = {};
                            xmlLog{end+1} = {sprintf('Error saving blank annotation #%.f (%s) @ t=%.f.', idx, name, t)};
                        end

                        typicalXML.Model.AllSpots.(sprintf('SpotsInFrame%.f', t)).(sprintf("Spot%.f",n)) = struct('Text', sprintf('Spot #%.f', idx), 'name_att', name, 'POSITION_X_att', x, 'POSITION_Y_att', y, 'POSITION_Z_att', z, 'POSITION_T_att', t, 'FRAME_att', t);
                        idx = idx + 1;
                    end
                end

                typicalXML.Model.AllSpots.nspots_att = idx;
                typicalXML.Log.Text = string(xmlLog);

                typicalXML.Settings.ImageData.filename_att = filename;
                typicalXML.Settings.ImageData.folder_att = path;
                typicalXML.Settings.ImageData.height_att = app.video_info.ny;
                typicalXML.Settings.ImageData.width_att = app.video_info.nx;
                typicalXML.Settings.ImageData.nframes_att = app.video_info.nt;
                typicalXML.Settings.ImageData.nslices_att = app.video_info.nz;

                typicalXML.Settings.BasicSettings.tend_att = app.video_info.nt-1';
                typicalXML.Settings.BasicSettings.tstart_att = '0';
                typicalXML.Settings.BasicSettings.xend_att = app.video_info.nx-1;
                typicalXML.Settings.BasicSettings.xstart_att = '0';
                typicalXML.Settings.BasicSettings.yend_att = app.video_info.ny-1;
                typicalXML.Settings.BasicSettings.ystart_att = '0';
                typicalXML.Settings.BasicSettings.zend_att = app.video_info.nz-1;
                typicalXML.Settings.BasicSettings.zstart_att = '0';

                writestruct(typicalXML, filename, AttributeSuffix="_att", StructNodeName="TrackMate");
                %}
            end

            app.data_flags.('Tracking_ROIs') = 1;
            app.video_info.annotations = filename;
            close(d)
        end

        % Callback function
        function ProcAllowflexibleaxesCheckBoxValueChanged(app, event)
            if ~app.ProcAllowflexibleaxesCheckBox.Value
                daspect(app.proc_xyAxes, [1 1 1]);
            else
                daspect(app.proc_xyAxes, 'auto');
            end
        end

        % Value changing function: ProcNoiseThresholdKnob
        function ProcNoiseThresholdKnobValueChanging(app, event)
            app.ProcNoiseThresholdField.Value = event.Value;
        end

        % Button pushed function: ProcMeasureROINoiseButton
        function ProcMeasureROINoiseButtonPushed(app, event)
            Program.GUIHandling.measure_threshold_from_roi(app);
        end

        % Button pushed function: ProcMeasure90pthNoiseButton
        function ProcMeasure90pthNoiseButtonPushed(app, event)
            check = inputdlg("Which percentile noise would you like to measure?", "NeuroPAL_ID", [1 125]);            
            slice = Program.GUIHandling.get_active_volume(app, 'array');            
            pth_noise = max(prctile(slice.array(:), str2double(check{1})), 1);
            
            app.ProcNoiseThresholdField.Value = pth_noise;
            app.ProcNoiseThresholdKnob.Value = pth_noise;
        end

        % Value changed function: proc_zSlider
        function proc_zSliderValueChanged(app, event)
            Program.GUIHandling.sync_processing_zcontrols(app, round(event.Value));
            Program.Routines.Processing.render();
        end

        % Value changed function: proc_tSlider
        function proc_tSliderValueChanged(app, event)
            value = round(app.proc_tSlider.Value);
            app.proc_tEditField.Value = value;
            app.drawProcImage();
        end

        % Value changed function: proc_tEditField
        function proc_tEditFieldValueChanged(app, event)
            value = round(app.proc_tEditField.Value);
            app.proc_tSlider.Value = value;
            app.drawProcImage();
        end

        % Value changed function: proc_zEditField
        function proc_zEditFieldValueChanged(app, event)
            value = round(app.proc_zEditField.Value);
            Program.GUIHandling.sync_processing_zcontrols(app, value);
            Program.Routines.Processing.render();
        end

        % Value changed function: proc_xEditField
        function proc_xEditFieldValueChanged(app, event)
            value = app.proc_xEditField.Value;
            app.proc_xSlider.Value = value;
            app.drawProcImage();
        end

        % Value changed function: proc_yEditField
        function proc_yEditFieldValueChanged(app, event)
            value = app.proc_yEditField.Value;
            app.proc_ySlider.Value = value;
            app.drawProcImage();            
        end

        % Button pushed function: ProcCropImageButton
        function ProcCropImageButtonPushed(app, event)
            Program.Routines.Processing.crop();
        end

        % Button pushed function: ProcXYFactorUpdateButton
        function ProcXYFactorUpdateButtonPushed(app, event)
            if (app.ProcXYFactorEditField.Value ~= 1 || app.ProcZSlicesEditField.Value ~= app.proc_zSlider.Limits(2)) || ...
                    (strcmp(app.VolumeDropDown.Value, 'Video') && (app.ProcTStartEditField.Value ~= 1 || app.ProcTStopEditField.Value ~= app.proc_tSlider.Limits(2)))
                Program.Routines.Processing.save_prompt('ds');
            end

            Program.Routines.GUI.set_manipulation_panel('closed');
        end

        % Callback function
        function ProcZFactorUpdateButtonPushed(app, event)
            if app.ProcZSlicesEditField.Value > size(app.image_data, 3)
                uialert(app.CELL_ID,"Can't set more z-slices than are present in the original image.","Error!")
                app.ProcZSlicesEditField.Value = size(app.image_data, 3);
            end

            switch app.VolumeDropDown.Value
                case 'Colormap'
                    dims = size(app.proc_image, 'data');
                    nz = dims(3);
                case 'Video'
                    nz = app.video_info.nz;
            end

            target_nz_parity = mod(app.ProcZSlicesEditField.Value, 2);
            if target_nz_parity ~= mod(nz, 2)
                if target_nz_parity ~= 0 
                    uialert(app.CELL_ID, "Can't create an odd number of slices from an even number of slices without compromising isotropy.", "Error!");
                    return
                end
            end

            Program.Routines.Processing.save_prompt('ds');
        end

        % Value changed function: ProcPreviewZslowCheckBox
        function ProcPreviewZslowCheckBoxValueChanged(app, event)
            if app.ProcPreviewZslowCheckBox.Value
                c_width = app.ProcAxGrid.ColumnWidth;
                r_height = app.ProcAxGrid.RowHeight;

                c_width{2} = 150;
                c_width{3} = 30;

                r_height{2} = 150;
                r_height{3} = 30;
                r_height{4} = 0;

                app.ProcAxGrid.ColumnWidth = c_width;
                app.ProcAxGrid.RowHeight = r_height;

                set(app.proc_xzAxes, 'Visible', 'on');
                set(app.proc_yzAxes, 'Visible', 'on');
                set(app.proc_xEditField, 'Enable', 'on')
                set(app.proc_yEditField, 'Enable', 'on')
                drawnow;

                app.proc_xEditField.Value = round(app.proc_xSlider.Value);
                app.proc_yEditField.Value = round(app.proc_ySlider.Value);
                app.proc_zEditField.Value = round(app.proc_zSlider.Value);
                app.proc_tEditField.Value = round(app.proc_tSlider.Value);

                app.drawProcImage();
            else
                set(app.proc_xzAxes, 'Visible', 'off');
                set(app.proc_yzAxes, 'Visible', 'off');
                set(app.proc_xEditField, 'Enable', 'off')
                set(app.proc_yEditField, 'Enable', 'off')

                app.ProcAxGrid.ColumnWidth{1} = '1x';
                app.ProcAxGrid.ColumnWidth{2} = 0;
                app.ProcAxGrid.ColumnWidth{3} = 0;

                app.ProcAxGrid.RowHeight{1} = '1x';
                app.ProcAxGrid.RowHeight{2} = 0;
                app.ProcAxGrid.RowHeight{3} = 0;
                app.ProcAxGrid.RowHeight{4} = 30;

                app.drawProcImage();
            end
        end

        % Button pushed function: ProcSaveButton
        function ProcSaveButtonPushed(app, event)
            Program.Routines.Processing.save();
        end

        % Value changed function: HidezerointensitypixelsCheckBox
        function HidezerointensitypixelsCheckBoxValueChanged(app, event)
            app.drawProcImage();
        end

        % Button pushed function: RuntestroutineButton
        function RuntestroutineButtonPushed(app, event)
            Program.ProgramTests.startRoutine(app, app.CELL_ID, app.DebugPanel);
        end

        % Menu selected function: TriggerBreakpointMenu
        function TriggerBreakpointMenuSelected(app, event)
             keyboard          
        end

        % Menu selected function: ProfileOnMenu
        function ProfileOnMenuSelected(app, event)
            set(app.ProfileOnMenu, 'Enable', 'off');
            set(app.ProfileOffMenu, 'Enable', 'on');
            profile on
        end

        % Menu selected function: ProfileOffMenu
        function ProfileOffMenuSelected(app, event)
            profile viewer
            set(app.ProfileOnMenu, 'Enable', 'on')
            set(app.ProfileOffMenu, 'Enable', 'off')
        end

        % Menu selected function: EnableautoprofilingMenu
        function EnableautoprofilingMenuSelected2(app, event)
            if app.EnableautoprofilingMenu.Checked
                app.EnableautoprofilingMenu.Checked = 0;
            else
                app.EnableautoprofilingMenu.Checked = 1;
            end
        end

        % Button pushed function: background_measure, blue_measure, 
        % ...and 2 other components
        function red_measureButtonPushed(app, event)
            Methods.ChunkyMethods.spectral_unmix(app, event);
            app.drawProcImage();
        end

        % Value changed function: ProcBCheckBox, ProcDICCheckBox, 
        % ...and 4 other components
        function ProcRCheckBoxValueChanged(app, event)
            app.drawProcImage();
            drawnow;
        end

        % Button pushed function: TrimButton
        function TrimButtonPushed(app, event)
            switch event.Source.Text
                case 'Trim'
                    app.proc_tGrinder.Limits = app.proc_tSlider.Limits;
                    app.proc_tGrinder.MajorTicks = app.proc_tSlider.MajorTicks;
                    app.proc_tGrinder.MinorTicks = app.proc_tSlider.MinorTicks;
                    app.proc_tGrinder.MajorTickLabels = app.proc_tSlider.MajorTickLabels;
        
                    app.proc_tGrinder.Value = [app.ProcTStartEditField.Value, app.ProcTStopEditField.Value];
        
                    app.proc_tGrinder.Visible = 'on';
                    app.proc_tSlider.Visible = 'off';

                    app.proc_tSlider.Visible = 'off';
                    app.proc_tGrinder.Visible = 'on';

                    app.TrimButton.Text = 'Done';
                    app.TrimButton.BackgroundColor = [0.52, 1, 0.52];

                case 'Done Trimming'
                    app.proc_tSlider.Visible = 'on';
                    app.proc_tGrinder.Visible = 'off';

                    app.TrimButton.Text = 'Trim';
                    app.TrimButton.BackgroundColor = [0.96, 0.96, 0.96];

                    if app.proc_tSlider.Value < app.ProcTStartEditField.Value
                        app.proc_tSlider.Value = app.ProcTStartEditField.Value;
                    elseif app.proc_tSlider.Value > app.ProcTStopEditField.Value
                        app.proc_tSlider.Value = app.ProcTStopEditField.Value;
                    end

                    app.proc_tSlider.Limits = [app.ProcTStartEditField.Value app.ProcTStopEditField.Value];
            end
        end

        % Value changed function: proc_tGrinder
        function proc_tGrinderValueChanged(app, event)
            t1 = app.proc_tGrinder.Value(1);
            if t1 ~= app.ProcTStartEditField.Value
                app.proc_tSlider.Value = t1;
                app.ProcTStartEditField.Value = t1;
            end
    
            t2 = app.proc_tGrinder.Value(2);
            if t2 ~= app.ProcTStopEditField.Value
                app.proc_tSlider.Value = t2;
                app.ProcTStopEditField.Value = t2;
            end
    
            app.drawProcImage();
        end

        % Callback function
        function HistogramModeSwitchValueChanged(app, event)
            app.drawProcImage();
        end

        % Menu selected function: RotationGUIMenu
        function RotationGUIMenuSelected(app, event)
            roi = drawrectangle(app.proc_xyAxes, 'Color', 'black','StripeColor','m', 'Tag', 'rotation_gui');
            roi.Rotatable = 1;
            Program.GUIHandling.draw_rotation_gui(app, roi);
        end

        % Value changed function: VolumeDropDown
        function VolumeDropDownValueChanged(app, event)
            if strcmp(app.VolumeDropDown.Value, 'Colormap')
                if ~isa(app.proc_image, 'matlab.io.MatFile')
                    app.proc_load('image', app.image_file);
                end
            end

            Program.Handlers.loading.start('Swapping volumes...');
            Program.GUIHandling.swap_volumes(app, event);
            Program.Handlers.loading.done();
            app.drawProcImage();
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            Program.Routines.GUI.set_manipulation_panel('rotate')
        end

        % Button pushed function: confirm_rotation
        function confirm_rotationPushed(app, event)
            rotate_actions = {};

            if app.flip_lr.Value
                rotate_actions{end+1} = 'hori';
            end

            if app.flip_ud.Value
                rotate_actions{end+1} = 'vert';
            end

            if app.proc_rot_spinner.Value ~= 0
                rotate_actions{end+1} = 'rotate';
            end

            if ~isempty(rotate_actions) 
                check = uiconfirm(app.CELL_ID, "Do you want to save this operation to the file?", "NeuroPAL_ID", "Options", ["Yes", "No, stick with preview"]);
            else
                app.proc_xyAxes.XLim(2) = app.proc_xSlider.Limits(2);
                app.proc_xyAxes.YLim(2) = app.proc_ySlider.Limits(2);
            end
                
            for a=1:length(rotate_actions)
                action = rotate_actions{a};

                if strcmp(check, "Yes")
                    app.proc_apply_processing(action);
                    if isfield(app.flags, action)
                        app.flags = rmfield(app.flags, action);
                    end
                else
                    app.flags.(action) = 1;
                end
            end
            
            Program.Routines.GUI.set_manipulation_panel('closed');

        end

        % Callback function
        function RotateSliderValueChanged2(app, event)
            previous_value = str2double(app.RotateSlider.Tag);
            rotated_img = imrotate(getimage(app.proc_xyAxes), app.RotateSlider.Value-previous_value);
            image(app.proc_xyAxes, rotated_img);
            app.proc_xyAxes.XLim = [1 size(rotated_img, 2)];
            app.proc_xyAxes.YLim = [1 size(rotated_img, 1)];
            app.RotateSlider.Tag = num2str(app.RotateSlider.Value);
        end

        % Value changed function: flip_ud
        function flip_udValueChanged(app, event)
            current_img = getimage(app.proc_xyAxes);
            dims = size(current_img, 1);
            image(app.proc_xyAxes, current_img(dims:-1:1, :, :));
        end

        % Value changed function: flip_lr
        function flip_lrValueChanged(app, event)
            current_img = getimage(app.proc_xyAxes);
            dims = size(current_img, 2);
            image(app.proc_xyAxes, current_img(:, dims:-1:1, :));
        end

        % Value changed function: proc_c4_ref, proc_c5_ref, proc_c6_ref
        function proc_c4_refValueChanged(app, event)
            Program.Helpers.dd_sync(event.Source, event.PreviousValue, event.Value, Program.Handlers.channels.handles{'pp_ref'})
        end

        % Value changed function: proc_c1_dropdown, proc_c2_dropdown, 
        % ...and 4 other components
        function proc_c1_dropdownValueChanged(app, event)
            Program.Helpers.dd_sync(event.Source, event.PreviousValue, event.Value, Program.Handlers.channels.handles{'pp_dd'})
        end

        % Button pushed function: proc_c1_down, proc_c1_up, proc_c2_down, 
        % ...and 9 other components
        function proc_c1_downButtonPushed(app, event)
            Program.Routines.GUI.move_channel(event);
        end

        % Button pushed function: proc_c1_delete, proc_c2_delete, 
        % ...and 4 other components
        function proc_c1_deleteButtonPushed(app, event)
            Program.Routines.GUI.delete_channel(event.Source.Layout.row)
        end

        % Value changed function: proc_c1_checkbox, proc_c2_checkbox, 
        % ...and 4 other components
        function prof_c1_checkboxValueChanged(app, event)
            app.drawProcImage();
            drawnow;
        end

        % Button pushed function: DownsampleButton
        function DownsampleButtonPushed(app, event)
            app.ProcZSlicesEditField.Value = app.proc_zSlider.Limits(2);
            app.ProcTStopEditField.Value = app.proc_tSlider.Limits(2);
            Program.Routines.GUI.set_manipulation_panel('downsample');
        end

        % Value changed function: EditChannelsButton
        function EditChannelsButtonValueChanged(app, event)
            Program.Handlers.channels.edit_channels();
        end

        % Value changed function: proc_rot_spinner
        function proc_rot_spinnerValueChanged(app, event)
            app.proc_rot_knob.Value = event.Value;
            
            if event.PreviousValue ~= 0
                current_img = Program.cache;
            else
                current_img = getimage(app.proc_xyAxes);
                Program.cache(current_img);
            end

            rotated_img = imrotate(current_img, event.Value);
            image(app.proc_xyAxes, rotated_img);
            app.proc_xyAxes.XLim(2) = size(rotated_img, 2);
            app.proc_xyAxes.YLim(2) = size(rotated_img, 1); 
        end

        % Value changed function: proc_rot_knob
        function proc_rot_knobValueChanged2(app, event)
            app.proc_rot_spinner.Value = event.Value;
            
            if event.PreviousValue ~= 0
                current_img = Program.cache;
            else
                current_img = getimage(app.proc_xyAxes);
                Program.cache(current_img);
            end

            rotated_img = imrotate(current_img, event.Value);
            image(app.proc_xyAxes, rotated_img);
            app.proc_xyAxes.XLim(2) = size(rotated_img, 2);
            app.proc_xyAxes.YLim(2) = size(rotated_img, 1); 
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Program.rotation_gui.preview_output(app);
        end

        % Button pushed function: CancelButton_3
        function CancelButton_3Pushed(app, event)
            Program.rotation_gui.close(app);
        end

        % Value changed function: proc_c1_editfield, proc_c2_editfield, 
        % ...and 2 other components
        function proc_c1_editfieldValueChanged(app, event)
            % Check whether we're dealing with a color stack.
            if strcmpi(app.VolumeDropDown.Value, 'Colormap')
                % If so, set the matfile object to be writable.
                app.proc_image.Properties.Writable = true;

                % Get the info and prefs fields of the matfile.
                info = app.proc_image.info;
                prefs = app.proc_image.prefs;

                % Get the red, green, blue, and white indices.
                red_idx = find(ismember(app.proc_c1_dropdown.Value, ...
                    app.proc_c1_dropdown.Items));
                green_idx = find(ismember(app.proc_c2_dropdown.Value, ...
                    app.proc_c2_dropdown.Items));
                blue_idx = find(ismember(app.proc_c3_dropdown.Value, ...
                    app.proc_c3_dropdown.Items));
                white_idx = find(ismember(app.proc_c4_dropdown.Value, ...
                    app.proc_c4_dropdown.Items));

                % Assemble them into an array.
                prefs.RGBW = [ ...
                    red_idx ...
                    green_idx ... 
                    blue_idx ...
                    white_idx];

                % Overwrite the file's current info and prefs fields.
                app.proc_image.info = info;
                app.proc_image.prefs = prefs;

                % Disable writing for this file.
                app.proc_image.Properties.Writable = false;
            end

            % Trigger a redraw.
            app.drawProcImage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CELL_ID = uifigure('Visible', 'off');
            app.CELL_ID.Name = 'NeuroPAL ID';
            app.CELL_ID.CloseRequestFcn = createCallbackFcn(app, @CELL_IDCloseRequest, true);
            app.CELL_ID.KeyPressFcn = createCallbackFcn(app, @CELL_IDKeyPress, true);
            app.FileMenu = uimenu(app.CELL_ID);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFile, true);
            app.OpenMenu.Text = 'Open';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.PreprocessingMenu = uimenu(app.CELL_ID);
            app.PreprocessingMenu.Text = 'Preprocessing';
            app.ArtifactRemovalMenu = uimenu(app.PreprocessingMenu);
            app.ArtifactRemovalMenu.Text = 'Artifact Removal';
            app.ManualMenu = uimenu(app.ArtifactRemovalMenu);
            app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualArtifactRemoval, true);
            app.ManualMenu.Text = 'Manual';
            app.AutomaticMenu = uimenu(app.ArtifactRemovalMenu);
            app.AutomaticMenu.MenuSelectedFcn = createCallbackFcn(app, @AutomaticArtifactRemoval, true);
            app.AutomaticMenu.Text = 'Automatic';
            app.DownsampleImageMenu = uimenu(app.PreprocessingMenu);
            app.DownsampleImageMenu.MenuSelectedFcn = createCallbackFcn(app, @DecimateButtonPushed, true);
            app.DownsampleImageMenu.Text = 'Downsample Image';
            app.ThresholdImageMenu = uimenu(app.PreprocessingMenu);
            app.ThresholdImageMenu.MenuSelectedFcn = createCallbackFcn(app, @AdaptThreshButtonPushed, true);
            app.ThresholdImageMenu.Text = 'Threshold Image';
            app.HistogramMatchingMenu = uimenu(app.PreprocessingMenu);
            app.HistogramMatchingMenu.MenuSelectedFcn = createCallbackFcn(app, @HistMatchButtonPushed, true);
            app.HistogramMatchingMenu.Text = 'Histogram Matching';
            app.ImageMenu = uimenu(app.CELL_ID);
            app.ImageMenu.Text = 'Image';
            app.NormalizeColorsMenu = uimenu(app.ImageMenu);
            app.NormalizeColorsMenu.MenuSelectedFcn = createCallbackFcn(app, @ZScoringMenuSelected, true);
            app.NormalizeColorsMenu.Text = 'Normalize Colors';
            app.SpectralUnmixingMenu = uimenu(app.ImageMenu);
            app.SpectralUnmixingMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorRegisterMenuSelected, true);
            app.SpectralUnmixingMenu.Text = 'Spectral Unmixing';
            app.AdjustGammaMenu = uimenu(app.ImageMenu);
            app.AdjustGammaMenu.Text = 'Adjust Gamma';
            app.AllChannels = uimenu(app.AdjustGammaMenu);
            app.AllChannels.MenuSelectedFcn = createCallbackFcn(app, @AllChannelsMenuSelected, true);
            app.AllChannels.Text = 'All Channels';
            app.IndividualChannels = uimenu(app.AdjustGammaMenu);
            app.IndividualChannels.MenuSelectedFcn = createCallbackFcn(app, @IndividualChannelsMenuSelected, true);
            app.IndividualChannels.Text = 'Individual Channels';
            app.AdjustBrightnessMenu = uimenu(app.ImageMenu);
            app.AdjustBrightnessMenu.Enable = 'off';
            app.AdjustBrightnessMenu.Text = 'Adjust Brightness';
            app.AdjustHistogramMenu = uimenu(app.ImageMenu);
            app.AdjustHistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustHistogramMenuSelected, true);
            app.AdjustHistogramMenu.Text = 'Adjust Histogram';
            app.RotateAllMenu = uimenu(app.ImageMenu);
            app.RotateAllMenu.Text = 'Rotate All';
            app.RotateAllHorizontalMenu = uimenu(app.RotateAllMenu);
            app.RotateAllHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllHorizontal, true);
            app.RotateAllHorizontalMenu.Text = 'Horizontal';
            app.RotateAllVerticalMenu = uimenu(app.RotateAllMenu);
            app.RotateAllVerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllVertical, true);
            app.RotateAllVerticalMenu.Text = 'Vertical';
            app.RotateAllClockwiseMenu = uimenu(app.RotateAllMenu);
            app.RotateAllClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllClockwise, true);
            app.RotateAllClockwiseMenu.Text = 'Clockwise';
            app.RotateAllAntiClockwiseMenu = uimenu(app.RotateAllMenu);
            app.RotateAllAntiClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllAntiClockwise, true);
            app.RotateAllAntiClockwiseMenu.Text = 'Anti-Clockwise';
            app.RotateImageMenu = uimenu(app.ImageMenu);
            app.RotateImageMenu.Text = 'Rotate Image';
            app.RotateImageHorizontalMenu = uimenu(app.RotateImageMenu);
            app.RotateImageHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageHorizontal, true);
            app.RotateImageHorizontalMenu.Text = 'Horizontal';
            app.RotateImageVerticalMenu = uimenu(app.RotateImageMenu);
            app.RotateImageVerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageVertical, true);
            app.RotateImageVerticalMenu.Text = 'Vertical';
            app.RotateImageClockwiseMenu = uimenu(app.RotateImageMenu);
            app.RotateImageClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageClockwise, true);
            app.RotateImageClockwiseMenu.Text = 'Clockwise';
            app.RotateImageAntiClockwiseMenu = uimenu(app.RotateImageMenu);
            app.RotateImageAntiClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageAntiClockwise, true);
            app.RotateImageAntiClockwiseMenu.Text = 'Anti-Clockwise';
            app.RotateNeuronsMenu = uimenu(app.ImageMenu);
            app.RotateNeuronsMenu.Text = 'Rotate Neurons';
            app.RotateNeuronsHorizontalMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsHorizontal, true);
            app.RotateNeuronsHorizontalMenu.Text = 'Horizontal';
            app.RotateNeuronsVerticalMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsVerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsVertical, true);
            app.RotateNeuronsVerticalMenu.Text = 'Vertical';
            app.RotateNeuronsClockwiseMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsClockwise, true);
            app.RotateNeuronsClockwiseMenu.Text = 'Clockwise';
            app.RotateNeuronsAntiClockwiseMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsAntiClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsAntiClockwise, true);
            app.RotateNeuronsAntiClockwiseMenu.Text = 'Anti-Clockwise';
            app.MirrorImageMenu = uimenu(app.ImageMenu);
            app.MirrorImageMenu.MenuSelectedFcn = createCallbackFcn(app, @MirrorImage, true);
            app.MirrorImageMenu.Text = 'Mirror Image';
            app.MirrorNeuronsMenu = uimenu(app.ImageMenu);
            app.MirrorNeuronsMenu.MenuSelectedFcn = createCallbackFcn(app, @MirrorNeurons, true);
            app.MirrorNeuronsMenu.Text = 'Mirror Neurons';
            app.NeuronsMenu = uimenu(app.CELL_ID);
            app.NeuronsMenu.Text = 'Neurons';
            app.ImportneuronsfromMenu = uimenu(app.NeuronsMenu);
            app.ImportneuronsfromMenu.Text = 'Import neurons from...';
            app.CSVfileMenu = uimenu(app.ImportneuronsfromMenu);
            app.CSVfileMenu.MenuSelectedFcn = createCallbackFcn(app, @CSVfileMenuSelected, true);
            app.CSVfileMenu.Text = '... CSV file.';
            app.NWBfileMenu = uimenu(app.ImportneuronsfromMenu);
            app.NWBfileMenu.MenuSelectedFcn = createCallbackFcn(app, @NWBfileMenuSelected, true);
            app.NWBfileMenu.Text = '... NWB file.';
            app.ImportactivityfromMenu = uimenu(app.NeuronsMenu);
            app.ImportactivityfromMenu.Text = 'Import activity from...';
            app.NWBfileMenu_2 = uimenu(app.ImportactivityfromMenu);
            app.NWBfileMenu_2.MenuSelectedFcn = createCallbackFcn(app, @NWBfileMenu_2Selected, true);
            app.NWBfileMenu_2.Text = '... NWB file.';
            app.ZephIRoutputMenu = uimenu(app.ImportactivityfromMenu);
            app.ZephIRoutputMenu.MenuSelectedFcn = createCallbackFcn(app, @ZephIRoutputMenuSelected, true);
            app.ZephIRoutputMenu.Text = '... ZephIR output.';
            app.FlipLRNeuronsMenu = uimenu(app.NeuronsMenu);
            app.FlipLRNeuronsMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipLRNeuronsMenuSelected, true);
            app.FlipLRNeuronsMenu.Text = 'Flip L/R Neurons';
            app.AutoSegmentationMenu = uimenu(app.NeuronsMenu);
            app.AutoSegmentationMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoSegmentationMenuSelected, true);
            app.AutoSegmentationMenu.Text = 'Auto-Segmentation';
            app.CellposeDetectMenu = uimenu(app.NeuronsMenu);
            app.CellposeDetectMenu.MenuSelectedFcn = createCallbackFcn(app, @CellposeDetectMenuSelected, true);
            app.CellposeDetectMenu.Text = 'Cellpose Detect';
            app.NeuronActivityMenu = uimenu(app.NeuronsMenu);
            app.NeuronActivityMenu.Text = 'Neuron Activity';
            app.TraceActivityMenu_2 = uimenu(app.NeuronActivityMenu);
            app.TraceActivityMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenVideo, true);
            app.TraceActivityMenu_2.Text = 'Trace Activity';
            app.DisplayNeuronActivityMenu = uimenu(app.NeuronActivityMenu);
            app.DisplayNeuronActivityMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplayNeuronActivityMenuSelected, true);
            app.DisplayNeuronActivityMenu.Enable = 'off';
            app.DisplayNeuronActivityMenu.Text = 'Display Neuron Activity';
            app.BatchDetectIDNeuronsMenu = uimenu(app.NeuronsMenu);
            app.BatchDetectIDNeuronsMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchIDNeurons, true);
            app.BatchDetectIDNeuronsMenu.Text = 'Batch Detect + ID Neurons';
            app.DeleteUserIDsMenu = uimenu(app.NeuronsMenu);
            app.DeleteUserIDsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteUserIDs, true);
            app.DeleteUserIDsMenu.Text = 'Delete User IDs';
            app.DeleteModelIDsMenu = uimenu(app.NeuronsMenu);
            app.DeleteModelIDsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteModelIDs, true);
            app.DeleteModelIDsMenu.Text = 'Delete Model IDs';
            app.ToggleAutoIDUpdatesMenu = uimenu(app.NeuronsMenu);
            app.ToggleAutoIDUpdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleAutoIDUpdates, true);
            app.ToggleAutoIDUpdatesMenu.Text = 'Disable Auto-ID Updates';
            app.ToggleNeuronDetectionMenu = uimenu(app.NeuronsMenu);
            app.ToggleNeuronDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleNeuronDetection, true);
            app.ToggleNeuronDetectionMenu.Text = 'Use NN-Detect Neurons';
            app.DisplayMenu = uimenu(app.CELL_ID);
            app.DisplayMenu.Text = 'Display';
            app.ToggleBirthTimesMenu = uimenu(app.DisplayMenu);
            app.ToggleBirthTimesMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleNeuronBirthTimes, true);
            app.ToggleBirthTimesMenu.Text = 'Show Birth Times';
            app.NeuronDotsMenu = uimenu(app.DisplayMenu);
            app.NeuronDotsMenu.MenuSelectedFcn = createCallbackFcn(app, @NeuronDotsMenuSelected, true);
            app.NeuronDotsMenu.Text = 'Neuron Dots';
            app.GFPColorMenu = uimenu(app.DisplayMenu);
            app.GFPColorMenu.MenuSelectedFcn = createCallbackFcn(app, @GFPColorMenuSelected, true);
            app.GFPColorMenu.Text = 'GFP Color';
            app.AnalysisMenu = uimenu(app.CELL_ID);
            app.AnalysisMenu.Text = 'Analysis';
            app.SaveIDInfoMenu = uimenu(app.AnalysisMenu);
            app.SaveIDInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveIDs2CSV, true);
            app.SaveIDInfoMenu.Text = 'Save ID Info';
            app.SaveIDImageMenu = uimenu(app.AnalysisMenu);
            app.SaveIDImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveIDImageMenuSelected, true);
            app.SaveIDImageMenu.Text = 'Save ID Image';
            app.SaveNWBMenu = uimenu(app.AnalysisMenu);
            app.SaveNWBMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveNWBMenuSelected, true);
            app.SaveNWBMenu.Text = 'Save .NWB';
            app.HelpMenu = uimenu(app.CELL_ID);
            app.HelpMenu.Text = 'Help';
            app.CheckforUpdatesMenu = uimenu(app.HelpMenu);
            app.CheckforUpdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforUpdatesMenuSelected, true);
            app.CheckforUpdatesMenu.Text = 'Check for Updates';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.InstructionsMenu = uimenu(app.HelpMenu);
            app.InstructionsMenu.MenuSelectedFcn = createCallbackFcn(app, @InstructionsMenuSelected, true);
            app.InstructionsMenu.Text = 'Instructions';
            app.KeyboardShortcutsMenu = uimenu(app.HelpMenu);
            app.KeyboardShortcutsMenu.MenuSelectedFcn = createCallbackFcn(app, @KeyboardShortcutsMenuSelected, true);
            app.KeyboardShortcutsMenu.Text = 'Keyboard Shortcuts';
            app.NeuroPALWebsiteMenu = uimenu(app.HelpMenu);
            app.NeuroPALWebsiteMenu.MenuSelectedFcn = createCallbackFcn(app, @NeuroPALWebsiteMenuSelected, true);
            app.NeuroPALWebsiteMenu.Text = 'NeuroPAL Website';
            app.ReportaBugMenu = uimenu(app.HelpMenu);
            app.ReportaBugMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportBug, true);
            app.ReportaBugMenu.Text = 'Report a Bug';
            app.DebugMenu = uimenu(app.CELL_ID);
            app.DebugMenu.Visible = 'off';
            app.DebugMenu.Text = 'Debug';
            app.LogtesteventMenu = uimenu(app.DebugMenu);
            app.LogtesteventMenu.MenuSelectedFcn = createCallbackFcn(app, @LogtesteventMenuSelected, true);
            app.LogtesteventMenu.Text = 'Log test event';
            app.LogfadeeventMenu = uimenu(app.DebugMenu);
            app.LogfadeeventMenu.MenuSelectedFcn = createCallbackFcn(app, @LogfadeeventMenuSelected, true);
            app.LogfadeeventMenu.Text = 'Log fade event';
            app.DebugstatesMenu = uimenu(app.DebugMenu);
            app.DebugstatesMenu.Text = 'Debug states';
            app.ResetProcessingTabMenu = uimenu(app.DebugstatesMenu);
            app.ResetProcessingTabMenu.Text = 'Reset Processing Tab';
            app.DebugactionsMenu = uimenu(app.DebugMenu);
            app.DebugactionsMenu.Text = 'Debug actions';
            app.GUIMenu = uimenu(app.DebugactionsMenu);
            app.GUIMenu.Text = 'GUI';
            app.RotationGUIMenu = uimenu(app.GUIMenu);
            app.RotationGUIMenu.MenuSelectedFcn = createCallbackFcn(app, @RotationGUIMenuSelected, true);
            app.RotationGUIMenu.Text = 'Rotation GUI';
            app.ProcessingMenu = uimenu(app.DebugactionsMenu);
            app.ProcessingMenu.Text = 'Processing';
            app.daspect_testMenu = uimenu(app.ProcessingMenu);
            app.daspect_testMenu.MenuSelectedFcn = createCallbackFcn(app, @daspect_testMenuSelected, true);
            app.daspect_testMenu.Text = 'daspect_test';
            app.projected_axes_testMenu = uimenu(app.ProcessingMenu);
            app.projected_axes_testMenu.MenuSelectedFcn = createCallbackFcn(app, @projected_axes_testMenuSelected, true);
            app.projected_axes_testMenu.Text = 'projected_axes_test';
            app.TriggerBreakpointMenu = uimenu(app.DebugactionsMenu);
            app.TriggerBreakpointMenu.MenuSelectedFcn = createCallbackFcn(app, @TriggerBreakpointMenuSelected, true);
            app.TriggerBreakpointMenu.Text = 'Trigger Breakpoint';
            app.OptimizationMenu = uimenu(app.DebugMenu);
            app.OptimizationMenu.Text = 'Optimization';
            app.ProfilingMenu = uimenu(app.OptimizationMenu);
            app.ProfilingMenu.Text = 'Profiling';
            app.EnableautoprofilingMenu = uimenu(app.ProfilingMenu);
            app.EnableautoprofilingMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableautoprofilingMenuSelected2, true);
            app.EnableautoprofilingMenu.Text = 'Enable autoprofiling';
            app.ProfileOnMenu = uimenu(app.ProfilingMenu);
            app.ProfileOnMenu.MenuSelectedFcn = createCallbackFcn(app, @ProfileOnMenuSelected, true);
            app.ProfileOnMenu.Enable = 'off';
            app.ProfileOnMenu.Text = 'Profile On';
            app.ProfileOffMenu = uimenu(app.ProfilingMenu);
            app.ProfileOffMenu.MenuSelectedFcn = createCallbackFcn(app, @ProfileOffMenuSelected, true);
            app.ProfileOffMenu.Enable = 'off';
            app.ProfileOffMenu.Text = 'Profile Off';
            app.VolTraceParent = uipanel(app.CELL_ID);
            app.VolTraceParent.Visible = 'off';
            app.VolTraceHelperGrid = uigridlayout(app.VolTraceParent);
            app.VolTraceHelperGrid.Tag = 'browser_trace';
            app.Panel = uipanel(app.VolTraceHelperGrid);
            app.GridLayout = uigridlayout(app.Panel);
            app.DeleteNeuronDropDown = uidropdown(app.GridLayout);
            app.DeleteNeuronDropDown.Items = {};
            app.DeleteNeuronDropDown.ValueChangedFcn = createCallbackFcn(app, @DeleteNeuronDropDownValueChanged, true);
            app.DeleteNeuronDropDown.Placeholder = 'Delete Neuron';
            app.DeleteNeuronDropDown.Value = {};
            app.DeletePlotDropDown = uidropdown(app.GridLayout);
            app.DeletePlotDropDown.Items = {};
            app.DeletePlotDropDown.ValueChangedFcn = createCallbackFcn(app, @DeletePlotDropDownValueChanged, true);
            app.DeletePlotDropDown.Placeholder = 'Delete Plot';
            app.DeletePlotDropDown.Value = {};
            app.AddPlotButton = uibutton(app.GridLayout, 'push');
            app.AddPlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlotButtonPushed, true);
            app.AddPlotButton.Text = 'Add Plot';
            app.LoadStimuliButton = uibutton(app.GridLayout, 'push');
            app.LoadStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStimuliButtonPushed, true);
            app.LoadStimuliButton.Text = 'Load Stimuli';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {};
            app.DropDown.Placeholder = 'Normalize...';
            app.DropDown.Value = {};
            app.GridLayout59 = uigridlayout(app.CELL_ID);
            app.TabGroup = uitabgroup(app.GridLayout59);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.NeuroPALIDTab = uitab(app.TabGroup);
            app.NeuroPALIDTab.Title = 'NeuroPAL ID';
            app.IdGridLayout = uigridlayout(app.NeuroPALIDTab);
            app.IdGridLayout.Visible = 'off';
            app.SidePanel = uipanel(app.IdGridLayout);
            app.SidePanel.Tag = 'side-panel';
            app.SideGrid = uigridlayout(app.SidePanel);
            app.SideGrid.Tag = 'side-grid';
            app.NeuronListBoxPanel = uipanel(app.SideGrid);
            app.NeuronListBoxPanel.Tag = 'id-list-boxes';
            app.NeuronListBoxGrid = uigridlayout(app.NeuronListBoxPanel);
            app.UnIDdNeuronsListBox = uilistbox(app.NeuronListBoxGrid);
            app.UnIDdNeuronsListBox.Items = {};
            app.UnIDdNeuronsListBox.ValueChangedFcn = createCallbackFcn(app, @UnIDdNeuronsListChanged, true);
            app.UnIDdNeuronsListBox.Value = {};
            app.IDdLabel = uilabel(app.NeuronListBoxGrid);
            app.IDdLabel.Text = '     ID''d     ';
            app.IDdNeuronsListBox = uilistbox(app.NeuronListBoxGrid);
            app.IDdNeuronsListBox.Items = {''};
            app.IDdNeuronsListBox.ValueChangedFcn = createCallbackFcn(app, @IDdNeuronsListChanged, true);
            app.IDdNeuronsListBox.Tag = 'neuron-selector';
            app.IDdNeuronsListBox.ClickedFcn = createCallbackFcn(app, @IDdNeuronsListBoxClicked, true);
            app.IDdNeuronsListBox.DoubleClickedFcn = createCallbackFcn(app, @IDdNeuronsListBoxDoubleClicked, true);
            app.IDdNeuronsListBox.Value = '';
            app.UnIDdLabel = uilabel(app.NeuronListBoxGrid);
            app.UnIDdLabel.Text = '     Un-ID''d     ';
            app.NeuronRankPanel = uipanel(app.SideGrid);
            app.NeuronRankGrid = uigridlayout(app.NeuronRankPanel);
            app.Panel_25 = uipanel(app.NeuronRankGrid);
            app.GridLayout22 = uigridlayout(app.Panel_25);
            app.UserNeuronIDsListBox = uilistbox(app.GridLayout22);
            app.UserNeuronIDsListBox.Items = {};
            app.UserNeuronIDsListBox.ValueChangedFcn = createCallbackFcn(app, @AutoNeuronIDListBoxValueChanged, true);
            app.UserNeuronIDsListBox.Value = {};
            app.UserNeuronIDsListBoxLabel = uilabel(app.GridLayout22);
            app.UserNeuronIDsListBoxLabel.Text = 'User Neuron IDs';
            app.NeuronRankedConfidenceLabel = uilabel(app.GridLayout22);
            app.NeuronRankedConfidenceLabel.Text = 'Neuron Ranked Confidence';
            app.Panel_17 = uipanel(app.NeuronRankGrid);
            app.GridLayout21 = uigridlayout(app.Panel_17);
            app.NextNeuronLabel = uilabel(app.GridLayout21);
            app.NextNeuronLabel.Text = 'Next Neuron:';
            app.NextNeuronDropDown = uidropdown(app.GridLayout21);
            app.NextNeuronDropDown.Items = {'Nearest', 'Probabilistic'};
            app.NextNeuronDropDown.Value = 'Nearest';
            app.ChannelPanel = uipanel(app.SideGrid);
            app.ChannelGrid = uigridlayout(app.ChannelPanel);
            app.Panel_23 = uipanel(app.ChannelGrid);
            app.GridLayout26_5 = uigridlayout(app.Panel_23);
            app.DICDropDown = uidropdown(app.GridLayout26_5);
            app.DICDropDown.Items = {'1', '2', '3', '4', '5'};
            app.DICDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.DICDropDown.Value = '1';
            app.DICCheckBox = uicheckbox(app.GridLayout26_5);
            app.DICCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.DICCheckBox.Text = '';
            app.DICLabel = uilabel(app.GridLayout26_5);
            app.DICLabel.Text = 'DIC:';
            app.Panel_19 = uipanel(app.ChannelGrid);
            app.GridLayout26 = uigridlayout(app.Panel_19);
            app.GLabel = uilabel(app.GridLayout26);
            app.GLabel.Text = 'G:';
            app.GCheckBox = uicheckbox(app.GridLayout26);
            app.GCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.GCheckBox.Text = '';
            app.GCheckBox.Value = true;
            app.GDropDown = uidropdown(app.GridLayout26);
            app.GDropDown.Items = {'1', '2', '3', '4', '5', ''};
            app.GDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.GDropDown.Value = '1';
            app.Panel_24 = uipanel(app.ChannelGrid);
            app.GridLayout26_6 = uigridlayout(app.Panel_24);
            app.GFPLabel = uilabel(app.GridLayout26_6);
            app.GFPLabel.Text = 'GFP:';
            app.GFPCheckBox = uicheckbox(app.GridLayout26_6);
            app.GFPCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.GFPCheckBox.Text = '';
            app.GFPDropDown = uidropdown(app.GridLayout26_6);
            app.GFPDropDown.Items = {'1', '2', '3', '4', '5'};
            app.GFPDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.GFPDropDown.Value = '1';
            app.Panel_20 = uipanel(app.ChannelGrid);
            app.GridLayout26_2 = uigridlayout(app.Panel_20);
            app.BLabel = uilabel(app.GridLayout26_2);
            app.BLabel.Text = 'B:';
            app.BCheckBox = uicheckbox(app.GridLayout26_2);
            app.BCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.BCheckBox.Text = '';
            app.BCheckBox.Value = true;
            app.BDropDown = uidropdown(app.GridLayout26_2);
            app.BDropDown.Items = {'1', '2', '3', '4', '5', ''};
            app.BDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.BDropDown.Value = '1';
            app.Panel_21 = uipanel(app.ChannelGrid);
            app.GridLayout26_3 = uigridlayout(app.Panel_21);
            app.WCheckBox = uicheckbox(app.GridLayout26_3);
            app.WCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.WCheckBox.Text = '';
            app.WDropDown = uidropdown(app.GridLayout26_3);
            app.WDropDown.Items = {'1', '2', '3', '4', '5'};
            app.WDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.WDropDown.Value = '1';
            app.WLabel = uilabel(app.GridLayout26_3);
            app.WLabel.Text = 'W:';
            app.Panel_18 = uipanel(app.ChannelGrid);
            app.GridLayout25 = uigridlayout(app.Panel_18);
            app.RLabel = uilabel(app.GridLayout25);
            app.RLabel.Text = 'R:';
            app.RCheckBox = uicheckbox(app.GridLayout25);
            app.RCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.RCheckBox.Text = '';
            app.RCheckBox.Value = true;
            app.RDropDown = uidropdown(app.GridLayout25);
            app.RDropDown.Items = {'1', '2', '3', '4', '5', ''};
            app.RDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.RDropDown.Value = '1';
            app.Panel_6 = uipanel(app.IdGridLayout);
            app.GridLayout19 = uigridlayout(app.Panel_6);
            app.GanglionTree = uitree(app.GridLayout19);
            app.GanglionTree.Multiselect = 'on';
            app.GanglionTree.SelectionChangedFcn = createCallbackFcn(app, @GanglionTreeSelectionChanged, true);
            app.PharynxNode = uitreenode(app.GanglionTree);
            app.PharynxNode.Text = 'Pharynx';
            app.AnteriorNode = uitreenode(app.PharynxNode);
            app.AnteriorNode.Text = 'Anterior';
            app.PosteriorNode = uitreenode(app.PharynxNode);
            app.PosteriorNode.Text = 'Posterior';
            app.HeadNode = uitreenode(app.GanglionTree);
            app.HeadNode.Text = 'Head';
            app.AnteriorLNode = uitreenode(app.HeadNode);
            app.AnteriorLNode.Text = 'Anterior (L)';
            app.AnteriorRNode = uitreenode(app.HeadNode);
            app.AnteriorRNode.Text = 'Anterior (R)';
            app.DorsalNode = uitreenode(app.HeadNode);
            app.DorsalNode.Text = 'Dorsal';
            app.LateralLNode = uitreenode(app.HeadNode);
            app.LateralLNode.Text = 'Lateral (L)';
            app.LateralRNode = uitreenode(app.HeadNode);
            app.LateralRNode.Text = 'Lateral (R)';
            app.VentralNode = uitreenode(app.HeadNode);
            app.VentralNode.Text = 'Ventral';
            app.RetroVesicularNode = uitreenode(app.HeadNode);
            app.RetroVesicularNode.Text = 'Retro-Vesicular';
            app.MidbodyNode = uitreenode(app.GanglionTree);
            app.MidbodyNode.Text = 'Midbody';
            app.AnteriorNode_2 = uitreenode(app.MidbodyNode);
            app.AnteriorNode_2.Text = 'Anterior';
            app.CentralNode = uitreenode(app.MidbodyNode);
            app.CentralNode.Text = 'Central';
            app.PosteriorNode_2 = uitreenode(app.MidbodyNode);
            app.PosteriorNode_2.Text = 'Posterior';
            app.VentralCordNode = uitreenode(app.MidbodyNode);
            app.VentralCordNode.Text = 'Ventral Cord';
            app.TailNode = uitreenode(app.GanglionTree);
            app.TailNode.Text = 'Tail';
            app.PreAnalNode = uitreenode(app.TailNode);
            app.PreAnalNode.Text = 'Pre-Anal';
            app.DorsoRectalNode = uitreenode(app.TailNode);
            app.DorsoRectalNode.Text = 'Dorso-Rectal';
            app.LumbarLNode = uitreenode(app.TailNode);
            app.LumbarLNode.Text = 'Lumbar (L)';
            app.LumbarRNode = uitreenode(app.TailNode);
            app.LumbarRNode.Text = 'Lumbar (R)';
            app.GanglionNeuronIDLabel = uilabel(app.GridLayout19);
            app.GanglionNeuronIDLabel.Text = 'Ganglion Neuron ID';
            app.Panel_7 = uipanel(app.IdGridLayout);
            app.GridLayout14 = uigridlayout(app.Panel_7);
            app.TabGroup4 = uitabgroup(app.GridLayout14);
            app.MaximumIntensityProjectionTab = uitab(app.TabGroup4);
            app.MaximumIntensityProjectionTab.Title = 'Maximum Intensity Projection';
            app.GridLayout76 = uigridlayout(app.MaximumIntensityProjectionTab);
            app.MaxProjection = uiaxes(app.GridLayout76);
            title(app.MaxProjection, 'Maximum Intensity Projection')
            app.NeuronActivityTab = uitab(app.TabGroup4);
            app.NeuronActivityTab.Title = 'Neuron Activity';
            app.NeuronActivityTab.Tag = 'neuron-activity-tab';
            app.GridLayout75 = uigridlayout(app.NeuronActivityTab);
            app.LoadNeuronActivityButton = uibutton(app.GridLayout75, 'push');
            app.LoadNeuronActivityButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNeuronActivityButtonPushed, true);
            app.LoadNeuronActivityButton.Text = 'Load Neuron Activity';
            app.Panel_8 = uipanel(app.IdGridLayout);
            app.GridLayout11 = uigridlayout(app.Panel_8);
            app.Panel_10 = uipanel(app.GridLayout11);
            app.GridLayout13 = uigridlayout(app.Panel_10);
            app.ZLeftLabel = uilabel(app.GridLayout13);
            app.ZLeftLabel.Text = 'LEFT';
            app.ZAxisDropDown = uidropdown(app.GridLayout13);
            app.ZAxisDropDown.Items = {'L/R', 'D/V'};
            app.ZAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ZAxisChanged, true);
            app.ZAxisDropDown.Value = 'L/R';
            app.Panel_11 = uipanel(app.GridLayout11);
            app.GridLayout12 = uigridlayout(app.Panel_11);
            app.FlipZButton = uibutton(app.GridLayout12, 'push');
            app.FlipZButton.ButtonPushedFcn = createCallbackFcn(app, @FlipZButtonPushed, true);
            app.FlipZButton.Text = 'Flip Z';
            app.ZRightLabel = uilabel(app.GridLayout12);
            app.ZRightLabel.Text = 'RIGHT';
            app.CenterZLabel = uilabel(app.GridLayout12);
            app.CenterZLabel.Text = 'Center Z:';
            app.ZCenterEditField = uieditfield(app.GridLayout12, 'numeric');
            app.ZCenterEditField.ValueChangedFcn = createCallbackFcn(app, @ZCenterChanged, true);
            app.ZCenterEditField.Value = 10;
            app.Panel_16 = uipanel(app.GridLayout11);
            app.GridLayout20 = uigridlayout(app.Panel_16);
            app.ZSliderLabel = uilabel(app.GridLayout20);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.GridLayout20);
            app.ZSlider.Limits = [1 20];
            app.ZSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ZSlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9];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
            app.ZSlider.Value = 1;
            app.XYPanel = uipanel(app.IdGridLayout);
            app.GridLayout10 = uigridlayout(app.XYPanel);
            app.XY = uiaxes(app.GridLayout10);
            app.Panel_9 = uipanel(app.IdGridLayout);
            app.IdMetadataGridLayout = uigridlayout(app.Panel_9);
            app.Panel_30 = uipanel(app.IdMetadataGridLayout);
            app.GridLayout24_2 = uigridlayout(app.Panel_30);
            app.AutoIDDropDown = uidropdown(app.GridLayout24_2);
            app.AutoIDDropDown.Items = {};
            app.AutoIDDropDown.ValueChangedFcn = createCallbackFcn(app, @AutoIDDropDownValueChanged, true);
            app.AutoIDDropDown.Enable = 'off';
            app.AutoIDDropDown.Value = {};
            app.NeuronsLabel = uilabel(app.GridLayout24_2);
            app.NeuronsLabel.Text = 'Neurons:';
            app.AutoDetectButton = uibutton(app.GridLayout24_2, 'push');
            app.AutoDetectButton.ButtonPushedFcn = createCallbackFcn(app, @AutoDetectButtonPushed, true);
            app.AutoDetectButton.Text = 'Auto-Detect';
            app.AutoIDAllButton = uibutton(app.GridLayout24_2, 'push');
            app.AutoIDAllButton.ButtonPushedFcn = createCallbackFcn(app, @AutoIDAllButtonPushed, true);
            app.AutoIDAllButton.Text = 'Auto-ID All';
            app.AutoLabel = uilabel(app.GridLayout24_2);
            app.AutoLabel.Text = 'Auto:';
            app.AutoIDButton = uibutton(app.GridLayout24_2, 'push');
            app.AutoIDButton.ButtonPushedFcn = createCallbackFcn(app, @AutoIDButtonPushed, true);
            app.AutoIDButton.Text = 'Auto ID';
            app.UserLabel = uilabel(app.GridLayout24_2);
            app.UserLabel.Text = 'User:';
            app.IDEditField = uieditfield(app.GridLayout24_2, 'text');
            app.IDEditField.ValueChangingFcn = createCallbackFcn(app, @IDEditFieldChanging, true);
            app.IDEditField.Enable = 'off';
            app.UserIDButton = uibutton(app.GridLayout24_2, 'push');
            app.UserIDButton.ButtonPushedFcn = createCallbackFcn(app, @UserIDButtonPushed, true);
            app.UserIDButton.Text = 'User ID';
            app.ClicksLabel = uilabel(app.GridLayout24_2);
            app.ClicksLabel.Text = 'Clicks:';
            app.MouseClickDropDown = uidropdown(app.GridLayout24_2);
            app.MouseClickDropDown.Items = {'Add Neuron', 'Detect Neuron'};
            app.MouseClickDropDown.Value = 'Add Neuron';
            app.AutoNameLabel = uilabel(app.GridLayout24_2);
            app.AutoNameLabel.Text = 'Auto Name:';
            app.AutoNameCheckBox = uicheckbox(app.GridLayout24_2);
            app.AutoNameCheckBox.Text = '';
            app.AutoNameCheckBox.Value = true;
            app.ColorAtlasCheckBoxLabel = uilabel(app.GridLayout24_2);
            app.ColorAtlasCheckBoxLabel.Text = 'Color Atlas:';
            app.ColorAtlasCheckBox = uicheckbox(app.GridLayout24_2);
            app.ColorAtlasCheckBox.Text = '';
            app.Panel_26 = uipanel(app.IdMetadataGridLayout);
            app.GridLayout24 = uigridlayout(app.Panel_26);
            app.OpenButton = uibutton(app.GridLayout24, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFile, true);
            app.OpenButton.Text = 'Open';
            app.FileLabel = uilabel(app.GridLayout24);
            app.FileLabel.Text = 'File:';
            app.SaveIDsButton = uibutton(app.GridLayout24, 'push');
            app.SaveIDsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIDs2CSV, true);
            app.SaveIDsButton.Text = 'IDs';
            app.SaveLabel = uilabel(app.GridLayout24);
            app.SaveLabel.Text = 'Save:';
            app.BodyLabel = uilabel(app.GridLayout24);
            app.BodyLabel.Text = 'Body:';
            app.BodyDropDown = uidropdown(app.GridLayout24);
            app.BodyDropDown.Items = {'Whole Worm', 'Head', 'Midbody', 'Anterior Midbody', 'Central Midbody', 'Posterior Midbody', 'Tail'};
            app.BodyDropDown.ValueChangedFcn = createCallbackFcn(app, @BodyDropDownValueChanged, true);
            app.BodyDropDown.Value = 'Whole Worm';
            app.SexLabel = uilabel(app.GridLayout24);
            app.SexLabel.Text = 'Sex:';
            app.SexDropDown = uidropdown(app.GridLayout24);
            app.SexDropDown.Items = {'XX', 'XO'};
            app.SexDropDown.ValueChangedFcn = createCallbackFcn(app, @SexDropDownValueChanged, true);
            app.SexDropDown.Value = 'XX';
            app.AgeDropDownLabel = uilabel(app.GridLayout24);
            app.AgeDropDownLabel.Text = 'Age:';
            app.AgeDropDown = uidropdown(app.GridLayout24);
            app.AgeDropDown.Items = {'Adult', 'L4', 'L3', 'L2', 'L1', '3-Fold'};
            app.AgeDropDown.ValueChangedFcn = createCallbackFcn(app, @AgeDropDownValueChanged, true);
            app.AgeDropDown.Value = 'Adult';
            app.StrainEditFieldLabel = uilabel(app.GridLayout24);
            app.StrainEditFieldLabel.Text = 'Strain:';
            app.StrainEditField = uieditfield(app.GridLayout24, 'text');
            app.StrainEditField.ValueChangedFcn = createCallbackFcn(app, @StrainEditFieldValueChanged, true);
            app.NotesEditFieldLabel = uilabel(app.GridLayout24);
            app.NotesEditFieldLabel.Text = 'Notes:';
            app.SubjectNotesTextArea = uieditfield(app.GridLayout24, 'text');
            app.SubjectNotesTextArea.ValueChangedFcn = createCallbackFcn(app, @SubjectNotesTextAreaValueChanged, true);
            app.VideoTrackingTab = uitab(app.TabGroup);
            app.VideoTrackingTab.Title = 'Video Tracking';
            app.VideoTrackingTab.Tag = 'raw';
            app.VideoGridLayout = uigridlayout(app.VideoTrackingTab);
            app.NeuronInformationPanel = uipanel(app.VideoGridLayout);
            app.NeuronInformationPanel.Title = 'Neuron Information';
            app.GridLayout9_2 = uigridlayout(app.NeuronInformationPanel);
            app.XCoordinateEditFieldLabel = uilabel(app.GridLayout9_2);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.GridLayout9_2, 'numeric');
            app.YCoordinateEditFieldLabel = uilabel(app.GridLayout9_2);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.GridLayout9_2, 'numeric');
            app.ZCoordinateEditFieldLabel = uilabel(app.GridLayout9_2);
            app.ZCoordinateEditFieldLabel.Text = 'Z Coordinate';
            app.ZCoordinateEditField = uieditfield(app.GridLayout9_2, 'numeric');
            app.Button = uibutton(app.GridLayout9_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '✔';
            app.Button_2 = uibutton(app.GridLayout9_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed2, true);
            app.Button_2.Text = '🗑';
            app.ColorLabel = uilabel(app.GridLayout9_2);
            app.ColorLabel.Text = 'Color';
            app.ColorButton = uibutton(app.GridLayout9_2, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = '';
            app.WorldlineIDEditFieldLabel = uilabel(app.GridLayout9_2);
            app.WorldlineIDEditFieldLabel.Text = 'Worldline ID';
            app.WorldlineIDEditField = uieditfield(app.GridLayout9_2, 'numeric');
            app.ProvenanceEditFieldLabel = uilabel(app.GridLayout9_2);
            app.ProvenanceEditFieldLabel.Text = 'Provenance';
            app.ProvenanceEditField = uieditfield(app.GridLayout9_2, 'text');
            app.ProvenanceEditField.Placeholder = 'NPAL';
            app.NameEditFieldLabel = uilabel(app.GridLayout9_2);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout9_2, 'text');
            app.NameEditField.Placeholder = 'Neuron Name';
            app.WorldlineInformationPanel = uipanel(app.VideoGridLayout);
            app.WorldlineInformationPanel.Title = 'Worldline Information';
            app.GridLayout51 = uigridlayout(app.WorldlineInformationPanel);
            app.Panel_32 = uipanel(app.GridLayout51);
            app.GridLayout52 = uigridlayout(app.Panel_32);
            app.AssignrandomcolorstoblanksButton = uibutton(app.GridLayout52, 'push');
            app.AssignrandomcolorstoblanksButton.ButtonPushedFcn = createCallbackFcn(app, @AssignrandomcolorstoblanksButtonPushed, true);
            app.AssignrandomcolorstoblanksButton.Text = 'Assign random colors to blanks';
            app.SelectworldlinebyIDButton = uibutton(app.GridLayout52, 'push');
            app.SelectworldlinebyIDButton.ButtonPushedFcn = createCallbackFcn(app, @SelectworldlinebyIDButtonPushed, true);
            app.SelectworldlinebyIDButton.Text = 'Select worldline by ID';
            app.WorldlineTree = uitree(app.GridLayout51, 'checkbox');
            app.WorldlineTree.SelectionChangedFcn = createCallbackFcn(app, @WorldlineTreeSelectionChanged, true);
            app.IDdNode = uitreenode(app.WorldlineTree);
            app.IDdNode.Text = 'ID''d';
            app.UnIDdNode = uitreenode(app.WorldlineTree);
            app.UnIDdNode.Text = 'Un-ID''d';
            app.WorkflowPanel = uipanel(app.VideoGridLayout);
            app.WorkflowPanel.Title = 'Workflow';
            app.GridLayout13_2 = uigridlayout(app.WorkflowPanel);
            app.InsertNeuroPALNeuronsButton = uibutton(app.GridLayout13_2, 'push');
            app.InsertNeuroPALNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @InsertNeuroPALNeuronsButtonPushed, true);
            app.InsertNeuroPALNeuronsButton.Text = 'Insert NeuroPAL Neurons';
            app.SaveAnnotationsButton = uibutton(app.GridLayout13_2, 'push');
            app.SaveAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnnotationsButtonPushed, true);
            app.SaveAnnotationsButton.Text = 'Save Annotations';
            app.Panel_33 = uipanel(app.GridLayout13_2);
            app.GridLayout14_2 = uigridlayout(app.Panel_33);
            app.RecommendFramesButton = uibutton(app.GridLayout14_2, 'push');
            app.RecommendFramesButton.ButtonPushedFcn = createCallbackFcn(app, @RecommendFramesButtonPushed, true);
            app.RecommendFramesButton.Text = 'Reference Frames';
            app.TrackNeuronsButton = uibutton(app.GridLayout14_2, 'push');
            app.TrackNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @TrackNeuronsButtonPushed, true);
            app.TrackNeuronsButton.Text = 'Track Neurons';
            app.ExtractActivityButton = uibutton(app.GridLayout14_2, 'push');
            app.ExtractActivityButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractActivityButtonPushed, true);
            app.ExtractActivityButton.Text = 'Extract Activity';
            app.InsertlastIDdFrameButton = uibutton(app.GridLayout13_2, 'push');
            app.InsertlastIDdFrameButton.ButtonPushedFcn = createCallbackFcn(app, @InsertlastIDdFrameButtonPushed, true);
            app.InsertlastIDdFrameButton.Text = 'Insert last ID''d Frame';
            app.ImportexistingtracksButton = uibutton(app.GridLayout13_2, 'push');
            app.ImportexistingtracksButton.ButtonPushedFcn = createCallbackFcn(app, @ImportexistingtracksButtonPushed, true);
            app.ImportexistingtracksButton.Text = 'Import existing tracks';
            app.AllowflexibleaspectratioCheckBox = uicheckbox(app.GridLayout13_2);
            app.AllowflexibleaspectratioCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllowflexibleaspectratioCheckBoxValueChanged, true);
            app.AllowflexibleaspectratioCheckBox.Text = 'Allow flexible aspect ratio.';
            app.AllowflexibleaspectratioCheckBox.Value = true;
            app.Panel_34 = uipanel(app.VideoGridLayout);
            app.GridLayout40 = uigridlayout(app.Panel_34);
            app.Panel_35 = uipanel(app.GridLayout40);
            app.GridLayout41 = uigridlayout(app.Panel_35);
            app.xEditFieldLabel = uilabel(app.GridLayout41);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GridLayout41, 'numeric');
            app.yEditFieldLabel = uilabel(app.GridLayout41);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.GridLayout41, 'numeric');
            app.zEditFieldLabel = uilabel(app.GridLayout41);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.GridLayout41, 'numeric');
            app.Panel_36 = uipanel(app.GridLayout40);
            app.GridLayout42 = uigridlayout(app.Panel_36);
            app.Panel_37 = uipanel(app.GridLayout42);
            app.GridLayout43 = uigridlayout(app.Panel_37);
            app.tEditFieldLabel = uilabel(app.GridLayout43);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.GridLayout43, 'numeric');
            app.Panel_38 = uipanel(app.GridLayout42);
            app.GridLayout44 = uigridlayout(app.Panel_38);
            app.tSlider = uislider(app.GridLayout44);
            app.tSlider.ValueChangedFcn = createCallbackFcn(app, @tSliderValueChanged, true);
            app.tSlider.ValueChangingFcn = createCallbackFcn(app, @tSliderValueChanging, true);
            app.Panel_55 = uipanel(app.VideoGridLayout);
            app.GridLayout2_2 = uigridlayout(app.Panel_55);
            app.xyAxes = uiaxes(app.GridLayout2_2);
            app.xyAxes.XTickLabel = '';
            app.xyAxes.YTickLabel = '';
            app.xyAxes.Tag = 'xy';
            app.xzAxes = uiaxes(app.GridLayout2_2);
            app.xzAxes.XTickLabel = '';
            app.xzAxes.YTickLabel = '';
            app.xzAxes.Tag = 'xz';
            app.yzAxes = uiaxes(app.GridLayout2_2);
            app.yzAxes.XTickLabel = '';
            app.yzAxes.YTickLabel = '';
            app.yzAxes.Tag = 'yz';
            app.xSlider = uislider(app.GridLayout2_2);
            app.xSlider.MajorTicks = [];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.xSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.xSlider.Value = 50;
            app.hor_zSlider = uislider(app.GridLayout2_2);
            app.hor_zSlider.MajorTicks = [];
            app.hor_zSlider.ValueChangedFcn = createCallbackFcn(app, @hor_zSliderValueChanged, true);
            app.hor_zSlider.ValueChangingFcn = createCallbackFcn(app, @hor_zSliderValueChanging, true);
            app.hor_zSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.hor_zSlider.Value = 50;
            app.ySlider = uislider(app.GridLayout2_2);
            app.ySlider.MajorTicks = [];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.ySlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.ySlider.Value = 50;
            app.vert_zSlider = uislider(app.GridLayout2_2);
            app.vert_zSlider.MajorTicks = [];
            app.vert_zSlider.ValueChangedFcn = createCallbackFcn(app, @vert_zSliderValueChanged, true);
            app.vert_zSlider.ValueChangingFcn = createCallbackFcn(app, @vert_zSliderValueChanging, true);
            app.vert_zSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.vert_zSlider.Value = 50;
            app.DefaultTabGroup = uitabgroup(app.VideoGridLayout);
            app.AnnotationSupportTab = uitab(app.DefaultTabGroup);
            app.AnnotationSupportTab.Title = 'Annotation Support';
            app.GridLayout7_2 = uigridlayout(app.AnnotationSupportTab);
            app.BookmarkedFramesPanel = uipanel(app.GridLayout7_2);
            app.BookmarkedFramesPanel.Title = 'Bookmarked Frames';
            app.GridLayout8_2 = uigridlayout(app.BookmarkedFramesPanel);
            app.GotoBookmarkButton = uibutton(app.GridLayout8_2, 'push');
            app.GotoBookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @GotoBookmarkButtonPushed, true);
            app.GotoBookmarkButton.Text = 'Go to Bookmark';
            app.SetBookmarkButton = uibutton(app.GridLayout8_2, 'push');
            app.SetBookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @SetBookmarkButtonPushed, true);
            app.SetBookmarkButton.Text = 'Set Bookmark';
            app.BookmarkNameEditField = uieditfield(app.GridLayout8_2, 'text');
            app.BookmarkNameEditField.Placeholder = 'Bookmark Name';
            app.DeleteBookmarkButton = uibutton(app.GridLayout8_2, 'push');
            app.DeleteBookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBookmarkButtonPushed, true);
            app.DeleteBookmarkButton.Text = 'Delete Bookmark';
            app.BookmarkListBox = uilistbox(app.GridLayout8_2);
            app.BookmarkListBox.Items = {};
            app.BookmarkListBox.Value = {};
            app.ColorControlPanel = uipanel(app.GridLayout7_2);
            app.ColorControlPanel.Title = 'Color Control';
            app.GridLayout16_2 = uigridlayout(app.ColorControlPanel);
            app.RSliderLabel = uilabel(app.GridLayout16_2);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.GridLayout16_2);
            app.RSlider.Limits = [0 10];
            app.RSlider.MajorTicks = [];
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.RSlider.Value = 1;
            app.GSliderLabel = uilabel(app.GridLayout16_2);
            app.GSliderLabel.Text = 'G';
            app.GSlider = uislider(app.GridLayout16_2);
            app.GSlider.Limits = [0 10];
            app.GSlider.MajorTicks = [];
            app.GSlider.ValueChangedFcn = createCallbackFcn(app, @GSliderValueChanged, true);
            app.GSlider.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.GSlider.Value = 1;
            app.BSliderLabel = uilabel(app.GridLayout16_2);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.GridLayout16_2);
            app.BSlider.Limits = [0 10];
            app.BSlider.MajorTicks = [];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.BSlider.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.BSlider.Value = 1;
            app.HotkeysPanel = uipanel(app.GridLayout7_2);
            app.HotkeysPanel.Title = 'Hotkeys';
            app.GridLayout47 = uigridlayout(app.HotkeysPanel);
            app.Frame1HKButton = uibutton(app.GridLayout47, 'state');
            app.Frame1HKButton.Text = 'CTRL+1';
            app.JmpFrameEditField = uieditfield(app.GridLayout47, 'numeric');
            app.JmpFrameEditField.Value = 1;
            app.Frame2HKButton = uibutton(app.GridLayout47, 'state');
            app.Frame2HKButton.Text = 'CTRL+2';
            app.JmpFrameEditField_2 = uieditfield(app.GridLayout47, 'numeric');
            app.JmpFrameEditField_2.Value = 2;
            app.DeleteHKButton = uibutton(app.GridLayout47, 'state');
            app.DeleteHKButton.Text = 'CTRL+a';
            app.JumpHKButton = uibutton(app.GridLayout47, 'state');
            app.JumpHKButton.Text = 'CTRL+0';
            app.TextArea = uitextarea(app.GridLayout47);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Value = {'Jump'; 'to Frame'};
            app.TextArea_2 = uitextarea(app.GridLayout47);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Value = {'Jump'; 'to Frame'};
            app.TextArea_3 = uitextarea(app.GridLayout47);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Enable = 'off';
            app.TextArea_3.Value = {'Jump'; 'to Un-ID''d'};
            app.TextArea_4 = uitextarea(app.GridLayout47);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Enable = 'off';
            app.TextArea_4.Value = {'Backward n'; 'Frames'};
            app.DropDown_2 = uidropdown(app.GridLayout47);
            app.DropDown_2.Items = {'In Frame', 'Cross Frame'};
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = 'In Frame';
            app.InsertHKButton = uibutton(app.GridLayout47, 'state');
            app.InsertHKButton.Text = 'CTRL+d';
            app.TextArea_5 = uitextarea(app.GridLayout47);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Enable = 'off';
            app.TextArea_5.Value = {'Forward n'; 'Frames'};
            app.FwdFramesEditField = uieditfield(app.GridLayout47, 'numeric');
            app.FwdFramesEditField.Value = 10;
            app.BwdFramesEditField = uieditfield(app.GridLayout47, 'numeric');
            app.BwdFramesEditField.Value = 10;
            app.AutosegmentFrameButton = uibutton(app.GridLayout7_2, 'push');
            app.AutosegmentFrameButton.Enable = 'off';
            app.AutosegmentFrameButton.Text = 'Autosegment Frame';
            app.Panel_39 = uipanel(app.GridLayout7_2);
            app.GridLayout48 = uigridlayout(app.Panel_39);
            app.AutojumponIDCheckBox = uicheckbox(app.GridLayout48);
            app.AutojumponIDCheckBox.Text = 'Auto-jump on ID.';
            app.OverlaylastIDdframeCheckBox = uicheckbox(app.GridLayout48);
            app.OverlaylastIDdframeCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlaylastIDdframeCheckBoxValueChanged, true);
            app.OverlaylastIDdframeCheckBox.Text = 'Overlay last ID''d frame.';
            app.OverlayFrameMIPCheckBox = uicheckbox(app.GridLayout48);
            app.OverlayFrameMIPCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayFrameMIPCheckBoxValueChanged, true);
            app.OverlayFrameMIPCheckBox.Text = 'Overlay Frame MIP.';
            app.ShowallneuronsCheckBox = uicheckbox(app.GridLayout48);
            app.ShowallneuronsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowallneuronsCheckBoxValueChanged, true);
            app.ShowallneuronsCheckBox.Text = 'Show all neurons.';
            app.OverlayNeuroPALvolCheckBox = uicheckbox(app.GridLayout48);
            app.OverlayNeuroPALvolCheckBox.Enable = 'off';
            app.OverlayNeuroPALvolCheckBox.Text = 'Overlay NeuroPAL vol.';
            app.Panel_40 = uipanel(app.GridLayout7_2);
            app.GridLayout53 = uigridlayout(app.Panel_40);
            app.MarkerSizeLabel = uilabel(app.GridLayout53);
            app.MarkerSizeLabel.Text = 'Marker Size';
            app.MarkerSizeSlider = uislider(app.GridLayout53);
            app.MarkerSizeSlider.Limits = [1 30];
            app.MarkerSizeSlider.MajorTicks = [];
            app.MarkerSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSliderValueChanged, true);
            app.MarkerSizeSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.MarkerSizeSlider.Value = 5;
            app.ManipulateNeuronsButton = uibutton(app.GridLayout7_2, 'push');
            app.ManipulateNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @ManipulateNeuronsButtonPushed, true);
            app.ManipulateNeuronsButton.Text = 'Manipulate Neurons';
            app.ParametersTab = uitab(app.DefaultTabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout58 = uigridlayout(app.ParametersTab);
            app.BasicParameterPanel = uipanel(app.GridLayout58);
            app.ParameterGridLayout = uigridlayout(app.BasicParameterPanel);
            app.EnableadvancedparametersCheckBox = uicheckbox(app.ParameterGridLayout);
            app.EnableadvancedparametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableadvancedparametersCheckBoxValueChanged, true);
            app.EnableadvancedparametersCheckBox.Text = 'Enable advanced parameters.';
            app.ImportparametersButton = uibutton(app.ParameterGridLayout, 'push');
            app.ImportparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ImportparametersButtonPushed, true);
            app.ImportparametersButton.Text = 'Import parameters';
            app.EditadvancedparametersButton = uibutton(app.ParameterGridLayout, 'push');
            app.EditadvancedparametersButton.ButtonPushedFcn = createCallbackFcn(app, @EditadvancedparametersButtonPushed, true);
            app.EditadvancedparametersButton.Enable = 'off';
            app.EditadvancedparametersButton.Text = 'Edit advanced parameters';
            app.ExportparametersButton = uibutton(app.ParameterGridLayout, 'push');
            app.ExportparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ExportparametersButtonPushed, true);
            app.ExportparametersButton.Text = 'Export parameters';
            app.AdvSetTab = uitabgroup(app.ParameterGridLayout);
            app.GeneralTab = uitab(app.AdvSetTab);
            app.GeneralTab.Title = 'General';
            app.GridLayout31 = uigridlayout(app.GeneralTab);
            app.AllowRotationsSwitchLabel = uilabel(app.GridLayout31);
            app.AllowRotationsSwitchLabel.Text = 'Allow Rotations';
            app.AllowRotationsSwitch = uiswitch(app.GridLayout31, 'slider');
            app.MotionPredictionSwitchLabel = uilabel(app.GridLayout31);
            app.MotionPredictionSwitchLabel.Text = 'Motion Prediction';
            app.MotionPredictionSwitch = uiswitch(app.GridLayout31, 'slider');
            app.UseGPUSwitchLabel = uilabel(app.GridLayout31);
            app.UseGPUSwitchLabel.Text = 'Use GPU';
            app.UseGPUSwitch = uiswitch(app.GridLayout31, 'slider');
            app.TrackingSelectionPanel = uipanel(app.GridLayout31);
            app.TrackingSelectionPanel.Title = 'Tracking Selection';
            app.GridLayout49 = uigridlayout(app.TrackingSelectionPanel);
            app.EditField = uieditfield(app.GridLayout49, 'numeric');
            app.EndFrameLabel = uilabel(app.GridLayout49);
            app.EndFrameLabel.Text = 'End Frame:';
            app.EditField_2 = uieditfield(app.GridLayout49, 'numeric');
            app.StartingFrameLabel = uilabel(app.GridLayout49);
            app.StartingFrameLabel.Text = 'Starting Frame:';
            app.TrackspecificregionButton = uibutton(app.GridLayout49, 'push');
            app.TrackspecificregionButton.Text = 'Track specific region';
            app.TrackspecificneuronsButton = uibutton(app.GridLayout49, 'push');
            app.TrackspecificneuronsButton.Text = 'Track specific neurons';
            app.ImageMotionTab = uitab(app.AdvSetTab);
            app.ImageMotionTab.Title = 'Image & Motion';
            app.GridLayout34 = uigridlayout(app.ImageMotionTab);
            app.foveasigmaLabel = uilabel(app.GridLayout34);
            app.foveasigmaLabel.Text = 'fovea sigma';
            app.EditFieldLabel = uilabel(app.GridLayout34);
            app.EditFieldLabel.Text = 'Sigma for Gaussian mask over foveated regions of the descriptors.';
            app.fovea_sigma = uieditfield(app.GridLayout34, 'numeric');
            app.fovea_sigma.Tooltip = {'Decreasing this can help prioritize keeping a keypoint at the center. Increase to a large number or set at -1 to disable.'};
            app.fovea_sigma.Value = 2.5;
            app.grid_shapeLabel = uilabel(app.GridLayout34);
            app.grid_shapeLabel.Text = 'grid_shape';
            app.SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel = uilabel(app.GridLayout34);
            app.SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel.Text = 'Size of the image descriptors in the xy-plane in pixels.';
            app.grid_shape = uieditfield(app.GridLayout34, 'numeric');
            app.grid_shape.Value = 25;
            app.dimmer_ratioLabel = uilabel(app.GridLayout34);
            app.dimmer_ratioLabel.Text = 'dimmer_ratio';
            app.CoefficientfordimmingnonfoveatedregionsEditFieldLabel = uilabel(app.GridLayout34);
            app.CoefficientfordimmingnonfoveatedregionsEditFieldLabel.Text = 'Coefficient for dimming non-foveated regions.';
            app.dimmer_ratio = uieditfield(app.GridLayout34, 'numeric');
            app.ComputationTab = uitab(app.AdvSetTab);
            app.ComputationTab.Title = 'Computation';
            app.GridLayout31_2 = uigridlayout(app.ComputationTab);
            app.n_epochLabel = uilabel(app.GridLayout31_2);
            app.n_epochLabel.Text = 'n_epoch';
            app.NumberofiterationsforimageregistrationLREditFieldLabel = uilabel(app.GridLayout31_2);
            app.NumberofiterationsforimageregistrationLREditFieldLabel.Text = 'Number of iterations for image registration, LR.';
            app.n_epoch_dLabel = uilabel(app.GridLayout31_2);
            app.n_epoch_dLabel.Text = 'n_epoch_d';
            app.n_epoch = uieditfield(app.GridLayout31_2, 'numeric');
            app.n_epoch.ValueDisplayFormat = '%.0f';
            app.n_epoch.Value = 40;
            app.EditField2_2Label = uilabel(app.GridLayout31_2);
            app.EditField2_2Label.Text = 'Number of iterations for feature detection regularization, LD.';
            app.n_epoch_d = uieditfield(app.GridLayout31_2, 'numeric');
            app.n_epoch_d.ValueDisplayFormat = '%.0f';
            app.n_epoch_d.Value = 10;
            app.n_chunksLabel = uilabel(app.GridLayout31_2);
            app.n_chunksLabel.Text = 'n_chunks';
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel = uilabel(app.GridLayout31_2);
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel.Text = 'Number of steps to divide the forward pass into.';
            app.n_chunks = uieditfield(app.GridLayout31_2, 'numeric');
            app.n_chunks.ValueDisplayFormat = '%.0f';
            app.n_chunks.Value = 10;
            app.sort_modeLabel = uilabel(app.GridLayout31_2);
            app.sort_modeLabel.Text = 'sort_mode';
            app.DropDown_2Label = uilabel(app.GridLayout31_2);
            app.DropDown_2Label.Text = 'Method for sorting frames and determining parent-child branches.';
            app.sort_mode = uidropdown(app.GridLayout31_2);
            app.sort_mode.Items = {'similarity', 'linear', 'depth'};
            app.sort_mode.Editable = 'on';
            app.sort_mode.Tooltip = {'''similarity'' minimizes distance between parent and child.'; '''linear'' branches out from reference frames linearly forwards and backwards, with every parent-child one frame apart, until it reaches the first frame, last frame, or another branch. Simplest and fastest.'; '''depth'' uses shortest-path grid search, then sorts frames based on depth in the resulting parent-child tree. This can scale up to O(n4) in computation with number of frames.'};
            app.sort_mode.Value = 'similarity';
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel = uilabel(app.ParameterGridLayout);
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel.Text = 'Hover over labels for further details or guidance.';
            app.WormMotionPanel = uipanel(app.ParameterGridLayout);
            app.WormMotionPanel.Title = 'Worm Motion';
            app.GridLayout5 = uigridlayout(app.WormMotionPanel);
            app.MotionSlider = uislider(app.GridLayout5);
            app.MotionSlider.Limits = [0 3];
            app.MotionSlider.MajorTicks = [0 1 2 3];
            app.MotionSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.ActivityTab = uitab(app.DefaultTabGroup);
            app.ActivityTab.Title = 'Activity';
            app.GridLayout3 = uigridlayout(app.ActivityTab);
            app.Panel_41 = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.Panel_41);
            app.ScanforIssuesButton = uibutton(app.GridLayout4, 'push');
            app.ScanforIssuesButton.Text = 'Scan for Issues';
            app.SaveExitButton = uibutton(app.GridLayout4, 'push');
            app.SaveExitButton.Text = 'Save & Exit';
            app.LoadStimulusFileButton = uibutton(app.GridLayout4, 'push');
            app.LoadStimulusFileButton.Text = 'Load Stimulus File';
            app.ActivityListBox = uilistbox(app.GridLayout4);
            app.ActivityListBox.Items = {};
            app.ActivityListBox.Value = {};
            app.Panel_42 = uipanel(app.GridLayout3);
            app.GridLayout5_2 = uigridlayout(app.Panel_42);
            app.ActivityAxes = uiaxes(app.GridLayout5_2);

            title(app.ActivityAxes, 'Neuron {x} Activity')
            xlabel(app.ActivityAxes, 'Frame')
            ylabel(app.ActivityAxes, 'df/f')
            zlabel(app.ActivityAxes, 'Z')
            app.ActivityAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ActivityAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.CreditTab = uitab(app.DefaultTabGroup);
            app.CreditTab.Title = 'Credit';
            app.GridLayout14_3 = uigridlayout(app.CreditTab);
            app.Label = uilabel(app.GridLayout14_3);
            app.Label.Text = 'Tracing relies on a modified version of Zephir, a multiple object tracking algorithm based on image registration and developed by James Yu et al. ZephIR tracks keypoints in a 2D or 3D movie by registering image descriptors sampled around each keypoint. ';
            app.LaboratoryPageButton = uibutton(app.GridLayout14_3, 'push');
            app.LaboratoryPageButton.ButtonPushedFcn = createCallbackFcn(app, @LaboratoryPageButtonPushed, true);
            app.LaboratoryPageButton.Text = 'Laboratory Page';
            app.ViewPreprintButton = uibutton(app.GridLayout14_3, 'push');
            app.ViewPreprintButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPreprintButtonPushed, true);
            app.ViewPreprintButton.Text = 'View Preprint';
            app.GithubRepositoryButton = uibutton(app.GridLayout14_3, 'push');
            app.GithubRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @GithubRepositoryButtonPushed, true);
            app.GithubRepositoryButton.Text = 'Github Repository';
            app.Image2 = uiimage(app.GridLayout14_3);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Data', 'Images', 'ZephIR_Logo.png');
            app.CitationTextAreaLabel = uilabel(app.GridLayout14_3);
            app.CitationTextAreaLabel.Text = 'Citation';
            app.CitationTextArea = uitextarea(app.GridLayout14_3);
            app.CitationTextArea.Value = {'Versatile Multiple Object Tracking in Sparse 2D/3D Videos Via Diffeomorphic Image Registration'; 'James Yu, Amin Nejatbakhsh, Mahdi Torkashvand, Sahana Gangadharan, Maedeh Seyedolmohadesin, Jinmahn Kim, Liam Paninski, Vivek Venkatachalam bioRxiv 2022.07.18.500485; doi: https://doi.org/10.1101/2022.07.18.500485'; 'This article is a preprint and has not been certified by peer review'};
            app.ImageProcessingTab = uitab(app.TabGroup);
            app.ImageProcessingTab.Title = 'Image Processing';
            app.ImageProcessingTab.Tag = 'raw';
            app.ProcessingGridLayout = uigridlayout(app.ImageProcessingTab);
            app.ProcMainPanel = uipanel(app.ProcessingGridLayout);
            app.ProcMainGrid = uigridlayout(app.ProcMainPanel);
            app.Panel_57 = uipanel(app.ProcMainGrid);
            app.GridLayout64 = uigridlayout(app.Panel_57);
            app.ProcThresholdPanel = uipanel(app.GridLayout64);
            app.ProcThresholdGrid = uigridlayout(app.ProcThresholdPanel);
            app.Panel_87 = uipanel(app.ProcThresholdGrid);
            app.GridLayout78 = uigridlayout(app.Panel_87);
            app.HidezerointensitypixelsCheckBox = uicheckbox(app.GridLayout78);
            app.HidezerointensitypixelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HidezerointensitypixelsCheckBoxValueChanged, true);
            app.HidezerointensitypixelsCheckBox.Text = 'Hide zero intensity pixels.';
            app.HidezerointensitypixelsCheckBox.Value = true;
            app.ProcHistogramMatchingButton = uibutton(app.ProcThresholdGrid, 'push');
            app.ProcHistogramMatchingButton.ButtonPushedFcn = createCallbackFcn(app, @HistMatchButtonPushed, true);
            app.ProcHistogramMatchingButton.Enable = 'off';
            app.ProcHistogramMatchingButton.Text = 'Percentile Normalize';
            app.ProcHistogramMatchingButton.Tooltip = 'Per channel: set 1st percentile as black point, 99.9th foreground percentile as white point, then clamp and rescale.';
            app.ProcNormalizeColorsButton = uibutton(app.ProcThresholdGrid, 'push');
            app.ProcNormalizeColorsButton.ButtonPushedFcn = createCallbackFcn(app, @ZScoringMenuSelected, true);
            app.ProcNormalizeColorsButton.Enable = 'off';
            app.ProcNormalizeColorsButton.Text = 'Normalize Colors';
            app.ProcHistogramManipulationLabel = uilabel(app.ProcThresholdGrid);
            app.ProcHistogramManipulationLabel.Text = 'Color Manipulation';
            app.ProcThresholdManipulationLabel = uilabel(app.ProcThresholdGrid);
            app.ProcThresholdManipulationLabel.Text = 'Background Threshold';
            app.ProcThresholdKnobPanel = uipanel(app.ProcThresholdGrid);
            app.ProcThresholdKnobGrid = uigridlayout(app.ProcThresholdKnobPanel);
            app.ProcNoiseThresholdKnob = uiknob(app.ProcThresholdKnobGrid, 'continuous');
            app.ProcNoiseThresholdKnob.ValueChangedFcn = createCallbackFcn(app, @ProcNoiseThresholdKnobValueChanged, true);
            app.ProcNoiseThresholdKnob.ValueChangingFcn = createCallbackFcn(app, @ProcNoiseThresholdKnobValueChanging, true);
            app.ProcNoiseThresholdKnob.Enable = 'off';
            app.ProcNoiseThresholdField = uieditfield(app.ProcThresholdKnobGrid, 'numeric');
            app.ProcNoiseThresholdField.RoundFractionalValues = 'on';
            app.ProcNoiseThresholdField.ValueDisplayFormat = '%.0f';
            app.ProcNoiseThresholdField.Enable = 'off';
            app.ProcMeasureROINoiseButton = uibutton(app.ProcThresholdGrid, 'push');
            app.ProcMeasureROINoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ProcMeasureROINoiseButtonPushed, true);
            app.ProcMeasureROINoiseButton.Enable = 'off';
            app.ProcMeasureROINoiseButton.Text = 'Measure ROI Noise';
            app.ProcMeasure90pthNoiseButton = uibutton(app.ProcThresholdGrid, 'push');
            app.ProcMeasure90pthNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ProcMeasure90pthNoiseButtonPushed, true);
            app.ProcMeasure90pthNoiseButton.Enable = 'off';
            app.ProcMeasure90pthNoiseButton.Text = 'Measure Percentile Noise';
            app.ProcHistogramPanel = uipanel(app.GridLayout64);
            app.ProcHistogramGrid = uigridlayout(app.ProcHistogramPanel);
            app.br_hist_panel = uipanel(app.ProcHistogramGrid);
            app.br_hist_grid = uigridlayout(app.br_hist_panel);
            app.br_hist_ax = uiaxes(app.br_hist_grid);
            zlabel(app.br_hist_ax, 'Z')
            app.br_hist_ax.XLim = [1 255];
            app.br_hist_ax.YScale = 'log';
            app.br_hist_slider = uislider(app.br_hist_grid, 'range');
            app.br_hist_slider.MajorTicks = [];
            app.br_hist_slider.MajorTickLabels = {''};
            app.br_hist_slider.ValueChangedFcn = createCallbackFcn(app, @br_hist_sliderValueChanged, true);
            app.br_hist_slider.ValueChangingFcn = createCallbackFcn(app, @br_hist_sliderValueChanging, true);
            app.br_hist_slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.br_hist_slider.Enable = 'off';
            app.Panel_85 = uipanel(app.br_hist_grid);
            app.GridLayout72_12 = uigridlayout(app.Panel_85);
            app.GammaEditField_6Label = uilabel(app.GridLayout72_12);
            app.GammaEditField_6Label.Text = 'Gamma:';
            app.br_GammaEditField = uieditfield(app.GridLayout72_12, 'numeric');
            app.br_GammaEditField.ValueChangedFcn = createCallbackFcn(app, @br_GammaEditFieldValueChanged, true);
            app.br_GammaEditField.Enable = 'off';
            app.br_Label = uilabel(app.GridLayout72_12);
            app.br_Label.Text = '{Ch} Channel';
            app.bm_hist_panel = uipanel(app.ProcHistogramGrid);
            app.bm_hist_grid = uigridlayout(app.bm_hist_panel);
            app.bm_hist_ax = uiaxes(app.bm_hist_grid);
            zlabel(app.bm_hist_ax, 'Z')
            app.bm_hist_ax.XLim = [1 255];
            app.bm_hist_ax.YScale = 'log';
            app.bm_hist_slider = uislider(app.bm_hist_grid, 'range');
            app.bm_hist_slider.MajorTicks = [];
            app.bm_hist_slider.ValueChangedFcn = createCallbackFcn(app, @bm_hist_sliderValueChanged, true);
            app.bm_hist_slider.ValueChangingFcn = createCallbackFcn(app, @bm_hist_sliderValueChanging, true);
            app.bm_hist_slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.bm_hist_slider.Enable = 'off';
            app.Panel_84 = uipanel(app.bm_hist_grid);
            app.GridLayout72_11 = uigridlayout(app.Panel_84);
            app.GammaEditField_5Label = uilabel(app.GridLayout72_11);
            app.GammaEditField_5Label.Text = 'Gamma:';
            app.bm_GammaEditField = uieditfield(app.GridLayout72_11, 'numeric');
            app.bm_GammaEditField.ValueChangedFcn = createCallbackFcn(app, @bm_GammaEditFieldValueChanged, true);
            app.bm_GammaEditField.Enable = 'off';
            app.bm_Label = uilabel(app.GridLayout72_11);
            app.bm_Label.Text = '{Ch} Channel';
            app.bl_hist_panel = uipanel(app.ProcHistogramGrid);
            app.bl_hist_grid = uigridlayout(app.bl_hist_panel);
            app.bl_hist_ax = uiaxes(app.bl_hist_grid);
            zlabel(app.bl_hist_ax, 'Z')
            app.bl_hist_ax.XLim = [1 255];
            app.bl_hist_ax.YScale = 'log';
            app.bl_hist_slider = uislider(app.bl_hist_grid, 'range');
            app.bl_hist_slider.MajorTicks = [];
            app.bl_hist_slider.MajorTickLabels = {''};
            app.bl_hist_slider.ValueChangedFcn = createCallbackFcn(app, @bl_hist_sliderValueChanged, true);
            app.bl_hist_slider.ValueChangingFcn = createCallbackFcn(app, @bl_hist_sliderValueChanging, true);
            app.bl_hist_slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.bl_hist_slider.Enable = 'off';
            app.Panel_83 = uipanel(app.bl_hist_grid);
            app.GridLayout72_10 = uigridlayout(app.Panel_83);
            app.GammaEditField_4Label = uilabel(app.GridLayout72_10);
            app.GammaEditField_4Label.Text = 'Gamma:';
            app.bl_GammaEditField = uieditfield(app.GridLayout72_10, 'numeric');
            app.bl_GammaEditField.ValueChangedFcn = createCallbackFcn(app, @bl_GammaEditFieldValueChanged, true);
            app.bl_GammaEditField.Enable = 'off';
            app.bl_Label = uilabel(app.GridLayout72_10);
            app.bl_Label.Text = '{Ch} Channel';
            app.tr_hist_panel = uipanel(app.ProcHistogramGrid);
            app.tr_hist_grid = uigridlayout(app.tr_hist_panel);
            app.tr_hist_ax = uiaxes(app.tr_hist_grid);
            zlabel(app.tr_hist_ax, 'Z')
            app.tr_hist_ax.XLim = [1 255];
            app.tr_hist_ax.YScale = 'log';
            app.tr_hist_slider = uislider(app.tr_hist_grid, 'range');
            app.tr_hist_slider.MajorTicks = [];
            app.tr_hist_slider.ValueChangedFcn = createCallbackFcn(app, @tr_hist_sliderValueChanged, true);
            app.tr_hist_slider.ValueChangingFcn = createCallbackFcn(app, @tr_hist_sliderValueChanging, true);
            app.tr_hist_slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.tr_hist_slider.Enable = 'off';
            app.Panel_82 = uipanel(app.tr_hist_grid);
            app.GridLayout72_9 = uigridlayout(app.Panel_82);
            app.GammaEditField_3Label = uilabel(app.GridLayout72_9);
            app.GammaEditField_3Label.Text = 'Gamma:';
            app.tr_GammaEditField = uieditfield(app.GridLayout72_9, 'numeric');
            app.tr_GammaEditField.ValueChangedFcn = createCallbackFcn(app, @tr_GammaEditFieldValueChanged, true);
            app.tr_GammaEditField.Enable = 'off';
            app.tr_Label = uilabel(app.GridLayout72_9);
            app.tr_Label.Text = '{Ch} Channel';
            app.tm_hist_panel = uipanel(app.ProcHistogramGrid);
            app.tm_hist_grid = uigridlayout(app.tm_hist_panel);
            app.tm_hist_ax = uiaxes(app.tm_hist_grid);
            zlabel(app.tm_hist_ax, 'Z')
            app.tm_hist_ax.XLim = [1 255];
            app.tm_hist_ax.YScale = 'log';
            app.tm_hist_slider = uislider(app.tm_hist_grid, 'range');
            app.tm_hist_slider.MajorTicks = [];
            app.tm_hist_slider.ValueChangedFcn = createCallbackFcn(app, @tm_hist_sliderValueChanged, true);
            app.tm_hist_slider.ValueChangingFcn = createCallbackFcn(app, @tm_hist_sliderValueChanging, true);
            app.tm_hist_slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.tm_hist_slider.Enable = 'off';
            app.Panel_81 = uipanel(app.tm_hist_grid);
            app.GridLayout72_8 = uigridlayout(app.Panel_81);
            app.GammaEditField_2Label = uilabel(app.GridLayout72_8);
            app.GammaEditField_2Label.Text = 'Gamma:';
            app.tm_GammaEditField = uieditfield(app.GridLayout72_8, 'numeric');
            app.tm_GammaEditField.ValueChangedFcn = createCallbackFcn(app, @tm_GammaEditFieldValueChanged, true);
            app.tm_GammaEditField.Enable = 'off';
            app.tm_Label = uilabel(app.GridLayout72_8);
            app.tm_Label.Text = '{Ch} Channel';
            app.tl_hist_panel = uipanel(app.ProcHistogramGrid);
            app.tl_hist_grid = uigridlayout(app.tl_hist_panel);
            app.tl_hist_ax = uiaxes(app.tl_hist_grid);
            zlabel(app.tl_hist_ax, 'Z')
            app.tl_hist_ax.XLim = [1 255];
            app.tl_hist_ax.YScale = 'log';
            app.tl_hist_slider = uislider(app.tl_hist_grid, 'range');
            app.tl_hist_slider.MajorTicks = [];
            app.tl_hist_slider.ValueChangedFcn = createCallbackFcn(app, @tl_hist_sliderValueChanged, true);
            app.tl_hist_slider.ValueChangingFcn = createCallbackFcn(app, @tl_hist_sliderValueChanging, true);
            app.tl_hist_slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.tl_hist_slider.Enable = 'off';
            app.Panel_80 = uipanel(app.tl_hist_grid);
            app.GridLayout72_7 = uigridlayout(app.Panel_80);
            app.GammaEditFieldLabel = uilabel(app.GridLayout72_7);
            app.GammaEditFieldLabel.Text = 'Gamma:';
            app.tl_GammaEditField = uieditfield(app.GridLayout72_7, 'numeric');
            app.tl_GammaEditField.ValueChangedFcn = createCallbackFcn(app, @tl_GammaEditFieldValueChanged, true);
            app.tl_GammaEditField.Enable = 'off';
            app.tl_Label = uilabel(app.GridLayout72_7);
            app.tl_Label.Text = '{Ch} Channel';
            app.ProcSidePanel = uipanel(app.ProcessingGridLayout);
            app.ProcSideGrid = uigridlayout(app.ProcSidePanel);
            app.ProcToggleVolumePanel = uipanel(app.ProcSideGrid);
            app.ProcToggleVolumePanel.Title = 'Toggle Volume';
            app.ProcToggleVolumeGrid = uigridlayout(app.ProcToggleVolumePanel);
            app.ProcShowMIPCheckBox = uicheckbox(app.ProcToggleVolumeGrid);
            app.ProcShowMIPCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcShowMIPCheckBoxValueChanged, true);
            app.ProcShowMIPCheckBox.Text = 'Show MIP';
            app.VolumeDropDown = uidropdown(app.ProcToggleVolumeGrid);
            app.VolumeDropDown.Items = {};
            app.VolumeDropDown.ValueChangedFcn = createCallbackFcn(app, @VolumeDropDownValueChanged, true);
            app.VolumeDropDown.Value = {};
            app.ProcSavePanel = uipanel(app.ProcSideGrid);
            app.ProcSaveGrid = uigridlayout(app.ProcSavePanel);
            app.ProcSaveButton = uibutton(app.ProcSaveGrid, 'push');
            app.ProcSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ProcSaveButtonPushed, true);
            app.ProcSaveButton.Text = 'Save';
            app.ProcResetButton = uibutton(app.ProcSaveGrid, 'push');
            app.ProcResetButton.ButtonPushedFcn = createCallbackFcn(app, @ProcResetButtonPushed, true);
            app.ProcResetButton.Text = 'Reset';
            app.SpectralUnmixingPanel = uipanel(app.ProcSideGrid);
            app.SpectralUnmixingPanel.Title = 'Spectral Unmixing';
            app.SpectralUnmixingGrid = uigridlayout(app.SpectralUnmixingPanel);
            app.RedLabel = uilabel(app.SpectralUnmixingGrid);
            app.RedLabel.Text = 'Red';
            app.GreenLabel = uilabel(app.SpectralUnmixingGrid);
            app.GreenLabel.Text = 'Green';
            app.BlueLabel = uilabel(app.SpectralUnmixingGrid);
            app.BlueLabel.Text = 'Blue';
            app.RLabel_2 = uilabel(app.SpectralUnmixingGrid);
            app.RLabel_2.Text = 'R';
            app.GLabel_2 = uilabel(app.SpectralUnmixingGrid);
            app.GLabel_2.Text = 'G';
            app.BLabel_2 = uilabel(app.SpectralUnmixingGrid);
            app.BLabel_2.Text = 'B';
            app.red_measure = uibutton(app.SpectralUnmixingGrid, 'push');
            app.red_measure.ButtonPushedFcn = createCallbackFcn(app, @red_measureButtonPushed, true);
            app.red_measure.Tag = 'red';
            app.red_measure.Text = '➤';
            app.green_measure = uibutton(app.SpectralUnmixingGrid, 'push');
            app.green_measure.ButtonPushedFcn = createCallbackFcn(app, @red_measureButtonPushed, true);
            app.green_measure.Tag = 'green';
            app.green_measure.Text = '➤';
            app.blue_measure = uibutton(app.SpectralUnmixingGrid, 'push');
            app.blue_measure.ButtonPushedFcn = createCallbackFcn(app, @red_measureButtonPushed, true);
            app.blue_measure.Tag = 'blue';
            app.blue_measure.Text = '➤';
            app.red_r = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.red_r.Editable = 'off';
            app.red_g = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.red_g.Editable = 'off';
            app.red_b = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.red_b.Editable = 'off';
            app.green_b = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.green_b.Editable = 'off';
            app.green_g = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.green_g.Editable = 'off';
            app.green_r = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.green_r.Editable = 'off';
            app.blue_r = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.blue_r.Editable = 'off';
            app.blue_g = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.blue_g.Editable = 'off';
            app.blue_b = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.blue_b.Editable = 'off';
            app.Label_3 = uilabel(app.SpectralUnmixingGrid);
            app.Label_3.Text = '💉';
            app.Panel_89 = uipanel(app.SpectralUnmixingGrid);
            app.GridLayout2_3 = uigridlayout(app.Panel_89);
            app.SigmagaussEditFieldLabel = uilabel(app.GridLayout2_3);
            app.SigmagaussEditFieldLabel.Tooltip = {'Sigma for 3D gaussian filtering. (Higher for better debleed, lower for less blur.)'};
            app.SigmagaussEditFieldLabel.Text = 'Sigma gauss';
            app.SigmagaussEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.SigmagaussEditField.Tooltip = {'Sigma for 3D gaussian filtering. (Higher for better debleed, lower for less blur.)'};
            app.SigmagaussEditField.Value = 0.3;
            app.DropperRadiusEditFieldLabel = uilabel(app.GridLayout2_3);
            app.DropperRadiusEditFieldLabel.Text = 'Dropper Radius';
            app.DropperradiusSpinner = uieditfield(app.GridLayout2_3, 'numeric');
            app.DropperradiusSpinner.ValueDisplayFormat = '%.0f';
            app.DropperradiusSpinner.Editable = 'off';
            app.BGLabel = uilabel(app.SpectralUnmixingGrid);
            app.BGLabel.Text = 'BG';
            app.background_r = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.background_r.Editable = 'off';
            app.background_g = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.background_g.Editable = 'off';
            app.background_b = uieditfield(app.SpectralUnmixingGrid, 'numeric');
            app.background_b.Editable = 'off';
            app.background_measure = uibutton(app.SpectralUnmixingGrid, 'push');
            app.background_measure.ButtonPushedFcn = createCallbackFcn(app, @red_measureButtonPushed, true);
            app.background_measure.Tag = 'background';
            app.background_measure.Text = '➤';
            app.VolumeChannelsPanel = uipanel(app.ProcSideGrid);
            app.VolumeChannelsPanel.Title = 'Volume Channels';
            app.GridLayout82 = uigridlayout(app.VolumeChannelsPanel);
            app.proc_channel_panel = uipanel(app.GridLayout82);
            app.proc_channel_grid = uigridlayout(app.proc_channel_panel);
            app.proc_c6_delete = uibutton(app.proc_channel_grid, 'push');
            app.proc_c6_delete.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_deleteButtonPushed, true);
            app.proc_c6_delete.Text = '🗑';
            app.proc_c5_delete = uibutton(app.proc_channel_grid, 'push');
            app.proc_c5_delete.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_deleteButtonPushed, true);
            app.proc_c5_delete.Text = '🗑';
            app.proc_c4_delete = uibutton(app.proc_channel_grid, 'push');
            app.proc_c4_delete.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_deleteButtonPushed, true);
            app.proc_c4_delete.Text = '🗑';
            app.proc_c3_delete = uibutton(app.proc_channel_grid, 'push');
            app.proc_c3_delete.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_deleteButtonPushed, true);
            app.proc_c3_delete.Text = '🗑';
            app.proc_c2_delete = uibutton(app.proc_channel_grid, 'push');
            app.proc_c2_delete.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_deleteButtonPushed, true);
            app.proc_c2_delete.Text = '🗑';
            app.proc_c1_delete = uibutton(app.proc_channel_grid, 'push');
            app.proc_c1_delete.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_deleteButtonPushed, true);
            app.proc_c1_delete.Text = '🗑';
            app.proc_c6_down = uibutton(app.proc_channel_grid, 'push');
            app.proc_c6_down.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c6_down.Text = '⮟';
            app.proc_c5_down = uibutton(app.proc_channel_grid, 'push');
            app.proc_c5_down.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c5_down.Text = '⮟';
            app.proc_c4_down = uibutton(app.proc_channel_grid, 'push');
            app.proc_c4_down.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c4_down.Text = '⮟';
            app.proc_c3_down = uibutton(app.proc_channel_grid, 'push');
            app.proc_c3_down.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c3_down.Text = '⮟';
            app.proc_c2_down = uibutton(app.proc_channel_grid, 'push');
            app.proc_c2_down.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c2_down.Text = '⮟';
            app.proc_c1_down = uibutton(app.proc_channel_grid, 'push');
            app.proc_c1_down.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c1_down.Text = '⮟';
            app.proc_c6_up = uibutton(app.proc_channel_grid, 'push');
            app.proc_c6_up.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c6_up.Text = '⮝';
            app.proc_c5_up = uibutton(app.proc_channel_grid, 'push');
            app.proc_c5_up.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c5_up.Text = '⮝';
            app.proc_c4_up = uibutton(app.proc_channel_grid, 'push');
            app.proc_c4_up.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c4_up.Text = '⮝';
            app.proc_c3_up = uibutton(app.proc_channel_grid, 'push');
            app.proc_c3_up.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c3_up.Text = '⮝';
            app.proc_c2_up = uibutton(app.proc_channel_grid, 'push');
            app.proc_c2_up.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c2_up.Text = '⮝';
            app.proc_c1_up = uibutton(app.proc_channel_grid, 'push');
            app.proc_c1_up.ButtonPushedFcn = createCallbackFcn(app, @proc_c1_downButtonPushed, true);
            app.proc_c1_up.Text = '⮝';
            app.proc_c6_dropdown = uidropdown(app.proc_channel_grid);
            app.proc_c6_dropdown.Items = {'None'};
            app.proc_c6_dropdown.ValueChangedFcn = createCallbackFcn(app, @proc_c1_dropdownValueChanged, true);
            app.proc_c6_dropdown.Value = 'None';
            app.proc_c5_dropdown = uidropdown(app.proc_channel_grid);
            app.proc_c5_dropdown.Items = {'None'};
            app.proc_c5_dropdown.ValueChangedFcn = createCallbackFcn(app, @proc_c1_dropdownValueChanged, true);
            app.proc_c5_dropdown.Value = 'None';
            app.proc_c4_dropdown = uidropdown(app.proc_channel_grid);
            app.proc_c4_dropdown.Items = {'None'};
            app.proc_c4_dropdown.ValueChangedFcn = createCallbackFcn(app, @proc_c1_dropdownValueChanged, true);
            app.proc_c4_dropdown.Value = 'None';
            app.proc_c3_dropdown = uidropdown(app.proc_channel_grid);
            app.proc_c3_dropdown.Items = {'None'};
            app.proc_c3_dropdown.ValueChangedFcn = createCallbackFcn(app, @proc_c1_dropdownValueChanged, true);
            app.proc_c3_dropdown.Value = 'None';
            app.proc_c2_dropdown = uidropdown(app.proc_channel_grid);
            app.proc_c2_dropdown.Items = {'None'};
            app.proc_c2_dropdown.ValueChangedFcn = createCallbackFcn(app, @proc_c1_dropdownValueChanged, true);
            app.proc_c2_dropdown.Value = 'None';
            app.proc_c1_dropdown = uidropdown(app.proc_channel_grid);
            app.proc_c1_dropdown.Items = {'None'};
            app.proc_c1_dropdown.ValueChangedFcn = createCallbackFcn(app, @proc_c1_dropdownValueChanged, true);
            app.proc_c1_dropdown.Value = 'None';
            app.proc_c6_ref = uidropdown(app.proc_channel_grid);
            app.proc_c6_ref.Items = {'White', 'DIC', 'GFP'};
            app.proc_c6_ref.ValueChangedFcn = createCallbackFcn(app, @proc_c4_refValueChanged, true);
            app.proc_c6_ref.Value = 'GFP';
            app.proc_c5_ref = uidropdown(app.proc_channel_grid);
            app.proc_c5_ref.Items = {'White', 'DIC', 'GFP'};
            app.proc_c5_ref.ValueChangedFcn = createCallbackFcn(app, @proc_c4_refValueChanged, true);
            app.proc_c5_ref.Value = 'DIC';
            app.proc_c4_ref = uidropdown(app.proc_channel_grid);
            app.proc_c4_ref.Items = {'White', 'DIC', 'GFP'};
            app.proc_c4_ref.ValueChangedFcn = createCallbackFcn(app, @proc_c4_refValueChanged, true);
            app.proc_c4_ref.Value = 'White';
            app.proc_c3_ref = uibutton(app.proc_channel_grid, 'state');
            app.proc_c3_ref.Text = 'Blue';
            app.proc_c2_ref = uibutton(app.proc_channel_grid, 'state');
            app.proc_c2_ref.Text = 'Green';
            app.proc_c1_ref = uibutton(app.proc_channel_grid, 'state');
            app.proc_c1_ref.Text = 'Red';
            app.proc_c6_checkbox = uicheckbox(app.proc_channel_grid);
            app.proc_c6_checkbox.ValueChangedFcn = createCallbackFcn(app, @prof_c1_checkboxValueChanged, true);
            app.proc_c6_checkbox.Text = '';
            app.proc_c5_checkbox = uicheckbox(app.proc_channel_grid);
            app.proc_c5_checkbox.ValueChangedFcn = createCallbackFcn(app, @prof_c1_checkboxValueChanged, true);
            app.proc_c5_checkbox.Text = '';
            app.proc_c4_checkbox = uicheckbox(app.proc_channel_grid);
            app.proc_c4_checkbox.ValueChangedFcn = createCallbackFcn(app, @prof_c1_checkboxValueChanged, true);
            app.proc_c4_checkbox.Text = '';
            app.proc_c3_checkbox = uicheckbox(app.proc_channel_grid);
            app.proc_c3_checkbox.ValueChangedFcn = createCallbackFcn(app, @prof_c1_checkboxValueChanged, true);
            app.proc_c3_checkbox.Text = '';
            app.proc_c2_checkbox = uicheckbox(app.proc_channel_grid);
            app.proc_c2_checkbox.ValueChangedFcn = createCallbackFcn(app, @prof_c1_checkboxValueChanged, true);
            app.proc_c2_checkbox.Text = '';
            app.proc_c1_checkbox = uicheckbox(app.proc_channel_grid);
            app.proc_c1_checkbox.ValueChangedFcn = createCallbackFcn(app, @prof_c1_checkboxValueChanged, true);
            app.proc_c1_checkbox.Text = '';
            app.proc_c6_editfield = uieditfield(app.proc_channel_grid, 'text');
            app.proc_c6_editfield.Visible = 'off';
            app.proc_c5_editfield = uieditfield(app.proc_channel_grid, 'text');
            app.proc_c5_editfield.Visible = 'off';
            app.proc_c4_editfield = uieditfield(app.proc_channel_grid, 'text');
            app.proc_c4_editfield.ValueChangedFcn = createCallbackFcn(app, @proc_c1_editfieldValueChanged, true);
            app.proc_c4_editfield.Visible = 'off';
            app.proc_c3_editfield = uieditfield(app.proc_channel_grid, 'text');
            app.proc_c3_editfield.ValueChangedFcn = createCallbackFcn(app, @proc_c1_editfieldValueChanged, true);
            app.proc_c3_editfield.Visible = 'off';
            app.proc_c2_editfield = uieditfield(app.proc_channel_grid, 'text');
            app.proc_c2_editfield.ValueChangedFcn = createCallbackFcn(app, @proc_c1_editfieldValueChanged, true);
            app.proc_c2_editfield.Visible = 'off';
            app.proc_c1_editfield = uieditfield(app.proc_channel_grid, 'text');
            app.proc_c1_editfield.ValueChangedFcn = createCallbackFcn(app, @proc_c1_editfieldValueChanged, true);
            app.proc_c1_editfield.Visible = 'off';
            app.EditChannelsButton = uibutton(app.GridLayout82, 'state');
            app.EditChannelsButton.ValueChangedFcn = createCallbackFcn(app, @EditChannelsButtonValueChanged, true);
            app.EditChannelsButton.Enable = 'off';
            app.EditChannelsButton.Visible = 'off';
            app.EditChannelsButton.Text = 'Edit Channels';
            app.ImageManipulationPanel = uipanel(app.ProcSideGrid);
            app.ImageManipulationPanel.Title = 'Image Manipulation';
            app.GridLayout83 = uigridlayout(app.ImageManipulationPanel);
            app.ProcCropImageButton = uibutton(app.GridLayout83, 'push');
            app.ProcCropImageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcCropImageButtonPushed, true);
            app.ProcCropImageButton.Text = 'Crop';
            app.RotateButton = uibutton(app.GridLayout83, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.DownsampleButton = uibutton(app.GridLayout83, 'push');
            app.DownsampleButton.ButtonPushedFcn = createCallbackFcn(app, @DownsampleButtonPushed, true);
            app.DownsampleButton.Text = 'Downsample';
            app.proc_ds_panel = uipanel(app.GridLayout83);
            app.proc_ds_panel.Visible = 'off';
            app.ProcDownsamplingGrid = uigridlayout(app.proc_ds_panel);
            app.xyFactorLabel = uilabel(app.ProcDownsamplingGrid);
            app.xyFactorLabel.Text = 'xy-Factor';
            app.ProcXYFactorEditField = uieditfield(app.ProcDownsamplingGrid, 'numeric');
            app.ProcXYFactorEditField.Enable = 'off';
            app.ProcXYFactorEditField.Value = 1;
            app.zSlicesEditFieldLabel = uilabel(app.ProcDownsamplingGrid);
            app.zSlicesEditFieldLabel.Text = 'z -Slices';
            app.ProcZSlicesEditField = uieditfield(app.ProcDownsamplingGrid, 'numeric');
            app.ProcZSlicesEditField.Enable = 'off';
            app.ProcXYFactorUpdateButton = uibutton(app.ProcDownsamplingGrid, 'push');
            app.ProcXYFactorUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ProcXYFactorUpdateButtonPushed, true);
            app.ProcXYFactorUpdateButton.Enable = 'off';
            app.ProcXYFactorUpdateButton.Text = 'Update';
            app.TrimButton = uibutton(app.ProcDownsamplingGrid, 'push');
            app.TrimButton.ButtonPushedFcn = createCallbackFcn(app, @TrimButtonPushed, true);
            app.TrimButton.Enable = 'off';
            app.TrimButton.Text = 'Trim';
            app.Panel_91 = uipanel(app.ProcDownsamplingGrid);
            app.GridLayout81 = uigridlayout(app.Panel_91);
            app.ProcTStartEditField = uieditfield(app.GridLayout81, 'numeric');
            app.ProcTStartEditField.Limits = [1 Inf];
            app.ProcTStartEditField.ValueDisplayFormat = '%.0f';
            app.ProcTStartEditField.Enable = 'off';
            app.ProcTStartEditField.Value = 1;
            app.ProcTStopEditField = uieditfield(app.GridLayout81, 'numeric');
            app.ProcTStopEditField.Limits = [1 Inf];
            app.ProcTStopEditField.ValueDisplayFormat = '%.0f';
            app.ProcTStopEditField.Enable = 'off';
            app.ProcTStopEditField.Value = 1;
            app.StartFrameEditFieldLabel = uilabel(app.ProcDownsamplingGrid);
            app.StartFrameEditFieldLabel.Text = 'Start Frame';
            app.StartFrameEditField = uieditfield(app.ProcDownsamplingGrid, 'numeric');
            app.StartFrameEditField.Visible = 'off';
            app.EndFrameEditFieldLabel = uilabel(app.ProcDownsamplingGrid);
            app.EndFrameEditFieldLabel.Text = 'End Frame';
            app.EndFrameEditField = uieditfield(app.ProcDownsamplingGrid, 'numeric');
            app.EndFrameEditField.Visible = 'off';
            app.ProcPreviewZslowCheckBox = uicheckbox(app.ProcDownsamplingGrid);
            app.ProcPreviewZslowCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcPreviewZslowCheckBoxValueChanged, true);
            app.ProcPreviewZslowCheckBox.Enable = 'off';
            app.ProcPreviewZslowCheckBox.Text = ' Preview Z';
            app.proc_rot_panel = uipanel(app.GridLayout83);
            app.proc_rot_panel.Visible = 'off';
            app.ProcGridOBSOLETE = uigridlayout(app.proc_rot_panel);
            app.flip_ud = uibutton(app.ProcGridOBSOLETE, 'state');
            app.flip_ud.ValueChangedFcn = createCallbackFcn(app, @flip_udValueChanged, true);
            app.flip_ud.Text = '⇅';
            app.confirm_rotation = uibutton(app.ProcGridOBSOLETE, 'push');
            app.confirm_rotation.ButtonPushedFcn = createCallbackFcn(app, @confirm_rotationPushed, true);
            app.confirm_rotation.Text = '✓';
            app.flip_lr = uibutton(app.ProcGridOBSOLETE, 'state');
            app.flip_lr.ValueChangedFcn = createCallbackFcn(app, @flip_lrValueChanged, true);
            app.flip_lr.Text = '⇄';
            app.proc_rot_spinner = uispinner(app.ProcGridOBSOLETE);
            app.proc_rot_spinner.ValueChangedFcn = createCallbackFcn(app, @proc_rot_spinnerValueChanged, true);
            app.proc_rot_knob = uiknob(app.ProcGridOBSOLETE, 'continuous');
            app.proc_rot_knob.Limits = [0 360];
            app.proc_rot_knob.MajorTicks = [90 180 270];
            app.proc_rot_knob.MajorTickLabels = {'90', '180', '270'};
            app.proc_rot_knob.ValueChangedFcn = createCallbackFcn(app, @proc_rot_knobValueChanged2, true);
            app.proc_rot_knob.MinorTicks = [0 90 180 270 360];
            app.proc_crop_panel = uipanel(app.GridLayout83);
            app.proc_crop_panel.Visible = 'off';
            app.GridLayout82_2 = uigridlayout(app.proc_crop_panel);
            app.OKButton = uibutton(app.GridLayout82_2, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton_3 = uibutton(app.GridLayout82_2, 'push');
            app.CancelButton_3.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_3Pushed, true);
            app.CancelButton_3.Text = 'Cancel';
            app.ProcAxPanel = uipanel(app.ProcessingGridLayout);
            app.ProcAxGrid = uigridlayout(app.ProcAxPanel);
            app.proc_xzAxes = uiaxes(app.ProcAxGrid);
            app.proc_xzAxes.XTickLabel = '';
            app.proc_xzAxes.YTickLabel = '';
            app.proc_xzAxes.Tag = 'xz';
            app.proc_xzAxes.Visible = 'off';
            app.proc_xyAxes = uiaxes(app.ProcAxGrid);
            app.proc_xyAxes.XTickLabel = '';
            app.proc_xyAxes.YTickLabel = '';
            app.proc_xyAxes.Tag = 'xy';
            app.proc_yzAxes = uiaxes(app.ProcAxGrid);
            app.proc_yzAxes.XTickLabel = '';
            app.proc_yzAxes.YTickLabel = '';
            app.proc_yzAxes.Tag = 'yz';
            app.proc_yzAxes.Visible = 'off';
            app.proc_hor_zSlider = uislider(app.ProcAxGrid);
            app.proc_hor_zSlider.MajorTicks = [];
            app.proc_hor_zSlider.ValueChangedFcn = createCallbackFcn(app, @proc_hor_zSliderValueChanged, true);
            app.proc_hor_zSlider.ValueChangingFcn = createCallbackFcn(app, @proc_hor_zSliderValueChanging, true);
            app.proc_hor_zSlider.MinorTicks = [];
            app.proc_hor_zSlider.Value = 1;
            app.proc_ySlider = uislider(app.ProcAxGrid);
            app.proc_ySlider.MajorTicks = [];
            app.proc_ySlider.ValueChangedFcn = createCallbackFcn(app, @proc_ySliderValueChanged, true);
            app.proc_ySlider.ValueChangingFcn = createCallbackFcn(app, @proc_ySliderValueChanging, true);
            app.proc_ySlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.proc_ySlider.Value = 1;
            app.proc_vert_zSlider = uislider(app.ProcAxGrid);
            app.proc_vert_zSlider.MajorTicks = [];
            app.proc_vert_zSlider.ValueChangedFcn = createCallbackFcn(app, @proc_vert_zSliderValueChanged, true);
            app.proc_vert_zSlider.ValueChangingFcn = createCallbackFcn(app, @proc_vert_zSliderValueChanging, true);
            app.proc_vert_zSlider.MinorTicks = [];
            app.proc_vert_zSlider.Value = 1;
            app.proc_zSlider = uislider(app.ProcAxGrid);
            app.proc_zSlider.MajorTicks = [];
            app.proc_zSlider.MajorTickLabels = {''};
            app.proc_zSlider.ValueChangedFcn = createCallbackFcn(app, @proc_zSliderValueChanged, true);
            app.proc_zSlider.ValueChangingFcn = createCallbackFcn(app, @proc_zSliderValueChanging, true);
            app.proc_zSlider.MinorTicks = [0 100];
            app.proc_xSlider = uislider(app.ProcAxGrid);
            app.proc_xSlider.MajorTicks = [];
            app.proc_xSlider.ValueChangedFcn = createCallbackFcn(app, @proc_xSliderValueChanged, true);
            app.proc_xSlider.ValueChangingFcn = createCallbackFcn(app, @proc_xSliderValueChanging, true);
            app.proc_xSlider.MinorTicks = [0 100];
            app.proc_xSlider.Value = 12.8690386071158;
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.LogGrid = uigridlayout(app.LogTab);
            app.UpperLogPanel = uipanel(app.LogGrid);
            app.UpperLogGrid = uigridlayout(app.UpperLogPanel);
            app.LogControlPanel = uigridlayout(app.UpperLogGrid);
            app.OpenRepositoryButton = uibutton(app.LogControlPanel, 'push');
            app.OpenRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRepositoryButtonPushed, true);
            app.OpenRepositoryButton.Text = 'Open Repository.';
            app.SubmitbugreportButton = uibutton(app.LogControlPanel, 'push');
            app.SubmitbugreportButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitbugreportButtonPushed, true);
            app.SubmitbugreportButton.Text = 'Submit bug report.';
            app.LogFilterButtonGroup = uibuttongroup(app.LogControlPanel);
            app.LogFilterButtonGroup.Title = 'Log Filter';
            app.ShowalleventsButton = uiradiobutton(app.LogFilterButtonGroup);
            app.ShowalleventsButton.Text = 'Show all events';
            app.ShowalleventsButton.Value = true;
            app.SystemeventsonlyButton = uiradiobutton(app.LogFilterButtonGroup);
            app.SystemeventsonlyButton.Text = 'System events only';
            app.MATLABeventsonlyButton = uiradiobutton(app.LogFilterButtonGroup);
            app.MATLABeventsonlyButton.Text = 'MATLAB events only';
            app.PythoneventsonlyButton = uiradiobutton(app.LogFilterButtonGroup);
            app.PythoneventsonlyButton.Text = 'Python events only';
            app.OpenlogfileButton = uibutton(app.LogControlPanel, 'push');
            app.OpenlogfileButton.Text = 'Open log file.';
            app.Panel_2 = uipanel(app.LogControlPanel);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.EnablelogCheckBox = uicheckbox(app.GridLayout2);
            app.EnablelogCheckBox.Text = 'Enable log.';
            app.EnablelogCheckBox.Value = true;
            app.EnablelognotificationsCheckBox = uicheckbox(app.GridLayout2);
            app.EnablelognotificationsCheckBox.Text = 'Enable log notifications.';
            app.EnabledebugmenuCheckBox = uicheckbox(app.GridLayout2);
            app.EnabledebugmenuCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnabledebugmenuCheckBoxValueChanged, true);
            app.EnabledebugmenuCheckBox.Text = 'Enable debug menu.';
            app.DebugPanel = uipanel(app.LogControlPanel);
            app.DebugPanel.Title = 'Debug Functionality';
            app.DebugGrid = uigridlayout(app.DebugPanel);
            app.RuntestroutineButton = uibutton(app.DebugGrid, 'push');
            app.RuntestroutineButton.ButtonPushedFcn = createCallbackFcn(app, @RuntestroutineButtonPushed, true);
            app.RuntestroutineButton.Enable = 'off';
            app.RuntestroutineButton.Text = 'Run test routine';
            app.PrintdebuglogButton = uibutton(app.DebugGrid, 'push');
            app.PrintdebuglogButton.Enable = 'off';
            app.PrintdebuglogButton.Text = 'Print debug log';
            app.TestResultPanel = uipanel(app.DebugGrid);
            app.TestResultPanel.Enable = 'off';
            app.TestResultGrid = uigridlayout(app.TestResultPanel);
            app.AutoSegmentationCheck = uicheckbox(app.TestResultGrid);
            app.AutoSegmentationCheck.Enable = 'off';
            app.AutoSegmentationCheck.Text = '';
            app.AutosegmentationLabel_2 = uilabel(app.TestResultGrid);
            app.AutosegmentationLabel_2.Text = 'Auto-segmentation';
            app.ArtifactRemovalCheck = uicheckbox(app.TestResultGrid);
            app.ArtifactRemovalCheck.Enable = 'off';
            app.ArtifactRemovalCheck.Text = '';
            app.SpectralUnmixingCheck = uicheckbox(app.TestResultGrid);
            app.SpectralUnmixingCheck.Enable = 'off';
            app.SpectralUnmixingCheck.Text = '';
            app.HistogramMatchingCheck = uicheckbox(app.TestResultGrid);
            app.HistogramMatchingCheck.Enable = 'off';
            app.HistogramMatchingCheck.Text = '';
            app.AutoIdentificationCheck = uicheckbox(app.TestResultGrid);
            app.AutoIdentificationCheck.Enable = 'off';
            app.AutoIdentificationCheck.Text = '';
            app.ExtractActivityCheck = uicheckbox(app.TestResultGrid);
            app.ExtractActivityCheck.Enable = 'off';
            app.ExtractActivityCheck.Text = '';
            app.ThumbnailAnalysisCheck = uicheckbox(app.TestResultGrid);
            app.ThumbnailAnalysisCheck.Enable = 'off';
            app.ThumbnailAnalysisCheck.Text = '';
            app.TrackNeuronsCheck = uicheckbox(app.TestResultGrid);
            app.TrackNeuronsCheck.Enable = 'off';
            app.TrackNeuronsCheck.Text = '';
            app.LoadimageLabel_2 = uilabel(app.TestResultGrid);
            app.LoadimageLabel_2.Text = 'Load image';
            app.BrowseimageLabel = uilabel(app.TestResultGrid);
            app.BrowseimageLabel.Text = 'Browse image';
            app.EditimageLabel_2 = uilabel(app.TestResultGrid);
            app.EditimageLabel_2.Text = 'Edit image';
            app.ArtifactRemovalLabel = uilabel(app.TestResultGrid);
            app.ArtifactRemovalLabel.Text = 'Artifact Removal';
            app.SpectralUnmixingLabel = uilabel(app.TestResultGrid);
            app.SpectralUnmixingLabel.Text = 'Spectral Unmixing';
            app.LoadvideoLabel_3 = uilabel(app.TestResultGrid);
            app.LoadvideoLabel_3.Text = 'Load video';
            app.AutoidentificationLabel_2 = uilabel(app.TestResultGrid);
            app.AutoidentificationLabel_2.Text = 'Auto-identification';
            app.HistogramMatchingLabel = uilabel(app.TestResultGrid);
            app.HistogramMatchingLabel.Text = 'Histogram Matching';
            app.BrowsevideoLabel_3 = uilabel(app.TestResultGrid);
            app.BrowsevideoLabel_3.Text = 'Browse video';
            app.EditvideoLabel_3 = uilabel(app.TestResultGrid);
            app.EditvideoLabel_3.Text = 'Edit video';
            app.ThumbnailAnalysisLabel = uilabel(app.TestResultGrid);
            app.ThumbnailAnalysisLabel.Text = 'Thumbnail Analysis';
            app.TrackNeuronsLabel = uilabel(app.TestResultGrid);
            app.TrackNeuronsLabel.Text = 'Track Neurons';
            app.ExtractActivityLabel = uilabel(app.TestResultGrid);
            app.ExtractActivityLabel.Text = 'Extract Activity';
            app.LoadImageCheck = uicheckbox(app.TestResultGrid);
            app.LoadImageCheck.Enable = 'off';
            app.LoadImageCheck.Text = '';
            app.BrowseImageCheck = uicheckbox(app.TestResultGrid);
            app.BrowseImageCheck.Enable = 'off';
            app.BrowseImageCheck.Text = '';
            app.EditImageCheck = uicheckbox(app.TestResultGrid);
            app.EditImageCheck.Enable = 'off';
            app.EditImageCheck.Text = '';
            app.LoadVideoCheck = uicheckbox(app.TestResultGrid);
            app.LoadVideoCheck.Enable = 'off';
            app.LoadVideoCheck.Text = '';
            app.BrowseVideoCheck = uicheckbox(app.TestResultGrid);
            app.BrowseVideoCheck.Enable = 'off';
            app.BrowseVideoCheck.Text = '';
            app.EditVideoCheck = uicheckbox(app.TestResultGrid);
            app.EditVideoCheck.Enable = 'off';
            app.EditVideoCheck.Text = '';
            app.BasicFunctionalityLabel = uilabel(app.TestResultGrid);
            app.BasicFunctionalityLabel.Text = 'Basic Functionality';
            app.InterdependentFunctionalityLabel = uilabel(app.TestResultGrid);
            app.InterdependentFunctionalityLabel.Text = 'Interdependent Functionality';
            app.AdvancedFunctionalityLabel = uilabel(app.TestResultGrid);
            app.AdvancedFunctionalityLabel.Text = 'Advanced Functionality';
            app.GUIFunctionalityLabel = uilabel(app.TestResultGrid);
            app.GUIFunctionalityLabel.Text = 'GUI Functionality';
            app.DynamicpanelingLabel = uilabel(app.TestResultGrid);
            app.DynamicpanelingLabel.Text = 'Dynamic paneling';
            app.DynamicgridstructuresLabel = uilabel(app.TestResultGrid);
            app.DynamicgridstructuresLabel.Text = 'Dynamic grid structures';
            app.AxialprojectionsLabel = uilabel(app.TestResultGrid);
            app.AxialprojectionsLabel.Text = 'Axial projections';
            app.DynamicPanelingCheck = uicheckbox(app.TestResultGrid);
            app.DynamicPanelingCheck.Enable = 'off';
            app.DynamicPanelingCheck.Text = '';
            app.DynamicGridStructuresCheck = uicheckbox(app.TestResultGrid);
            app.DynamicGridStructuresCheck.Enable = 'off';
            app.DynamicGridStructuresCheck.Text = '';
            app.AxialProjectionsCheck = uicheckbox(app.TestResultGrid);
            app.AxialProjectionsCheck.Enable = 'off';
            app.AxialProjectionsCheck.Text = '';
            app.FlexibleimportsLabel = uilabel(app.TestResultGrid);
            app.FlexibleimportsLabel.Text = 'Flexible imports';
            app.TypeproofingLabel = uilabel(app.TestResultGrid);
            app.TypeproofingLabel.Text = 'Type proofing';
            app.RobustDataHandlingLabel = uilabel(app.TestResultGrid);
            app.RobustDataHandlingLabel.Text = 'Robust Data Handling';
            app.FlexibleImportsCheck = uicheckbox(app.TestResultGrid);
            app.FlexibleImportsCheck.Enable = 'off';
            app.FlexibleImportsCheck.Text = '';
            app.TypeProofingCheck = uicheckbox(app.TestResultGrid);
            app.TypeProofingCheck.Enable = 'off';
            app.TypeProofingCheck.Text = '';
            app.SystemcallsLabel = uilabel(app.TestResultGrid);
            app.SystemcallsLabel.Text = 'System calls';
            app.SystemCallsCheck = uicheckbox(app.TestResultGrid);
            app.SystemCallsCheck.Enable = 'off';
            app.SystemCallsCheck.Text = '';
            app.SaveRoutineLabel = uilabel(app.TestResultGrid);
            app.SaveRoutineLabel.Text = 'Save Routine';
            app.SaveRoutineCheck = uicheckbox(app.TestResultGrid);
            app.SaveRoutineCheck.Enable = 'off';
            app.SaveRoutineCheck.Text = '';
            app.SystemInfoGrid = uigridlayout(app.UpperLogGrid);
            app.OperatingSystemEditField_2Label = uilabel(app.SystemInfoGrid);
            app.OperatingSystemEditField_2Label.Text = 'Operating System:';
            app.OperatingSystemEditField = uieditfield(app.SystemInfoGrid, 'text');
            app.OperatingSystemEditField.Enable = 'off';
            app.OperatingSystemEditField.Placeholder = 'N/A';
            app.MATLABRuntimeEditField = uieditfield(app.SystemInfoGrid, 'text');
            app.MATLABRuntimeEditField.Enable = 'off';
            app.MATLABRuntimeEditField.Placeholder = 'N/A';
            app.MATLABRuntimeEditFieldLabel = uilabel(app.SystemInfoGrid);
            app.MATLABRuntimeEditFieldLabel.Text = 'MATLAB Runtime:';
            app.PythonExecutableEditField = uieditfield(app.SystemInfoGrid, 'text');
            app.PythonExecutableEditField.Enable = 'off';
            app.PythonExecutableEditField.Placeholder = 'N/A';
            app.PythonExecutableEditFieldLabel = uilabel(app.SystemInfoGrid);
            app.PythonExecutableEditFieldLabel.Text = 'Python Executable:';
            app.NeuroPALEditField = uieditfield(app.SystemInfoGrid, 'text');
            app.NeuroPALEditField.Enable = 'off';
            app.NeuroPALEditField.Placeholder = 'N/A';
            app.NeuroPALEditField_2Label = uilabel(app.SystemInfoGrid);
            app.NeuroPALEditField_2Label.Text = 'NeuroPAL:';
            app.LoadedFilesPanel = uipanel(app.SystemInfoGrid);
            app.LoadedFilesPanel.Title = 'Loaded Files';
            app.GridLayout8 = uigridlayout(app.LoadedFilesPanel);
            app.FileListTree = uitree(app.GridLayout8, 'checkbox');
            app.DataNode = uitreenode(app.FileListTree);
            app.DataNode.Text = 'Data';
            app.DataMetadataNode = uitreenode(app.DataNode);
            app.DataMetadataNode.Text = 'Metadata';
            app.VolumeNode = uitreenode(app.DataNode);
            app.VolumeNode.Text = 'Volume';
            app.NeuronsNode = uitreenode(app.DataNode);
            app.NeuronsNode.Text = 'Neurons';
            app.ProcessingNode = uitreenode(app.FileListTree);
            app.ProcessingNode.Text = 'Processing';
            app.DownsamplingNode = uitreenode(app.ProcessingNode);
            app.DownsamplingNode.Text = 'Downsampling';
            app.SegmentationNode = uitreenode(app.ProcessingNode);
            app.SegmentationNode.Text = 'Segmentation';
            app.ModelNode = uitreenode(app.SegmentationNode);
            app.ModelNode.Text = 'Model';
            app.NeuronInfoNode = uitreenode(app.SegmentationNode);
            app.NeuronInfoNode.Text = 'Neuron Info';
            app.ActivityNode = uitreenode(app.ProcessingNode);
            app.ActivityNode.Text = 'Activity';
            app.ZephIRNode = uitreenode(app.ActivityNode);
            app.ZephIRNode.Text = 'ZephIR';
            app.VideoNode = uitreenode(app.ZephIRNode);
            app.VideoNode.Text = 'Video';
            app.AnnotationsNode = uitreenode(app.ZephIRNode);
            app.AnnotationsNode.Text = 'Annotations';
            app.ZephIRMetaDataNode = uitreenode(app.ZephIRNode);
            app.ZephIRMetaDataNode.Text = 'Metadata';
            app.ActivityCSVNode = uitreenode(app.ActivityNode);
            app.ActivityCSVNode.Text = 'Activity CSV';
            app.OutputNode = uitreenode(app.FileListTree);
            app.OutputNode.Text = 'Output';
            app.NeuroPALArrayNode = uitreenode(app.OutputNode);
            app.NeuroPALArrayNode.Text = 'NeuroPAL Array';
            app.NeuroPALNeuronIDsNode = uitreenode(app.OutputNode);
            app.NeuroPALNeuronIDsNode.Text = 'NeuroPAL Neuron IDs';
            app.Log = uitextarea(app.LogGrid);
            app.Log.Placeholder = '(Empty Log.)';
            app.HoverLabel = uilabel(app.CELL_ID);
            app.HoverLabel.Visible = 'off';
            app.HoverLabel.Text = 'HoverLabel (Don''t Delete)';
            app.AdjustNeuronMarkerAlignmentPanel = uipanel(app.CELL_ID);
            app.AdjustNeuronMarkerAlignmentPanel.Title = 'Adjust Neuron Marker Alignment';
            app.AdjustNeuronMarkerAlignmentPanel.Visible = 'off';
            app.NeuronMarkerGrid = uigridlayout(app.AdjustNeuronMarkerAlignmentPanel);
            app.DeformNeuronMarkersPanel = uipanel(app.NeuronMarkerGrid);
            app.DeformNeuronMarkersPanel.Title = 'Deform Neuron Markers';
            app.GridLayout17_2 = uigridlayout(app.DeformNeuronMarkersPanel);
            app.HeightSpinnerLabel = uilabel(app.GridLayout17_2);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout17_2);
            app.WidthSpinnerLabel = uilabel(app.GridLayout17_2);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.GridLayout17_2);
            app.ScaleEditFieldLabel = uilabel(app.GridLayout17_2);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.GridLayout17_2, 'numeric');
            app.SaveButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed2, true);
            app.SaveButton.Text = 'Save';
            app.MoveNeuronMarkersPanel = uipanel(app.NeuronMarkerGrid);
            app.MoveNeuronMarkersPanel.Title = 'Move Neuron Markers';
            app.GridLayout16_3 = uigridlayout(app.MoveNeuronMarkersPanel);
            app.CenterVerticallyButton = uibutton(app.GridLayout16_3, 'push');
            app.CenterVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @CenterVerticallyButtonPushed, true);
            app.CenterVerticallyButton.Text = 'Center Vertically';
            app.CenterHorizontallyButton = uibutton(app.GridLayout16_3, 'push');
            app.CenterHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @CenterHorizontallyButtonPushed, true);
            app.CenterHorizontallyButton.Text = 'Center Horizontally';
            app.Panel_45 = uipanel(app.GridLayout16_3);
            app.GridLayout27_2 = uigridlayout(app.Panel_45);
            app.CW = uibutton(app.GridLayout27_2, 'push');
            app.CW.ButtonPushedFcn = createCallbackFcn(app, @CWButtonPushed, true);
            app.CW.Text = '↻';
            app.CCW = uibutton(app.GridLayout27_2, 'push');
            app.CCW.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCW.Text = '↺';
            app.Down = uibutton(app.GridLayout27_2, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.Down.Text = '⬇️';
            app.Right = uibutton(app.GridLayout27_2, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Text = '➡️';
            app.Up = uibutton(app.GridLayout27_2, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.Up.Text = '⬆️';
            app.Left = uibutton(app.GridLayout27_2, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Text = '⬅️';
            app.Panel_46 = uipanel(app.GridLayout16_3);
            app.GridLayout18_2 = uigridlayout(app.Panel_46);
            app.MoveEditField = uieditfield(app.GridLayout18_2, 'numeric');
            app.MoveEditField.ValueDisplayFormat = '%.0f';
            app.MoveEditField.Value = 5;
            app.MoveLabel = uilabel(app.GridLayout18_2);
            app.MoveLabel.Text = 'Move ';
            app.pixelsandLabel = uilabel(app.GridLayout18_2);
            app.pixelsandLabel.Text = 'pixels and';
            app.RotateEditField = uieditfield(app.GridLayout18_2, 'numeric');
            app.RotateEditField.Value = 2;
            app.degreesatatimeLabel = uilabel(app.GridLayout18_2);
            app.degreesatatimeLabel.Text = 'degrees at a time.';
            app.Panel_86 = uipanel(app.GridLayout16_3);
            app.GridLayout77 = uigridlayout(app.Panel_86);
            app.FlipHorizontallyButton = uibutton(app.GridLayout77, 'push');
            app.FlipHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontallyButtonPushed, true);
            app.FlipHorizontallyButton.Text = 'Flip Horizontally';
            app.FlipVerticallyButton = uibutton(app.GridLayout77, 'push');
            app.FlipVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticallyButtonPushed, true);
            app.FlipVerticallyButton.Text = 'Flip Vertically';
            app.FlipinZdimensionButton = uibutton(app.GridLayout77, 'push');
            app.FlipinZdimensionButton.ButtonPushedFcn = createCallbackFcn(app, @FlipinZdimensionButtonPushed, true);
            app.FlipinZdimensionButton.Text = 'Flip in Z-dimension';
            app.ImportNeuronsfromCSVButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.ImportNeuronsfromCSVButton.Text = 'Import Neurons from CSV';
            app.CancelButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectNeuronSubsetButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.SelectNeuronSubsetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNeuronSubsetButtonPushed, true);
            app.SelectNeuronSubsetButton.Text = 'Select Neuron Subset';
            app.Panel_43 = uipanel(app.NeuronMarkerGrid);
            app.GridLayout53_2 = uigridlayout(app.Panel_43);
            app.MarkerSizeLabel_2 = uilabel(app.GridLayout53_2);
            app.MarkerSizeLabel_2.Text = 'Marker Size';
            app.MarkerSizeSlider_2 = uislider(app.GridLayout53_2);
            app.MarkerSizeSlider_2.Limits = [1 30];
            app.MarkerSizeSlider_2.MajorTicks = [];
            app.MarkerSizeSlider_2.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.MarkerSizeSlider_2.Value = 5;
            app.ResetMarkerPositionsButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.ResetMarkerPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMarkerPositionsButtonPushed, true);
            app.ResetMarkerPositionsButton.Text = 'Reset Marker Positions';
            app.Panel_44 = uipanel(app.NeuronMarkerGrid);
            app.GridLayout48_2 = uigridlayout(app.Panel_44);
            app.AutojumponIDCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.AutojumponIDCheckBox_2.Text = 'Auto-jump on ID.';
            app.OverlaylastIDdframeCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.OverlaylastIDdframeCheckBox_2.Text = 'Overlay last ID''d frame.';
            app.OverlayNeuroPALvolCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.OverlayNeuroPALvolCheckBox_2.Text = 'Overlay NeuroPAL vol.';
            app.OverlayFrameMIPCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.OverlayFrameMIPCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @OverlayFrameMIPCheckBox_2ValueChanged, true);
            app.OverlayFrameMIPCheckBox_2.Text = 'Overlay Frame MIP.';
            app.ShowallneuronsCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.ShowallneuronsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowallneuronsCheckBox_2ValueChanged, true);
            app.ShowallneuronsCheckBox_2.Text = 'Show all neurons.';
            app.EnableClickDragCheckBox = uicheckbox(app.GridLayout48_2);
            app.EnableClickDragCheckBox.Text = 'Enable Click & Drag.';
            app.EnableinvolumeeditingCheckBox = uicheckbox(app.GridLayout48_2);
            app.EnableinvolumeeditingCheckBox.Text = 'Enable in-volume editing.';
            app.EnableinvolumeeditingCheckBox.Value = true;
            app.AdvancedParameterPanel = uipanel(app.CELL_ID);
            app.AdvancedParameterPanel.Visible = 'off';
            app.NeuronMarkerGrid_2 = uigridlayout(app.AdvancedParameterPanel);
            app.OpenGridSearchMenuButton = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.OpenGridSearchMenuButton.ButtonPushedFcn = createCallbackFcn(app, @OpenGridSearchMenuButtonPushed, true);
            app.OpenGridSearchMenuButton.Text = 'Open Grid Search Menu';
            app.SaveButton_2 = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.SaveButton_2.Text = 'Save';
            app.CancelButton_2 = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_2Pushed, true);
            app.CancelButton_2.Text = 'Cancel';
            app.OpenZephIRdocumentationButton = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.OpenZephIRdocumentationButton.Text = 'Open ZephIR documentation';
            app.TabGroup2 = uitabgroup(app.NeuronMarkerGrid_2);
            app.RegularizationLossCoefficientsTab = uitab(app.TabGroup2);
            app.RegularizationLossCoefficientsTab.Title = 'Regularization & Loss Coefficients';
            app.GridLayout35 = uigridlayout(app.RegularizationLossCoefficientsTab);
            app.dLabel = uilabel(app.GridLayout35);
            app.dLabel.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.dLabel.Text = 'λd';
            app.EditField3Label = uilabel(app.GridLayout35);
            app.EditField3Label.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.EditField3Label.Text = 'Coefficient for feature detection loss, λD. ';
            app.l_d = uieditfield(app.GridLayout35, 'numeric');
            app.l_d.ValueDisplayFormat = '%.2f';
            app.l_d.Enable = 'off';
            app.l_d.Value = -1;
            app.tLabel = uilabel(app.GridLayout35);
            app.tLabel.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.tLabel.Text = 'λt';
            app.EditField2Label = uilabel(app.GridLayout35);
            app.EditField2Label.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.EditField2Label.Text = 'Coefficient for temporal smoothing loss, λT, enforcing a 0th-order linear fit for intensity over n_frame frames.';
            app.l_t = uieditfield(app.GridLayout35, 'numeric');
            app.l_t.ValueDisplayFormat = '%.2f';
            app.l_t.Enable = 'off';
            app.l_t.Value = -1;
            app.NLabel = uilabel(app.GridLayout35);
            app.NLabel.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.NLabel.Text = 'λN';
            app.NmodeLabel = uilabel(app.GridLayout35);
            app.NmodeLabel.Tooltip = {'''disp'': use inter-keypoint displacements'; '''norm'': use inter-keypoint distances (rotation is not penalized)'; '''ljp'': use a Lenard-Jones potential on inter-keypoint distances (collapsing onto the same position is highly penalized)'; 'default: ''disp'''};
            app.NmodeLabel.Text = 'λN mode';
            app.MethodtouseforcalculatingNLabel = uilabel(app.GridLayout35);
            app.MethodtouseforcalculatingNLabel.Text = 'Method to use for calculating LN.';
            app.l_n_mode = uidropdown(app.GridLayout35);
            app.l_n_mode.Items = {'disp', 'norm', 'ljp'};
            app.l_n_mode.Editable = 'on';
            app.l_n_mode.Enable = 'off';
            app.l_n_mode.Value = 'disp';
            app.nn_maxLabel = uilabel(app.GridLayout35);
            app.nn_maxLabel.Tooltip = {'default: 5'};
            app.nn_maxLabel.Text = 'nn_max';
            app.EditFieldLabel_2 = uilabel(app.GridLayout35);
            app.EditFieldLabel_2.Tooltip = {'default: 5'};
            app.EditFieldLabel_2.Text = 'Maximum number of neighboring keypoints to be connected by springs for calculating λN.';
            app.nn_max = uieditfield(app.GridLayout35, 'numeric');
            app.nn_max.ValueDisplayFormat = '%.2f';
            app.nn_max.Enable = 'off';
            app.nn_max.Tooltip = {'default: 5'};
            app.l_d_grid = uicheckbox(app.GridLayout35);
            app.l_d_grid.Text = '';
            app.l_t_grid = uicheckbox(app.GridLayout35);
            app.l_t_grid.Text = '';
            app.l_n_grid = uicheckbox(app.GridLayout35);
            app.l_n_grid.Text = '';
            app.l_n_mode_grid = uicheckbox(app.GridLayout35);
            app.l_n_mode_grid.Text = '';
            app.nn_max_grid = uicheckbox(app.GridLayout35);
            app.nn_max_grid.Text = '';
            app.GridSearchLabel = uilabel(app.GridLayout35);
            app.GridSearchLabel.Text = 'Grid Search?';
            app.ParameterLabel = uilabel(app.GridLayout35);
            app.ParameterLabel.Text = 'Parameter';
            app.DescriptionLabel = uilabel(app.GridLayout35);
            app.DescriptionLabel.Text = 'Description';
            app.EditField4Label = uilabel(app.GridLayout35);
            app.EditField4Label.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.EditField4Label.Text = 'Coefficient for spring constant for intra-keypoint spatial regularization, λN.';
            app.l_n = uieditfield(app.GridLayout35, 'numeric');
            app.l_n.ValueDisplayFormat = '%.2f';
            app.l_n.Enable = 'off';
            app.l_n.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.l_n.Value = 5;
            app.GradientLearningRateTab = uitab(app.TabGroup2);
            app.GradientLearningRateTab.Title = 'Gradient & Learning Rate';
            app.GridLayout32 = uigridlayout(app.GradientLearningRateTab);
            app.lr_ceilingLabel = uilabel(app.GridLayout32);
            app.lr_ceilingLabel.Text = 'lr_ceiling';
            app.EditFieldLabel_3 = uilabel(app.GridLayout32);
            app.EditFieldLabel_3.Text = 'Maximum value for initial learning rate. Note that learning rate decays by a factor of 0.5 every 10 epochs.';
            app.lr_ceiling = uieditfield(app.GridLayout32, 'numeric');
            app.lr_ceiling.Enable = 'off';
            app.lr_ceiling.Value = 0.2;
            app.LRFloorLabel = uilabel(app.GridLayout32);
            app.LRFloorLabel.Text = 'LR Floor';
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel = uilabel(app.GridLayout32);
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel.Text = 'Minimum value for initial learning rate. default: 0.02';
            app.lr_floor = uieditfield(app.GridLayout32, 'numeric');
            app.lr_floor.ValueDisplayFormat = '%.2f';
            app.lr_floor.Enable = 'off';
            app.lr_floor.Value = 0.02;
            app.LRCoeffLabel = uilabel(app.GridLayout32);
            app.LRCoeffLabel.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.LRCoeffLabel.Text = 'LR Coeff';
            app.EditField6Label = uilabel(app.GridLayout32);
            app.EditField6Label.Text = 'Initial learning rate, times the distance between current frame and its parent.';
            app.lr_coeff = uieditfield(app.GridLayout32, 'numeric');
            app.lr_coeff.ValueDisplayFormat = '%.2f';
            app.lr_coeff.Enable = 'off';
            app.lr_coeff.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.lr_coeff.Value = 2;
            app.clip_gradLabel = uilabel(app.GridLayout32);
            app.clip_gradLabel.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.clip_gradLabel.Text = 'clip_grad';
            app.EditFieldLabel_4 = uilabel(app.GridLayout32);
            app.EditFieldLabel_4.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.EditFieldLabel_4.Text = 'Max value for gradients for gradient descent. -1 to uncap.';
            app.clip_grad = uieditfield(app.GridLayout32, 'numeric');
            app.clip_grad.ValueDisplayFormat = '%.2f';
            app.clip_grad.Enable = 'off';
            app.clip_grad.Value = -1;
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel = uilabel(app.GridLayout32);
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Text = 'Multiply gradients in the z-axis by (1 + z_compensator).';
            app.z_comp = uieditfield(app.GridLayout32, 'numeric');
            app.z_comp.ValueDisplayFormat = '%.2f';
            app.z_comp.Enable = 'off';
            app.z_comp.Value = -1;
            app.ZCompLabel = uilabel(app.GridLayout32);
            app.ZCompLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.ZCompLabel.Text = 'Z Comp.';
            app.clip_grad_grid = uicheckbox(app.GridLayout32);
            app.clip_grad_grid.Text = '';
            app.lr_coeff_grid = uicheckbox(app.GridLayout32);
            app.lr_coeff_grid.Text = '';
            app.lr_floor_grid = uicheckbox(app.GridLayout32);
            app.lr_floor_grid.Text = '';
            app.lr_ceil_grid = uicheckbox(app.GridLayout32);
            app.lr_ceil_grid.Text = '';
            app.z_comp_grid = uicheckbox(app.GridLayout32);
            app.z_comp_grid.Text = '';
            app.GridSearchLabel_2 = uilabel(app.GridLayout32);
            app.GridSearchLabel_2.Text = 'Grid Search?';
            app.ParameterLabel_2 = uilabel(app.GridLayout32);
            app.ParameterLabel_2.Text = 'Parameter';
            app.DescriptionLabel_2 = uilabel(app.GridLayout32);
            app.DescriptionLabel_2.Text = 'Description';
            app.RetrievalFunctionTab = uitab(app.TabGroup2);
            app.RetrievalFunctionTab.Title = 'Retrieval Function';
            app.GridLayout50 = uigridlayout(app.RetrievalFunctionTab);
            app.TextArea_6 = uitextarea(app.GridLayout50);
            app.TextArea_6.Value = {'Test'};
            app.GridSearchPanel = uipanel(app.CELL_ID);
            app.GridSearchPanel.Visible = 'off';
            app.GridLayout54 = uigridlayout(app.GridSearchPanel);
            app.TrackingSelectionPanel_2 = uipanel(app.GridLayout54);
            app.TrackingSelectionPanel_2.Title = 'Tracking Selection';
            app.GridLayout49_2 = uigridlayout(app.TrackingSelectionPanel_2);
            app.EditField_3 = uieditfield(app.GridLayout49_2, 'numeric');
            app.EndFrameLabel_2 = uilabel(app.GridLayout49_2);
            app.EndFrameLabel_2.Text = 'End Frame:';
            app.EditField_4 = uieditfield(app.GridLayout49_2, 'numeric');
            app.StartingFrameLabel_2 = uilabel(app.GridLayout49_2);
            app.StartingFrameLabel_2.Text = 'Starting Frame:';
            app.ListBox = uilistbox(app.GridLayout49_2);
            app.WorldlinestoincludeLabel = uilabel(app.GridLayout49_2);
            app.WorldlinestoincludeLabel.Text = 'Worldlines to include:';
            app.TrackspecificregionButton_2 = uibutton(app.GridLayout49_2, 'push');
            app.TrackspecificregionButton_2.Text = 'Track specific region';
            app.VideoPreprocessingPanel = uipanel(app.GridLayout54);
            app.VideoPreprocessingPanel.Title = 'Video Pre-processing';
            app.GridLayout55 = uigridlayout(app.VideoPreprocessingPanel);
            app.DownsamplingFactorxyEditFieldLabel = uilabel(app.GridLayout55);
            app.DownsamplingFactorxyEditFieldLabel.Text = 'Downsampling Factor (xy)';
            app.DownsamplingFactorxyEditField = uieditfield(app.GridLayout55, 'numeric');
            app.DownsamplingFactorzEditFieldLabel = uilabel(app.GridLayout55);
            app.DownsamplingFactorzEditFieldLabel.Text = 'Downsampling Factor (z)';
            app.DownsamplingFactorzEditField = uieditfield(app.GridLayout55, 'numeric');
            app.DownsamplingFactortEditFieldLabel = uilabel(app.GridLayout55);
            app.DownsamplingFactortEditFieldLabel.Text = 'Downsampling Factor (t)';
            app.DownsamplingFactortEditField = uieditfield(app.GridLayout55, 'numeric');
            app.OnlyappliestogridsearchLabel = uilabel(app.GridLayout55);
            app.OnlyappliestogridsearchLabel.Text = '(Only applies to grid search.)';
            app.ListBox_2 = uilistbox(app.GridLayout55);
            app.ChannelstoincludeLabel = uilabel(app.GridLayout55);
            app.ChannelstoincludeLabel.Text = 'Channels to include:';
            app.SearchrangesPanel = uipanel(app.GridLayout54);
            app.SearchrangesPanel.Title = 'Search ranges';
            app.GridLayout56 = uigridlayout(app.SearchrangesPanel);
            app.UITable = uitable(app.GridLayout56);
            app.UITable.ColumnName = {'Parameter'; 'Low End'; 'High End'};
            app.UITable.RowName = {};
            app.EstimatedSearchtimeLabel = uilabel(app.GridLayout54);
            app.EstimatedSearchtimeLabel.Text = 'Estimated Search time:';
            app.CalculateButton = uibutton(app.GridLayout54, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.Panel_47 = uipanel(app.GridLayout54);
            app.GridLayout57 = uigridlayout(app.Panel_47);
            app.EditField_5 = uieditfield(app.GridLayout57, 'numeric');
            app.minutesLabel = uilabel(app.GridLayout57);
            app.minutesLabel.Text = 'minutes.';
            app.StartGridSearchButton = uibutton(app.GridLayout54, 'push');
            app.StartGridSearchButton.Text = 'Start Grid Search';
            app.CancelGridSearchButton = uibutton(app.GridLayout54, 'push');
            app.CancelGridSearchButton.ButtonPushedFcn = createCallbackFcn(app, @CancelGridSearchButtonPushed, true);
            app.CancelGridSearchButton.Text = 'Cancel Grid Search';
            app.TrackingButton = uibutton(app.CELL_ID, 'push');
            app.TrackingButton.ButtonPushedFcn = createCallbackFcn(app, @OpenVideo, true);
            app.TrackingButton.Visible = 'off';
            app.TrackingButton.Text = {'Click to'; 'Load Video'};
            app.IdButton = uibutton(app.CELL_ID, 'push');
            app.IdButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFile, true);
            app.IdButton.Visible = 'off';
            app.IdButton.Text = {'Click to'; 'Load Image'};
            app.ProcessingButton = uibutton(app.CELL_ID, 'push');
            app.ProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessingButtonPushed, true);
            app.ProcessingButton.Visible = 'off';
            app.ProcessingButton.Text = {'Click to'; 'Load File'};
            app.PlaceholderButtonsCovertabsifnodataloadedLabel = uilabel(app.CELL_ID);
            app.PlaceholderButtonsCovertabsifnodataloadedLabel.Visible = 'off';
            app.PlaceholderButtonsCovertabsifnodataloadedLabel.Text = 'Placeholder Buttons (Cover tabs if no data loaded)';
            app.PlaceholderTrackingtabsLabel = uilabel(app.CELL_ID);
            app.PlaceholderTrackingtabsLabel.Visible = 'off';
            app.PlaceholderTrackingtabsLabel.Text = 'Placeholder Tracking tabs';
            app.PlaceholderActivitytabLabel = uilabel(app.CELL_ID);
            app.PlaceholderActivitytabLabel.Visible = 'off';
            app.PlaceholderActivitytabLabel.Text = 'Placeholder Activity tab';
            app.PlaceholderProcTimeline = uipanel(app.CELL_ID);
            app.PlaceholderProcTimeline.Visible = 'off';
            app.GridLayout40_2 = uigridlayout(app.PlaceholderProcTimeline);
            app.Panel_75 = uipanel(app.GridLayout40_2);
            app.GridLayout41_2 = uigridlayout(app.Panel_75);
            app.xEditField_2Label = uilabel(app.GridLayout41_2);
            app.xEditField_2Label.Text = 'x';
            app.proc_xEditField = uieditfield(app.GridLayout41_2, 'numeric');
            app.proc_xEditField.ValueChangedFcn = createCallbackFcn(app, @proc_xEditFieldValueChanged, true);
            app.yEditField_2Label = uilabel(app.GridLayout41_2);
            app.yEditField_2Label.Text = 'y';
            app.proc_yEditField = uieditfield(app.GridLayout41_2, 'numeric');
            app.proc_yEditField.ValueChangedFcn = createCallbackFcn(app, @proc_yEditFieldValueChanged, true);
            app.zEditField_2Label = uilabel(app.GridLayout41_2);
            app.zEditField_2Label.Text = 'z';
            app.proc_zEditField = uieditfield(app.GridLayout41_2, 'numeric');
            app.proc_zEditField.ValueChangedFcn = createCallbackFcn(app, @proc_zEditFieldValueChanged, true);
            app.Panel_76 = uipanel(app.GridLayout40_2);
            app.GridLayout42_2 = uigridlayout(app.Panel_76);
            app.Panel_77 = uipanel(app.GridLayout42_2);
            app.GridLayout43_2 = uigridlayout(app.Panel_77);
            app.tEditField_2Label = uilabel(app.GridLayout43_2);
            app.tEditField_2Label.Text = 't';
            app.proc_tEditField = uieditfield(app.GridLayout43_2, 'numeric');
            app.proc_tEditField.ValueChangedFcn = createCallbackFcn(app, @proc_tEditFieldValueChanged, true);
            app.Panel_78 = uipanel(app.GridLayout42_2);
            app.GridLayout44_2 = uigridlayout(app.Panel_78);
            app.proc_tSlider = uislider(app.GridLayout44_2);
            app.proc_tSlider.Limits = [1 100];
            app.proc_tSlider.ValueChangedFcn = createCallbackFcn(app, @proc_tSliderValueChanged, true);
            app.proc_tSlider.Value = 1;
            app.proc_tGrinder = uislider(app.GridLayout44_2, 'range');
            app.proc_tGrinder.MajorTicks = [];
            app.proc_tGrinder.MajorTickLabels = {''};
            app.proc_tGrinder.ValueChangedFcn = createCallbackFcn(app, @proc_tGrinderValueChanged, true);
            app.proc_tGrinder.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.proc_tGrinder.Visible = 'off';
            app.PlaceholderTimelinesliderLabel = uilabel(app.CELL_ID);
            app.PlaceholderTimelinesliderLabel.Visible = 'off';
            app.PlaceholderTimelinesliderLabel.Text = 'Placeholder Timeline slider';
            app.ProcToggleChannelsPanel = uipanel(app.CELL_ID);
            app.ProcToggleChannelsPanel.Title = 'Toggle Channels';
            app.ProcToggleChannelsPanel.Visible = 'off';
            app.ProcToggleChannelGrid = uigridlayout(app.ProcToggleChannelsPanel);
            app.ProcDICPanel = uipanel(app.ProcToggleChannelGrid);
            app.ProcDICGrid = uigridlayout(app.ProcDICPanel);
            app.ProcDICDropDown = uidropdown(app.ProcDICGrid);
            app.ProcDICDropDown.Items = {'-', '1', '2', '3', '4', '5', '6'};
            app.ProcDICDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcRDropDownValueChanged, true);
            app.ProcDICDropDown.Value = '-';
            app.ProcDICCheckBox = uicheckbox(app.ProcDICGrid);
            app.ProcDICCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcRCheckBoxValueChanged, true);
            app.ProcDICCheckBox.Text = '';
            app.ProcDICLabel = uilabel(app.ProcDICGrid);
            app.ProcDICLabel.Text = 'DIC:';
            app.ProcGFPPanel = uipanel(app.ProcToggleChannelGrid);
            app.ProcGFPGrid = uigridlayout(app.ProcGFPPanel);
            app.ProcGFPLabel = uilabel(app.ProcGFPGrid);
            app.ProcGFPLabel.Text = 'GFP:';
            app.ProcGFPCheckBox = uicheckbox(app.ProcGFPGrid);
            app.ProcGFPCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcRCheckBoxValueChanged, true);
            app.ProcGFPCheckBox.Text = '';
            app.ProcGFPDropDown = uidropdown(app.ProcGFPGrid);
            app.ProcGFPDropDown.Items = {'-', '1', '2', '3', '4', '5', '6'};
            app.ProcGFPDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcRDropDownValueChanged, true);
            app.ProcGFPDropDown.Value = '-';
            app.ProcWhitePanel = uipanel(app.ProcToggleChannelGrid);
            app.ProcWhiteGrid = uigridlayout(app.ProcWhitePanel);
            app.ProcWCheckBox = uicheckbox(app.ProcWhiteGrid);
            app.ProcWCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcRCheckBoxValueChanged, true);
            app.ProcWCheckBox.Text = '';
            app.ProcWDropDown = uidropdown(app.ProcWhiteGrid);
            app.ProcWDropDown.Items = {'-', '1', '2', '3', '4', '5', '6'};
            app.ProcWDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcRDropDownValueChanged, true);
            app.ProcWDropDown.Value = '-';
            app.ProcWLabel = uilabel(app.ProcWhiteGrid);
            app.ProcWLabel.Text = 'W:';
            app.ProcGreenPanel = uipanel(app.ProcToggleChannelGrid);
            app.ProcGreenGrid = uigridlayout(app.ProcGreenPanel);
            app.ProcGLabel = uilabel(app.ProcGreenGrid);
            app.ProcGLabel.Text = 'G:';
            app.ProcGCheckBox = uicheckbox(app.ProcGreenGrid);
            app.ProcGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcRCheckBoxValueChanged, true);
            app.ProcGCheckBox.Text = '';
            app.ProcGCheckBox.Value = true;
            app.ProcGDropDown = uidropdown(app.ProcGreenGrid);
            app.ProcGDropDown.Items = {'-', '1', '2', '3', '4', '5', '6'};
            app.ProcGDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcRDropDownValueChanged, true);
            app.ProcGDropDown.Value = '-';
            app.ProcBluePanel = uipanel(app.ProcToggleChannelGrid);
            app.ProcBlueGrid = uigridlayout(app.ProcBluePanel);
            app.ProcBLabel = uilabel(app.ProcBlueGrid);
            app.ProcBLabel.Text = 'B:';
            app.ProcBCheckBox = uicheckbox(app.ProcBlueGrid);
            app.ProcBCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcRCheckBoxValueChanged, true);
            app.ProcBCheckBox.Text = '';
            app.ProcBCheckBox.Value = true;
            app.ProcBDropDown = uidropdown(app.ProcBlueGrid);
            app.ProcBDropDown.Items = {'-', '1', '2', '3', '4', '5', '6'};
            app.ProcBDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcRDropDownValueChanged, true);
            app.ProcBDropDown.Value = '-';
            app.ProcRedPanel = uipanel(app.ProcToggleChannelGrid);
            app.ProcRedGrid = uigridlayout(app.ProcRedPanel);
            app.ProcRLabel = uilabel(app.ProcRedGrid);
            app.ProcRLabel.Text = 'R:';
            app.ProcRCheckBox = uicheckbox(app.ProcRedGrid);
            app.ProcRCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcRCheckBoxValueChanged, true);
            app.ProcRCheckBox.Text = '';
            app.ProcRCheckBox.Value = true;
            app.ProcRDropDown = uidropdown(app.ProcRedGrid);
            app.ProcRDropDown.Items = {'-', '1', '2', '3', '4', '5', '6'};
            app.ProcRDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcRDropDownValueChanged, true);
            app.ProcRDropDown.Value = '-';
            app.load_graphic = uiimage(app.CELL_ID);
            app.load_graphic.Visible = 'off';
	            app.load_graphic.ImageSource = fullfile(pathToMLAPP, 'Data', 'Images', 'loading.gif');
	            % Keep the main workflow tabs in user-facing order.
	            app.TabGroup.Children = [ ...
	                app.NeuroPALIDTab; ...
	                app.ImageProcessingTab; ...
	                app.VideoTrackingTab; ...
	                app.LogTab];
	            app.TabGroup.SelectedTab = app.NeuroPALIDTab;
	            % Show the figure after all components are created
	            app.CELL_ID.Visible = 'on';
	        end
    end
    % App creation and deletion
    methods (Access = public)
        % Construct app
        function app = visualize_light
            % Create UIFigure and components
            createComponents(app)
            % Register the app with App Designer
            registerApp(app, app.CELL_ID)
            % Execute the startup function
            runStartupFcn(app, @startupFcn)
            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CELL_ID)
        end
    end
end